classdef DialogExcluededPoints < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        excluded_points_table  matlab.ui.control.Table
        add_rowButton          matlab.ui.control.StateButton
        delete_rowButton       matlab.ui.control.StateButton
        closeButton            matlab.ui.control.StateButton
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = public)
        peak_parameter % Description
        CallingApp
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = app.UIFigure.Position(3);
            window_height = app.UIFigure.Position(4);
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.UIFigure.Position = [x,y,window_width,window_height];
            
            if ~isempty(app.CallingApp.excluded_points_table)
                t = app.CallingApp.excluded_points_table;
            else
                %create intial peak_parameter table
                excluded = 1000;
                t = table(excluded);
            end
            
            % Add data to the Table UI Componnt
            app.excluded_points_table.Data = t;
        end

        % Value changed function: add_rowButton
        function add_rowButtonValueChanged(app, event)
            % Get Table UI component data
            t = app.excluded_points_table.DisplayData;
            
            %computes current size of the table to at one line
            size_t = size(t);
            
            %create intial peak_parameter table
            t{size_t(1)+1,1} = t{size_t(1),1}+0.1;
            
            % Add data to the Table UI Component
            app.excluded_points_table.Data = t;
            
            app.add_rowButton.Value = 0;
        end

        % Value changed function: closeButton
        function closeButtonValueChanged(app, event)
            app.CallingApp.excluded_points_table = app.excluded_points_table.DisplayData;
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: delete_rowButton
        function delete_rowButtonValueChanged(app, event)
            % Get Table UI component data
            t = app.excluded_points_table.DisplayData;
            
            %create intial peak_parameter table
            t(end,:) = [];
            
            % Add data to the Table UI Component
            app.excluded_points_table.Data = t;
            
            app.delete_rowButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.excluded_points_table = uitable(app.UIFigure);
            app.excluded_points_table.ColumnName = {'excluded'};
            app.excluded_points_table.RowName = {};
            app.excluded_points_table.ColumnEditable = true;
            app.add_rowButton = uibutton(app.UIFigure, 'state');
            app.add_rowButton.ValueChangedFcn = createCallbackFcn(app, @add_rowButtonValueChanged, true);
            app.add_rowButton.Text = 'add_row';
            app.delete_rowButton = uibutton(app.UIFigure, 'state');
            app.delete_rowButton.ValueChangedFcn = createCallbackFcn(app, @delete_rowButtonValueChanged, true);
            app.delete_rowButton.Text = 'delete_row';
            app.closeButton = uibutton(app.UIFigure, 'state');
            app.closeButton.ValueChangedFcn = createCallbackFcn(app, @closeButtonValueChanged, true);
            app.closeButton.Text = 'close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogExcluededPoints(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end