classdef DialogExportApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportManu                      matlab.ui.Figure
        menuMenu                        matlab.ui.container.Menu
        BrowsePathMenu                  matlab.ui.container.Menu
        ExporttopathMenu                matlab.ui.container.Menu
        ExporttopathButton              matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.StateButton
        PathEditFieldLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        GraphtoexportDropDownLabel      matlab.ui.control.Label
        GraphtoexportDropDown           matlab.ui.control.DropDown
        LowerwavelengthSpinnerLabel     matlab.ui.control.Label
        LowerwavelengthSpinner          matlab.ui.control.Spinner
        UpperwavelengthSpinnerLabel     matlab.ui.control.Label
        UpperwavelengthSpinner          matlab.ui.control.Spinner
        ExportassinglepicturesCheckBox  matlab.ui.control.CheckBox
        MapsettingsLabel                matlab.ui.control.Label
        ExportformatDropDownLabel       matlab.ui.control.Label
        ExportformatDropDown            matlab.ui.control.DropDown
        SpectralPositionLabel           matlab.ui.control.Label
        xSpinnerLabel                   matlab.ui.control.Label
        xSpinner                        matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        ySpinner                        matlab.ui.control.Spinner
        widthSpinnerLabel               matlab.ui.control.Label
        widthSpinner                    matlab.ui.control.Spinner
        heightSpinnerLabel              matlab.ui.control.Label
        heightSpinner                   matlab.ui.control.Spinner
        powerlowSpinnerLabel            matlab.ui.control.Label
        powerlowSpinner                 matlab.ui.control.Spinner
        powerhighSpinnerLabel           matlab.ui.control.Label
        powerhighSpinner                matlab.ui.control.Spinner
        TemperaturelowSpinnerLabel      matlab.ui.control.Label
        TemperaturelowSpinner           matlab.ui.control.Spinner
        TemperaturehighSpinnerLabel     matlab.ui.control.Label
        TemperaturehighSpinner          matlab.ui.control.Spinner
        MapappearanceDropDownLabel      matlab.ui.control.Label
        MapappearanceDropDown           matlab.ui.control.DropDown
        ContourCheckBox                 matlab.ui.control.CheckBox
        GeneralLabel                    matlab.ui.control.Label
        ExportSizeLabel                 matlab.ui.control.Label
        FontsizeSpinnerLabel            matlab.ui.control.Label
        FontsizeSpinner                 matlab.ui.control.Spinner
        locktoareaCheckBox              matlab.ui.control.CheckBox
        MaxCountsEditFieldLabel         matlab.ui.control.Label
        MaxCountsEditField              matlab.ui.control.NumericEditField
        MinCountsEditFieldLabel         matlab.ui.control.Label
        MinCountsEditField              matlab.ui.control.NumericEditField
        ColorstepsEditFieldLabel        matlab.ui.control.Label
        ColorstepsEditField             matlab.ui.control.NumericEditField
        map_colorDropDownLabel          matlab.ui.control.Label
        map_colorDropDown               matlab.ui.control.DropDown
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        ExporttofigButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        CallingApp; % Description
        old_plot;
        label;
        area_dim;
        PLMap_Data;
    end
    
    methods (Access = private)
        
        function save(app)
            %defines the map to export
            
            %--------------------------------------------------------------------------------------------------------
            %define start and end Wavelength index
            %--------------------------------------------------------------------------------------------------------
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.LowerwavelengthSpinner.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.UpperwavelengthSpinner.Value,1);
            power_index_low = find_index(app.PLMap_Data.Power,app.powerlowSpinner.Value,1);
            temperature_index_low = find_index(app.PLMap_Data.Temperature_index,app.TemperaturelowSpinner.Value,1);
            power_index_high = find_index(app.PLMap_Data.Power,app.powerhighSpinner.Value,1);
            temperature_index_high = find_index(app.PLMap_Data.Temperature_index,app.TemperaturehighSpinner.Value,1);
            
            %--------------------------------------------------------------------------------------------------------
            %check if all Wavelength should be exported in different pictures
            %--------------------------------------------------------------------------------------------------------      
            switch app.ExportassinglepicturesCheckBox.Value
                case 1
                    %------------------------------------------------------------------------------------------------
                    %displays a statusBar indicationg the progras of exporting the maps
                    %------------------------------------------------------------------------------------------------
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Export png','Message','1');
                    full_range_count = end_wavelength_index - start_wavelength_index +1;
                    i = 0;
                    for wavelength_index = start_wavelength_index : end_wavelength_index
                        for t_index = temperature_index_low : temperature_index_high
                            for p_index = power_index_low : power_index_high
                                %--------------------------------------------------------------------------------------------
                                %display the persentage of Importing the Data
                                %--------------------------------------------------------------------------------------------
                                i = i+1;
                                Persentage =  i/full_range_count;
                                d.Value = Persentage;
                                d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                
                                %--------------------------------------------------------------------------------------------------------
                                %creats a figure
                                %--------------------------------------------------------------------------------------------------------
                                Fig2 = figure;
                                Fig2.Visible = 'off';
                                ax = axes(Fig2);
                                
                                %--------------------------------------------------------------------------------------------------------
                                %define filename
                                %--------------------------------------------------------------------------------------------------------
                                filename = replace(num2str(app.PLMap_Data.Wavelength(wavelength_index),'%.1f'),'.',',');
                                meta_data = join(['_',replace(num2str(app.PLMap_Data.Power(p_index),'%.1f'),'.',','),'_mW_temperature_index',replace(num2str(app.PLMap_Data.Temperature_index(t_index),'%.1f'),'.',',')],"");
                               
                                %get info for map
                                app.label{1} = join(['Wavelength',': ', filename,' nm'],"");
                                
                                %displays Map
                                [~,~,~,~,~,~] = update_map(app.PLMap_Data,app.PLMap_Data.Temperature_index(t_index),...
                                    app.PLMap_Data.Power(p_index),app.PLMap_Data.Wavelength(wavelength_index),...
                                    app.PLMap_Data.Wavelength(wavelength_index),ax,app.ColorstepsEditField.Value,app.map_colorDropDown.Value,...
                                    app.MinCountsEditField.Value,app.MaxCountsEditField.Value,app.label,...
                                    app.MapappearanceDropDown.Value,app.ContourCheckBox.Value,...
                                    app.CallingApp.contour_lines,app.CallingApp.contourtext,app.area_dim,...
                                    app.locktoareaCheckBox.Value);
                                
                                %--------------------------------------------------------------------------------------------------------
                                %set plot style
                                %--------------------------------------------------------------------------------------------------------
                                ax.FontSize = app.FontsizeSpinner.Value;
                                
                                %--------------------------------------------------------------------------------------------------------
                                %saves the plot to the directory if needed
                                %--------------------------------------------------------------------------------------------------------
                                newstr = split(app.CallingApp.PLMap_Data.Sample,': ');
                                full_filename = join([num2str(newstr{2}),'_wavelength_',filename,meta_data]);
                                saveas(Fig2, fullfile(app.PathEditField.Value, full_filename), app.ExportformatDropDown.Value);
                                delete(Fig2);
                            end
                        end
                    end
                    %------------------------------------------------------------------------------------------------
                    %close the satusBar
                    %------------------------------------------------------------------------------------------------
                    close(d);
                    close(f);
                case 0
                    %------------------------------------------------------------------------------------------------
                    %creats the graphes in the wavelength range for all t and p
                    %------------------------------------------------------------------------------------------------
                    for t = temperature_index_low : temperature_index_high
                        for p = power_index_low : power_index_high
                            %--------------------------------------------------------------------------------------------------------
                                %creats a figure
                                %--------------------------------------------------------------------------------------------------------
                                Fig2 = figure;
                                Fig2.Visible = 'off';
                                ax = axes(Fig2);
                                
                                %--------------------------------------------------------------------------------------------------------
                                %define filename
                                %--------------------------------------------------------------------------------------------------------
                                filename = join([replace(num2str(app.LowerwavelengthSpinner.Value,'%.1f'),'.',','),'-',replace(num2str(app.UpperwavelengthSpinner.Value,'%.1f'),'.',',')],"");
                                meta_data = join(['_nm_',replace(num2str(app.PLMap_Data.Power(p),'%.1f'),'.',','),'_mW_temperature_index',replace(num2str(app.PLMap_Data.Temperature_index(t),'%.1f'),'.',',')],"");
                                
                                %get info for map
                                if isempty(app.TitleEditField.Value)
                                    app.label{1} = join(['Wavelength',': ', filename,' nm'],"");
                                else
                                    app.label{1} = app.TitleEditField.Value;
                                end
                                
                                %displays Map
                                [~,~,~,~,~,~] = update_map(app.PLMap_Data,app.PLMap_Data.Temperature_index(t),...
                                    app.PLMap_Data.Power(p),app.LowerwavelengthSpinner.Value,...
                                    app.UpperwavelengthSpinner.Value,ax,app.ColorstepsEditField.Value,app.map_colorDropDown.Value,...
                                    app.MinCountsEditField.Value,app.MaxCountsEditField.Value,app.label,...
                                    app.MapappearanceDropDown.Value,app.ContourCheckBox.Value,...
                                    app.CallingApp.contour_lines,app.CallingApp.contourtext,app.area_dim,...
                                    app.locktoareaCheckBox.Value);
                                
                                %--------------------------------------------------------------------------------------------------------
                                %set plot style
                                %--------------------------------------------------------------------------------------------------------
                                ax.FontSize = app.FontsizeSpinner.Value;
                                
                                %change plot size
                                Fig2.Units = 'centimeters';
                                Fig2.OuterPosition = [0.25 0.25 app.widthSpinner.Value/10 app.heightSpinner.Value/10];
                                
                                %--------------------------------------------------------------------------------------------------------
                                %saves the plot to the directory if needed
                                %--------------------------------------------------------------------------------------------------------
                                newstr = split(app.CallingApp.PLMap_Data.Sample,': ');
                                full_filename = join([num2str(newstr{2}),'_range_',filename,meta_data,'.',app.ExportformatDropDown.Value]);
                                exportgraphics(Fig2,fullfile(app.PathEditField.Value, full_filename));
                                %saveas(Fig2, fullfile(app.PathEditField.Value, full_filename), app.ExportformatDropDown.Value);
                                delete(Fig2);
                        end
                    end
            end
        end
        
        function get_data(app)
            switch app.GraphtoexportDropDown.Value
                case 'single_graph'
                    app.area_dim = app.CallingApp.area_dim_1;
                    app.locktoareaCheckBox.Value = app.CallingApp.locktoareaCheckBox_1.Value;
                    app.ContourCheckBox.Value = app.CallingApp.ContourCheckBox_1.Value;
                    app.MapappearanceDropDown.Value = app.CallingApp.MapappearanceDropDown_1.Value;
                    b = define_Spinner(app.LowerwavelengthSpinner,app.CallingApp.PLMap_Data.Wavelength,app.CallingApp.MapLowerWavelengthSpinner_1.Value);
                    b = define_Spinner(app.UpperwavelengthSpinner,app.CallingApp.PLMap_Data.Wavelength,app.CallingApp.MapUpperWavelengthSpinner_1.Value);
                    app.ColorstepsEditField.Value = app.CallingApp.MapColorstepsEditField_1.Value;
                    app.MinCountsEditField.Value = app.CallingApp.MapMinCountsEditField_1.Value;
                    app.MaxCountsEditField.Value = app.CallingApp.MapMaxCountsEditField_1.Value;
                    b = define_Spinner(app.powerlowSpinner,app.CallingApp.PLMap_Data.Power,app.CallingApp.PowerSpinner_1.Value);
                    b = define_Spinner(app.powerhighSpinner,app.CallingApp.PLMap_Data.Power,'upper');
                    b = define_Spinner(app.TemperaturelowSpinner,app.CallingApp.PLMap_Data.Temperature_index,app.CallingApp.TemperatureIndexSpinner_1.Value);
                    b = define_Spinner(app.TemperaturehighSpinner,app.CallingApp.PLMap_Data.Temperature_index,'upper');
                case 'multi_graph_1'
                    app.area_dim = app.CallingApp.area_dim_2;
                    app.locktoareaCheckBox.Value = app.CallingApp.locktoareaCheckBox_2.Value;
                    app.ContourCheckBox.Value = app.CallingApp.ContourCheckBox_2.Value;
                    app.MapappearanceDropDown.Value = app.CallingApp.MapappearanceDropDown_2.Value;
                    b = define_Spinner(app.LowerwavelengthSpinner,app.CallingApp.PLMap_Data{1}.Wavelength,app.CallingApp.MapLowerWavelengthSpinner_2.Value);
                    b = define_Spinner(app.UpperwavelengthSpinner,app.CallingApp.PLMap_Data{1}.Wavelength,app.CallingApp.MapUpperWavelengthSpinner_2.Value);
                    app.ColorstepsEditField.Value = app.CallingApp.MapColorstepsEditField_2.Value;
                    app.MinCountsEditField.Value = app.CallingApp.MapMinCountsEditField_2.Value;
                    app.MaxCountsEditField.Value = app.CallingApp.MapMaxCountsEditField_2.Value;
                    b = define_Spinner(app.powerlowSpinner,app.CallingApp.PLMap_Data{1}.Power,app.CallingApp.PowerSpinner_2.Value);
                    b = define_Spinner(app.powerhighSpinner,app.CallingApp.PLMap_Data{1}.Power,'upper');
                    b = define_Spinner(app.TemperaturelowSpinner,app.CallingApp.PLMap_Data{1}.Temperature_index,app.CallingApp.TemperatureIndexSpinner_2.Value);
                    b = define_Spinner(app.TemperaturehighSpinner,app.CallingApp.PLMap_Data{1}.Temperature_index,'upper');
                case 'multi_graph_2'
                    app.area_dim = app.CallingApp.area_dim_3;
                    app.locktoareaCheckBox.Value = app.CallingApp.locktoareaCheckBox_3.Value;
                    app.ContourCheckBox.Value = app.CallingApp.ContourCheckBox_3.Value;
                    app.MapappearanceDropDown.Value = app.CallingApp.MapappearanceDropDown_3.Value;
                    b = define_Spinner(app.LowerwavelengthSpinner,app.CallingApp.PLMap_Data{2}.Wavelength,app.CallingApp.MapLowerWavelengthSpinner_3.Value);
                    b = define_Spinner(app.UpperwavelengthSpinner,app.CallingApp.PLMap_Data{2}.Wavelength,app.CallingApp.MapUpperWavelengthSpinner_3.Value);
                    app.ColorstepsEditField.Value = app.CallingApp.MapColorstepsEditField_3.Value;
                    app.MinCountsEditField.Value = app.CallingApp.MapMinCountsEditField_3.Value;
                    app.MaxCountsEditField.Value = app.CallingApp.MapMaxCountsEditField_3.Value;
                    b = define_Spinner(app.powerlowSpinner,app.CallingApp.PLMap_Data{2}.Power,app.CallingApp.PowerSpinner_3.Value);
                    b = define_Spinner(app.powerhighSpinner,app.CallingApp.PLMap_Data{2}.Power,'upper');
                    b = define_Spinner(app.TemperaturelowSpinner,app.CallingApp.PLMap_Data{2}.Temperature_index,app.CallingApp.TemperatureIndexSpinner_3.Value);
                    b = define_Spinner(app.TemperaturehighSpinner,app.CallingApp.PLMap_Data{2}.Temperature_index,'upper');
                case 'gradient_graph'
                    app.area_dim = app.CallingApp.area_dim_4;
                    app.locktoareaCheckBox.Value = app.CallingApp.locktoareaCheckBox_4.Value;
                    app.ContourCheckBox.Value = app.CallingApp.ContourCheckBox_4.Value;
                    app.MapappearanceDropDown.Value = app.CallingApp.MapappearanceDropDown_4.Value;
                    b = define_Spinner(app.LowerwavelengthSpinner,app.CallingApp.PLMap_Data.Wavelength,app.CallingApp.MapLowerWavelengthSpinner_4.Value);
                    b = define_Spinner(app.UpperwavelengthSpinner,app.CallingApp.PLMap_Data.Wavelength,app.CallingApp.MapUpperWavelengthSpinner_4.Value);
                    app.ColorstepsEditField.Value = app.CallingApp.MapColorstepsEditField_4.Value;
                    app.MinCountsEditField.Value = app.CallingApp.MapMinCountsEditField_4.Value;
                    app.MaxCountsEditField.Value = app.CallingApp.MapMaxCountsEditField_4.Value;
                    b = define_Spinner(app.powerlowSpinner,app.CallingApp.PLMap_Data.Power,app.CallingApp.PowerSpinner_4.Value);
                    b = define_Spinner(app.powerhighSpinner,app.CallingApp.PLMap_Data.Power,'upper');
                    b = define_Spinner(app.TemperaturelowSpinner,app.CallingApp.PLMap_Data.Temperature_index,app.CallingApp.TemperatureIndexSpinner_4.Value);
                    b = define_Spinner(app.TemperaturehighSpinner,app.CallingApp.PLMap_Data.Temperature_index,'upper'); 
                case 'fit_graph'
                    app.area_dim = app.CallingApp.area_dim_5;
                    app.locktoareaCheckBox.Value = app.CallingApp.locktoareaCheckBox_5.Value;
                    app.ContourCheckBox.Value = app.CallingApp.ContourCheckBox_5.Value;
                    app.MapappearanceDropDown.Value = app.CallingApp.MapappearanceDropDown_5.Value;
                    b = define_Spinner(app.LowerwavelengthSpinner,app.CallingApp.PLMap_Data.Wavelength,app.CallingApp.x_min.Value);
                    b = define_Spinner(app.UpperwavelengthSpinner,app.CallingApp.PLMap_Data.Wavelength,app.CallingApp.x_max.Value);
                    app.ColorstepsEditField.Value = app.CallingApp.MapColorstepsEditField_5.Value;
                    app.MinCountsEditField.Value = app.CallingApp.MapMinCountsEditField_5.Value;
                    app.MaxCountsEditField.Value = app.CallingApp.MapMaxCountsEditField_5.Value;
                    b = define_Spinner(app.powerlowSpinner,app.CallingApp.PLMap_Data.Power,app.CallingApp.changingvalue3_1Spinner.Value);
                    b = define_Spinner(app.powerhighSpinner,app.CallingApp.PLMap_Data.Power,'upper');
                    b = define_Spinner(app.TemperaturelowSpinner,app.CallingApp.PLMap_Data.Temperature_index,app.CallingApp.changingvalue4_1Spinner.Value);
                    b = define_Spinner(app.TemperaturehighSpinner,app.CallingApp.PLMap_Data.Temperature_index,'upper');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, map_typ)
            %store main app opject
            app.CallingApp = mainapp;
            switch app.CallingApp.selectedTab.Title
                case 'Single Graph'
                    app.GraphtoexportDropDown.Value = 'single_graph';
                    app.GraphtoexportDropDown.Items = {'single_graph'};
                    app.PLMap_Data = app.CallingApp.PLMap_Data;
                case 'Multi Graph'
                    if (~isempty(app.CallingApp.PLMap_Data{1}))
                        app.GraphtoexportDropDown.Value = 'multi_graph_1';
                        app.GraphtoexportDropDown.Items = {'multi_graph_1', 'multi_graph_2'};
                         app.PLMap_Data = app.CallingApp.PLMap_Data{1};
                    else
                        app.GraphtoexportDropDown.Value = 'multi_graph_2';
                        app.GraphtoexportDropDown.Items = {'multi_graph_1', 'multi_graph_2'};
                         app.PLMap_Data = app.CallingApp.PLMap_Data{2};
                    end
                case 'Gradient'
                    app.GraphtoexportDropDown.Value = 'gradient_graph';
                    app.GraphtoexportDropDown.Items = {'gradient_graph'};
                     app.PLMap_Data = app.CallingApp.PLMap_Data;
                case 'Peak Fitting Tool'
                    app.GraphtoexportDropDown.Value = 'fit_graph';
                    app.GraphtoexportDropDown.Items = {'fit_graph'};
                     app.PLMap_Data = app.CallingApp.PLMap_Data;
%                 case 'Analyze Fit Data'
%                     app.GraphtoexportDropDown.Value = 'analyze_fit_data';
%                     app.GraphtoexportDropDown.Items = {'analyze_fit_data'};
%                      app.PLMap_Data = app.CallingApp.data_map;
            end
            if isempty(app.CallingApp.PLMap_Data)
                opts = 'modal';
                f = errordlg('There are no data in the Plots','Data Error',opts);
                waitfor(f);
                delete(app);
                return
            end
            get_data(app);
            app.old_plot = app.GraphtoexportDropDown.Value;
            app.PathEditField.Value = app.CallingApp.data_path;
            
            app.map_colorDropDown.Value = map_typ;
            
            %get info for map
            app.label{1} = 'Map';
            app.label{2} = 'X [mm]';
            app.label{3} = 'Y [mm]';
            app.label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [app.LowerwavelengthSpinner.Value,app.UpperwavelengthSpinner.Value,app.TemperaturelowSpinner.Value,...
                app.powerlowSpinner.Value,~,~] = update_map(app.PLMap_Data,app.TemperaturelowSpinner.Value, ...
                app.powerlowSpinner.Value,app.LowerwavelengthSpinner.Value,app.UpperwavelengthSpinner.Value, ...
                app.UIAxes,app.ColorstepsEditField.Value,map_typ,app.MinCountsEditField.Value,app.MaxCountsEditField.Value, ...
                app.label,app.MapappearanceDropDown.Value,app.ContourCheckBox.Value,app.CallingApp.contour_lines, ...
                app.CallingApp.contourtext,app.area_dim,app.locktoareaCheckBox.Value);
            
            app.UIAxes.FontSize = app.FontsizeSpinner.Value;
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = app.ExportManu.Position(3);
            window_height = app.ExportManu.Position(4);
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.ExportManu.Position = [x,y,window_width,window_height];
        end

        % Callback function: ExporttopathButton, ExporttopathMenu
        function ExporttopathButtonPushed(app, event)
            %saves the data
            save(app)

            %transfers the path to the caling app
            app.CallingApp.data_path = app.PathEditField.Value;
            delete(app)
        end

        % Callback function: BrowseButton, BrowsePathMenu
        function BrowseButtonValueChanged(app, event)
            %gets the Path of the Data - Note that one has just to give the folder where the Data are
            Path_of_Data = uigetdir(app.PathEditField.Value);
            str = [Path_of_Data,"/"];
            
            %Import datadiractions and define paths
            app.PathEditField.Value = char(join(str,"")); %path containing the data
            app.BrowseButton.Value = 0;
        end

        % Value changed function: GraphtoexportDropDown
        function GraphtoexportDropDownValueChanged(app, event)
            if (~isempty(app.CallingApp.PLMap_Data{app.index}))
                get_data(app)
                app.old_plot = app.GraphtoexportDropDown.Value;
            else
                f = errordlg('There are no data for this Plot','Data Error');
                app.GraphtoexportDropDown.Value = app.old_plot;
                get_data(app);
            end
        end

        % Value changed function: ColorstepsEditField, 
        % ContourCheckBox, FontsizeSpinner, LowerwavelengthSpinner, 
        % MapappearanceDropDown, MaxCountsEditField, 
        % MinCountsEditField, TemperaturelowSpinner, 
        % UpperwavelengthSpinner, map_colorDropDown, powerlowSpinner
        function LowerwavelengthSpinnerValueChanged(app, event)
             %displays Map
            [app.LowerwavelengthSpinner.Value,app.UpperwavelengthSpinner.Value,app.TemperaturelowSpinner.Value,...
                app.powerlowSpinner.Value,~,~] = update_map(app.PLMap_Data,app.TemperaturelowSpinner.Value, ...
                app.powerlowSpinner.Value,app.LowerwavelengthSpinner.Value,app.UpperwavelengthSpinner.Value, ...
                app.UIAxes,app.ColorstepsEditField.Value,app.map_colorDropDown.Value,app.MinCountsEditField.Value, ...
                app.MaxCountsEditField.Value,app.label,app.MapappearanceDropDown.Value,app.ContourCheckBox.Value, ...
                app.CallingApp.contour_lines,app.CallingApp.contourtext,app.area_dim,app.locktoareaCheckBox.Value);
            
            app.UIAxes.FontSize = app.FontsizeSpinner.Value;
        end

        % Value changed function: ExportassinglepicturesCheckBox
        function ExportassinglepicturesCheckBoxValueChanged(app, event)
            switch app.ExportassinglepicturesCheckBox.Value
                case 1
                    app.powerhighSpinnerLabel.Enable = 'off';
                    app.powerhighSpinner.Enable = 'off';
                    app.TemperaturehighSpinnerLabel.Enable = 'off';
                    app.TemperaturehighSpinner.Enable = 'off';
                case 0
                    app.powerhighSpinnerLabel.Enable = 'on';
                    app.powerhighSpinner.Enable = 'on';
                    app.TemperaturehighSpinnerLabel.Enable = 'on';
                    app.TemperaturehighSpinner.Enable = 'on';
            end
        end

        % Value changed function: locktoareaCheckBox
        function locktoareaCheckBoxValueChanged(app, event)
            if app.locktoareaCheckBox.Value
                prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                dlgtitle = 'Input area';
                dims = [1 35];
                definput = {'38','38','10','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                % Handle response
                if isempty(answer)
                    app.locktoareaCheckBox.Value = 0;
                    return
                else
                    app.area_dim(1) = str2num(answer{1});
                    app.area_dim(2) = str2num(answer{2});
                    app.area_dim(3) = str2num(answer{3});
                    app.area_dim(4) = str2num(answer{4});
                end
            else
                app.area_dim = [];
            end
            LowerwavelengthSpinnerValueChanged(app, event);
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            %change map title
            app.label{1} = app.TitleEditField.Value;
            
            %redrawe map
            LowerwavelengthSpinnerValueChanged(app, event);
        end

        % Button pushed function: ExporttofigButton
        function ExporttofigButtonPushed(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map,ax, 'colorbar', app.c_map);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportManu = uifigure('Visible', 'off');
            app.ExportManu.Name = 'Export menu';
            app.menuMenu = uimenu(app.ExportManu);
            app.menuMenu.Text = 'menu';
            app.BrowsePathMenu = uimenu(app.menuMenu);
            app.BrowsePathMenu.MenuSelectedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowsePathMenu.Accelerator = 'b';
            app.BrowsePathMenu.Text = 'Browse Path';
            app.ExporttopathMenu = uimenu(app.menuMenu);
            app.ExporttopathMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttopathButtonPushed, true);
            app.ExporttopathMenu.Accelerator = 'e';
            app.ExporttopathMenu.Text = 'Export to path';
            app.ExporttopathButton = uibutton(app.ExportManu, 'push');
            app.ExporttopathButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttopathButtonPushed, true);
            app.ExporttopathButton.Text = 'Export to path';
            app.BrowseButton = uibutton(app.ExportManu, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Tooltip = {'To select the path where the graphic will be saved.'};
            app.BrowseButton.Text = 'Browse';
            app.PathEditFieldLabel = uilabel(app.ExportManu);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.ExportManu, 'text');
            app.GraphtoexportDropDownLabel = uilabel(app.ExportManu);
            app.GraphtoexportDropDownLabel.Text = 'Graph to export';
            app.GraphtoexportDropDown = uidropdown(app.ExportManu);
            app.GraphtoexportDropDown.Items = {'single_graph', 'multi_graph_1', 'multi_graph_2', 'gradient_graph', 'fit_graph'};
            app.GraphtoexportDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphtoexportDropDownValueChanged, true);
            app.GraphtoexportDropDown.Value = 'single_graph';
            app.LowerwavelengthSpinnerLabel = uilabel(app.ExportManu);
            app.LowerwavelengthSpinnerLabel.Text = 'Lower wavelength';
            app.LowerwavelengthSpinner = uispinner(app.ExportManu);
            app.LowerwavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.UpperwavelengthSpinnerLabel = uilabel(app.ExportManu);
            app.UpperwavelengthSpinnerLabel.Text = 'Upper wavelength';
            app.UpperwavelengthSpinner = uispinner(app.ExportManu);
            app.UpperwavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.ExportassinglepicturesCheckBox = uicheckbox(app.ExportManu);
            app.ExportassinglepicturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportassinglepicturesCheckBoxValueChanged, true);
            app.ExportassinglepicturesCheckBox.Text = 'Export as single pictures';
            app.MapsettingsLabel = uilabel(app.ExportManu);
            app.MapsettingsLabel.Text = 'Map settings';
            app.ExportformatDropDownLabel = uilabel(app.ExportManu);
            app.ExportformatDropDownLabel.Text = 'Export format';
            app.ExportformatDropDown = uidropdown(app.ExportManu);
            app.ExportformatDropDown.Items = {'png', 'jpg', 'eps', 'pdf', 'emf', 'tif'};
            app.ExportformatDropDown.Value = 'png';
            app.SpectralPositionLabel = uilabel(app.ExportManu);
            app.SpectralPositionLabel.Enable = 'off';
            app.SpectralPositionLabel.Text = 'Spectral Position';
            app.xSpinnerLabel = uilabel(app.ExportManu);
            app.xSpinnerLabel.Enable = 'off';
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.ExportManu);
            app.xSpinner.Enable = 'off';
            app.ySpinnerLabel = uilabel(app.ExportManu);
            app.ySpinnerLabel.Enable = 'off';
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.ExportManu);
            app.ySpinner.Enable = 'off';
            app.widthSpinnerLabel = uilabel(app.ExportManu);
            app.widthSpinnerLabel.Text = 'width';
            app.widthSpinner = uispinner(app.ExportManu);
            app.widthSpinner.Value = 15.5;
            app.heightSpinnerLabel = uilabel(app.ExportManu);
            app.heightSpinnerLabel.Text = 'height';
            app.heightSpinner = uispinner(app.ExportManu);
            app.heightSpinner.Value = 9.58;
            app.powerlowSpinnerLabel = uilabel(app.ExportManu);
            app.powerlowSpinnerLabel.Text = 'power low';
            app.powerlowSpinner = uispinner(app.ExportManu);
            app.powerlowSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.powerhighSpinnerLabel = uilabel(app.ExportManu);
            app.powerhighSpinnerLabel.Enable = 'off';
            app.powerhighSpinnerLabel.Text = 'power high';
            app.powerhighSpinner = uispinner(app.ExportManu);
            app.powerhighSpinner.Enable = 'off';
            app.TemperaturelowSpinnerLabel = uilabel(app.ExportManu);
            app.TemperaturelowSpinnerLabel.Text = 'Temperature low';
            app.TemperaturelowSpinner = uispinner(app.ExportManu);
            app.TemperaturelowSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.TemperaturehighSpinnerLabel = uilabel(app.ExportManu);
            app.TemperaturehighSpinnerLabel.Enable = 'off';
            app.TemperaturehighSpinnerLabel.Text = 'Temperature high';
            app.TemperaturehighSpinner = uispinner(app.ExportManu);
            app.TemperaturehighSpinner.Enable = 'off';
            app.MapappearanceDropDownLabel = uilabel(app.ExportManu);
            app.MapappearanceDropDownLabel.Text = 'Map appearance';
            app.MapappearanceDropDown = uidropdown(app.ExportManu);
            app.MapappearanceDropDown.Items = {'integrated', 'averaged'};
            app.MapappearanceDropDown.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.MapappearanceDropDown.Value = 'integrated';
            app.ContourCheckBox = uicheckbox(app.ExportManu);
            app.ContourCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.ContourCheckBox.Text = 'Contour';
            app.GeneralLabel = uilabel(app.ExportManu);
            app.GeneralLabel.Text = 'General';
            app.ExportSizeLabel = uilabel(app.ExportManu);
            app.ExportSizeLabel.Enable = 'off';
            app.ExportSizeLabel.Text = 'Export Size';
            app.FontsizeSpinnerLabel = uilabel(app.ExportManu);
            app.FontsizeSpinnerLabel.Text = 'Font size';
            app.FontsizeSpinner = uispinner(app.ExportManu);
            app.FontsizeSpinner.Limits = [1 Inf];
            app.FontsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.FontsizeSpinner.Value = 14;
            app.locktoareaCheckBox = uicheckbox(app.ExportManu);
            app.locktoareaCheckBox.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBoxValueChanged, true);
            app.locktoareaCheckBox.Text = 'lock to area';
            app.MaxCountsEditFieldLabel = uilabel(app.ExportManu);
            app.MaxCountsEditFieldLabel.Text = 'Max Counts';
            app.MaxCountsEditField = uieditfield(app.ExportManu, 'numeric');
            app.MaxCountsEditField.ValueDisplayFormat = '%1.2e';
            app.MaxCountsEditField.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.MaxCountsEditField.Value = Inf;
            app.MinCountsEditFieldLabel = uilabel(app.ExportManu);
            app.MinCountsEditFieldLabel.Text = 'Min Counts';
            app.MinCountsEditField = uieditfield(app.ExportManu, 'numeric');
            app.MinCountsEditField.ValueDisplayFormat = '%1.2e';
            app.MinCountsEditField.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.MinCountsEditField.Value = -Inf;
            app.ColorstepsEditFieldLabel = uilabel(app.ExportManu);
            app.ColorstepsEditFieldLabel.Text = 'Color steps';
            app.ColorstepsEditField = uieditfield(app.ExportManu, 'numeric');
            app.ColorstepsEditField.RoundFractionalValues = 'on';
            app.ColorstepsEditField.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.ColorstepsEditField.Value = 100;
            app.map_colorDropDownLabel = uilabel(app.ExportManu);
            app.map_colorDropDownLabel.Text = 'map_color';
            app.map_colorDropDown = uidropdown(app.ExportManu);
            app.map_colorDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black', 'wbry'};
            app.map_colorDropDown.ValueChangedFcn = createCallbackFcn(app, @LowerwavelengthSpinnerValueChanged, true);
            app.map_colorDropDown.Value = 'parula';
            app.TitleEditFieldLabel = uilabel(app.ExportManu);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.ExportManu, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.ExporttofigButton = uibutton(app.ExportManu, 'push');
            app.ExporttofigButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttofigButtonPushed, true);
            app.ExporttofigButton.Tooltip = {'Creates a new window into which the graph is copied and can be further processed with the Matlab internal functions.'};
            app.ExporttofigButton.Text = 'Export to fig';
            app.UIAxes = uiaxes(app.ExportManu);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.ExportManu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogExportApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportManu)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportManu)
        end
    end
end