classdef DialogFitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        t_peak_parameter  matlab.ui.control.Table
        add_rowButton     matlab.ui.control.StateButton
        delete_rowButton  matlab.ui.control.StateButton
        closeButton       matlab.ui.control.StateButton
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = public)
        peak_parameter % Description
        CallingApp
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = app.UIFigure.Position(3);
            window_height = app.UIFigure.Position(4);
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.UIFigure.Position = [x,y,window_width,window_height];
            
            if ~isempty(app.CallingApp.fit_parameter_table)
                t = app.CallingApp.fit_parameter_table;
            else
                %create intial peak_parameter table
                wavelength = 1000;
                delta_wavelength = 10;
                wavelength_min = 990;
                wavelength_max = 1010;
                finde_peak = convertCharsToStrings('only_range');
                hight = 500000;
                delta_hight = 500000;
                hight_min = 0;
                hight_max = 1000000;
                find_hight = true;
                FWHM = 0.04;
                delta_FWHM = 0.04;
                FWHM_min = 0;
                FWHM_max = 0.08;
                left_points = 10;
                right_points = 10;
                combined_with = convertCharsToStrings('none');
                
                t = table(wavelength,delta_wavelength,wavelength_min,wavelength_max,finde_peak,hight,delta_hight,hight_min,hight_max,find_hight,FWHM,delta_FWHM,FWHM_min,FWHM_max,left_points,right_points,combined_with);
                
                % Convert Material to categorical
                cats = categorical(t.finde_peak,{'close_to','fixed','only_range'});
                t.finde_peak = cats;
                
                % Convert Material to categorical
                cats = categorical(t.combined_with,{'before','none'});
                t.combined_with = cats;
            end
            
            % Add data to the Table UI Componnt
            app.t_peak_parameter.Data = t;
        end

        % Value changed function: add_rowButton
        function add_rowButtonValueChanged(app, event)
            % Get Table UI component data
            t = app.t_peak_parameter.DisplayData;
            
            %computes current size of the table to at one line
            size_t = size(t);
            
            %check if another peak can be added
            if t{size_t(1),1}+ t{size_t(1),2} + 0.1 > app.CallingApp.x_max.Value
                f = errordlg('The peak position would be out of the defined range. No other peak can be added beyond the last one.','range Error');
                return
            end
            
            %create intial peak_parameter table
            t{size_t(1)+1,1} = t{size_t(1),1}+0.1;
            t{size_t(1)+1,2} = t{size_t(1),2};
            t{size_t(1)+1,3} = t{size_t(1),3}+0.1;
            t{size_t(1)+1,4} = t{size_t(1),4}+0.1;
            t{size_t(1)+1,5} = t{size_t(1),5};
            t{size_t(1)+1,6} = t{size_t(1),6};
            t{size_t(1)+1,7} = t{size_t(1),7};
            t{size_t(1)+1,8} = t{size_t(1),8};
            t{size_t(1)+1,9} = t{size_t(1),9};
            t{size_t(1)+1,10} = t{size_t(1),10};
            t{size_t(1)+1,11} = t{size_t(1),11};
            t{size_t(1)+1,12} = t{size_t(1),12};
            t{size_t(1)+1,13} = t{size_t(1),13};
            t{size_t(1)+1,14} = t{size_t(1),14};
            t{size_t(1)+1,15} = t{size_t(1),15};
            t{size_t(1)+1,16} = t{size_t(1),16};
            t{size_t(1)+1,17} = "none";
            
            % Add data to the Table UI Component
            app.t_peak_parameter.Data = t;
            
            app.add_rowButton.Value = 0;
        end

        % Value changed function: closeButton
        function closeButtonValueChanged(app, event)
            app.CallingApp.fit_parameter_table = app.t_peak_parameter.DisplayData;
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: delete_rowButton
        function delete_rowButtonValueChanged(app, event)
            % Get Table UI component data
            t = app.t_peak_parameter.DisplayData;
            
            %create intial peak_parameter table
            t(end,:) = [];
            
            % Add data to the Table UI Component
            app.t_peak_parameter.Data = t;
            
            app.delete_rowButton.Value = 0;
        end

        % Cell edit callback: t_peak_parameter
        function t_peak_parameterCellEdit(app, event)
            %get the event data
            indices = event.Indices;
            newData = event.NewData;
            previousData = event.PreviousData;
            
            % Get Table UI component data
            t = app.t_peak_parameter.DisplayData;
            
            %% correct the peaks to be in the range of the fitting
            if indices(2) == 1
                if newData < app.CallingApp.x_min.Value || newData > app.CallingApp.x_max.Value
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The peak position is not in the defined fit range.','range Error');
                elseif (newData - t{indices(1),2}) < app.CallingApp.x_min.Value || (newData + t{indices(1),2}) > app.CallingApp.x_max.Value
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The peak position in combination with the specified delta_wavlength is not within the defined fit range.','range Error');
                elseif t{indices(1),indices(2)} >= 0
                    if indices(1) > 1
                        if t{indices(1),indices(2)} > t{indices(1)-1,indices(2)}
                            t{indices(1),3} = t{indices(1),1}-t{indices(1),2};
                            t{indices(1),4} = t{indices(1),1}+t{indices(1),2};
                        else
                            t{indices(1),indices(2)} = previousData;
                            f = errordlg('Peak positions must be in ascending order.','order Error');
                        end
                    else
                        t{indices(1),3} = t{indices(1),1}-t{indices(1),2};
                        t{indices(1),4} = t{indices(1),1}+t{indices(1),2};
                    end
                end
                %checks that ther are no negativ values in the
                %delta_wavlength or if it gose out of the x_range
            elseif indices(2) == 2
                if t{indices(1),indices(2)} < 0
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('No negative values are allowed for  delta_wavlength.','negativ Error');
                elseif ( t{indices(1),1} - t{indices(1),2}) < app.CallingApp.x_min.Value || (t{indices(1),1} + t{indices(1),2}) > app.CallingApp.x_max.Value
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The peak position in combination with the specified delta_wavlength is not within the defined fit range.','range Error');
                elseif t{indices(1),indices(2)} >= 0
                    t{indices(1),3} = t{indices(1),1}-t{indices(1),2};
                    t{indices(1),4} = t{indices(1),1}+t{indices(1),2};
                end
                %checks that ther are no negativ values in the peak hight
            elseif indices(2) == 3 || indices(2) == 4
                if t{indices(1),2} > 0
                    t{indices(1),3} = t{indices(1),1}-t{indices(1),2};
                    t{indices(1),4} = t{indices(1),1}+t{indices(1),2};
                elseif t{indices(1),3} < app.CallingApp.x_min.Value || t{indices(1),4} > app.CallingApp.x_max.Value
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The boundarie is not in the defined fit range.','range Error');
                elseif t{indices(1),3} > t{indices(1),4}
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The maximum value must be greater than the minimum value.','order Error');
                end
                %checks that ther are no negativ values in the peak hight
            elseif indices(2) == 6
                if t{indices(1),indices(2)} < 0
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('No negative values are allowed for the peak height.','negativ Error');
                elseif t{indices(1),indices(2)} < t{indices(1),7}
                    t{indices(1),7} = t{indices(1),indices(2)};
                    t{indices(1),8} = t{indices(1),6}-t{indices(1),7};
                    t{indices(1),9} = t{indices(1),6}+t{indices(1),7};
                elseif t{indices(1),indices(2)} > t{indices(1),9} || t{indices(1),indices(2)} < t{indices(1),8}
                    t{indices(1),8} = t{indices(1),6}-t{indices(1),7};
                    t{indices(1),9} = t{indices(1),6}+t{indices(1),7};
                end
                %checks if the delta_hight is in range
            elseif indices(2) == 7
                if t{indices(1),indices(2)} < 0 || t{indices(1),indices(2)} > t{indices(1),6}
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('delta_hight cannot be less than zero or greater than hight (otherwise hight_min would become negative).','range Error');
                elseif t{indices(1),indices(2)} >= 0
                    t{indices(1),8} = t{indices(1),6}-t{indices(1),7};
                    t{indices(1),9} = t{indices(1),6}+t{indices(1),7};
                end
                %checks if hight_min / hight_max is in range
            elseif indices(2) == 8 || indices(2) == 9
                if t{indices(1),7} > 0
                    t{indices(1),8} = t{indices(1),6}-t{indices(1),7};
                    t{indices(1),9} = t{indices(1),6}+t{indices(1),7};
                elseif t{indices(1),8} < 0
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('No negative values are allowed for the peak height.','negativ Error');
                elseif t{indices(1),8} > t{indices(1),9}
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The maximum value must be greater than the minimum value.','order Error');
                end
                %checks if the FWHM is in range
            elseif indices(2) == 11
                if t{indices(1),indices(2)} < 0
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('The FWHM needs to be positiv','negativ Error');
                elseif t{indices(1),indices(2)} < t{indices(1),12}
                    t{indices(1),12} = t{indices(1),indices(2)};
                    t{indices(1),13} = t{indices(1),indices(2)}-t{indices(1),12};
                    t{indices(1),14} = t{indices(1),indices(2)}+t{indices(1),12};
                elseif t{indices(1),indices(2)} > t{indices(1),14} || t{indices(1),indices(2)} < t{indices(1),13}
                    t{indices(1),13} = t{indices(1),indices(2)}-t{indices(1),12};
                    t{indices(1),14} = t{indices(1),indices(2)}+t{indices(1),12};
                end
                %checks if the delta_hight is in range
            elseif indices(2) == 12
                if t{indices(1),indices(2)} < 0 || t{indices(1),indices(2)} > t{indices(1),7}
                    t{indices(1),indices(2)} = previousData;
                    f = errordlg('delta_FWHM must be between zero and the FWHM.','range Error');
                else
                    t{indices(1),13} = t{indices(1),11}-t{indices(1),12};
                    t{indices(1),14} = t{indices(1),11}+t{indices(1),12};
                end
                %% correct the the points to the left an right to be the same for alle connected peaks
            elseif indices(2) > 14
                %get the table data
                size_table = size(t);
                
                %gets peaks that belong together
                peak_combine = t.combined_with;
                peak_combine_logic = ismember(peak_combine,'before');
                peak_block = zeros(size_table(1),1);
                u = 0;
                for i = 1:size_table(1)
                    if peak_combine_logic(i)
                        peak_block(i) = u;
                    else
                        u = u+1;
                        peak_block(i) = u;
                    end
                end
                
                if indices(2) == 17
                    if indices(1) == 1 && peak_combine_logic(1) == 1
                        t{indices(1),indices(2)} = "none";
                        % Add data to the Table UI Component
                        app.t_peak_parameter.Data = t;
                        return
                    end
                end
                
                %calculate how many peaks are in each block
                for u = 1 : max(peak_block)
                    peak_block_index = find(peak_block == u);
                    peak_block_numel = numel(peak_block_index);
                    peak_fit_counter(u) =  peak_block_numel;
                end
                
                if max(peak_fit_counter) > 8
                    t{indices(1),indices(2)} = "none";
                end
                
                %gets the indexes of the row that are conceted
                peak_block_index = find(peak_block == peak_block(indices(1)));
                
                if indices(2) == 17
                    if peak_combine_logic(indices(1)) == 1
                        t{peak_block_index,15} = t{indices(1),15};
                        t{peak_block_index,16} = t{indices(1),16};
                    end
                else
                    t{peak_block_index,indices(2)} = newData;
                end
            end
            
            % Add data to the Table UI Component
            app.t_peak_parameter.Data = t;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.t_peak_parameter = uitable(app.UIFigure);
            app.t_peak_parameter.ColumnName = {'wavelength'; 'delta_wavelength'; 'wavelength_min'; 'wavelength_max'; 'finde_peak'; 'hight'; 'delta_hight'; 'hight_min'; 'hight_max'; 'find_hight'; 'FWHM'; 'delta_FWHM'; 'FWHM_min'; 'FWHM_max'; 'left_points'; 'right_points'; 'combined_with'};
            app.t_peak_parameter.RowName = {};
            app.t_peak_parameter.ColumnEditable = true;
            app.t_peak_parameter.CellEditCallback = createCallbackFcn(app, @t_peak_parameterCellEdit, true);
            app.add_rowButton = uibutton(app.UIFigure, 'state');
            app.add_rowButton.ValueChangedFcn = createCallbackFcn(app, @add_rowButtonValueChanged, true);
            app.add_rowButton.Text = 'add_row';
            app.delete_rowButton = uibutton(app.UIFigure, 'state');
            app.delete_rowButton.ValueChangedFcn = createCallbackFcn(app, @delete_rowButtonValueChanged, true);
            app.delete_rowButton.Text = 'delete_row';
            app.closeButton = uibutton(app.UIFigure, 'state');
            app.closeButton.ValueChangedFcn = createCallbackFcn(app, @closeButtonValueChanged, true);
            app.closeButton.Text = 'close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogFitApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end