classdef DialogImportApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportManu                      matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        BrowsePathMenu                  matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        StartImportMenu                 matlab.ui.container.Menu
        StartImportButton               matlab.ui.control.Button
        DatatoImportDropDownLabel       matlab.ui.control.Label
        DatatoImportDropDown            matlab.ui.control.DropDown
        HeaderlinesSpinnerLabel         matlab.ui.control.Label
        HeaderlinesSpinner              matlab.ui.control.Spinner
        OnlyfirstfilecontainsxdataCheckBox  matlab.ui.control.CheckBox
        BrowseButton                    matlab.ui.control.StateButton
        ImportwithparallelcomputingCheckBox  matlab.ui.control.CheckBox
        LoadDataButton                  matlab.ui.control.StateButton
        PathEditField                   matlab.ui.control.TextArea
        SavedatatoadditionalpathCheckBox  matlab.ui.control.CheckBox
        FileTable                       matlab.ui.control.Table
        import_typ                      matlab.ui.control.DropDown
        rowofdataSpinnerLabel           matlab.ui.control.Label
        rowofdataSpinner                matlab.ui.control.Spinner
        NameTable                       matlab.ui.control.Table
        Value1SpinnerLabel              matlab.ui.control.Label
        Value1Spinner                   matlab.ui.control.Spinner
        Value2SpinnerLabel              matlab.ui.control.Label
        Value2Spinner                   matlab.ui.control.Spinner
        Value3SpinnerLabel              matlab.ui.control.Label
        Value3Spinner                   matlab.ui.control.Spinner
        Value4SpinnerLabel              matlab.ui.control.Label
        Value4Spinner                   matlab.ui.control.Spinner
        delimiterEditFieldLabel         matlab.ui.control.Label
        delimiterEditField              matlab.ui.control.EditField
        get_refButton                   matlab.ui.control.Button
        ImporttoTabDropDownLabel        matlab.ui.control.Label
        ImporttoTabDropDown             matlab.ui.control.DropDown
        automaticexportafterimportLabel  matlab.ui.control.Label
        region_of_interest_highSpinnerLabel  matlab.ui.control.Label
        region_of_interest_highSpinner  matlab.ui.control.Spinner
        region_of_interest_lowSpinnerLabel  matlab.ui.control.Label
        region_of_interest_lowSpinner   matlab.ui.control.Spinner
        startSpinnerLabel               matlab.ui.control.Label
        startSpinner                    matlab.ui.control.Spinner
        transition_factorSpinnerLabel   matlab.ui.control.Label
        transition_factorSpinner        matlab.ui.control.Spinner
        standard_exportButton           matlab.ui.control.StateButton
        direction                       matlab.ui.control.Switch
        createstandardexportCheckBox    matlab.ui.control.CheckBox
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        CallingApp % Description
        k;
        list_data;
        app_fit;
        app_gradient;
        app_multi;
        app_single;
        path_add_save;
        map_typ;
        min_file_size;
    end
    
    methods (Access = private)
        
        function PL_Map = import(app)
            %set broadcast variables
            row_of_data = app.rowofdataSpinner.Value;
            import_type = app.import_typ.Value;
            
            %defines Data path vor saving
            if app.SavedatatoadditionalpathCheckBox.Value
                path_add_save_temp = uigetdir(app.PathEditField.Value{1},'Select path for saving');
                str = [path_add_save_temp,"/"];
                app.path_add_save = char(join(str,""));
                
                app.PathEditField.Value{end + 1} = char(join(["Your save path is: ",app.path_add_save],""));
                drawnow
            end
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please wait',...
                'Indeterminate','on');
            pause(1)
            %sets the path of saving
            %             switch import_type
            %                 case 'manually'
            %                     path_save = "I:\Sciebo\Sciebo_data\PHD\Daten\Projekte\Trapstates_temp_dependend(Schmidt)\Gate_15_IR_950nm";
            %                 case 'Auto_PL'
            %                     path_save = app.PathEditField.Value{1};
            %             end
            path_save = app.PathEditField.Value{1};
            
            str = [path_save,"/"];
            path_save = char(join(str,""));
            
            %declare of app constant values
            headerlines = app.HeaderlinesSpinner.Value;
            checkbox_state = app.OnlyfirstfilecontainsxdataCheckBox.Value;
            parallel_state = app.ImportwithparallelcomputingCheckBox.Value;
            
%             app.PathEditField.Value{end + 1} = 'preparing files ...';
%             drawnow
            d.Message = 'preparing files ...';
            
            %extract all files without data
            sortedT = app.FileTable.Data;
            sortedS = table2struct(sortedT); % change it back to struct array if necessary
            sortedS = sortedS(sortedT.bytes>app.min_file_size);
            
%             app.PathEditField.Value{end + 1} = 'extracting changing parameters ...';
%             drawnow
            d.Message = 'extracting changing parameters ...';
            
            %prelocate data space
            X_Position_temp = zeros(1,length(sortedS));
            Y_Position_temp = zeros(1,length(sortedS));
            Power_temp = zeros(1,length(sortedS));
            Temperature_temp = zeros(1,length(sortedS));
            
            switch import_type
                case 'Auto_PL'
                    %extract all data-name informations
                    for ii = 1 : length(sortedS)
                        tmp_X_Value = regexp(sortedS(ii).name, '_x_(\w)*\,(\w)*_mm_','tokens');
                        X_Position_temp(ii) = str2double([tmp_X_Value{1}{1},'.',tmp_X_Value{1}{2}]);
                        tmp_Y_Value = regexp(sortedS(ii).name, '_y_(\w)*\,(\w)*_mm_','tokens');
                        Y_Position_temp(ii) = str2double([tmp_Y_Value{1}{1},'.',tmp_Y_Value{1}{2}]);
                        tmp_Power_Value = regexp(sortedS(ii).name, '_V_(\w)*\,(\w)*_mW_','tokens');
                        Power_temp(ii) = str2double([tmp_Power_Value{1}{1},'.',tmp_Power_Value{1}{2}]);
                        tmp_Temperature_Value = regexp(sortedS(ii).name, '_nm_(\w)*\,(\w)*_K_','tokens');
                        Temperature_temp(ii) = str2double([tmp_Temperature_Value{1}{1},'.',tmp_Temperature_Value{1}{2}]);
                    end
                case 'manually'
                    %extract all data-name informations
                    for ii = 1 : length(sortedS)
                        %takes the first file name as an example and splits it at
                        %the delimiter
                        str = convertCharsToStrings(sortedS(ii).name);
                        newStr = split(str,app.delimiterEditField.Value);
                        newStr = replace(newStr,",",".");
                        
                        %get parameter of changing values
                        parameter = get_parameter(app,newStr);
                        X_Position_temp(ii) = cell2mat(parameter(1));
                        Y_Position_temp(ii) = cell2mat(parameter(2));
                        Power_temp(ii) = cell2mat(parameter(3));
                        Temperature_temp(ii) = cell2mat(parameter(4));
                        
                        %                         tmp_X_Value = regexp(sortedS(ii).name, '_T_(\w)*\,(\w)*_K_','tokens');
                        %                         X_Position_temp(ii) = str2double([tmp_X_Value{1}{1},'.',tmp_X_Value{1}{2}]);
                        %                         tmp_Y_Value = regexp(sortedS(ii).name, '(_CV_)+[+-]?\d+\,\d*+(_V_)','match');
                        %                         tmp_Y_Value = regexp(tmp_Y_Value, '[+-]?\d+\,\d*','match');
                        %                         Y_Position_temp(ii) = str2double(strrep(tmp_Y_Value{1}{1},',','.'));
                        %                         tmp_Power_Value = regexp(sortedS(ii).name, '_V_(\w)*\,(\w)*_Hz_','tokens');
                        %                         Power_temp(ii) = str2double([tmp_Power_Value{1}{1},'.',tmp_Power_Value{1}{2}]);
                        %                         tmp_Temperature_Value = regexp(sortedS(ii).name, '_mA_(\w)*\,(\w)*_s_','tokens');
                        %                         Temperature_temp(ii) = str2double([tmp_Temperature_Value{1}{1},'.',tmp_Temperature_Value{1}{2}]);
                    end
            end
            
            %% define unique values of x and find step size
            X_Position = unique(X_Position_temp,'sorted');
            if numel(X_Position) == 1
                X_Steps = 0;
            else
                X_Steps = X_Position(2)-X_Position(1);
            end
            
            %% define unique values of y and find step size
            Y_Position = unique(Y_Position_temp,'sorted');
            if numel(Y_Position) == 1
                Y_Steps = 0;
            else
                Y_Steps = Y_Position(2)-Y_Position(1);
            end
            
            %% define unique values of the laser power and find step size
            Power = unique(Power_temp,'sorted');
            if numel(Power) == 1
                Power_Steps = 0;
            else
                Power_Steps = Power(2)-Power(1);
            end
            
            %initiate Data Map
            fieldnames = convertCharsToStrings({sortedS(:).name});
            fieldfolders = convertCharsToStrings({sortedS(:).folder});
            data_byt_size = extractfield(sortedS,'bytes');
            
            %sets the path of saving
            switch import_type
                case 'manually'
                    index = 1;
                case 'Auto_PL'
                    newStr = split(sortedS(1).name,'_');
                    str_temp = [newStr(4:length(newStr)-3)];
                    str = join(str_temp,"_");
                    index = find(contains(fieldnames, str));
            end
            
            
            [~,index_wavelength_data] = max(data_byt_size,[],'all','linear');
            fid = fopen(fullfile(sortedS(index_wavelength_data).folder,sortedS(index_wavelength_data).name), 'r');
            data = textscan(fid, '%s%s%s%[^\n\r]', 'Headerlines', headerlines, 'Delimiter', '\t', 'ReturnOnError', false);
            fclose(fid);
            
            switch import_type
                case 'manually'
                    %create data vector for wavelength
                    Wavelength_Vector = str2double(strrep(data{:,1},',','.'));
                    [Wavelength_Vector_unique,ia,~] = unique(Wavelength_Vector,'sorted');
                    Wavelength_Vector_unique = Wavelength_Vector;
                    unique_wavelength_state = 0;
                case 'Auto_PL'
                    %create data vector for wavelength
                    Wavelength_Vector = str2double(strrep(data{:,1},',','.'));
                    [Wavelength_Vector_unique,ia,~] = unique(Wavelength_Vector,'sorted');
                    if length(Wavelength_Vector_unique) ~= length(Wavelength_Vector)
                        unique_wavelength_state = 1;
                    else
                        unique_wavelength_state = 0;
                    end
            end
            
            
            %create data tensors
            data_map = ones(length(index),length(Power),length(Y_Position),length(X_Position),length(Wavelength_Vector_unique));
            temp_map = NaN(length(index),length(Power),length(Y_Position),length(X_Position));
            Counts_list = ones(length(sortedS),length(Wavelength_Vector_unique));
            
            switch import_type
                case 'manually'
                    %prelocate and reset temp variables
                    Counts_list(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                case 'Auto_PL'
                    %prelocate and reset temp variables
                    counts = str2double(strrep(data{:,row_of_data},',','.'));
                    %set first count values
                    switch unique_wavelength_state
                        case 1
                            Counts_list(1,:) = counts(ia);
                        case 0
                            Counts_list(1,:) = counts;
                    end
            end
            
%             app.PathEditField.Value{end + 1} = 'extracting meta data ...';
%             drawnow
            d.Message = 'extracting meta data ...';
            
            %set the Meta Data from header
            fid = fopen(fullfile(sortedS(index_wavelength_data).folder,sortedS(index_wavelength_data).name), 'r');
            data = textscan(fid, '%s%s%s%[^\n\r]', 'Delimiter', '\t', 'ReturnOnError', false);
            fclose(fid);
            
            switch import_type
                case 'manually'
                    Sample = data{1,1}(2);
                    Sample = Sample{1};
                    
                    Date = data{1,1}(4);
                    Date = Date{1};
                    
                    Measurment_time = data{1,1}(11);
                    Measurment_time = Measurment_time{1};
                case 'Auto_PL'
                    Sample = data{1,1}(2);
                    Sample = Sample{1};
                    
                    Date = data{1,1}(4);
                    Date = Date{1};
                    
                    Measurment_time_1 = data{1,1}(7);
                    Measurment_time_1 = Measurment_time_1{1};
                    
                    Measurment_time_2 = data{1,2}(7);
                    Measurment_time_2 = Measurment_time_2{1};
                    
                    Measurment_time = convertStringsToChars(strcat(Measurment_time_1," ",Measurment_time_2));
            end
            
            %start the import of the Counts
%             app.PathEditField.Value{end + 1} = 'start import ...';
%             drawnow
            d.Message = 'import data ...';
            pause(1)
            switch parallel_state
                case 1
                    parfor i = 1 : length(sortedS)
                        %prelocate and reset temp variables
                        counts = zeros(1,numel(Wavelength_Vector));
                        
                        fid = fopen(fullfile(fieldfolders(i),fieldnames(i)), 'r');
                        data = textscan(fid, '%s%s%s%[^\n\r]', 'Headerlines', headerlines, 'Delimiter', '\t', 'ReturnOnError', false);
                        fclose(fid);
                        if i == index_wavelength_data
                            counts(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                        else
                            switch checkbox_state
                                case 1
                                    counts(1,:) = str2double(strrep(data{:,1},',','.'));
                                case 0
                                    counts(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                            end
                        end
                        
                        
                        switch import_type
                            case 'manually'
                                Counts_list(i,:) = counts;
                            case 'Auto_PL'
                                %sets the counts in the counts list
                                switch unique_wavelength_state
                                    case 1
                                        Counts_list(i,:) = counts(ia);
                                    case 0
                                        Counts_list(i,:) = counts;
                                end
                        end
                    end
                case 0
                    % sort files row wise left to right - bottom to top
                    j = 0;
                    
                    for i = setdiff(1 : length(sortedS), index_wavelength_data)
                        
                        j = j+1;
                        
                        %display the persentage of Importing the Data
                        Persentage =  (j-1)/(length(sortedS));
                        d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                        
                        %prelocate and reset temp variables
                        counts = zeros(1,numel(Wavelength_Vector));
                        
                        fid = fopen(fullfile(fieldfolders(i),fieldnames(i)), 'r');
                        data = textscan(fid, '%s%s%s%[^\n\r]', 'Headerlines', headerlines, 'Delimiter', '\t', 'ReturnOnError', false);
                        fclose(fid);
                        switch checkbox_state
                            case 1
                                counts(1,:) = str2double(strrep(data{:,1},',','.'));
                            case 0
                                counts(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                        end
                        
                        %sets the counts in the counts list
                        switch import_type
                            case 'manually'
                                Counts_list(i,:) = counts;
                            case 'Auto_PL'
                                %sets the counts in the counts list
                                switch unique_wavelength_state
                                    case 1
                                        Counts_list(i,:) = counts(ia);
                                    case 0
                                        Counts_list(i,:) = counts;
                                end
                        end
                    end
            end
%             app.PathEditField.Value{end + 1} = 'end import ...';
%             drawnow
%             
%             app.PathEditField.Value{end + 1} = 'combining data ...';
%             drawnow
            d.Message = 'combining data ...';
            
            temp_index = NaN(1,length(sortedS));
            %fill the datamap
            for q = 1 : length(sortedS)
                n = find(index <= q,1,'last');
                p = find(Power == Power_temp(q),1);
                y = find(Y_Position == Y_Position_temp(q),1);
                x = find(X_Position == X_Position_temp(q),1);
                data_map(n,p,y,x,:) = Counts_list(q,:);
                temp_map(n,p,y,x) = Temperature_temp(1,q);
                temp_index(q) = n;
            end
            
            temp_index = unique(temp_index,'sorted');
            
            %assine data to structure
            PL_Map.data_array = data_map;
            PL_Map.data_array = int32(PL_Map.data_array);
            PL_Map.Wavelength = Wavelength_Vector_unique;
            PL_Map.X_vector = X_Position;
            PL_Map.X_Steps = X_Steps;
            PL_Map.Y_vector = Y_Position;
            PL_Map.Y_Steps = Y_Steps;
            PL_Map.Power = Power;
            PL_Map.Power_Steps = Power_Steps;
            PL_Map.Temperature_array = temp_map;
            PL_Map.Temperature_index = temp_index;
            PL_Map.Sample = Sample;
            PL_Map.Date = Date;
            PL_Map.Measurment_time = Measurment_time;
            
            app.PathEditField.Value{end + 1} = char(join(["save to path: ",path_save],""));
            drawnow
            
            %save data into .mat file
            save([path_save, 'Data_PL_Map'], 'PL_Map','-v7.3','-nocompression')
            if app.SavedatatoadditionalpathCheckBox.Value
                app.PathEditField.Value{end + 1} = char(join(["save to path: ",app.path_add_save],""));
                drawnow
                
                save([app.path_add_save, 'Data_PL_Map'], 'PL_Map','-v7.3','-nocompression')
            end
            pause(2)
            clear X_Position_temp Y_Position_temp Power_temp tmp_X_Value tmp_Y_Value tmp_Power_Value
            
            %close the statusbar
            close(d);
            close(f);
        end
        
        function get_reference(app)
            %set broadcast variables
            row_of_data = app.rowofdataSpinner.Value;
            import_type = app.import_typ.Value;
            
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please wait',...
                'Indeterminate','on');
            drawnow
            
            %declare of app constant values
            headerlines = app.HeaderlinesSpinner.Value;
            checkbox_state = app.OnlyfirstfilecontainsxdataCheckBox.Value;
            parallel_state = app.ImportwithparallelcomputingCheckBox.Value;

            d.Message = 'preparing files ...';
            
            %extract all files without data
            sortedT = app.FileTable.Data;
            sortedS = table2struct(sortedT); % change it back to struct array if necessary
            sortedS = sortedS(sortedT.bytes>app.CallingApp.min_file_size);
            
            d.Message = 'extracting changing parameters ...';
            
            %prelocate data space
            X_Position_temp = zeros(1,length(sortedS));
            Y_Position_temp = zeros(1,length(sortedS));
            Power_temp = zeros(1,length(sortedS));
            Temperature_temp = zeros(1,length(sortedS));
            
            switch import_type
                case 'Auto_PL'
                    %extract all data-name informations
                    for ii = 1 : length(sortedS)
                        tmp_X_Value = regexp(sortedS(ii).name, '_x_(\w)*\,(\w)*_mm_','tokens');
                        X_Position_temp(ii) = str2double([tmp_X_Value{1}{1},'.',tmp_X_Value{1}{2}]);
                        tmp_Y_Value = regexp(sortedS(ii).name, '_y_(\w)*\,(\w)*_mm_','tokens');
                        Y_Position_temp(ii) = str2double([tmp_Y_Value{1}{1},'.',tmp_Y_Value{1}{2}]);
                        tmp_Power_Value = regexp(sortedS(ii).name, '_V_(\w)*\,(\w)*_mW_','tokens');
                        Power_temp(ii) = str2double([tmp_Power_Value{1}{1},'.',tmp_Power_Value{1}{2}]);
                        tmp_Temperature_Value = regexp(sortedS(ii).name, '_nm_(\w)*\,(\w)*_K_','tokens');
                        Temperature_temp(ii) = str2double([tmp_Temperature_Value{1}{1},'.',tmp_Temperature_Value{1}{2}]);
                    end
                case 'manually'
                    %extract all data-name informations
                    for ii = 1 : length(sortedS)
                        %takes the first file name as an example and splits it at
                        %the delimiter
                        str = convertCharsToStrings(sortedS(ii).name);
                        newStr = split(str,app.delimiterEditField.Value);
                        newStr = replace(newStr,",",".");
                        
                        %get parameter of changing values
                        parameter = get_parameter(app,newStr);
                        X_Position_temp(ii) = cell2mat(parameter(1));
                        Y_Position_temp(ii) = cell2mat(parameter(2));
                        Power_temp(ii) = cell2mat(parameter(3));
                        Temperature_temp(ii) = cell2mat(parameter(4));
                    end
            end
            
            %% define unique values of x and find step size
            X_Position = unique(X_Position_temp,'sorted');
            if numel(X_Position) == 1
                X_Steps = 0;
            else
                X_Steps = X_Position(2)-X_Position(1);
            end
            
            %% define unique values of y and find step size
            Y_Position = unique(Y_Position_temp,'sorted');
            if numel(Y_Position) == 1
                Y_Steps = 0;
            else
                Y_Steps = Y_Position(2)-Y_Position(1);
            end
            
            %% define unique values of the laser power and find step size
            Power = unique(Power_temp,'sorted');
            if numel(Power) == 1
                Power_Steps = 0;
            else
                Power_Steps = Power(2)-Power(1);
            end
            
            %initiate Data Map
            fieldnames = convertCharsToStrings({sortedS(:).name});
            fieldfolders = convertCharsToStrings({sortedS(:).folder});
            data_byt_size = extractfield(sortedS,'bytes');
            
            %sets the path of saving
            switch import_type
                case 'manually'
                    index = 1;
                case 'Auto_PL'
                    newStr = split(sortedS(1).name,'_');
                    str_temp = [newStr(4:length(newStr)-3)];
                    str = join(str_temp,"_");
                    index = find(contains(fieldnames, str));
            end
            
            
            [~,index_wavelength_data] = max(data_byt_size,[],'all','linear');
            fid = fopen(fullfile(sortedS(index_wavelength_data).folder,sortedS(index_wavelength_data).name), 'r');
            data = textscan(fid, '%s%s%s%[^\n\r]', 'Headerlines', headerlines, 'Delimiter', '\t', 'ReturnOnError', false);
            fclose(fid);
            
            switch import_type
                case 'manually'
                    %create data vector for wavelength
                    Wavelength_Vector = str2double(strrep(data{:,1},',','.'));
                    [Wavelength_Vector_unique,ia,~] = unique(Wavelength_Vector,'sorted');
                    Wavelength_Vector_unique = Wavelength_Vector;
                    unique_wavelength_state = 0;
                case 'Auto_PL'
                    %create data vector for wavelength
                    Wavelength_Vector = str2double(strrep(data{:,1},',','.'));
                    [Wavelength_Vector_unique,ia,~] = unique(Wavelength_Vector,'sorted');
                    if length(Wavelength_Vector_unique) ~= length(Wavelength_Vector)
                        unique_wavelength_state = 1;
                    else
                        unique_wavelength_state = 0;
                    end
            end
            
            
            %create data tensors
            data_map = ones(length(index),length(Power),length(Y_Position),length(X_Position),length(Wavelength_Vector_unique));
            temp_map = NaN(length(index),length(Power),length(Y_Position),length(X_Position));
            Counts_list = ones(length(sortedS),length(Wavelength_Vector_unique));
            
            switch import_type
                case 'manually'
                    %prelocate and reset temp variables
                    Counts_list(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                case 'Auto_PL'
                    %prelocate and reset temp variables
                    counts = str2double(strrep(data{:,row_of_data},',','.'));
                    %set first count values
                    switch unique_wavelength_state
                        case 1
                            Counts_list(1,:) = counts(ia);
                        case 0
                            Counts_list(1,:) = counts;
                    end
            end
            
            d.Message = 'extracting meta data ...';
            
            %set the Meta Data from header
            fid = fopen(fullfile(sortedS(index_wavelength_data).folder,sortedS(index_wavelength_data).name), 'r');
            data = textscan(fid, '%s%s%s%[^\n\r]', 'Delimiter', '\t', 'ReturnOnError', false);
            fclose(fid);
            
            %start the import of the Counts
            d.Message = 'import data ...';
            pause(1)
            
            switch parallel_state
                case 1
                    parfor i = 1 : length(sortedS)
                        %prelocate and reset temp variables
                        counts = zeros(1,numel(Wavelength_Vector));
                        
                        fid = fopen(fullfile(fieldfolders(i),fieldnames(i)), 'r');
                        data = textscan(fid, '%s%s%s%[^\n\r]', 'Headerlines', headerlines, 'Delimiter', '\t', 'ReturnOnError', false);
                        fclose(fid);
                        if i == index_wavelength_data
                            counts(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                        else
                            switch checkbox_state
                                case 1
                                    counts(1,:) = str2double(strrep(data{:,1},',','.'));
                                case 0
                                    counts(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                            end
                        end
                        
                        
                        switch import_type
                            case 'manually'
                                Counts_list(i,:) = counts;
                            case 'Auto_PL'
                                %sets the counts in the counts list
                                switch unique_wavelength_state
                                    case 1
                                        Counts_list(i,:) = counts(ia);
                                    case 0
                                        Counts_list(i,:) = counts;
                                end
                        end
                    end
                case 0
                    % sort files row wise left to right - bottom to top
                    j = 0;
                    
                    for i = setdiff(1 : length(sortedS), index_wavelength_data)
                        
                        j = j+1;
                        
                        %display the persentage of Importing the Data
                        Persentage =  (j-1)/(length(sortedS));
                        d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                        
                        %prelocate and reset temp variables
                        counts = zeros(1,numel(Wavelength_Vector));
                        
                        fid = fopen(fullfile(fieldfolders(i),fieldnames(i)), 'r');
                        data = textscan(fid, '%s%s%s%[^\n\r]', 'Headerlines', headerlines, 'Delimiter', '\t', 'ReturnOnError', false);
                        fclose(fid);
                        switch checkbox_state
                            case 1
                                counts(1,:) = str2double(strrep(data{:,1},',','.'));
                            case 0
                                counts(1,:) = str2double(strrep(data{:,row_of_data},',','.'));
                        end
                        
                        %sets the counts in the counts list
                        switch import_type
                            case 'manually'
                                Counts_list(i,:) = counts;
                            case 'Auto_PL'
                                %sets the counts in the counts list
                                switch unique_wavelength_state
                                    case 1
                                        Counts_list(i,:) = counts(ia);
                                    case 0
                                        Counts_list(i,:) = counts;
                                end
                        end
                    end
            end

            d.Message = 'combining data ...';
            
            temp_index = NaN(1,length(sortedS));
            %fill the datamap
            for q = 1 : length(sortedS)
                n = find(index <= q,1,'last');
                p = find(Power == Power_temp(q),1);
                y = find(Y_Position == Y_Position_temp(q),1);
                x = find(X_Position == X_Position_temp(q),1);
                data_map(n,p,y,x,:) = Counts_list(q,:);
                temp_map(n,p,y,x) = Temperature_temp(1,q);
                temp_index(q) = n;
            end
            
            temp_index = unique(temp_index,'sorted');
            
            %assine data to structure
            PL_Map.data_array = data_map;
            PL_Map.Wavelength = Wavelength_Vector_unique;
            PL_Map.X_vector = X_Position;
            PL_Map.X_Steps = X_Steps;
            PL_Map.Y_vector = Y_Position;
            PL_Map.Y_Steps = Y_Steps;
            PL_Map.Power = Power;
            PL_Map.Power_Steps = Power_Steps;
            PL_Map.Temperature_array = temp_map;
            PL_Map.Temperature_index = temp_index;
            
            pause(2)
            clear X_Position_temp Y_Position_temp Power_temp tmp_X_Value tmp_Y_Value tmp_Power_Value
            
            %close the statusbar
            close(d);
            close(f);
            
            %gets the summed intensity from the ref peaces
            idw_start = find_index(Wavelength_Vector_unique,750,1);
            idw_end = find_index(Wavelength_Vector_unique,899,1);
            j = 0;
            for x = 2 : 0.25 : 5
                for y = 1.5 : 0.25 : 2.5
                    j = j +1;
                    idx = find_index(X_Position,x,1);
                    idy = find_index(Y_Position,y,1);
                    v_counts = data_map(1,1,idy,idx,idw_start:idw_end);
                    qw1(j,1) = sum(v_counts,'all');
                end
            end
            
            j = 0;
            for x = 73 : 0.25 : 75
                for y = 1.5 : 0.25 : 2.5
                    j = j +1;
                    idx = find_index(X_Position,x,1);
                    idy = find_index(Y_Position,y,1);
                    v_counts = data_map(1,1,idy,idx,idw_start:idw_end);
                    qw2(j,1) = sum(v_counts,'all');
                end
            end
            
            idw_start = find_index(Wavelength_Vector_unique,1021,1);
            idw_end = find_index(Wavelength_Vector_unique,1400,1);
            j = 0;
            for x = 1.5 : 0.25 : 4.5
                for y = 6.5 : 0.25 : 7.5
                    j = j +1;
                    idx = find_index(X_Position,x,1);
                    idy = find_index(Y_Position,y,1);
                    v_counts = data_map(1,1,idy,idx,idw_start:idw_end);
                    qd1(j,1) = sum(v_counts,'all');
                end
            end
            
            j = 0;
            for x = 73 : 0.25 : 75
                for y = 6.5 : 0.25 : 7.5
                    j = j +1;
                    idx = find_index(X_Position,x,1);
                    idy = find_index(Y_Position,y,1);
                    v_counts = data_map(1,1,idy,idx,idw_start:idw_end);
                    qd2(j,1) = sum(v_counts,'all');
                end
            end
            
            %combines the to ref samples
            qw = [qw1;qw2];
            qd = [qd1;qd2];
            
            qw_mean = mean(qw,'all');
            qd_mean = mean(qd,'all');
            
            %puts in the data in the table
            ref_sample = {'qw';'qd'};
            mean_counts = [qw_mean;qd_mean];
            
            T = table(ref_sample,mean_counts);
            app.FileTable.Data = T;
        end
        
        function data_tabele = select_data(app)
            %start_path = pwd;
            [file,path] = uigetfile('*','Select data','MultiSelect', 'on');
            str = [path];
            
            %Import datadiractions and define paths
            app.PathEditField.Value = cellstr(char(str)); %path containing the data
            app.CallingApp.data_path = char(str);
            %cd(path);
            full_file_list = dir(path);
            fieldnames = convertCharsToStrings({full_file_list(:).name});
            data_structure = full_file_list(ismember(fieldnames,file));
            T = struct2table(data_structure); % convert the struct array to a table
            data_tabele = sortrows(T, 'date'); % sort the table by 'DOB'
            
            %cd(start_path);
        end
        
        function [PL_Map,Data_Fit] = load_data(app)
            %Imports the Data to a Variable
            temp_import_Structure = load([app.PathEditField.Value{1}, 'Data_PL_Map']);
            PL_Map = temp_import_Structure.PL_Map;
            
            %check if the data have the old formate and changes it
            if isa(PL_Map.data_array,'double')
                %check if the data are realy only int (for CV data they are
                %not! so then these data stay the same
                TF = floor(PL_Map.data_array) == PL_Map.data_array;
                if all(TF,'all')
                    PL_Map.data_array = int32(PL_Map.data_array);
                    
                    %save data into .mat file
                    %nocompression so data load faster
                    save([app.PathEditField.Value{1}, 'Data_PL_Map'], 'PL_Map','-v7.3','-nocompression');
                end
            end
            
            Data_Fit = [];
            
            %change folder to surch for fit data
            oldFolder = cd(app.PathEditField.Value{1});
            files = ls('*Data_Fit*');
            if ~isempty(files)
                    Data_Fit = load([app.PathEditField.Value{1}, files]);
            end
            cd(oldFolder);
        end
        
        function parameter = select_parameter(app,newStr,index)
            parameter = str2num(newStr(index));
            if isempty(parameter)
                parameter = {convertStringsToChars(newStr(index))};
            else
                parameter = num2cell(parameter);
            end
        end
        
        function parameter = get_parameter(app,newStr)
            parameter = {};
            %findes the 4 parameter from the file name
            index = [app.Value1Spinner.Value,app.Value2Spinner.Value,app.Value3Spinner.Value,app.Value4Spinner.Value];
            for i = 1 : 4
                parameter(i) = select_parameter(app,newStr,index(i));
            end
            
            %displays the parameter in the table
            set(app.NameTable,'Data',parameter(1,:));
            
        end
        
        function create_data_list(app,path,d)
            d.Message = 'Create file list';
            
            list = dir(path); %get all dir enteries
            list = list(~ismember({list.name},{'.' '..'})); %clear unwanted data
            current_list = app.list_data;
            
            %check for loadable data
            TF_load_data = ismember({list.name},{'Data_PL_Map.mat'});
            if any(TF_load_data)
                app.list_data = list(TF_load_data);
                app.PathEditField.Value{end + 1} = 'found loadable data! Please press -Load Data-';
                drawnow
                return
            elseif ~isempty(current_list(1).name)
                if any(ismember({current_list.name},{'Data_PL_Map.mat'}))
                    app.PathEditField.Value{end + 1} = 'found loadable data! Please press -Load Data-';
                    drawnow
                    return
                end
            end
            
            %if an folder was created but its empty jump to the next
            if isempty(list)
                quest_text = join(['no data in path: ', path]);
                answer = questdlg(quest_text,'No data in folder','continue','copy path','continue');
                % Handle response
                switch answer
                    case 'continue'
                        return
                    case 'copy path'
                        copyed_array = mat2clip(path);
                        return
                end
            end
            
            data_test = list.isdir; %check for other subdirectoryes

            if data_test == 1
                %if the subset has the coverted data only open this ones
                if any(ismember({list.name},{'Converted Data'}))
                    path_new = join([path,'\','Converted Data'],'');
                    
                    d.Message = 'open Converted Data';

                    create_data_list(app,path_new,d);
                else
                    %if there are only otherones open them all one after the other
                    for i = 1 : length(list)
                        if list(i).isdir == 1
                            path_new = join([path,'\',list(i).name],'');
                            
                            d.Message = 'open subfolder';
                            
                            create_data_list(app,path_new,d);
                        end
                    end
                end
            else
                %gets all the file/folder names and only imports the files
                %containing txt extanction
                d.Message = 'import all .txt files';
                
                fieldnames(:,1) = convertCharsToStrings({list(:).name});
                TF = contains(fieldnames,'txt');
                text_data = list(TF);
                if app.k == 0
                    app.list_data = text_data;
                else
                    app.list_data = [app.list_data;text_data];
                end
                app.k = app.k +1;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %store main app opject
            app.CallingApp = mainapp;
            app.PathEditField.Value = cellstr(app.CallingApp.data_path);
            
            str = pwd; %path containing the data
            str = [str,'/'];
            programm_path = join(str,"");
            temp_import_Structure = load([programm_path, 'config']);
            config_data = temp_import_Structure.config;
            app.region_of_interest_lowSpinner.Value = config_data.region_of_interest_low;
            app.region_of_interest_highSpinner.Value = config_data.region_of_interest_high;
            app.min_file_size = config_data.min_file_size;
            app.map_typ = config_data.map_typ;
            app.startSpinner.Value = config_data.start_x;
            app.transition_factorSpinner.Value = config_data.transition_factor;
            
            switch app.CallingApp.TabGroup.SelectedTab.Title
                case 'Multi Graph'
                    app.ImporttoTabDropDownLabel.Visible = 'on';
                    app.ImporttoTabDropDown.Visible = 'on';
            end
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = 620;
            window_height = 700;
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.ImportManu.Position = [x,y,window_width,window_height];
        end

        % Callback function: StartImportButton, StartImportMenu
        function StartImportButtonPushed(app, event)
            Data_Map = import(app);
            
            if app.createstandardexportCheckBox.Value
                switch app.import_typ.Value
                    case 'Auto_PL'
                        wavelength_range(1) = app.region_of_interest_lowSpinner.Value;
                        wavelength_range(2) = app.region_of_interest_highSpinner.Value;
                        
                        transistion_standard_export(Data_Map,wavelength_range,app.startSpinner.Value,...
                            app.direction.Value,app.transition_factorSpinner.Value,app.PathEditField.Value{1});
                        
                        if app.SavedatatoadditionalpathCheckBox.Value
                            transistion_standard_export(Data_Map,wavelength_range,app.startSpinner.Value,...
                                app.direction.Value,app.transition_factorSpinner.Value,app.path_add_save);
                        end
                end
            end
            
            import_data_public(app.CallingApp,Data_Map,[],app.DatatoImportDropDown.Value);
            app.CallingApp.data_path = app.PathEditField.Value{1};
            cd(app.CallingApp.programm_path);
            delete(app)
        end

        % Callback function: BrowseButton, BrowsePathMenu
        function BrowseDataButtonValueChanged(app, event)
            %declar starting values
            app.k = 0;
            fields = {'name','folder','date','bytes','isdir','datenum'};
            c = cell(length(fields),1);
            app.list_data = cell2struct(c,fields);
            
            %check the import state
            switch app.import_typ.Value
                case 'Auto_PL'
                    %gets the Path of the Data - Note that one has just to give the folder where the Data are
                    Path_of_Data = uigetdir(app.PathEditField.Value{1});
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait',...
                        'Indeterminate','on');
                    drawnow
                    str = [Path_of_Data,"/"];
                    
                    %Import datadiractions and define paths
                    app.PathEditField.Value = cellstr(char(join(str,""))); %path containing the data
                    app.CallingApp.data_path = char(join(str,""));
                    
                    %create List of Data and sort them
                    create_data_list(app,app.PathEditField.Value{1},d)
                    
                    d.Message = 'convert list to table';
                    drawnow
                    
                    T = struct2table(app.list_data); % convert the struct array to a table
                    
                    d.Message = 'sort the table by time of creation';
                    drawnow
                    
                    sortedT = sortrows(T, 'date'); % sort the table by 'DOB'
                    app.FileTable.Data = sortedT;
                case 'manually'
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait',...
                        'Indeterminate','on');
                    data_tabele = select_data(app);
                    oldData = get(app.FileTable,'Data');
                    newData = [oldData; data_tabele];
                    set(app.FileTable,'Data',newData);
            end
            %gets the date from the file table
            data = get(app.FileTable,'Data');
            
            %checks if the file table is not empty
            if isempty(data) ~= 1
                data = table2struct(data);
                %takes the first file name as an example and splits it at
                %the delimiter
                str = convertCharsToStrings(data(1).name);
                newStr = split(str,app.delimiterEditField.Value);
                newStr = replace(newStr,",",".");
                
                %define the size of the data name
                limet = size(newStr);
                app.Value1Spinner.Limits = [1 limet(1)];
                app.Value2Spinner.Limits = [1 limet(1)];
                app.Value3Spinner.Limits = [1 limet(1)];
                app.Value4Spinner.Limits = [1 limet(1)];
                
                %get parameter of changing values
                parameter = get_parameter(app,newStr);
            end
            %resets the Button
            close(d);
            close(f);
            
            app.standard_exportButton.Enable = 'on';
            app.BrowseButton.Value = 0;
        end

        % Value changed function: DatatoImportDropDown
        function DatatoImportDropDownValueChanged(app, event)
            switch app.DatatoImportDropDown.Value
                case 'PL_Map'
                    app.ImporttoTabDropDown.Items = {'Multi Graph 1', 'Multi Graph 2', 'Multi Graph both'};
                case 'Fit_Data'
                    app.ImporttoTabDropDown.Items = {'Fit'};
            end
        end

        % Callback function: LoadDataButton, LoadDataMenu
        function LoadDataButtonValueChanged(app, event)
            figure_ui = uifigure;
            d = uiprogressdlg(figure_ui,'Title','Load Data','Indeterminate','on');
            pause(0.1);
            drawnow;
            
            [data,Data_Fit] = load_data(app);
            switch app.CallingApp.TabGroup.SelectedTab.Title
                case 'Multi Graph'
                    import_data_public(app.CallingApp,data,Data_Fit,app.ImporttoTabDropDown.Value);
                otherwise
                    import_data_public(app.CallingApp,data,Data_Fit,app.DatatoImportDropDown.Value);
            end
            
            app.CallingApp.data_path = app.PathEditField.Value{1};
            cd(app.CallingApp.programm_path);
            close(d)
            close(figure_ui)
            delete(app)
        end

        % Value changed function: import_typ
        function import_typValueChanged(app, event)
            switch app.import_typ.Value
                case 'Auto_PL'
                    app.rowofdataSpinner.Value = 2;
            end
        end

        % Value changed function: Value1Spinner, Value2Spinner, 
        % Value3Spinner, Value4Spinner
        function Value1SpinnerValueChanged(app, event)
            %gets the date from the file table
            data = get(app.FileTable,'Data');
            
            %checks if the file table is not empty
            if isempty(data) ~= 1
                data = table2struct(data);
                %takes the first file name as an example and splits it at
                %the delimiter
                str = convertCharsToStrings(data(1).name);
                newStr = split(str,app.delimiterEditField.Value);
                newStr = replace(newStr,",",".");
                
                %get parameter of changing values
                parameter = get_parameter(app,newStr);
            end
        end

        % Button pushed function: get_refButton
        function get_refButtonPushed(app, event)
            get_reference(app)
        end

        % Value changed function: standard_exportButton
        function standard_exportButtonValueChanged(app, event)
            %get the data
            [Data_Map,~] = load_data(app);
            
            %exports the data with the standard export
            wavelength_range(1) = app.region_of_interest_lowSpinner.Value;
            wavelength_range(2) = app.region_of_interest_highSpinner.Value;
            
            transistion_standard_export(Data_Map,wavelength_range,app.startSpinner.Value,...
                app.direction.Value,app.transition_factorSpinner.Value,app.PathEditField.Value{1});
            
            app.standard_exportButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportManu = uifigure('Visible', 'off');
            app.ImportManu.Name = 'hyperspectral analyzer by Marcel Schmidt';
            app.Menu = uimenu(app.ImportManu);
            app.Menu.Text = 'Menu';
            app.BrowsePathMenu = uimenu(app.Menu);
            app.BrowsePathMenu.MenuSelectedFcn = createCallbackFcn(app, @BrowseDataButtonValueChanged, true);
            app.BrowsePathMenu.Accelerator = 'b';
            app.BrowsePathMenu.Text = 'Browse Path';
            app.LoadDataMenu = uimenu(app.Menu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataMenu.Accelerator = 'l';
            app.LoadDataMenu.Text = 'Load Data';
            app.StartImportMenu = uimenu(app.Menu);
            app.StartImportMenu.MenuSelectedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportMenu.Accelerator = 'i';
            app.StartImportMenu.Text = 'Start Import';
            app.StartImportButton = uibutton(app.ImportManu, 'push');
            app.StartImportButton.ButtonPushedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportButton.Text = 'Start Import';
            app.DatatoImportDropDownLabel = uilabel(app.ImportManu);
            app.DatatoImportDropDownLabel.Text = 'Data to Import';
            app.DatatoImportDropDown = uidropdown(app.ImportManu);
            app.DatatoImportDropDown.Items = {'PL_Map', 'Temp. dependend PL', 'Temp. dependend CV', ' conductance simulation', 'Fit_Data'};
            app.DatatoImportDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatoImportDropDownValueChanged, true);
            app.DatatoImportDropDown.Value = 'PL_Map';
            app.HeaderlinesSpinnerLabel = uilabel(app.ImportManu);
            app.HeaderlinesSpinnerLabel.Text = 'Header lines';
            app.HeaderlinesSpinner = uispinner(app.ImportManu);
            app.HeaderlinesSpinner.Limits = [0 Inf];
            app.HeaderlinesSpinner.Value = 10;
            app.OnlyfirstfilecontainsxdataCheckBox = uicheckbox(app.ImportManu);
            app.OnlyfirstfilecontainsxdataCheckBox.Text = 'Only first file contains x data';
            app.OnlyfirstfilecontainsxdataCheckBox.Value = true;
            app.BrowseButton = uibutton(app.ImportManu, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseDataButtonValueChanged, true);
            app.BrowseButton.Text = 'Browse';
            app.ImportwithparallelcomputingCheckBox = uicheckbox(app.ImportManu);
            app.ImportwithparallelcomputingCheckBox.Text = 'Import with parallel computing';
            app.ImportwithparallelcomputingCheckBox.Value = true;
            app.LoadDataButton = uibutton(app.ImportManu, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PathEditField = uitextarea(app.ImportManu);
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = {'blub'};
            app.SavedatatoadditionalpathCheckBox = uicheckbox(app.ImportManu);
            app.SavedatatoadditionalpathCheckBox.Text = 'Save data to additional path';
            app.FileTable = uitable(app.ImportManu);
            app.FileTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FileTable.RowName = {};
            app.FileTable.ColumnEditable = true;
            app.import_typ = uidropdown(app.ImportManu);
            app.import_typ.Items = {'Auto_PL', 'manually'};
            app.import_typ.ValueChangedFcn = createCallbackFcn(app, @import_typValueChanged, true);
            app.import_typ.Value = 'Auto_PL';
            app.rowofdataSpinnerLabel = uilabel(app.ImportManu);
            app.rowofdataSpinnerLabel.Text = 'row of data';
            app.rowofdataSpinner = uispinner(app.ImportManu);
            app.rowofdataSpinner.Limits = [1 Inf];
            app.rowofdataSpinner.Value = 2;
            app.NameTable = uitable(app.ImportManu);
            app.NameTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.NameTable.RowName = {};
            app.Value1SpinnerLabel = uilabel(app.ImportManu);
            app.Value1SpinnerLabel.Text = 'Value 1';
            app.Value1Spinner = uispinner(app.ImportManu);
            app.Value1Spinner.Limits = [1 Inf];
            app.Value1Spinner.RoundFractionalValues = 'on';
            app.Value1Spinner.ValueDisplayFormat = '%.0f';
            app.Value1Spinner.ValueChangedFcn = createCallbackFcn(app, @Value1SpinnerValueChanged, true);
            app.Value1Spinner.Value = 1;
            app.Value2SpinnerLabel = uilabel(app.ImportManu);
            app.Value2SpinnerLabel.Text = 'Value 2';
            app.Value2Spinner = uispinner(app.ImportManu);
            app.Value2Spinner.Limits = [1 Inf];
            app.Value2Spinner.RoundFractionalValues = 'on';
            app.Value2Spinner.ValueDisplayFormat = '%.0f';
            app.Value2Spinner.ValueChangedFcn = createCallbackFcn(app, @Value1SpinnerValueChanged, true);
            app.Value2Spinner.Value = 2;
            app.Value3SpinnerLabel = uilabel(app.ImportManu);
            app.Value3SpinnerLabel.Text = 'Value 3';
            app.Value3Spinner = uispinner(app.ImportManu);
            app.Value3Spinner.Limits = [1 Inf];
            app.Value3Spinner.RoundFractionalValues = 'on';
            app.Value3Spinner.ValueDisplayFormat = '%.0f';
            app.Value3Spinner.ValueChangedFcn = createCallbackFcn(app, @Value1SpinnerValueChanged, true);
            app.Value3Spinner.Value = 3;
            app.Value4SpinnerLabel = uilabel(app.ImportManu);
            app.Value4SpinnerLabel.Text = 'Value 4';
            app.Value4Spinner = uispinner(app.ImportManu);
            app.Value4Spinner.Limits = [1 Inf];
            app.Value4Spinner.RoundFractionalValues = 'on';
            app.Value4Spinner.ValueDisplayFormat = '%.0f';
            app.Value4Spinner.ValueChangedFcn = createCallbackFcn(app, @Value1SpinnerValueChanged, true);
            app.Value4Spinner.Value = 4;
            app.delimiterEditFieldLabel = uilabel(app.ImportManu);
            app.delimiterEditFieldLabel.Text = 'delimiter';
            app.delimiterEditField = uieditfield(app.ImportManu, 'text');
            app.delimiterEditField.Value = '_';
            app.get_refButton = uibutton(app.ImportManu, 'push');
            app.get_refButton.ButtonPushedFcn = createCallbackFcn(app, @get_refButtonPushed, true);
            app.get_refButton.Text = 'get_ref';
            app.ImporttoTabDropDownLabel = uilabel(app.ImportManu);
            app.ImporttoTabDropDownLabel.Visible = 'off';
            app.ImporttoTabDropDownLabel.Text = 'Import to Tab';
            app.ImporttoTabDropDown = uidropdown(app.ImportManu);
            app.ImporttoTabDropDown.Items = {'Multi Graph 1', 'Multi Graph 2', 'Multi Graph both'};
            app.ImporttoTabDropDown.Visible = 'off';
            app.ImporttoTabDropDown.Value = 'Multi Graph 1';
            app.automaticexportafterimportLabel = uilabel(app.ImportManu);
            app.automaticexportafterimportLabel.Text = 'automatic export after import:';
            app.region_of_interest_highSpinnerLabel = uilabel(app.ImportManu);
            app.region_of_interest_highSpinnerLabel.Text = 'region_of_interest_high';
            app.region_of_interest_highSpinner = uispinner(app.ImportManu);
            app.region_of_interest_highSpinner.Limits = [0 Inf];
            app.region_of_interest_highSpinner.Value = 1200;
            app.region_of_interest_lowSpinnerLabel = uilabel(app.ImportManu);
            app.region_of_interest_lowSpinnerLabel.Text = 'region_of_interest_low';
            app.region_of_interest_lowSpinner = uispinner(app.ImportManu);
            app.region_of_interest_lowSpinner.Limits = [0 Inf];
            app.region_of_interest_lowSpinner.Value = 1000;
            app.startSpinnerLabel = uilabel(app.ImportManu);
            app.startSpinnerLabel.Text = 'start';
            app.startSpinner = uispinner(app.ImportManu);
            app.startSpinner.Value = 38;
            app.transition_factorSpinnerLabel = uilabel(app.ImportManu);
            app.transition_factorSpinnerLabel.Text = 'transition_factor';
            app.transition_factorSpinner = uispinner(app.ImportManu);
            app.transition_factorSpinner.LowerLimitInclusive = 'off';
            app.transition_factorSpinner.Limits = [0 Inf];
            app.transition_factorSpinner.Value = 0.1;
            app.standard_exportButton = uibutton(app.ImportManu, 'state');
            app.standard_exportButton.ValueChangedFcn = createCallbackFcn(app, @standard_exportButtonValueChanged, true);
            app.standard_exportButton.Enable = 'off';
            app.standard_exportButton.Text = 'standard_export';
            app.direction = uiswitch(app.ImportManu, 'slider');
            app.direction.Items = {'x', 'y'};
            app.direction.Value = 'x';
            app.createstandardexportCheckBox = uicheckbox(app.ImportManu);
            app.createstandardexportCheckBox.Text = 'create standard export';
            app.createstandardexportCheckBox.Value = true;
            % Show the figure after all components are created
            app.ImportManu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogImportApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportManu)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportManu)
        end
    end
end