classdef DialogPreferencesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportManu                      matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        SavePraferencesMenu             matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        region_of_interest_lowSpinnerLabel  matlab.ui.control.Label
        region_of_interest_lowSpinner   matlab.ui.control.Spinner
        region_of_interest_highSpinnerLabel  matlab.ui.control.Label
        region_of_interest_highSpinner  matlab.ui.control.Spinner
        star_xSpinnerLabel              matlab.ui.control.Label
        star_xSpinner                   matlab.ui.control.Spinner
        transition_factorSpinnerLabel   matlab.ui.control.Label
        transition_factorSpinner        matlab.ui.control.Spinner
        GeneralTab                      matlab.ui.container.Tab
        StartpositionXLabel             matlab.ui.control.Label
        StartpositionXSpinner           matlab.ui.control.Spinner
        StartpositionYLabel             matlab.ui.control.Label
        StartpositionYSpinner           matlab.ui.control.Spinner
        StartcolorstepsSpinnerLabel     matlab.ui.control.Label
        StartcolorstepsSpinner          matlab.ui.control.Spinner
        StartPathLabel                  matlab.ui.control.Label
        StartPathTextArea               matlab.ui.control.TextArea
        GetPathButton                   matlab.ui.control.StateButton
        MinimumfilesizebytesSpinnerLabel  matlab.ui.control.Label
        MinimumfilesizebytesSpinner     matlab.ui.control.Spinner
        ContourlinesSpinnerLabel        matlab.ui.control.Label
        ContourlinesSpinner             matlab.ui.control.Spinner
        contourtextSwitchLabel          matlab.ui.control.Label
        contourtextSwitch               matlab.ui.control.Switch
        startmaptypDropDownLabel        matlab.ui.control.Label
        startmaptypDropDown             matlab.ui.control.DropDown
        histogramLabel                  matlab.ui.control.Label
        binsSpinnerLabel                matlab.ui.control.Label
        binsSpinner                     matlab.ui.control.Spinner
        show_statisticsCheckBox         matlab.ui.control.CheckBox
        FittingParameterTab             matlab.ui.container.Tab
        WindowsizedataSpinnerLabel      matlab.ui.control.Label
        WindowsizedataSpinner           matlab.ui.control.Spinner
        Windowsize1DiffSpinnerLabel     matlab.ui.control.Label
        Windowsize1DiffSpinner          matlab.ui.control.Spinner
        deltaPositionSpinnerLabel       matlab.ui.control.Label
        deltaPositionSpinner            matlab.ui.control.Spinner
        deltaHeightSpinnerLabel         matlab.ui.control.Label
        deltaHeightSpinner              matlab.ui.control.Spinner
        startFWHMSpinnerLabel           matlab.ui.control.Label
        startFWHMSpinner                matlab.ui.control.Spinner
        SmoothingParameterLabel         matlab.ui.control.Label
        FitParameterLabel               matlab.ui.control.Label
        absoptionwindowLabel            matlab.ui.control.Label
        absorptin_minSpinnerLabel       matlab.ui.control.Label
        absorptin_minSpinner            matlab.ui.control.Spinner
        absorptin_maxSpinnerLabel       matlab.ui.control.Label
        absorptin_maxSpinner            matlab.ui.control.Spinner
        generalLabel                    matlab.ui.control.Label
        batch_sizeSpinnerLabel          matlab.ui.control.Label
        batch_sizeSpinner               matlab.ui.control.Spinner
        SavePreferencesButton           matlab.ui.control.StateButton
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        CallingApp % Description
        config;
    end
    
    methods (Access = private)
        
        function load_data(app)
            %Imports the Data to a Variable
            str = pwd; %path containing the data
            str = [str,'/'];
            path_data = join(str,"");
            temp_import_Structure = load([path_data, 'config']);
            data = temp_import_Structure.config;
            app.config.x = data.x;
            app.StartpositionXSpinner.Value = data.x;
            app.config.y = data.y;
            app.StartpositionYSpinner.Value = data.y;
            app.config.color_steps = data.color_steps;
            app.StartcolorstepsSpinner.Value = data.color_steps;
            app.config.path = data.path;
            app.StartPathTextArea.Value = data.path;
            app.config.window_size_data = data.window_size_data;
            app.WindowsizedataSpinner.Value = data.window_size_data;
            app.config.window_size_1diff = data.window_size_1diff;
            app.Windowsize1DiffSpinner.Value = data.window_size_1diff;
            app.config.delta_position = data.delta_position;
            app.deltaPositionSpinner.Value = data.delta_position;
            app.config.delta_height = data.delta_height;
            app.deltaHeightSpinner.Value = data.delta_height;
            app.config.delta_FWHM = data.delta_FWHM;
            app.startFWHMSpinner.Value = data.delta_FWHM;
            app.MinimumfilesizebytesSpinner.Value = data.min_file_size;
            app.config.min_file_size = data.min_file_size;
            app.config.contour_lines = data.contour_lines;
            app.ContourlinesSpinner.Value = data.contour_lines;
            app.contourtextSwitch.Value = data.contourtext;
            app.config.contourtext = data.contourtext;
            app.config.region_of_interest_high = data.region_of_interest_high;
            app.region_of_interest_highSpinner.Value = data.region_of_interest_high;
            app.config.region_of_interest_low = data.region_of_interest_low;
            app.region_of_interest_lowSpinner.Value = data.region_of_interest_low;
            app.config.start_x = data.start_x;
            app.star_xSpinner.Value = data.start_x;
            app.config.transition_factor = data.transition_factor;
            app.transition_factorSpinner.Value = data.transition_factor;
            app.config.map_typ = data.map_typ;
            app.startmaptypDropDown.Value = data.map_typ;
            app.config.absorptin_min = data.absorptin_min;
            app.absorptin_minSpinner.Value = data.absorptin_min;
            app.config.absorptin_max = data.absorptin_max;
            app.absorptin_maxSpinner.Value = data.absorptin_max;
            app.config.batch_size = data.batch_size;
            app.batch_sizeSpinner.Value = data.batch_size;
            app.config.bin = data.bin;
            app.binsSpinner.Value = data.bin;
            app.config.show_histogram_statistics = data.show_histogram_statistics;
            app.show_statisticsCheckBox.Value = data.show_histogram_statistics;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %store main app opject
            app.CallingApp = mainapp;
            load_data(app);
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = 600;
            window_height = 700;
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.ImportManu.Position = [x,y,window_width,window_height];
        end

        % Value changed function: SavePreferencesButton
        function SavePreferencesButtonValueChanged(app, event)
            str = pwd; %path containing the data
            str = [str,'/'];
            path_data = join(str,"");
            cd(path_data);
            config = app.config;
            save('config.mat','config');
            delete(app)
        end

        % Value changed function: StartpositionXSpinner
        function StartpositionXSpinnerValueChanged(app, event)
            app.config.x = app.StartpositionXSpinner.Value;
        end

        % Value changed function: StartpositionYSpinner
        function StartpositionYSpinnerValueChanged(app, event)
            app.config.y = app.StartpositionYSpinner.Value;  
        end

        % Value changed function: StartcolorstepsSpinner
        function StartcolorstepsSpinnerValueChanged(app, event)
            app.config.color_steps = app.StartcolorstepsSpinner.Value; 
        end

        % Value changed function: StartPathTextArea
        function StartPathTextAreaValueChanged(app, event)
            app.config.path = app.StartPathTextArea.Value;
        end

        % Value changed function: WindowsizedataSpinner
        function WindowsizedataSpinnerValueChanged(app, event)
            app.config.window_size_data = app.WindowsizedataSpinner.Value;  
        end

        % Value changed function: Windowsize1DiffSpinner
        function Windowsize1DiffSpinnerValueChanged(app, event)
            app.config.window_size_1diff = app.Windowsize1DiffSpinner.Value;       
        end

        % Value changed function: deltaPositionSpinner
        function deltaPositionSpinnerValueChanged(app, event)
            app.config.delta_position = app.deltaPositionSpinner.Value;
        end

        % Value changed function: deltaHeightSpinner
        function deltaHeightSpinnerValueChanged(app, event)
            app.config.delta_height = app.deltaHeightSpinner.Value;
        end

        % Value changed function: startFWHMSpinner
        function startFWHMSpinnerValueChanged(app, event)
            app.config.delta_FWHM = app.startFWHMSpinner.Value;
        end

        % Value changed function: GetPathButton
        function GetPathButtonValueChanged(app, event)
            str = pwd; %path containing the data
            str = [str,'/'];
            Path_of_Data = join(str,"");
            
            %gets the Path of the Data - Note that one has just to give the folder where the Data are
            Path_of_Data = uigetdir(Path_of_Data);
            str = [Path_of_Data,"/"];
            
            %Import datadiractions and define paths
            app.StartPathTextArea.Value = char(join(str,"")); %path containing the data
            app.config.path = char(join(str,""));
            app.GetPathButton.Value = 0;
        end

        % Value changed function: MinimumfilesizebytesSpinner
        function MinimumfilesizebytesSpinnerValueChanged(app, event)
            app.config.min_file_size = app.MinimumfilesizebytesSpinner.Value;
        end

        % Value changed function: ContourlinesSpinner
        function ContourlinesSpinnerValueChanged(app, event)
            app.config.contour_lines = app.ContourlinesSpinner.Value;
            update_contour_lines(app.CallingApp,app.config.contour_lines,app.config.contourtext);
        end

        % Value changed function: contourtextSwitch
        function contourtextSwitchValueChanged(app, event)
            app.config.contourtext = app.contourtextSwitch.Value;
            update_contour_lines(app.CallingApp,app.config.contour_lines,app.config.contourtext);
        end

        % Value changed function: region_of_interest_lowSpinner
        function region_of_interest_lowSpinnerValueChanged(app, event)
            app.config.region_of_interest_low = app.region_of_interest_lowSpinner.Value;
        end

        % Value changed function: region_of_interest_highSpinner
        function region_of_interest_highSpinnerValueChanged(app, event)
            app.config.region_of_interest_high = app.region_of_interest_highSpinner.Value;
        end

        % Value changed function: star_xSpinner
        function star_xSpinnerValueChanged(app, event)
            app.config.start_x = app.star_xSpinner.Value;
        end

        % Value changed function: startmaptypDropDown
        function startmaptypDropDownValueChanged(app, event)
            app.config.map_typ = app.startmaptypDropDown.Value;
        end

        % Value changed function: absorptin_minSpinner
        function absorptin_minSpinnerValueChanged(app, event)
            app.config.absorptin_min = app.absorptin_minSpinner.Value;
        end

        % Value changed function: absorptin_maxSpinner
        function absorptin_maxSpinnerValueChanged(app, event)
            app.config.absorptin_max = app.absorptin_maxSpinner.Value;
        end

        % Value changed function: batch_sizeSpinner
        function batch_sizeSpinnerValueChanged(app, event)
            app.config.batch_size = app.batch_sizeSpinner.Value;
        end

        % Value changed function: binsSpinner
        function binsSpinnerValueChanged(app, event)
            app.config.bin = app.binsSpinner.Value;
        end

        % Value changed function: show_statisticsCheckBox
        function show_statisticsCheckBoxValueChanged(app, event)
            app.config.show_histogram_statistics = app.show_statisticsCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportManu = uifigure('Visible', 'off');
            app.ImportManu.Name = 'Import menu';
            app.Menu = uimenu(app.ImportManu);
            app.Menu.Text = 'Menu';
            app.SavePraferencesMenu = uimenu(app.Menu);
            app.SavePraferencesMenu.Accelerator = 's';
            app.SavePraferencesMenu.Text = 'Save Praferences';
            app.TabGroup = uitabgroup(app.ImportManu);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.region_of_interest_lowSpinnerLabel = uilabel(app.ImportTab);
            app.region_of_interest_lowSpinnerLabel.Text = 'region_of_interest_low';
            app.region_of_interest_lowSpinner = uispinner(app.ImportTab);
            app.region_of_interest_lowSpinner.Limits = [0 Inf];
            app.region_of_interest_lowSpinner.ValueChangedFcn = createCallbackFcn(app, @region_of_interest_lowSpinnerValueChanged, true);
            app.region_of_interest_lowSpinner.Value = 1000;
            app.region_of_interest_highSpinnerLabel = uilabel(app.ImportTab);
            app.region_of_interest_highSpinnerLabel.Text = 'region_of_interest_high';
            app.region_of_interest_highSpinner = uispinner(app.ImportTab);
            app.region_of_interest_highSpinner.Limits = [0 Inf];
            app.region_of_interest_highSpinner.ValueChangedFcn = createCallbackFcn(app, @region_of_interest_highSpinnerValueChanged, true);
            app.region_of_interest_highSpinner.Value = 1200;
            app.star_xSpinnerLabel = uilabel(app.ImportTab);
            app.star_xSpinnerLabel.Text = 'star_x';
            app.star_xSpinner = uispinner(app.ImportTab);
            app.star_xSpinner.ValueChangedFcn = createCallbackFcn(app, @star_xSpinnerValueChanged, true);
            app.star_xSpinner.Value = 38;
            app.transition_factorSpinnerLabel = uilabel(app.ImportTab);
            app.transition_factorSpinnerLabel.Text = 'transition_factor';
            app.transition_factorSpinner = uispinner(app.ImportTab);
            app.transition_factorSpinner.LowerLimitInclusive = 'off';
            app.transition_factorSpinner.Limits = [0 Inf];
            app.transition_factorSpinner.Value = 0.1;
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.StartpositionXLabel = uilabel(app.GeneralTab);
            app.StartpositionXLabel.Text = 'Start position X';
            app.StartpositionXSpinner = uispinner(app.GeneralTab);
            app.StartpositionXSpinner.ValueChangedFcn = createCallbackFcn(app, @StartpositionXSpinnerValueChanged, true);
            app.StartpositionXSpinner.Value = 38;
            app.StartpositionYLabel = uilabel(app.GeneralTab);
            app.StartpositionYLabel.Text = 'Start position Y';
            app.StartpositionYSpinner = uispinner(app.GeneralTab);
            app.StartpositionYSpinner.ValueChangedFcn = createCallbackFcn(app, @StartpositionYSpinnerValueChanged, true);
            app.StartpositionYSpinner.Value = 38;
            app.StartcolorstepsSpinnerLabel = uilabel(app.GeneralTab);
            app.StartcolorstepsSpinnerLabel.Text = 'Start color steps';
            app.StartcolorstepsSpinner = uispinner(app.GeneralTab);
            app.StartcolorstepsSpinner.ValueChangedFcn = createCallbackFcn(app, @StartcolorstepsSpinnerValueChanged, true);
            app.StartcolorstepsSpinner.Value = 50;
            app.StartPathLabel = uilabel(app.GeneralTab);
            app.StartPathLabel.Text = 'Start Path:';
            app.StartPathTextArea = uitextarea(app.GeneralTab);
            app.StartPathTextArea.ValueChangedFcn = createCallbackFcn(app, @StartPathTextAreaValueChanged, true);
            app.StartPathTextArea.Value = {'test'};
            app.GetPathButton = uibutton(app.GeneralTab, 'state');
            app.GetPathButton.ValueChangedFcn = createCallbackFcn(app, @GetPathButtonValueChanged, true);
            app.GetPathButton.Text = 'Get Path';
            app.MinimumfilesizebytesSpinnerLabel = uilabel(app.GeneralTab);
            app.MinimumfilesizebytesSpinnerLabel.Text = 'Minimum file size (bytes)';
            app.MinimumfilesizebytesSpinner = uispinner(app.GeneralTab);
            app.MinimumfilesizebytesSpinner.Limits = [1 Inf];
            app.MinimumfilesizebytesSpinner.RoundFractionalValues = 'on';
            app.MinimumfilesizebytesSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumfilesizebytesSpinnerValueChanged, true);
            app.MinimumfilesizebytesSpinner.Value = 1;
            app.ContourlinesSpinnerLabel = uilabel(app.GeneralTab);
            app.ContourlinesSpinnerLabel.Text = 'Contour lines';
            app.ContourlinesSpinner = uispinner(app.GeneralTab);
            app.ContourlinesSpinner.ValueChangedFcn = createCallbackFcn(app, @ContourlinesSpinnerValueChanged, true);
            app.ContourlinesSpinner.Value = 5;
            app.contourtextSwitchLabel = uilabel(app.GeneralTab);
            app.contourtextSwitchLabel.Text = 'contour text';
            app.contourtextSwitch = uiswitch(app.GeneralTab, 'slider');
            app.contourtextSwitch.Items = {'off', 'on'};
            app.contourtextSwitch.ValueChangedFcn = createCallbackFcn(app, @contourtextSwitchValueChanged, true);
            app.contourtextSwitch.Value = 'off';
            app.startmaptypDropDownLabel = uilabel(app.GeneralTab);
            app.startmaptypDropDownLabel.Text = 'start map typ';
            app.startmaptypDropDown = uidropdown(app.GeneralTab);
            app.startmaptypDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black'};
            app.startmaptypDropDown.ValueChangedFcn = createCallbackFcn(app, @startmaptypDropDownValueChanged, true);
            app.startmaptypDropDown.Value = 'hot';
            app.histogramLabel = uilabel(app.GeneralTab);
            app.histogramLabel.Text = 'histogram';
            app.binsSpinnerLabel = uilabel(app.GeneralTab);
            app.binsSpinnerLabel.Text = 'bins';
            app.binsSpinner = uispinner(app.GeneralTab);
            app.binsSpinner.ValueChangedFcn = createCallbackFcn(app, @binsSpinnerValueChanged, true);
            app.binsSpinner.Value = 50;
            app.show_statisticsCheckBox = uicheckbox(app.GeneralTab);
            app.show_statisticsCheckBox.ValueChangedFcn = createCallbackFcn(app, @show_statisticsCheckBoxValueChanged, true);
            app.show_statisticsCheckBox.Text = 'show_statistics';
            app.show_statisticsCheckBox.Value = true;
            app.FittingParameterTab = uitab(app.TabGroup);
            app.FittingParameterTab.Title = 'Fitting Parameter';
            app.WindowsizedataSpinnerLabel = uilabel(app.FittingParameterTab);
            app.WindowsizedataSpinnerLabel.Text = 'Window size data';
            app.WindowsizedataSpinner = uispinner(app.FittingParameterTab);
            app.WindowsizedataSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizedataSpinnerValueChanged, true);
            app.WindowsizedataSpinner.Value = 100;
            app.Windowsize1DiffSpinnerLabel = uilabel(app.FittingParameterTab);
            app.Windowsize1DiffSpinnerLabel.Text = 'Window size 1.Diff';
            app.Windowsize1DiffSpinner = uispinner(app.FittingParameterTab);
            app.Windowsize1DiffSpinner.ValueChangedFcn = createCallbackFcn(app, @Windowsize1DiffSpinnerValueChanged, true);
            app.Windowsize1DiffSpinner.Value = 100;
            app.deltaPositionSpinnerLabel = uilabel(app.FittingParameterTab);
            app.deltaPositionSpinnerLabel.Text = 'delta Position';
            app.deltaPositionSpinner = uispinner(app.FittingParameterTab);
            app.deltaPositionSpinner.Limits = [0 Inf];
            app.deltaPositionSpinner.ValueDisplayFormat = '%.2e';
            app.deltaPositionSpinner.ValueChangedFcn = createCallbackFcn(app, @deltaPositionSpinnerValueChanged, true);
            app.deltaPositionSpinner.Value = 1;
            app.deltaHeightSpinnerLabel = uilabel(app.FittingParameterTab);
            app.deltaHeightSpinnerLabel.Text = 'delta Height [%]';
            app.deltaHeightSpinner = uispinner(app.FittingParameterTab);
            app.deltaHeightSpinner.Step = 0.1;
            app.deltaHeightSpinner.Limits = [0 100];
            app.deltaHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @deltaHeightSpinnerValueChanged, true);
            app.deltaHeightSpinner.Value = 1;
            app.startFWHMSpinnerLabel = uilabel(app.FittingParameterTab);
            app.startFWHMSpinnerLabel.Text = 'start FWHM';
            app.startFWHMSpinner = uispinner(app.FittingParameterTab);
            app.startFWHMSpinner.Step = 0.1;
            app.startFWHMSpinner.Limits = [0 Inf];
            app.startFWHMSpinner.ValueDisplayFormat = '%.2e';
            app.startFWHMSpinner.ValueChangedFcn = createCallbackFcn(app, @startFWHMSpinnerValueChanged, true);
            app.startFWHMSpinner.Value = 0.1;
            app.SmoothingParameterLabel = uilabel(app.FittingParameterTab);
            app.SmoothingParameterLabel.Text = 'Smoothing Parameter';
            app.FitParameterLabel = uilabel(app.FittingParameterTab);
            app.FitParameterLabel.Text = 'Fit Parameter';
            app.absoptionwindowLabel = uilabel(app.FittingParameterTab);
            app.absoptionwindowLabel.Text = 'absoption window';
            app.absorptin_minSpinnerLabel = uilabel(app.FittingParameterTab);
            app.absorptin_minSpinnerLabel.Text = 'absorptin_min';
            app.absorptin_minSpinner = uispinner(app.FittingParameterTab);
            app.absorptin_minSpinner.ValueChangedFcn = createCallbackFcn(app, @absorptin_minSpinnerValueChanged, true);
            app.absorptin_minSpinner.Value = 1370;
            app.absorptin_maxSpinnerLabel = uilabel(app.FittingParameterTab);
            app.absorptin_maxSpinnerLabel.Text = 'absorptin_max';
            app.absorptin_maxSpinner = uispinner(app.FittingParameterTab);
            app.absorptin_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @absorptin_maxSpinnerValueChanged, true);
            app.absorptin_maxSpinner.Value = 1410;
            app.generalLabel = uilabel(app.FittingParameterTab);
            app.generalLabel.Text = 'general';
            app.batch_sizeSpinnerLabel = uilabel(app.FittingParameterTab);
            app.batch_sizeSpinnerLabel.Text = 'batch_size';
            app.batch_sizeSpinner = uispinner(app.FittingParameterTab);
            app.batch_sizeSpinner.Limits = [10 Inf];
            app.batch_sizeSpinner.RoundFractionalValues = 'on';
            app.batch_sizeSpinner.ValueChangedFcn = createCallbackFcn(app, @batch_sizeSpinnerValueChanged, true);
            app.batch_sizeSpinner.Value = 500;
            app.SavePreferencesButton = uibutton(app.ImportManu, 'state');
            app.SavePreferencesButton.ValueChangedFcn = createCallbackFcn(app, @SavePreferencesButtonValueChanged, true);
            app.SavePreferencesButton.Text = 'Save Preferences';
            % Show the figure after all components are created
            app.ImportManu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogPreferencesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportManu)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportManu)
        end
    end
end