classdef app_fit_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        menuMenu                        matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        CopytoclipboardMenu             matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        copy_mapMenu                    matlab.ui.container.Menu
        mapMenu_2                       matlab.ui.container.Menu
        fit_dataMenu_2                  matlab.ui.container.Menu
        copy_graphMenu                  matlab.ui.container.Menu
        spectrumMenu                    matlab.ui.container.Menu
        first_derivativeMenu            matlab.ui.container.Menu
        second_derivativeMenu           matlab.ui.container.Menu
        fit_graphMenu                   matlab.ui.container.Menu
        fit_histogrammMenu              matlab.ui.container.Menu
        correlation_graphMenu           matlab.ui.container.Menu
        coppy_dataMenu                  matlab.ui.container.Menu
        spectrum_dataMenu               matlab.ui.container.Menu
        fit_histogrammMenu_2            matlab.ui.container.Menu
        build_correlation_lineMenu      matlab.ui.container.Menu
        send_data_toMenu                matlab.ui.container.Menu
        single_graphMenu                matlab.ui.container.Menu
        multi_graph_1Menu               matlab.ui.container.Menu
        multi_graph_2Menu               matlab.ui.container.Menu
        multi_graph_bothMenu            matlab.ui.container.Menu
        gradient_graphMenu              matlab.ui.container.Menu
        fit_graphMenu_2                 matlab.ui.container.Menu
        growth_simMenu                  matlab.ui.container.Menu
        data_analysisMenu               matlab.ui.container.Menu
        residuals_dataMenu              matlab.ui.container.Menu
        enableMenu_2                    matlab.ui.container.Menu
        disableMenu_2                   matlab.ui.container.Menu
        residuals_1_diffMenu            matlab.ui.container.Menu
        enableMenu_3                    matlab.ui.container.Menu
        disableMenu_3                   matlab.ui.container.Menu
        Fit_dataMenu                    matlab.ui.container.Menu
        choose_regtangleMenu            matlab.ui.container.Menu
        choose_pointMenu                matlab.ui.container.Menu
        choose_pointsMenu               matlab.ui.container.Menu
        delet_pointMenu                 matlab.ui.container.Menu
        shift_pointsMenu                matlab.ui.container.Menu
        map_settingsMenu                matlab.ui.container.Menu
        mapMenu                         matlab.ui.container.Menu
        map_colorMenu_1                 matlab.ui.container.Menu
        fit_dataMenu                    matlab.ui.container.Menu
        map_colorMenu_2                 matlab.ui.container.Menu
        CorrelationMenu                 matlab.ui.container.Menu
        map_colorMenu_3                 matlab.ui.container.Menu
        histogram_settingsMenu          matlab.ui.container.Menu
        show_statisticsMenu             matlab.ui.container.Menu
        enableMenu                      matlab.ui.container.Menu
        disableMenu                     matlab.ui.container.Menu
        binsMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        PeakFittingToolTab              matlab.ui.container.Tab
        FitValuesLabel                  matlab.ui.control.Label
        FitValuesTextArea               matlab.ui.control.TextArea
        DataSpectrumPanel               matlab.ui.container.Panel
        SmoothingDropDown_5Label        matlab.ui.control.Label
        SmoothingDropDown_5             matlab.ui.control.DropDown
        WindowsizeSpinner_5Label        matlab.ui.control.Label
        WindowsizeSpinner_5             matlab.ui.control.Spinner
        sgolaydegreeSpinner_5Label      matlab.ui.control.Label
        sgolaydegreeSpinner_5           matlab.ui.control.Spinner
        BaselineDropDownLabel           matlab.ui.control.Label
        BaselineDropDown                matlab.ui.control.DropDown
        start_indexSpinnerLabel         matlab.ui.control.Label
        start_indexSpinner              matlab.ui.control.Spinner
        end_indexSpinnerLabel           matlab.ui.control.Label
        end_indexSpinner                matlab.ui.control.Spinner
        applytovaluesCheckBox           matlab.ui.control.CheckBox
        pointsDropDownLabel             matlab.ui.control.Label
        pointsDropDown_5                matlab.ui.control.DropDown
        start_xSpinnerLabel             matlab.ui.control.Label
        start_xSpinner                  matlab.ui.control.Spinner
        constantEditFieldLabel          matlab.ui.control.Label
        constantEditField               matlab.ui.control.NumericEditField
        DiffPanel                       matlab.ui.container.Panel
        SmoothingDropDown_6Label        matlab.ui.control.Label
        SmoothingDropDown_6             matlab.ui.control.DropDown
        WindowsizeSpinner_6Label        matlab.ui.control.Label
        WindowsizeSpinner_6             matlab.ui.control.Spinner
        sgolaydegreeSpinner_6Label      matlab.ui.control.Label
        sgolaydegreeSpinner_6           matlab.ui.control.Spinner
        FittingOptionsPanel             matlab.ui.container.Panel
        FitsingledataButton             matlab.ui.control.Button
        set_parameterCheckBox           matlab.ui.control.CheckBox
        minPeakdistanceSpinnerLabel     matlab.ui.control.Label
        minPeakdistanceSpinner          matlab.ui.control.Spinner
        NumberofpointsleftSpinnerLabel  matlab.ui.control.Label
        NumberofpointsleftSpinner       matlab.ui.control.Spinner
        NumberofpointsrightSpinnerLabel  matlab.ui.control.Label
        NumberofpointsrightSpinner      matlab.ui.control.Spinner
        FitmultidataButton              matlab.ui.control.Button
        eSpinner_2Label                 matlab.ui.control.Label
        power_peak_distance             matlab.ui.control.Spinner
        subtractwindowCheckBox          matlab.ui.control.CheckBox
        ProminenceEditFieldLabel        matlab.ui.control.Label
        ProminenceEditField             matlab.ui.control.NumericEditField
        accuracyEditFieldLabel          matlab.ui.control.Label
        accuracyEditField               matlab.ui.control.NumericEditField
        singlefitandsaveButton          matlab.ui.control.Button
        maxiterEditFieldLabel           matlab.ui.control.Label
        maxiterEditField                matlab.ui.control.NumericEditField
        maxpeaksSpinnerLabel            matlab.ui.control.Label
        maxpeaksSpinner                 matlab.ui.control.Spinner
        atpositionSpinnerLabel          matlab.ui.control.Label
        atpositionSpinner               matlab.ui.control.Spinner
        fit_parameterButton             matlab.ui.control.StateButton
        Fit_modeDropDownLabel           matlab.ui.control.Label
        Fit_modeDropDown                matlab.ui.control.DropDown
        exclude_pointsCheckBox          matlab.ui.control.CheckBox
        excluded_pontsButton            matlab.ui.control.StateButton
        Fit_modelDropDownLabel          matlab.ui.control.Label
        Fit_modelDropDown               matlab.ui.control.DropDown
        get_parametersButton            matlab.ui.control.Button
        peak_position_2_diffButton      matlab.ui.control.Button
        SpectrumMapParameterPanel       matlab.ui.container.Panel
        changingvalue1SpinnerLabel      matlab.ui.control.Label
        changingvalue1_1Spinner         matlab.ui.control.Spinner
        Spectrum_RefreshButton_2        matlab.ui.control.Button
        Map_switch_5                    matlab.ui.control.Switch
        xminLabel                       matlab.ui.control.Label
        x_min                           matlab.ui.control.Spinner
        xmaxLabel                       matlab.ui.control.Label
        x_max                           matlab.ui.control.Spinner
        SweepDropDownLabel              matlab.ui.control.Label
        SweepDropDown                   matlab.ui.control.DropDown
        changingvalue2SpinnerLabel      matlab.ui.control.Label
        changingvalue2_1Spinner         matlab.ui.control.Spinner
        MaxzaxisLabel                   matlab.ui.control.Label
        MapMaxCountsEditField_5         matlab.ui.control.NumericEditField
        MinzaxisLabel                   matlab.ui.control.Label
        MapMinCountsEditField_5         matlab.ui.control.NumericEditField
        changingvalue3SpinnerLabel      matlab.ui.control.Label
        changingvalue3_1Spinner         matlab.ui.control.Spinner
        changingvalue4SpinnerLabel      matlab.ui.control.Label
        changingvalue4_1Spinner         matlab.ui.control.Spinner
        MapappearanceDropDown_5         matlab.ui.control.DropDown
        ContourCheckBox_5               matlab.ui.control.CheckBox
        Switch_5                        matlab.ui.control.Switch
        XLabel                          matlab.ui.control.Label
        Switch_6                        matlab.ui.control.Switch
        YLabel                          matlab.ui.control.Label
        WaterfallDropDownLabel          matlab.ui.control.Label
        WaterfallDropDown               matlab.ui.control.DropDown
        ColorstepsEditField_4Label_2    matlab.ui.control.Label
        MapColorstepsEditField_5        matlab.ui.control.NumericEditField
        OffsetSpinner_2Label            matlab.ui.control.Label
        OffsetSpinner_2                 matlab.ui.control.Spinner
        locktoareaCheckBox_5            matlab.ui.control.CheckBox
        show_sample_infoButton          matlab.ui.control.Button
        wavelength_energy               matlab.ui.control.Switch
        find_peaks_in_2_diffCheckBox    matlab.ui.control.CheckBox
        Map5                            matlab.ui.control.UIAxes
        Spectrum5                       matlab.ui.control.UIAxes
        Spectrum6                       matlab.ui.control.UIAxes
        Spectrum7                       matlab.ui.control.UIAxes
        AnalyzeFitDataTab               matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        display_peaksTab                matlab.ui.container.Tab
        MaxzaxisLabel_2                 matlab.ui.control.Label
        MapMaxCountsEditField_6         matlab.ui.control.NumericEditField
        MinzaxisLabel_2                 matlab.ui.control.Label
        MapMinCountsEditField_6         matlab.ui.control.NumericEditField
        ColorstepsEditField_4Label_3    matlab.ui.control.Label
        MapColorstepsEditField_6        matlab.ui.control.NumericEditField
        MapRefreshButton_6              matlab.ui.control.Button
        ContourCheckBox_6               matlab.ui.control.CheckBox
        Map_switch_6                    matlab.ui.control.Switch
        DisplayedPeakDropDown_1Label    matlab.ui.control.Label
        DisplayedPeakDropDown_1         matlab.ui.control.DropDown
        DisplayedValueDropDown_1Label   matlab.ui.control.Label
        DisplayedValueDropDown_1        matlab.ui.control.DropDown
        compare_two_peaksButton         matlab.ui.control.Button
        SaveDataButton_2                matlab.ui.control.Button
        display_peak_as_wavelengthCheckBox  matlab.ui.control.CheckBox
        locktoareaCheckBox_6            matlab.ui.control.CheckBox
        fitDropDown_1Label              matlab.ui.control.Label
        fitDropDown_1                   matlab.ui.control.DropDown
        changingvalue3Label_3           matlab.ui.control.Label
        changingvalue3_4Spinner         matlab.ui.control.Spinner
        changingvalue4Label_3           matlab.ui.control.Label
        changingvalue4_4Spinner         matlab.ui.control.Spinner
        FreeScanPanel                   matlab.ui.container.Panel
        startingSwitch                  matlab.ui.control.Switch
        startingSwitchLabel             matlab.ui.control.Label
        set_lineButton                  matlab.ui.control.StateButton
        set_line_from_centerButton      matlab.ui.control.StateButton
        snapp_to_gridCheckBox           matlab.ui.control.CheckBox
        Switch_7                        matlab.ui.control.Switch
        X1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        Y1EditField                     matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        Y2EditField                     matlab.ui.control.NumericEditField
        get_line_distanceCheckBox       matlab.ui.control.CheckBox
        steps_widthSpinnerLabel         matlab.ui.control.Label
        steps_widthSpinner              matlab.ui.control.Spinner
        start_indexSpinner_2Label       matlab.ui.control.Label
        start_indexSpinner_2            matlab.ui.control.Spinner
        end_indexSpinner_2Label         matlab.ui.control.Label
        end_indexSpinner_2              matlab.ui.control.Spinner
        index_stepsSpinnerLabel         matlab.ui.control.Label
        index_stepsSpinner              matlab.ui.control.Spinner
        sorting_peaksTab                matlab.ui.container.Tab
        SortPeaksButton                 matlab.ui.control.Button
        SaveDataButton_1                matlab.ui.control.Button
        X_startSpinnerLabel             matlab.ui.control.Label
        X_startSpinner                  matlab.ui.control.Spinner
        Y_startSpinnerLabel             matlab.ui.control.Label
        Y_startSpinner                  matlab.ui.control.Spinner
        ThresholddistanceSpinnerLabel   matlab.ui.control.Label
        ThresholddistanceSpinner        matlab.ui.control.Spinner
        NearestneighboursSpinnerLabel   matlab.ui.control.Label
        NearestneighboursSpinner        matlab.ui.control.Spinner
        save_changesButton_2            matlab.ui.control.Button
        delet_pointsTab                 matlab.ui.container.Tab
        deleteoutlierButton             matlab.ui.control.Button
        k_minSpinnerLabel               matlab.ui.control.Label
        k_minSpinner                    matlab.ui.control.Spinner
        k_maxSpinnerLabel               matlab.ui.control.Label
        k_maxSpinner                    matlab.ui.control.Spinner
        reset_points_to_deletButton     matlab.ui.control.Button
        SaveDataButton_3                matlab.ui.control.Button
        DisplayedPeakDropDown_3Label    matlab.ui.control.Label
        DisplayedPeakDropDown_3         matlab.ui.control.DropDown
        DisplayedValueDropDown_3Label   matlab.ui.control.Label
        DisplayedValueDropDown_3        matlab.ui.control.DropDown
        reset_dataButton                matlab.ui.control.StateButton
        save_changesButton              matlab.ui.control.Button
        fitDropDown_2Label              matlab.ui.control.Label
        fitDropDown_2                   matlab.ui.control.DropDown
        changingvalue3Label_2           matlab.ui.control.Label
        changingvalue3_3Spinner         matlab.ui.control.Spinner
        changingvalue4Label_2           matlab.ui.control.Label
        changingvalue4_3Spinner         matlab.ui.control.Spinner
        build_correlation_lineTab       matlab.ui.container.Tab
        displayerrorbarsCheckBox        matlab.ui.control.CheckBox
        CorrelationsDropDownLabel       matlab.ui.control.Label
        CorrelationsDropDown            matlab.ui.control.DropDown
        Line_StyleDropDownLabel         matlab.ui.control.Label
        Line_StyleDropDown              matlab.ui.control.DropDown
        changingvalue3Label             matlab.ui.control.Label
        changingvalue3_2Spinner         matlab.ui.control.Spinner
        changingvalue4Label             matlab.ui.control.Label
        changingvalue4_2Spinner         matlab.ui.control.Spinner
        changingvalue1Label             matlab.ui.control.Label
        changingvalue1_2Spinner         matlab.ui.control.Spinner
        changingvalue2Label             matlab.ui.control.Label
        changingvalue2_2Spinner         matlab.ui.control.Spinner
        DisplayedPeakDropDown_2Label    matlab.ui.control.Label
        DisplayedPeakDropDown_2         matlab.ui.control.DropDown
        DisplayedValueDropDown_2Label   matlab.ui.control.Label
        DisplayedValueDropDown_2        matlab.ui.control.DropDown
        SaveDataButton_4                matlab.ui.control.Button
        fitDropDown_3Label              matlab.ui.control.Label
        fitDropDown_3                   matlab.ui.control.DropDown
        error_intervalDropDownLabel     matlab.ui.control.Label
        error_intervalDropDown          matlab.ui.control.DropDown
        show_sigle_fitCheckBox          matlab.ui.control.CheckBox
        DisplaydataalongvalueDropDownLabel  matlab.ui.control.Label
        DisplaydataalongvalueDropDown   matlab.ui.control.DropDown
        build_correlation_statisticTab  matlab.ui.container.Tab
        RefreshButton                   matlab.ui.control.Button
        Correlations_x_axisDropDownLabel  matlab.ui.control.Label
        Correlations_x_axisDropDown     matlab.ui.control.DropDown
        Correlations_y_axisDropDownLabel  matlab.ui.control.Label
        Correlations_y_axisDropDown     matlab.ui.control.DropDown
        Correlations_colorDropDown_Label  matlab.ui.control.Label
        Correlations_colorDropDown      matlab.ui.control.DropDown
        Correlations_z_axisDropDownLabel  matlab.ui.control.Label
        Correlations_z_axisDropDown     matlab.ui.control.DropDown
        xSpinnerLabel                   matlab.ui.control.Label
        xSpinner                        matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        ySpinner                        matlab.ui.control.Spinner
        d1SpinnerLabel                  matlab.ui.control.Label
        d1Spinner                       matlab.ui.control.Spinner
        only_points_in_rangeCheckBox    matlab.ui.control.CheckBox
        d2SpinnerLabel                  matlab.ui.control.Label
        d2Spinner                       matlab.ui.control.Spinner
        HistogramButtonGroup            matlab.ui.container.ButtonGroup
        PositionButton                  matlab.ui.control.RadioButton
        FWHMButton                      matlab.ui.control.RadioButton
        HeightButton                    matlab.ui.control.RadioButton
        Distance_to_positionButton      matlab.ui.control.RadioButton
        Gaussian_contButton             matlab.ui.control.RadioButton
        rmseButton                      matlab.ui.control.RadioButton
        noneButton                      matlab.ui.control.RadioButton
        display_peak_as_wavelengthCheckBox_2  matlab.ui.control.CheckBox
        DisplayedPeakDropDown_4Label    matlab.ui.control.Label
        DisplayedPeakDropDown_4         matlab.ui.control.DropDown
        DisplayedValueDropDown_4Label   matlab.ui.control.Label
        DisplayedValueDropDown_4        matlab.ui.control.DropDown
        fitDropDown_4Label              matlab.ui.control.Label
        fitDropDown_4                   matlab.ui.control.DropDown
        SaveDataButton_5                matlab.ui.control.Button
        Comparison_with_simulationTab   matlab.ui.container.Tab
        Load_sim_dataButton             matlab.ui.control.Button
        display_modeSwitch              matlab.ui.control.Switch
        display_typeSwitch              matlab.ui.control.Switch
        auto_MLCheckBox                 matlab.ui.control.CheckBox
        n_contour_linesSpinnerLabel     matlab.ui.control.Label
        n_contour_linesSpinner          matlab.ui.control.Spinner
        DisplayedmaterialDropDownLabel  matlab.ui.control.Label
        DisplayedmaterialDropDown       matlab.ui.control.DropDown
        DisplayedLayerDropDownLabel     matlab.ui.control.Label
        DisplayedLayerDropDown          matlab.ui.control.DropDown
        ML_contour_stepsEditFieldLabel  matlab.ui.control.Label
        ML_contour_stepsEditField       matlab.ui.control.NumericEditField
        ML_minSpinnerLabel              matlab.ui.control.Label
        ML_minSpinner                   matlab.ui.control.Spinner
        ML_maxSpinnerLabel              matlab.ui.control.Label
        ML_maxSpinner                   matlab.ui.control.Spinner
        overlay_contourButton           matlab.ui.control.Button
        MapRefreshButton_7              matlab.ui.control.Button
        sumCheckBox                     matlab.ui.control.CheckBox
        lower_boundEditFieldLabel       matlab.ui.control.Label
        lower_boundEditField            matlab.ui.control.NumericEditField
        upper_boundEditFieldLabel       matlab.ui.control.Label
        upper_boundEditField            matlab.ui.control.NumericEditField
        excludeCheckBox                 matlab.ui.control.CheckBox
        Map6                            matlab.ui.control.UIAxes
        Spectrum8                       matlab.ui.control.UIAxes
        Spectrum8_2                     matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        DialogImportApp;
        DialogExportApp;
        DialogPreferencesApp;
        DialogFitApp;
        DialogExcluededPoints;
        cross_handle;
        cross_handle_fit;
        area_count_range_handle8;
        displayed_fit_parameter;
        displayed_peak;
        displayed_fit;
        starting_x;
        starting_y;
        starting_power;
        starting_tamperature;
        starting_color_steps;
        map_typ_data;
        idx_list_data;
        map_typ_fit_data;
        idx_list_fit_data;
        idx_list_correlation_data;
        map_typ_correlation_data;
        Fit_Data_Map_temp;
        x_vector;
        spectrum;
        fit_spectrum;
        Fit_Data;
        Fit_Data_error_68_pos;
        Fit_Data_error_68_neg;
        Fit_Data_error_95_pos;
        Fit_Data_error_95_neg;
        Fit_Data_error_997_pos;
        Fit_Data_error_997_neg;
        Fit_rmse;
        Fit_rmse_temp;
        Fit_Data_function_parameter;
        Fit_Data_function_grade;
        points_for_delt;
        % fit_parameter is a cell aray with structure elements containing
        % the fiting parameter in the format (t,p,peak)
        fit_parameter;
        last_map;
        last_fit_map;
        c_map;
        c_fit_map;
        c_correlation;
        %window absorption
        absorptin_min;
        absorptin_max;
        %size of parallel fitted data
        batch_size;
        %gets the pressed fit button
        fit_button;
        bin;
        show_histogram_statistics;
        histogramm_data;
        n_fitparameter;
        grade;
        line_handle;
        circle1_handle;
        circle2_handle;
        residual_data;
        residual_1_diff;
        fit_line_handle;
        x_free_line;
        y_free_line;
        y_err_neg;
        y_err_pos;
    end
    
    properties (Access = public)
        gradient_dimantion;
        PLMap_Data;
        data;
        data_path;
        programm_path;
        min_file_size;
        contourtext;
        contour_lines;
        selectedTab;
        data_map;
        data_position;
        area_dim_5;
        area_dim_6;
        fit_parameter_table;
        excluded_points_table;
        Fit_Data_Map;
        rmse_Map;
        rsquare_Map;
        Fit_Data_Map_save;
        rmse_Map_save;
        rsquare_Map_save;
        Ga_thickness_matrix;
        Al_thickness_matrix;
        In_thickness_matrix;
        contour_map;
    end
    
    methods (Access = private)
        function import_data(app,Data_Map,Data_Fit,data_type)
            %start fit data
            %clear all data
            app.PLMap_Data = [];
            app.Fit_Data_Map = [];
            app.rmse_Map = [];
            app.rsquare_Map = [];
            app.fit_parameter = {};
            app.Fit_Data_error_68_pos = [];
            app.Fit_Data_error_68_neg = [];
            app.Fit_Data_error_95_pos = [];
            app.Fit_Data_error_95_neg = [];
            app.Fit_Data_error_997_pos = [];
            app.Fit_Data_error_997_neg = [];
            app.Fit_Data_function_parameter = [];
            app.Fit_Data_function_grade = [];
            
            %set raw data an display all in the peak fitting tab
            app.PLMap_Data = Data_Map;
            start_fit_map(app,data_type);
            
            %sets Fit_Data_function_parameter
            if ~isempty(Data_Fit)
                app.Fit_Data_function_parameter = Data_Fit.Fit_Data_function_parameter;
            else
                app.Fit_Data_function_parameter = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets Fit_Data_function_parameter
            if ~isempty(Data_Fit)
                app.Fit_Data_function_grade = Data_Fit.Fit_Data_function_grade;
            else
                app.Fit_Data_function_grade = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets Fit_Data_Map
            if ~isempty(Data_Fit)
                app.Fit_Data_Map = Data_Fit.Fit_Data_Map;
                
                app.displayed_fit = 1;
                
                %define indeces of power and tamperature
                [~, p] = min(abs(app.PLMap_Data.Power-app.changingvalue3_2Spinner.Value));
                app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
                [~, t] = min(abs(app.PLMap_Data.Temperature_index-app.changingvalue4_2Spinner.Value));
                app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
                
                parameter_array(:,:) = app.Fit_Data_function_parameter(app.displayed_fit,t,p,:,:);
                grade_array(:,:) = app.Fit_Data_function_grade(app.displayed_fit,t,p,:,:);
                
                app.n_fitparameter = [];
                app.grade = [];
                app.n_fitparameter = max(parameter_array,[],'all');
                app.grade = max(grade_array,[],'all');
                
                if str2num(app.DisplayedPeakDropDown_1.Value)>app.grade
                    app.DisplayedPeakDropDown_1.Value = '1';
                end
                if str2num(app.DisplayedPeakDropDown_2.Value)>app.grade
                    app.DisplayedPeakDropDown_1.Value = '1';
                end
                if str2num(app.DisplayedPeakDropDown_3.Value)>app.grade
                    app.DisplayedPeakDropDown_1.Value = '1';
                end
                
                app.DisplayedPeakDropDown_1.Items = split(num2str(1:app.grade))';
                app.DisplayedPeakDropDown_2.Items = split(num2str(1:app.grade))';
                app.DisplayedPeakDropDown_3.Items = split(num2str(1:app.grade))';
                
                switch app.n_fitparameter
                    case 3
                        app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM'};
                        app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM'};
                        app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM'};
                        app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Position(Height)','Position(FWHM)',...
                            'FWHM(Height)','FWHM(Position)'};
                    case 4
                        app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                        app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                        app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                        app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Height(Eta)','Position(Height)','Position(FWHM)','Position(Eta)'...
                            'FWHM(Height)','FWHM(Position)','FWHM(Eta)','Eta(height)','Eta(Position)','Eta(FWHM)'};
                end
                
                app.displayed_peak  = str2num(app.DisplayedPeakDropDown_1.Value);
                
                switch app.DisplayedValueDropDown_1.Value
                    case 'Height'
                        app.displayed_fit_parameter = 1;
                    case 'Position'
                        app.displayed_fit_parameter = 2;
                    case 'FWHM'
                        app.displayed_fit_parameter = 3;
                    case 'Eta'
                        app.displayed_fit_parameter = 4;
                    case 'rmse'
                        app.displayed_fit_parameter = 5;
                        app.displayerrorbarsCheckBox.Value = 0;
                        app.displayerrorbarsCheckBox.Enable = 'off';
                end
                
                %displays map
                update_fit_map(app);
            else
                app.Fit_Data_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets fit rmse_Map
            if ~isempty(Data_Fit)
                app.rmse_Map = Data_Fit.rmse_Map;
            else
                app.rmse_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets fit rsquare_Map
            if ~isempty(Data_Fit)
                app.rsquare_Map = Data_Fit.rsquare_Map;
            else
                app.rsquare_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets Data_fit_parameter
            if ~isempty(Data_Fit)
                app.fit_parameter = Data_Fit.Data_fit_parameter;
            else
                app.fit_parameter = cell(numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power),8);
            end
            
            %sets Fit_Data_error_68_pos
            if ~isempty(Data_Fit)
                app.Fit_Data_error_68_pos = Data_Fit.Fit_Data_error_68_pos;
            else
                app.Fit_Data_error_68_pos = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_68_neg
            if ~isempty(Data_Fit)
                app.Fit_Data_error_68_neg = Data_Fit.Fit_Data_error_68_neg;
            else
                app.Fit_Data_error_68_neg = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_95_pos
            if ~isempty(Data_Fit)
                app.Fit_Data_error_95_pos = Data_Fit.Fit_Data_error_95_pos;
            else
                app.Fit_Data_error_95_pos = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_95_neg
            if ~isempty(Data_Fit)
                app.Fit_Data_error_95_neg = Data_Fit.Fit_Data_error_95_neg;
            else
                app.Fit_Data_error_95_neg = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_997_pos
            if ~isempty(Data_Fit)
                app.Fit_Data_error_997_pos = Data_Fit.Fit_Data_error_997_pos;
            else
                app.Fit_Data_error_997_pos = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_997_neg
            if ~isempty(Data_Fit)
                app.Fit_Data_error_997_neg = Data_Fit.Fit_Data_error_997_neg;
            else
                app.Fit_Data_error_997_neg = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets the same data vor the save data
            app.Fit_Data_Map_save = app.Fit_Data_Map;
            app.rmse_Map_save = app.rmse_Map;
            app.rsquare_Map_save = app.rsquare_Map;
        end
        
        function Data_Fit = create_Fit_Data(app)
            Data_Fit.Fit_Data_Map = app.Fit_Data_Map;
            Data_Fit.Fit_Data_error_68_pos = app.Fit_Data_error_68_pos;
            Data_Fit.Fit_Data_error_68_neg = app.Fit_Data_error_68_neg;
            Data_Fit.Fit_Data_error_95_pos = app.Fit_Data_error_95_pos;
            Data_Fit.Fit_Data_error_95_neg = app.Fit_Data_error_95_neg;
            Data_Fit.Fit_Data_error_997_pos = app.Fit_Data_error_997_pos;
            Data_Fit.Fit_Data_error_997_neg = app.Fit_Data_error_997_neg;
            Data_Fit.Fit_Data_function_parameter = app.Fit_Data_function_parameter;
            Data_Fit.Fit_Data_function_grade = app.Fit_Data_function_grade;
            Data_Fit.rmse_Map = app.rmse_Map;
            Data_Fit.rsquare_Map = app.rsquare_Map;
            Data_Fit.Data_fit_parameter = app.fit_parameter;
        end
        % ----------------------------------------------------------------------------------------------------------------
        % Fit data
        % ----------------------------------------------------------------------------------------------------------------
        function [x_vector,spectrum_smoothed,base_line,diff_x,diff_spectrum,diff_spectrum_smoothed,fit_spectrum,peak_x,...
                diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum] = fit_spectrum_parameter(app)
        %get the current indexes for the spectrum
        x = find_index(app.PLMap_Data.X_vector,app.changingvalue1_1Spinner.Value,1);
        app.changingvalue1_1Spinner.Value = app.PLMap_Data.X_vector(x);
        y = find_index(app.PLMap_Data.Y_vector,app.changingvalue2_1Spinner.Value,1);
        app.changingvalue2_1Spinner.Value = app.PLMap_Data.Y_vector(y);
        p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
        app.changingvalue3_1Spinner.Value = app.PLMap_Data.Power(p);
        t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
        app.changingvalue4_1Spinner.Value = app.PLMap_Data.Temperature_index(t);
        
        start_x = app.PLMap_Data.Wavelength;
        switch app.wavelength_energy.Value
            case 'energy'
                start_x = converter.energy_wavelength(start_x);
        end
        
        x_min_index = find_index(start_x,app.x_min.Value,1);
        x_max_index = find_index(start_x,app.x_max.Value,1);
        
        app.x_min.Value = start_x(x_min_index);
        app.x_max.Value = start_x(x_max_index);
        
        %enaable the spectral transition spinner
        switch app.pointsDropDown_5.Value
            case 'All_points'
                app.start_xSpinnerLabel.Enable = 'off';
                app.start_xSpinner.Enable = 'off';
            otherwise
                app.start_xSpinnerLabel.Enable = 'on';
                app.start_xSpinner.Enable = 'on';
        end
        hold(app.Map5,"on");
        %draws the waferadges in the Graph
        delete(app.cross_handle);
        app.cross_handle = plot(app.Map5,app.changingvalue1_1Spinner.Value,app.changingvalue2_1Spinner.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
        hold(app.Map5,"off");
        
        %gets the spectrum from the data array
        app.spectrum = get_spectrum_from_data_map(app.PLMap_Data.data_array,t,p,y,x);
        
        %get displayed spectral range
        [app.spectrum,x_vector,~,~] = sweep_data(app.spectrum,app.PLMap_Data.Wavelength, ...
            app.PLMap_Data.Wavelength(x_min_index),app.PLMap_Data.Wavelength(x_max_index),app.SweepDropDown.Value);
        
        %apply baseline
        switch app.BaselineDropDown.Value
            case 'Linear'
                if app.applytovaluesCheckBox.Value
                    [app.spectrum,~,~] = linear_fit(app,x_vector',app.spectrum, ...
                        app.start_indexSpinner.Value,app.end_indexSpinner.Value);
                    base_line = [];
                else
                    [~,m,c] = linear_fit(app,x_vector',app.spectrum, ...
                        app.start_indexSpinner.Value,app.end_indexSpinner.Value);
                    base_line(1,1) = m;
                    base_line(2,1) = c;
                end
            case 'constant'
                if app.applytovaluesCheckBox.Value
                    app.spectrum = app.spectrum - app.constantEditField.Value;
                    base_line = [];
                else
                    base_line(1,1) = app.constantEditField.Value;
                end
            case 'constant_mean'
                if app.applytovaluesCheckBox.Value
                    [app.spectrum,~] = substract_constent_mean(app,app.spectrum,app.start_indexSpinner.Value,app.end_indexSpinner.Value);
                    base_line = [];
                else
                    [~,spectral_mean] = substract_constent_mean(app,app.spectrum,app.start_indexSpinner.Value,app.end_indexSpinner.Value);
                    base_line(1,1) = spectral_mean;
                end
            case 'None'
                base_line = [];
        end
        
        %defines the used points
        [app.spectrum,x_vector] = define_used_points(app.spectrum,x_vector, ...
            app.pointsDropDown_5.Value,app.start_xSpinner.Value);
        
        %             %exclude window
        %             if app.subtractwindowCheckBox.Value == 1
        %                 [x_vector,app.spectrum] = exclude_points(x_vector,app.spectrum,app.absorptin_min,app.absorptin_max);
        %             end
        
        switch app.wavelength_energy.Value
            case 'energy'
                x_vector = converter.energy_wavelength(x_vector);
        end
        
        %smoothed data
        spectrum_smoothed = smooth_data(app.spectrum,app.SmoothingDropDown_5.Value,app.WindowsizeSpinner_5.Value ...
            ,app.sgolaydegreeSpinner_5.Value);
        [diff_spectrum,diff_x] = diff_data(x_vector,spectrum_smoothed);
        diff_spectrum_smoothed = smooth_data(diff_spectrum,app.SmoothingDropDown_6.Value,app.WindowsizeSpinner_6.Value ...
            ,app.sgolaydegreeSpinner_6.Value);
        [diff2_spectrum,diff2_x] = diff_data(diff_x,diff_spectrum_smoothed);
        
        %find the minima in the smoothed data
        [Index_of_peak,P,~] = find_minima(app,diff2_x,diff2_spectrum');
        Index_of_peak = Index_of_peak';
        P = P';
        
        %gets the x and y values for dispaly in second diff
        if isempty(Index_of_peak) == 0
            %displayes the prominance in the text area
            app.FitValuesTextArea.Value = char(join(['The peak prominences found are: ',num2str(P(Index_of_peak),'%.2e\t')]));
            
            %readout the peak values
            peak_x_temp = diff2_x(Index_of_peak);
            peak_x = [];
            diff2_spectrum_peak_intensity = [];
            for i = 1 : numel(peak_x_temp)
                Index = find_index(diff2_x,peak_x_temp(i),1);
                peak_x(i) = diff2_x(Index);
                diff2_spectrum_peak_intensity(i) = diff2_spectrum(Index);
            end
        else
            peak_x = [];
            diff2_spectrum_peak_intensity = [];
        end
        fit_spectrum = [];
        end
        
        function [Index_of_Lower_x,Index_of_Upper_x] = get_fit_range(app,x,Peak_x,number_of_points_left,number_of_points_right)
            switch app.wavelength_energy.Value
                case 'wavelegth'
                    %get fit range (x has to be the displayed spectral range)
                    [~, Index_x_min] = min(abs(x-min(Peak_x)));
                    Index_of_Lower_x = Index_x_min - number_of_points_left;
                    [~, Index_x_max] = min(abs(x-max(Peak_x)));
                    Index_of_Upper_x = Index_x_max + number_of_points_right;
                    
                    if numel(x) < Index_of_Upper_x
                        f = errordlg("There weren't enough points on the right side. The number of available" + ...
                            "points has been corrected.",'Point Error');
                        correction = numel(x)-Index_x_max-number_of_points_right;
                        Index_of_Upper_x = Index_of_Upper_x + correction;
                        app.NumberofpointsrightSpinner.Value = number_of_points_right + correction;
                    elseif Index_of_Lower_x < 1
                        f = errordlg("There weren't enough dots on the left side. The number of available points" + ...
                            " has been corrected.",'Point Error');
                        correction = 1 - Index_x_min + number_of_points_left;
                        Index_of_Lower_x = Index_of_Lower_x + correction;
                        app.NumberofpointsleftSpinner.Value = number_of_points_left - correction;
                    end
                case 'energy'
                    %get fit range (x has to be the displayed spectral range)
                    [~, Index_x_min] = min(abs(x-max(Peak_x)));
                    Index_of_Lower_x = Index_x_min - number_of_points_left;
                    [~, Index_x_max] = min(abs(x-min(Peak_x)));
                    Index_of_Upper_x = Index_x_max + number_of_points_right;
                    
                    if numel(x) < Index_of_Upper_x
                        f = errordlg("There weren't enough points on the right side. The number of available" + ...
                            "points has been corrected.",'Point Error');
                        correction = numel(x)-Index_x_max-number_of_points_right;
                        Index_of_Upper_x = Index_of_Upper_x + correction;
                        app.NumberofpointsrightSpinner.Value = number_of_points_right + correction;
                    elseif Index_of_Lower_x < 1
                        f = errordlg("There weren't enough dots on the left side. The number of available points" + ...
                            " has been corrected.",'Point Error');
                        correction = 1 - Index_x_min + number_of_points_left;
                        Index_of_Lower_x = Index_of_Lower_x + correction;
                        app.NumberofpointsleftSpinner.Value = number_of_points_left - correction;
                    end
            end
        end
        
        function [Fit_Data_Map_Display,rmse_Map_Dispaly,rsquare_Map_Dispaly,x_fit_range,y_fit_range] = fit_data(app)
            %% declare output variables
            Fit_Data_Map_Display = {};
            rmse_Map_Dispaly = [];
            rsquare_Map_Dispaly = [];
            x_fit_range = [];
            y_fit_range = [];
            
            %declare FitModel
            fit_mode = app.Fit_modelDropDown.Value;
            
            %% check if things are not empty
            if app.set_parameterCheckBox.Value
                if isempty(app.fit_parameter_table)
                    f = errordlg('No fit parameters have been defined. Please first click on: fit_parameter - to define fit parameters','No parameter error');
                    return
                end
            end
            if app.exclude_pointsCheckBox.Value
                if isempty(app.excluded_points_table)
                    f = errordlg('There are no points to exclude defined','no points Error');
                    return
                end
            end
            
            %% define constants
            c_light = 299792458;
            h_quant = 6.62607015e-34;
            e_charge = 1.602176634e-19;
            energy_factor = c_light*h_quant/(1e-9*e_charge);
            
            %get easyer names for the pl vactors
            vx = app.PLMap_Data.X_vector;
            vy = app.PLMap_Data.Y_vector;
            vp = app.PLMap_Data.Power;
            vt = app.PLMap_Data.Temperature_index;
            
            %% gets the indices for the current parameters
            x = find_index(vx,app.changingvalue1_1Spinner.Value,1);
            y = find_index(vy,app.changingvalue2_1Spinner.Value,1);
            p = find_index(vp,app.changingvalue3_1Spinner.Value,1);
            t = find_index(vt,app.changingvalue4_1Spinner.Value,1);
            
            %% check for existing Data
            app.FitValuesTextArea.Value = ' ';
            app.FitValuesTextArea.Value{1} = 'check for existing data ...';
            drawnow
            number_of_different_fits = 1;
            switch app.fit_button
                case 'single_fit_and_save'
                    %check for existing data
                    if any(~isnan(app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,:)), 'all')
                        answer = questdlg('There are existing data. Do you want to overwrite the present data?', ...
                            'existing data', ...
                            'Yes','No','No');
                        % Handle response
                        switch answer
                            case 'No'
                                return
                        end
                        number_of_different_fits = [];
                    end
                case 'multi_fit'
                    data_size = size(app.Fit_Data_Map);
                    if data_size(1) >= app.atpositionSpinner.Value
                        switch app.Fit_modeDropDown.Value
                            case 'all'
                                %check for existing data
                                if any(~isnan(app.Fit_Data_Map(app.atpositionSpinner.Value,:,:,:,:,:)), 'all')
                                    answer = questdlg('There are existing data. Do you want to overwrite the present data?', ...
                                        'existing data', ...
                                        'Yes','No','No');
                                    % Handle response
                                    switch answer
                                        case 'No'
                                            return
                                    end
                                    number_of_different_fits = [];
                                end
                            case 'constant_t'
                                %check for existing data
                                if any(~isnan(app.Fit_Data_Map(app.atpositionSpinner.Value,t,:,:,:,:)), 'all')
                                    answer = questdlg('There are existing data. Do you want to overwrite the present data?', ...
                                        'existing data', ...
                                        'Yes','No','No');
                                    % Handle response
                                    switch answer
                                        case 'No'
                                            return
                                    end
                                    number_of_different_fits = [];
                                end
                            case 'constant_p'
                                %check for existing data
                                if any(~isnan(app.Fit_Data_Map(app.atpositionSpinner.Value,:,p,:,:,:)), 'all')
                                    answer = questdlg('There are existing data. Do you want to overwrite the present data?', ...
                                        'existing data', ...
                                        'Yes','No','No');
                                    % Handle response
                                    switch answer
                                        case 'No'
                                            return
                                    end
                                    number_of_different_fits = [];
                                end
                            case 'constant_y'
                                %check for existing data
                                if any(~isnan(app.Fit_Data_Map(app.atpositionSpinner.Value,:,:,y,:,:)), 'all')
                                    answer = questdlg('There are existing data. Do you want to overwrite the present data?', ...
                                        'existing data', ...
                                        'Yes','No','No');
                                    % Handle response
                                    switch answer
                                        case 'No'
                                            return
                                    end
                                    number_of_different_fits = [];
                                end
                            case 'constant_x'
                                %check for existing data
                                if any(~isnan(app.Fit_Data_Map(app.atpositionSpinner.Value,:,:,:,x,:)), 'all')
                                    answer = questdlg('There are existing data. Do you want to overwrite the present data?', ...
                                        'existing data', ...
                                        'Yes','No','No');
                                    % Handle response
                                    switch answer
                                        case 'No'
                                            return
                                    end
                                    number_of_different_fits = [];
                                end
                        end
                    end
            end
            
            %% get first spectral data
            [spectrum_data,x_range] = creat_spectrum_data(app,t,p,y,x);
            
            %% check if start values are in the x_min/x_max range
            if app.set_parameterCheckBox.Value
                if app.fit_parameter_table.wavelength_min(1) < min(x_range)
                    f = errordlg('The minimum start_value is not in the x_min/x_max range.','range Error');
                    return
                elseif app.fit_parameter_table.wavelength_min(end) > max(x_range)
                    f = errordlg('The minimum start_value is not in the x_min/x_max range.','range Error');
                    return
                end
            end
            
            %% get excluded points
            if app.exclude_pointsCheckBox.Value
                excluded = app.excluded_points_table.excluded(:);
            elseif app.subtractwindowCheckBox.Value
                if min(x_range)<app.absorptin_min && max(x_range)>app.absorptin_max
                    idx_range_min = find_index(x_range,app.absorptin_min,1);
                    idx_range_max = find_index(x_range,app.absorptin_max,1);
                    excluded = x_range(idx_range_min:idx_range_max);
                end
            else
                excluded = [];
            end
            
            %% definne the range off the diff spectra
            x_diff2 = x_range(1:end-2);
            
            
            %% create list of spectra
            app.FitValuesTextArea.Value{end + 1} = 'create list of spectra ...';
            drawnow
            k = 0;
            
            switch app.fit_button
                case {'single_fit','single_fit_and_save'}
                    k = k+1;
                    Spectrum(1,:) = spectrum_data;
                case 'multi_fit'
                    switch app.Fit_modeDropDown.Value
                        case 'all'
                            Spectrum = NaN(numel(vt)*numel(vp)*numel(vy)*numel(vx),numel(x_range));
                            for t = 1 : numel(vt)
                                for p = 1 : numel(vp)
                                    for y = 1 : numel(vy)
                                        for x = 1 : numel(vx)
                                            k = k+1;
                                            [spectrum_data,~] = creat_spectrum_data(app,t,p,y,x);
                                            Spectrum(k,:) = spectrum_data;
                                        end
                                    end
                                end
                            end
                        case 'constant_t'
                            Spectrum = NaN(numel(vp)*numel(vy)*numel(vx),numel(x_range));
                            for p = 1 : numel(vp)
                                for y = 1 : numel(vy)
                                    for x = 1 : numel(vx)
                                        k = k+1;
                                        [spectrum_data,~] = creat_spectrum_data(app,t,p,y,x);
                                        Spectrum(k,:) = spectrum_data;
                                    end
                                end
                            end
                        case 'constant_p'
                            Spectrum = NaN(numel(vt)*numel(vy)*numel(vx),numel(x_range));
                            for t = 1 : numel(vt)
                                for y = 1 : numel(vy)
                                    for x = 1 : numel(vx)
                                        k = k+1;
                                        [spectrum_data,~] = creat_spectrum_data(app,t,p,y,x);
                                        Spectrum(k,:) = spectrum_data;
                                    end
                                end
                            end
                        case 'constant_y'
                            Spectrum = NaN(numel(vt)*numel(vp)*numel(vx),numel(x_range));
                            for t = 1 : numel(vt)
                                for p = 1 : numel(vp)
                                    for x = 1 : numel(vx)
                                        k = k+1;
                                        [spectrum_data,~] = creat_spectrum_data(app,t,p,y,x);
                                        Spectrum(k,:) = spectrum_data;
                                    end
                                end
                            end
                        case 'constant_x'
                            Spectrum = NaN(numel(vt)*numel(vp)*numel(vy),numel(x_range));
                            for t = 1 : numel(vt)
                                for p = 1 : numel(vp)
                                    for y = 1 : numel(vy)
                                        k = k+1;
                                        [spectrum_data,~] = creat_spectrum_data(app,t,p,y,x);
                                        Spectrum(k,:) = spectrum_data;
                                    end
                                end
                            end
                    end
            end
            
            %% get the peaks to fit
            % get the smoothed second diff
            Smoothed_Spectrum_diff = diff(smooth_data(Spectrum',app.SmoothingDropDown_5.Value,app.WindowsizeSpinner_5.Value,app.sgolaydegreeSpinner_5.Value));
            Spectrum_diff2_smoothed = diff(smooth_data(Smoothed_Spectrum_diff,app.SmoothingDropDown_6.Value,app.WindowsizeSpinner_6.Value,app.sgolaydegreeSpinner_6.Value));
            
            %find the minima in the smoothed data
            app.FitValuesTextArea.Value{end + 1} = 'find peaks in 2nd derivative ...';
            drawnow
            switch fit_mode
                case '2nd_diff'
                list = {'movmean','movmedian','gaussian','lowess','loess','rlowess','rloess','sgolay'};
                [indx,~] = listdlg('ListString',list);
                
                prompt = {'Enter Windowsize:','Enter sgolay degree:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'21','3'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                smoothed_Spectrum_diff2_smoothed = smooth_data(Spectrum_diff2_smoothed,list{indx},str2num(answer{1}),str2num(answer{2}));
                size_of_smooth_data = size(smoothed_Spectrum_diff2_smoothed);
                
                %interpolate the data and findes the peaks
                steps = (x_diff2(2)-x_diff2(1))/10;
                xq = [x_diff2(1) : steps : max(x_diff2)];
                peak_x_temp = zeros(app.maxpeaksSpinner.Value,size_of_smooth_data(2));
                
                for i = 1 : size_of_smooth_data(2)
                    yq = makima(x_diff2,smoothed_Spectrum_diff2_smoothed(:,i)',xq);
                    
                    %find the minima in the interpolated data
                    [Index_of_peak,~,peaks] = find_minima(app,xq',yq');
                    temp = peaks(Index_of_peak);
                    for j = 1 : numel(temp)
                    peak_x_temp(j,i) = temp(j);
                    end
                    clear temp
                end
                
%                 test = 1;
%                 peaks_cv = [vx',peak_x_temp'];
                otherwise
                    [~,~,peak_x_temp] = find_minima(app,x_diff2,Spectrum_diff2_smoothed);
            end
            size_peak_x_temp = size(peak_x_temp);
            
            if app.set_parameterCheckBox.Value == 0
                switch app.fit_button
                    case {'single_fit','single_fit_and_save'}
                        [~,~,n_peaks_temporary] = find(peak_x_temp);
                        % error if there is no match
                        if isempty(n_peaks_temporary)
                            f = errordlg('No peak was found in the second derivation. Please check if the minimum depth or peak distance was selected correctly.','Peak Error');
                            return
                        elseif numel(n_peaks_temporary)>8
                            f = errordlg('There ware more then 8 peak found in the second derivation. Please increase prominence or peak seperation to reduce the number of fitted Peaks.','Peak Error');
                            return
                        end
                end
            end
            
            %gets the maximum number of peaks to fit
            n_peak = 0;
            for i = 1 : size_peak_x_temp(2)
                x_column(:) = peak_x_temp(:,i);
                [~,~,v_column] = find(x_column);
                peak_numel = numel(v_column);
                if peak_numel > n_peak
                    n_peak = peak_numel;
                end
            end
            
            blub = 1;
            %if less then 2 peaks are found set them to 2 so the prelocated
            %arrays function correctly (for nan(1,2,1) all dim with size of
            %1 a erased after the second dimension
            if n_peak == 1
                n_peak = 2;
            end
            
            %overwrites the found peaks with the fit parameter if defined
            if app.set_parameterCheckBox.Value
                %get the table data
                size_table = size(app.fit_parameter_table);
                peak_logic = app.fit_parameter_table.finde_peak;
                
                %gets peaks that belong together
                peak_combine = app.fit_parameter_table.combined_with;
                peak_combine_logic = ismember(peak_combine,'before');
                peak_block = zeros(size_table(1),1);
                u = 0;
                for i = 1:size_table(1)
                    if peak_combine_logic(i)
                        peak_block(i) = u;
                    else
                        u = u+1;
                        peak_block(i) = u;
                    end
                end
                
                %calculate how many peaks are in each block
                for u = 1 : max(peak_block)
                    peak_block_index = find(peak_block == u);
                    peak_block_numel = numel(peak_block_index);
                    peak_fit_counter(u) =  peak_block_numel;
                end
                
                %sets the peaks to the maximum number of peaks in a
                %batch
                n_peak = max(peak_fit_counter);
                
                %if less peaks a found then have been declered set them to
                %the number of peaks in the table
                if n_peak == 1
                    n_peak = 2;
                end
                
                % individual fit batches
                fit_batches = numel(peak_fit_counter);
                number_of_different_fits = fit_batches;
                
                %prelocat the array for speedup
                new_peak_x = zeros(n_peak,size_peak_x_temp(2)*fit_batches);
                start_values = nan(7,n_peak,size_peak_x_temp(2)*fit_batches);
                size_spectrum = size(Spectrum);
                Spectrum_new = zeros(size_spectrum(1)*fit_batches,size_spectrum(2));
                left_points = nan(n_peak,size_peak_x_temp(2)*fit_batches);
                right_points = nan(n_peak,size_peak_x_temp(2)*fit_batches);
                
                k = 0;
                %go through all peaks exept the not mapped
                for j = 1 : size_peak_x_temp(2)
                    if ~all(Spectrum(j,:) == 1)
                        for e = 1 : fit_batches
                            k = k +1;
                            block_index = find(peak_block == e);
                            
                            n_loop = numel(block_index);
                            % go through all peaks
                            for ll = 1 : n_loop
                                i = block_index(ll);
                                %check if the peak should find a better automatic peak
                                switch peak_logic(i)
                                    case 'close_to'
                                        logic_peak_index = find_index(peak_x_temp(:,j),app.fit_parameter_table.wavelength(i),1);
                                        %if the autopeak is in the range of the delta peak
                                        %take the autopeak as it gives a better start value
                                        %for the fit
                                        if abs(peak_x_temp(logic_peak_index,j)-app.fit_parameter_table.wavelength(i)) < app.fit_parameter_table.delta_wavelength(i)
                                            new_peak_x(logic_peak_index,k) = peak_x_temp(logic_peak_index,j);
                                            peak_x_temp(logic_peak_index,j) = 0;
                                        else
                                            logic_peak_index = find_index(x_diff2,app.fit_parameter_table.wavelength(i),1);
                                            new_peak_x(logic_peak_index,k) = app.fit_parameter_table.wavelength(i);
                                        end
                                    case 'fixed'
                                        logic_peak_index = find_index(x_diff2,app.fit_parameter_table.wavelength(i),1);
                                        new_peak_x(logic_peak_index,k) = app.fit_parameter_table.wavelength(i);
                                    case 'only_range'
                                        temp(:) = peak_x_temp(:,j);
                                        temptemp = temp(temp~=0);
                                        if i<=numel(temptemp)
                                            logic_peak_index = find_index(peak_x_temp(:,j),temptemp(i),1);
                                            new_peak_x(logic_peak_index,k) = peak_x_temp(logic_peak_index,j);
                                        end
                                end
                                start_values(1,ll,k) = (app.fit_parameter_table.wavelength_min(i))';
                                start_values(2,ll,k) = (app.fit_parameter_table.wavelength_max(i))';
                                start_values(3,ll,k) = (app.fit_parameter_table.hight_min(i))';
                                start_values(4,ll,k) = (app.fit_parameter_table.hight_max(i))';
                                start_values(5,ll,k) = (app.fit_parameter_table.FWHM(i))';
                                start_values(6,ll,k) = (app.fit_parameter_table.FWHM_min(i))';
                                start_values(7,ll,k) = (app.fit_parameter_table.FWHM_max(i))';
                                left_points(ll,k) = app.fit_parameter_table.left_points(i);
                                right_points(ll,k) = app.fit_parameter_table.right_points(i);
                            end
                            Spectrum_new(k,:) = Spectrum(j,:);
                        end
                    else
                        for e = 1 : fit_batches
                            k = k+1;
                            Spectrum_new(k,:) = Spectrum(j,:);
                        end
                        
                    end
                end
                Spectrum = [];
                Spectrum = Spectrum_new;
                peak_x_temp = [];
                peak_x_temp = new_peak_x;
                
                switch fit_mode
                    case '2nd_diff'
                        size_peak_x_temp = size(peak_x_temp);
                        hight_x_temp = zeros(1,size_peak_x_temp(2));
                        for i = 1 : size_peak_x_temp(2)
                            [~,~,peak_temp] = find(peak_x_temp(:,i));
                            if ~isempty(peak_temp)
                                Index = find_index(x_range,peak_temp,1);
                                hight_x_temp(1,i) = Spectrum(i,Index);
                            end
                        end
                    otherwise
                end
            else
                left_points = app.NumberofpointsleftSpinner.Value;
                right_points = app.NumberofpointsrightSpinner.Value;
            end
            
            %% prepare the fit
            %dispaly a masssage that the fit is started end running
            app.FitValuesTextArea.Value{end + 1} = 'start parallel fit of data - please wait ...';
            drawnow
            
            set_Value = app.set_parameterCheckBox.Value;
            n_iter = app.maxiterEditField.Value;
            accuracy = app.accuracyEditField.Value;
            Sweep = app.SweepDropDown.Value;
            
            %prelocats the fit arays and appand data structure
            Fit_Data_Map_temp = cell(k,1);
            rmse_Map_temp = nan(k,1);
            rsquare_Map_temp = nan(k,1);
            
            switch app.fit_button
                case 'single_fit'
                otherwise
                    if ~isempty(number_of_different_fits)
                        app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,1);
                        app.rmse_Map = extend_array(app.rmse_Map,number_of_different_fits,1);
                        app.rsquare_Map = extend_array(app.rsquare_Map,number_of_different_fits,1);
                        app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,1);
                        app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,1);
                        app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,1);
                        app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,1);
                        app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,1);
                        app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,1);
                    end
            end
            clear i
            switch fit_mode
                case '2nd_diff'
                otherwise
                    %% fit the data
                    switch app.fit_button
                        case {'single_fit','single_fit_and_save'}
                            size_peak_x_temp = size(peak_x_temp);
                            if set_Value
                                for i = 1 : size_peak_x_temp(2)
                                    [~,~,peak_x] = find(peak_x_temp(:,i));
                                    if isempty(peak_x) == 0
                                        %gets the range left an right of the peaks
                                        [Index_of_Lower_x,Index_of_Upper_x] = get_fit_range(app,x_range,peak_x, ...
                                            left_points(1,i),right_points(1,i));
                                        
                                        %fits the funktion to the data
                                        [f,gof] = Fit_data(x_range,Spectrum(i,:),peak_x,...
                                            'off',accuracy,n_iter,start_values(:,:,i),Index_of_Lower_x,Index_of_Upper_x,Sweep,excluded,fit_mode);
                                        
                                        %combines the data to an list of all fits
                                        Fit_Data_Map_Display{i,1} = f;
                                        if ~isempty(gof)
                                            rmse_Map_Dispaly(i,1) = gof.rmse;
                                            rsquare_Map_Dispaly(i,1) = gof.rsquare;
                                        end
                                        
                                        if i == 1
                                            x_fit_range = x_range(Index_of_Lower_x:Index_of_Upper_x);
                                            y_fit_range = Spectrum(i,Index_of_Lower_x:Index_of_Upper_x);
                                        else
                                            x_fit_range = [x_fit_range;x_range(Index_of_Lower_x:Index_of_Upper_x)];
                                            y_fit_range = [y_fit_range,Spectrum(i,Index_of_Lower_x:Index_of_Upper_x)];
                                        end
                                    end
                                end
                                %delet excluded points
                                if ~isempty(excluded)
                                    for ex = 1 :numel(excluded)
                                        y_fit_range((excluded(ex)-0.0001 < x_fit_range) & (x_fit_range < excluded(ex)+0.0001)) = [];
                                        x_fit_range((excluded(ex)-0.0001 < x_fit_range) & (x_fit_range < excluded(ex)+0.0001)) = [];
                                    end
                                end
                            else
                                %define start values from table
                                start_values = [];
                                
                                for i = 1 : size_peak_x_temp(2)
                                    [~,~,peak_x] = find(peak_x_temp(:,i));
                                    if isempty(peak_x) == 0
                                        %gets the range left an right of the peaks
                                        [Index_of_Lower_x,Index_of_Upper_x] = get_fit_range(app,x_range,peak_x, ...
                                            left_points,right_points);
                                        
                                        %fits the funktion to the data
                                        [f,gof] = Fit_data(x_range,Spectrum(i,:),peak_x,...
                                            'off',accuracy,n_iter,start_values,Index_of_Lower_x,Index_of_Upper_x,Sweep,excluded,fit_mode);
                                        
                                        %combines the data to an list of all fits
                                        Fit_Data_Map_Display{i,1} = f;
                                        if ~isempty(gof)
                                            rmse_Map_Dispaly(i,1) = gof.rmse;
                                            rsquare_Map_Dispaly(i,1) = gof.rsquare;
                                        end
                                    end
                                end
                                x_fit_range = x_range(Index_of_Lower_x:Index_of_Upper_x);
                                y_fit_range = Spectrum(i,Index_of_Lower_x:Index_of_Upper_x);
                                
                                %delet excluded points
                                if ~isempty(excluded)
                                    for ex = 1 :numel(excluded)
                                        y_fit_range((excluded(ex)-0.0001 < x_fit_range) & (x_fit_range < excluded(ex)+0.0001)) = [];
                                        x_fit_range((excluded(ex)-0.0001 < x_fit_range) & (x_fit_range < excluded(ex)+0.0001)) = [];
                                    end
                                end
                            end
                        case 'multi_fit'
                            %% parallel peak fit
                            %parpool();
                            batchsize = app.batch_size;
                            batches = floor(k/batchsize);
                            mod_batches = mod(k,batchsize);
                            
                            %displays a statusBar indicationg the progras of importing the data into an matlab structure
                            figure_ui = uifigure;
                            d = uiprogressdlg(figure_ui,'Title','Loading Data','Message','1');
                            
                            elapsedTime = nan(1,batches+1);
                            elapsedTime(1) = 1;
                            
                            %iterate batches
                            for ii = 1 : batches+1
                                tic
                                if ii == batches+1
                                    start = batches*batchsize+1;
                                    last = batches*batchsize+mod_batches;
                                else
                                    start = (ii-1)*batchsize+1;
                                    last = ii*batchsize;
                                end
                                
                                %display the persentage of Importing the Data
                                s = seconds(mean(elapsedTime,'all','omitnan')*(batches+1-(ii-1)));
                                s.Format = 'hh:mm:ss';
                                estimate_time = char(s);
                                Persentage =  (ii-1)/(batches+1);
                                d.Value = Persentage;
                                d.Message = sprintf(['Percentage: %4.1f / estimate time = ',estimate_time],Persentage*100);
                                pause(1)
                                
                                if set_Value
                                    parfor i = start : last
                                        [~,~,peak_x] = find(peak_x_temp(:,i));
                                        if isempty(peak_x) == 0
                                            %gets the range left an right of the peaks
                                            [Index_of_Lower_x,Index_of_Upper_x] = get_fit_range_no_iter(x_range,peak_x, ...
                                                left_points(1,i),right_points(1,i));
                                            
                                            %fits the funktion to the data
                                            [f,gof] = Fit_data(x_range,Spectrum(i,:),peak_x,...
                                                'off',accuracy,n_iter,start_values(:,:,i),Index_of_Lower_x,Index_of_Upper_x,Sweep,excluded,fit_mode);
                                            
                                            %combines the data to an list of all fits
                                            Fit_Data_Map_temp{i,1} = f;
                                            if ~isempty(gof)
                                                rmse_Map_temp(i,1) = gof.rmse;
                                                rsquare_Map_temp(i,1) = gof.rsquare;
                                            end
                                        end
                                    end
                                else
                                    %define start values from table
                                    start_values = [];
                                    
                                    parfor i = start : last
                                        [~,~,peak_x] = find(peak_x_temp(:,i));
                                        if isempty(peak_x) == 0
                                            %gets the range left an right of the peaks
                                            [Index_of_Lower_x,Index_of_Upper_x] = get_fit_range_no_iter(x_range,peak_x, ...
                                                left_points,right_points);
                                            
                                            %fits the funktion to the data
                                            [f,gof] = Fit_data(x_range,Spectrum(i,:),peak_x,...
                                                'off',accuracy,n_iter,start_values,Index_of_Lower_x,Index_of_Upper_x,Sweep,excluded,fit_mode);
                                            
                                            %combines the data to an list of all fits
                                            Fit_Data_Map_temp{i,1} = f;
                                            if ~isempty(gof)
                                                rmse_Map_temp(i,1) = gof.rmse;
                                                rsquare_Map_temp(i,1) = gof.rsquare;
                                            end
                                        end
                                    end
                                end
                                %get the time for the fit iteration
                                elapsedTime(ii) = toc;
                            end
                            %close the statusbar
                            close(d);
                            close(figure_ui);
                    end
            end
            
            %% create fit data array
            app.FitValuesTextArea.Value{end + 1} = 'fitting completed - create fit data array ...';
            drawnow
            
            %% prelocate the datamap
            app.FitValuesTextArea.Value{end + 1} = 'prelocat data array ...';
            drawnow
            size_array = (app.Fit_Data_Map);
            

            k = 0;
            max_size = size(Fit_Data_Map_temp);
            switch app.fit_button
                case 'single_fit_and_save'
                    if app.set_parameterCheckBox.Value
                        for e = 1 : fit_batches
                            k = k+1;
                            if ~isempty(Fit_Data_Map_Display{k,1})
                                %define Datapoints out of 3Dim array
                                coeffvals = coeffvalues(Fit_Data_Map_Display{k,1});
                                ci_68 = confint(Fit_Data_Map_Display{k,1},0.6827);
                                ci_95 = confint(Fit_Data_Map_Display{k,1},0.9545);
                                ci_997 = confint(Fit_Data_Map_Display{k,1},0.9973);
                                size_coeff = size(coeffvals);
                                if size_array(6)<size_coeff(2)
                                    app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                    app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                    app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                    app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                    app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                    app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                    app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                end
                                for i = 1 : size_coeff(2)
                                    app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = coeffvals(1,i);
                                    app.Fit_Data_error_68_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(2,i);
                                    app.Fit_Data_error_68_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(1,i);
                                    app.Fit_Data_error_95_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(2,i);
                                    app.Fit_Data_error_95_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(1,i);
                                    app.Fit_Data_error_997_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(2,i);
                                    app.Fit_Data_error_997_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(1,i);
                                end
                                fit_formula = formula(Fit_Data_Map_Display{k,1});
                                [app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x)] = get_fit_function_model(fit_formula);
                                app.rmse_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rmse_Map_Dispaly(k,1);
                                app.rsquare_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rsquare_Map_Dispaly(k,1);
                            end
                        end
                    else
                        k = k+1;
                        %define Datapoints out of 3Dim array
                        if ~isempty(Fit_Data_Map_Display{k,1})
                            coeffvals = coeffvalues(Fit_Data_Map_Display{k,1});
                            ci_68 = confint(Fit_Data_Map_Display{k,1},0.6827);
                            ci_95 = confint(Fit_Data_Map_Display{k,1},0.9545);
                            ci_997 = confint(Fit_Data_Map_Display{k,1},0.9973);
                            size_coeff = size(coeffvals);
                            if size_array(6)<size_coeff(2)
                                app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                            end
                            for i = 1 : size_coeff(2)
                                app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,i) = coeffvals(1,i);
                                app.Fit_Data_error_68_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(2,i);
                                app.Fit_Data_error_68_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(1,i);
                                app.Fit_Data_error_95_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(2,i);
                                app.Fit_Data_error_95_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(1,i);
                                app.Fit_Data_error_997_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(2,i);
                                app.Fit_Data_error_997_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(1,i);
                            end
                            fit_formula = formula(Fit_Data_Map_Display{k,1});
                            [app.Fit_Data_function_parameter(app.atpositionSpinner.Value,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value,t,p,y,x)] = get_fit_function_model(fit_formula);
                            app.rmse_Map(app.atpositionSpinner.Value,t,p,y,x) = rmse_Map_Dispaly(k,1);
                            app.rsquare_Map(app.atpositionSpinner.Value,t,p,y,x) = rsquare_Map_Dispaly(k,1);
                        end
                    end
                case 'multi_fit'
                    %displays a statusBar indicationg the progras of importing the data into an matlab structure
                    figure_ui = uifigure;
                    d = uiprogressdlg(figure_ui,'Title','Loading Data','Message','1');
                    pause(1)
                    switch app.Fit_modeDropDown.Value
                        case 'all'
                            for t = 1 : numel(vt)
                                for p = 1 : numel(vp)
                                    for y = 1 : numel(vy)
                                        for x = 1 : numel(vx)
                                            if app.set_parameterCheckBox.Value
                                                for e = 1 : fit_batches
                                                    k = k+1;
                                                    switch fit_mode
                                                        case '2nd_diff'
                                                            [~,~,the_peak] = find(peak_x_temp(:,k));
                                                            if ~isempty(the_peak)
                                                                app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,1) = hight_x_temp(1,k);
                                                                app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,2) = the_peak;
                                                                app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x) = 2;
                                                                app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x) = 1;
                                                            end
                                                        otherwise
                                                            if ~isempty(Fit_Data_Map_temp{k,1})
                                                                %define Datapoints out of 3Dim array
                                                                coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                                ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                                ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                                ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                                size_coeff = size(coeffvals);
                                                                if size_array(6)<size_coeff(2)
                                                                    app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                                    app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                                    app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                                    app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                                    app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                                    app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                                    app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                                end
                                                                for i = 1 : size_coeff(2)
                                                                    app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = coeffvals(1,i);
                                                                    app.Fit_Data_error_68_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(2,i);
                                                                    app.Fit_Data_error_68_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(1,i);
                                                                    app.Fit_Data_error_95_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(2,i);
                                                                    app.Fit_Data_error_95_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(1,i);
                                                                    app.Fit_Data_error_997_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(2,i);
                                                                    app.Fit_Data_error_997_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(1,i);
                                                                end
                                                                fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                                [app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                                app.rmse_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rmse_Map_temp(k,1);
                                                                app.rsquare_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rsquare_Map_temp(k,1);
                                                            end
                                                    end
                                                    %display the persentage of Importing the Data
                                                    Persentage =  k/max_size(1);
                                                    d.Value = Persentage;
                                                    d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                                end
                                            else
                                                k = k+1;
                                                %define Datapoints out of 3Dim array
                                                if ~isempty(Fit_Data_Map_temp{k,1})
                                                    coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                    ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                    ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                    ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                    size_coeff = size(coeffvals);
                                                    if size_array(6)<size_coeff(2)
                                                        app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                    end
                                                    for i = 1 : size_coeff(2)
                                                        app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,i) = coeffvals(1,i);
                                                        app.Fit_Data_error_68_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(2,i);
                                                        app.Fit_Data_error_68_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(1,i);
                                                        app.Fit_Data_error_95_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(2,i);
                                                        app.Fit_Data_error_95_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(1,i);
                                                        app.Fit_Data_error_997_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(2,i);
                                                        app.Fit_Data_error_997_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(1,i);
                                                    end
                                                    fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                    [app.Fit_Data_function_parameter(app.atpositionSpinner.Value,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                    app.rmse_Map(app.atpositionSpinner.Value,t,p,y,x) = rmse_Map_temp(k,1);
                                                    app.rsquare_Map(app.atpositionSpinner.Value,t,p,y,x) = rsquare_Map_temp(k,1);
                                                end
                                                %display the persentage of Importing the Data
                                                Persentage =  k/max_size(1);
                                                d.Value = Persentage;
                                                d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                            end
                                        end
                                    end
                                end
                            end
                        case 'constant_t'
                            for p = 1 : numel(vp)
                                for y = 1 : numel(vy)
                                    for x = 1 : numel(vx)
                                        if app.set_parameterCheckBox.Value
                                            for e = 1 : fit_batches
                                                k = k+1;
                                                if ~isempty(Fit_Data_Map_temp{k,1})
                                                    %define Datapoints out of 3Dim array
                                                    coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                    ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                    ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                    ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                    size_coeff = size(coeffvals);
                                                    if size_array(6)<size_coeff(2)
                                                        app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                    end
                                                    for i = 1 : size_coeff(2)
                                                        app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = coeffvals(1,i);
                                                        app.Fit_Data_error_68_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(2,i);
                                                        app.Fit_Data_error_68_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(1,i);
                                                        app.Fit_Data_error_95_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(2,i);
                                                        app.Fit_Data_error_95_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(1,i);
                                                        app.Fit_Data_error_997_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(2,i);
                                                        app.Fit_Data_error_997_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(1,i);
                                                    end
                                                    fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                    [app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                    app.rmse_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rmse_Map_temp(k,1);
                                                    app.rsquare_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rsquare_Map_temp(k,1);
                                                end
                                                %display the persentage of Importing the Data
                                                Persentage =  k/max_size(1);
                                                d.Value = Persentage;
                                                d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                            end
                                        else
                                            k = k+1;
                                            %define Datapoints out of 3Dim array
                                            if ~isempty(Fit_Data_Map_temp{k,1})
                                                coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                size_coeff = size(coeffvals);
                                                if size_array(6)<size_coeff(2)
                                                    app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                end
                                                for i = 1 : size_coeff(2)
                                                    app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,i) = coeffvals(1,i);
                                                    app.Fit_Data_error_68_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(2,i);
                                                    app.Fit_Data_error_68_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(1,i);
                                                    app.Fit_Data_error_95_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(2,i);
                                                    app.Fit_Data_error_95_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(1,i);
                                                    app.Fit_Data_error_997_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(2,i);
                                                    app.Fit_Data_error_997_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(1,i);
                                                end
                                                fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                [app.Fit_Data_function_parameter(app.atpositionSpinner.Value,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                app.rmse_Map(app.atpositionSpinner.Value,t,p,y,x) = rmse_Map_temp(k,1);
                                                app.rsquare_Map(app.atpositionSpinner.Value,t,p,y,x) = rsquare_Map_temp(k,1);
                                            end
                                            %display the persentage of Importing the Data
                                            Persentage =  k/max_size(1);
                                            d.Value = Persentage;
                                            d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                        end
                                    end
                                end
                            end
                        case 'constant_p'
                            for t = 1 : numel(vt)
                                for y = 1 : numel(vy)
                                    for x = 1 : numel(vx)
                                        if app.set_parameterCheckBox.Value
                                            for e = 1 : fit_batches
                                                k = k+1;
                                                if ~isempty(Fit_Data_Map_temp{k,1})
                                                    %define Datapoints out of 3Dim array
                                                    coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                    ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                    ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                    ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                    size_coeff = size(coeffvals);
                                                    if size_array(6)<size_coeff(2)
                                                        app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                    end
                                                    for i = 1 : size_coeff(2)
                                                        app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = coeffvals(1,i);
                                                        app.Fit_Data_error_68_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(2,i);
                                                        app.Fit_Data_error_68_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(1,i);
                                                        app.Fit_Data_error_95_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(2,i);
                                                        app.Fit_Data_error_95_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(1,i);
                                                        app.Fit_Data_error_997_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(2,i);
                                                        app.Fit_Data_error_997_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(1,i);
                                                    end
                                                    fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                    [app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                    app.rmse_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rmse_Map_temp(k,1);
                                                    app.rsquare_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rsquare_Map_temp(k,1);
                                                end
                                                %display the persentage of Importing the Data
                                                Persentage =  k/max_size(1);
                                                d.Value = Persentage;
                                                d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                            end
                                        else
                                            k = k+1;
                                            %define Datapoints out of 3Dim array
                                            if ~isempty(Fit_Data_Map_temp{k,1})
                                                coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                size_coeff = size(coeffvals);
                                                if size_array(6)<size_coeff(2)
                                                    app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                end
                                                for i = 1 : size_coeff(2)
                                                    app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,i) = coeffvals(1,i);
                                                    app.Fit_Data_error_68_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(2,i);
                                                    app.Fit_Data_error_68_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(1,i);
                                                    app.Fit_Data_error_95_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(2,i);
                                                    app.Fit_Data_error_95_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(1,i);
                                                    app.Fit_Data_error_997_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(2,i);
                                                    app.Fit_Data_error_997_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(1,i);
                                                end
                                                fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                [app.Fit_Data_function_parameter(app.atpositionSpinner.Value,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                app.rmse_Map(app.atpositionSpinner.Value,t,p,y,x) = rmse_Map_temp(k,1);
                                                app.rsquare_Map(app.atpositionSpinner.Value,t,p,y,x) = rsquare_Map_temp(k,1);
                                            end
                                            %display the persentage of Importing the Data
                                            Persentage =  k/max_size(1);
                                            d.Value = Persentage;
                                            d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                        end
                                    end
                                end
                            end
                        case 'constant_y'
                            for t = 1 : numel(vt)
                                for p = 1 : numel(vp)
                                    for x = 1 : numel(vx)
                                        if app.set_parameterCheckBox.Value
                                            for e = 1 : fit_batches
                                                k = k+1;
                                                if ~isempty(Fit_Data_Map_temp{k,1})
                                                    %define Datapoints out of 3Dim array
                                                    coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                    ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                    ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                    ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                    size_coeff = size(coeffvals);
                                                    if size_array(6)<size_coeff(2)
                                                        app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                    end
                                                    for i = 1 : size_coeff(2)
                                                        app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = coeffvals(1,i);
                                                        app.Fit_Data_error_68_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(2,i);
                                                        app.Fit_Data_error_68_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(1,i);
                                                        app.Fit_Data_error_95_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(2,i);
                                                        app.Fit_Data_error_95_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(1,i);
                                                        app.Fit_Data_error_997_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(2,i);
                                                        app.Fit_Data_error_997_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(1,i);
                                                    end
                                                    fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                    [app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                    app.rmse_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rmse_Map_temp(k,1);
                                                    app.rsquare_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rsquare_Map_temp(k,1);
                                                end
                                                %display the persentage of Importing the Data
                                                Persentage =  k/max_size(1);
                                                d.Value = Persentage;
                                                d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                            end
                                        else
                                            k = k+1;
                                            %define Datapoints out of 3Dim array
                                            if ~isempty(Fit_Data_Map_temp{k,1})
                                                coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                size_coeff = size(coeffvals);
                                                if size_array(6)<size_coeff(2)
                                                    app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                end
                                                for i = 1 : size_coeff(2)
                                                    app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,i) = coeffvals(1,i);
                                                    app.Fit_Data_error_68_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(2,i);
                                                    app.Fit_Data_error_68_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(1,i);
                                                    app.Fit_Data_error_95_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(2,i);
                                                    app.Fit_Data_error_95_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(1,i);
                                                    app.Fit_Data_error_997_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(2,i);
                                                    app.Fit_Data_error_997_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(1,i);
                                                end
                                                fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                [app.Fit_Data_function_parameter(app.atpositionSpinner.Value,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                app.rmse_Map(app.atpositionSpinner.Value,t,p,y,x) = rmse_Map_temp(k,1);
                                                app.rsquare_Map(app.atpositionSpinner.Value,t,p,y,x) = rsquare_Map_temp(k,1);
                                            end
                                            %display the persentage of Importing the Data
                                            Persentage =  k/max_size(1);
                                            d.Value = Persentage;
                                            d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                        end
                                    end
                                end
                            end
                        case 'constant_x'
                            for t = 1 : numel(vt)
                                for p = 1 : numel(vp)
                                    for y = 1 : numel(vy)
                                        if app.set_parameterCheckBox.Value
                                            for e = 1 : fit_batches
                                                k = k+1;
                                                if ~isempty(Fit_Data_Map_temp{k,1})
                                                    %define Datapoints out of 3Dim array
                                                    coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                    ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                    ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                    ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                    size_coeff = size(coeffvals);
                                                    if size_array(6)<size_coeff(2)
                                                        app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                        app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                    end
                                                    for i = 1 : size_coeff(2)
                                                        app.Fit_Data_Map(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = coeffvals(1,i);
                                                        app.Fit_Data_error_68_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(2,i);
                                                        app.Fit_Data_error_68_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_68(1,i);
                                                        app.Fit_Data_error_95_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(2,i);
                                                        app.Fit_Data_error_95_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_95(1,i);
                                                        app.Fit_Data_error_997_pos(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(2,i);
                                                        app.Fit_Data_error_997_neg(app.atpositionSpinner.Value+e-1,t,p,y,x,i) = ci_997(1,i);
                                                    end
                                                    fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                    [app.Fit_Data_function_parameter(app.atpositionSpinner.Value+e-1,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value+e-1,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                    app.rmse_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rmse_Map_temp(k,1);
                                                    app.rsquare_Map(app.atpositionSpinner.Value+e-1,t,p,y,x) = rsquare_Map_temp(k,1);
                                                end
                                                %display the persentage of Importing the Data
                                                Persentage =  k/max_size(1);
                                                d.Value = Persentage;
                                                d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                            end
                                        else
                                            k = k+1;
                                            %define Datapoints out of 3Dim array
                                            if ~isempty(Fit_Data_Map_temp{k,1})
                                                coeffvals = coeffvalues(Fit_Data_Map_temp{k,1});
                                                ci_68 = confint(Fit_Data_Map_temp{k,1},0.6827);
                                                ci_95 = confint(Fit_Data_Map_temp{k,1},0.9545);
                                                ci_997 = confint(Fit_Data_Map_temp{k,1},0.9973);
                                                size_coeff = size(coeffvals);
                                                if size_array(6)<size_coeff(2)
                                                    app.Fit_Data_Map = extend_array(app.Fit_Data_Map,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_pos = extend_array(app.Fit_Data_error_68_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_68_neg = extend_array(app.Fit_Data_error_68_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_pos = extend_array(app.Fit_Data_error_95_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_95_neg = extend_array(app.Fit_Data_error_95_neg,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_pos = extend_array(app.Fit_Data_error_997_pos,number_of_different_fits,6);
                                                    app.Fit_Data_error_997_neg = extend_array(app.Fit_Data_error_997_neg,number_of_different_fits,6);
                                                end
                                                for i = 1 : size_coeff(2)
                                                    app.Fit_Data_Map(app.atpositionSpinner.Value,t,p,y,x,i) = coeffvals(1,i);
                                                    app.Fit_Data_error_68_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(2,i);
                                                    app.Fit_Data_error_68_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_68(1,i);
                                                    app.Fit_Data_error_95_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(2,i);
                                                    app.Fit_Data_error_95_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_95(1,i);
                                                    app.Fit_Data_error_997_pos(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(2,i);
                                                    app.Fit_Data_error_997_neg(app.atpositionSpinner.Value,t,p,y,x,i) = ci_997(1,i);
                                                end
                                                fit_formula = formula(Fit_Data_Map_temp{k,1});
                                                [app.Fit_Data_function_parameter(app.atpositionSpinner.Value,t,p,y,x),app.Fit_Data_function_grade(app.atpositionSpinner.Value,t,p,y,x)] = get_fit_function_model(fit_formula);
                                                app.rmse_Map(app.atpositionSpinner.Value,t,p,y,x) = rmse_Map_temp(k,1);
                                                app.rsquare_Map(app.atpositionSpinner.Value,t,p,y,x) = rsquare_Map_temp(k,1);
                                            end
                                            %display the persentage of Importing the Data
                                            Persentage =  k/max_size(1);
                                            d.Value = Persentage;
                                            d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                                        end
                                    end
                                end
                            end
                    end
                    %close the statusbar
                    close(d);
                    close(figure_ui);
            end


            %% delets useless fit enteties
            size_data = size(app.Fit_Data_Map);
            del_counter = 0;
            delete_positions = [];
            if any(~isnan(app.Fit_Data_Map), 'all')
                for i = 1 : size_data(1)
                    if all(isnan(app.Fit_Data_Map(i,:,:,:,:,:)), 'all')
                        del_counter = del_counter +1;
                        delete_positions(del_counter) = i;
                    end
                end
            end
            if  ~isempty(delete_positions)
                app.Fit_Data_Map = delete_array_position(app.Fit_Data_Map,delete_positions);
                app.Fit_Data_error_68_pos = delete_array_position(app.Fit_Data_error_68_pos,delete_positions);
                app.Fit_Data_error_68_neg = delete_array_position(app.Fit_Data_error_68_neg,delete_positions);
                app.Fit_Data_error_95_pos = delete_array_position(app.Fit_Data_error_95_pos,delete_positions);
                app.Fit_Data_error_95_neg = delete_array_position(app.Fit_Data_error_95_neg,delete_positions);
                app.Fit_Data_error_997_pos = delete_array_position(app.Fit_Data_error_997_pos,delete_positions);
                app.Fit_Data_error_997_neg = delete_array_position(app.Fit_Data_error_997_neg,delete_positions);
                app.rmse_Map = delete_array_position(app.rmse_Map,delete_positions);
                app.rsquare_Map = delete_array_position(app.rsquare_Map,delete_positions);
            end
            
            app.Fit_Data_Map(app.Fit_Data_Map==0) = nan;
            
            new_data_size = size(app.Fit_Data_Map);
            
            app.fitDropDown_1.Items = split(num2str(1:new_data_size(1)))';
            app.fitDropDown_2.Items = split(num2str(1:new_data_size(1)))';
            app.fitDropDown_3.Items = split(num2str(1:new_data_size(1)))';
            
            app.FitValuesTextArea.Value{end + 1} = 'all done please go to Analyse fit Data tab';
            drawnow
        end
        
        function [spectrum_data,x_vector] = creat_spectrum_data(app,t,p,y,x)
            %gets the spectrum from the data array
            spectrum_data = get_spectrum_from_data_map(app.PLMap_Data.data_array,t,p,y,x);
            
            %get displayed spectral range
            [spectrum_data,x_vector,~,~] = sweep_data(spectrum_data,app.PLMap_Data.Wavelength, ...
                app.x_min.Value,app.x_max.Value,app.SweepDropDown.Value);
            
            %apply baseline
            switch app.BaselineDropDown.Value
                case 'Linear'
                    if app.applytovaluesCheckBox.Value
                        [spectrum_data,~,~] = linear_fit(app,x_vector',spectrum_data, ...
                            app.start_indexSpinner.Value,app.end_indexSpinner.Value);
                    end
                case 'constant'
                    if app.applytovaluesCheckBox.Value
                        spectrum_data = spectrum_data - app.constantEditField.Value;
                    end
                case 'constant_mean'
                    if app.applytovaluesCheckBox.Value
                        [spectrum_data,~] = substract_constent_mean(app,spectrum_data,app.start_indexSpinner.Value,app.end_indexSpinner.Value);
                    end
            end
            
            %defines the used points
            switch app.pointsDropDown_5.Value
                case {'even','odd'}
                    [spectrum_data,x_vector] = define_used_points(spectrum_data,x_vector, ...
                        app.pointsDropDown_5.Value,app.start_xSpinner.Value);
            end
            
            %             %exclude window
            %             if app.subtractwindowCheckBox.Value == 1
            %                 [~,spectrum_data] = exclude_points(x_vector,spectrum_data,app.absorptin_min,app.absorptin_max);
            %             end
        end
        
        function [Index_of_peak,prominence,peak_x_temp] = find_minima(app,x,Spectrum)
            peak_width = app.minPeakdistanceSpinner.Value*10^(app.power_peak_distance.Value);
            
            if app.maxpeaksSpinner.Value > 0
                [Index_of_peak,prominence] = islocalmin(Spectrum,1,'MinSeparation',peak_width,'MinProminence',app.ProminenceEditField.Value);
                [~,I] = maxk(prominence,app.maxpeaksSpinner.Value,1);
                size_of_index = size(Index_of_peak);
                for i = 1 : size_of_index(2)
                    Index_of_peak(setdiff(1:size_of_index(1),I(:,i)),i) = 0;
                end
            else
                [Index_of_peak,prominence] = islocalmin(Spectrum,1,'MinSeparation',peak_width,'MinProminence',app.ProminenceEditField.Value);
            end
            
            %meaks the peak index to an value of x
            peak_x_temp = x.*Index_of_peak;
        end
        
        function fit_spectra = creat_fit_graphs(app,wavelength,fit_parameter,t,p,y,x)
            n = size(fit_parameter);
            size_wavelength = size(wavelength);
            j = 0;
            spectra = zeros(1,size_wavelength(1));
            
            for i = 1 : n(6)
                if isnan(fit_parameter(t,p,y,x,1,i))
                    j = j+1;
                else
                    switch app.Fit_modelDropDown.Value
                        case 'gauss'
                            switch app.SweepDropDown.Value
                                case {'PL_Map','all'}
                                    %'PL_Map'
                                    %define constants
                                    c_light = 299792458;
                                    h_quant = 6.62607015e-34;
                                    e_charge = 1.602176634e-19;
                                    energy_factor = c_light*h_quant/(1e-9*e_charge);
                                    spectra(i-j,:) = fit_parameter(t,p,y,x,1,i).*exp(-((energy_factor./wavelength-fit_parameter(t,p,y,x,2,i))./fit_parameter(t,p,y,x,3,i)).^2);
                                otherwise
                                    spectra(i-j,:) = fit_parameter(t,p,y,x,1,i).*exp(-((wavelength-fit_parameter(t,p,y,x,2,i))/fit_parameter(t,p,y,x,3,i)).^2);
                            end
                        case 'pseudo-voigt'
                            switch app.SweepDropDown.Value
                                case {'PL_Map','all'}
                                    %'PL_Map'
                                    %define constants
                                    c_light = 299792458;
                                    h_quant = 6.62607015e-34;
                                    e_charge = 1.602176634e-19;
                                    energy_factor = c_light*h_quant/(1e-9*e_charge);
                                    fit_parameter(t,p,y,x,1,i)
                                    fit_parameter(t,p,y,x,2,i)
                                    fit_parameter(t,p,y,x,3,i)
                                    fit_parameter(t,p,y,x,4,i)
                                    spectra(i-j,:) = fit_parameter(t,p,y,x,1,i).*(fit_parameter(t,p,y,x,4,i).*exp(-log(2).*((energy_factor./wavelength-fit_parameter(t,p,y,x,2,i))./fit_parameter(t,p,y,x,3,i)).^2)+(1-fit_parameter(t,p,y,x,4,i))./(1+((energy_factor./wavelength-fit_parameter(t,p,y,x,2,i))./fit_parameter(t,p,y,x,3,i)).^2));
                                otherwise
                                    spectra(i-j,:) = fit_parameter(t,p,y,x,1,i).*(fit_parameter(t,p,y,x,4,i).*exp(-log(2).*((wavelength-fit_parameter(t,p,y,x,2,i))./fit_parameter(t,p,y,x,3,i)).^2)+(1-fit_parameter(t,p,y,x,4,i))./(1+(wavelength-fit_parameter(t,p,y,x,2,i))./fit_parameter(t,p,y,x,3,i)).^2);
                            end
                    end
                end
            end
            y_combined_fit = sum(spectra,1);
            fit_spectra = [y_combined_fit;spectra];
        end
        
        function [spectrum,m,c] = linear_fit(~,x,y,index_min,index_max)
            x_linear_fit = x(index_min:index_max);
            y_linear_fit = y(index_min:index_max);
            fitvars = polyfit(x_linear_fit, y_linear_fit, 1);
            m = fitvars(1);
            c = fitvars(2);
            spectrum = y-(m.*x+c);
        end
        
        function [spectrum,mean_y] = substract_constent_mean(~,y,index_min,index_max)
            y_mean = y(index_min:index_max);
            mean_y = mean(y_mean);
            spectrum = y-mean_y;
        end
        
        % ----------------------------------------------------------------------------------------------------------------
        % Display Spectrum
        % ----------------------------------------------------------------------------------------------------------------
        function [diff2_x,diff2_spectrum] = display_all(app)
            %get the spectral data
            [app.x_vector,spectrum_smoothed,base_line,diff_x,diff_spectrum,diff_spectrum_smoothed,app.fit_spectrum,peak_x,...
                diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum] = fit_spectrum_parameter(app);
            
            %dispaly the fit data
            display_fit_spectrum(app,app.x_vector,spectrum_smoothed,app.fit_spectrum,1,1,base_line,diff_x,diff_spectrum, ...
                diff_spectrum_smoothed,peak_x,diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum);
        end
        
        function display_fit_spectrum(app,x_vector,spectrum_smoothed,fit_spectrum,x_fit_range,y_fit_range,base_line,diff_x,diff_spectrum, ...
                diff_spectrum_smoothed,peak_x,diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum)
        %Display Spectrum
        specify_display_style_spectrum_smoothed(app,app.Spectrum5,x_vector,app.spectrum,spectrum_smoothed,fit_spectrum,x_fit_range,y_fit_range,base_line);
        %corrects for the not maped points
        if all(app.spectrum == 1)
            size_diff_spectrum = size(diff_spectrum);
            diff_spectrum = zeros(size_diff_spectrum);
            size_diff_spectrum_smoothed = size(diff_spectrum_smoothed);
            diff_spectrum_smoothed = zeros(size_diff_spectrum_smoothed);
            size_diff2_spectrum = size(diff2_spectrum);
            diff2_spectrum = zeros(size_diff2_spectrum);
            peak_x = [];
            diff2_spectrum_peak_intensity = [];
        end
        
        if app.residual_data
            residuals = app.spectrum-spectrum_smoothed;
            max_resi = max(abs(residuals));
            plot(app.Spectrum6,x_vector,residuals,[min(x_vector) max(x_vector)],[mean(residuals) mean(residuals)],'--g',[min(x_vector) max(x_vector)],[0 0])
        else
            %Display first Diff
            specify_display_style_spectrum_diff(app,app.Spectrum6,diff_x,diff_spectrum,diff_spectrum_smoothed,peak_x,diff2_spectrum_peak_intensity);
        end
        
        if app.residual_1_diff
            residuals = diff_spectrum-diff_spectrum_smoothed;
            max_resi = max(abs(residuals));
            plot(app.Spectrum7,diff_x,residuals,[min(diff_x) max(diff_x)],[mean(residuals) mean(residuals)],'--g',[min(diff_x) max(diff_x)],[0 0])
        else
            %Display second Diff
            temp = [];
            specify_display_style_spectrum_diff(app,app.Spectrum7,diff2_x,temp,diff2_spectrum,peak_x,diff2_spectrum_peak_intensity);
        end
        end
        
        function specify_display_style_spectrum_smoothed(app,Defind_Spectrum_Axis,x,spectrum,smoothed_spectrum,fit_spectrum,x_fit_range,y_fit_range,base_line)
            %displays the spectrum
            cla(Defind_Spectrum_Axis)
            hold(Defind_Spectrum_Axis,'on')
            plot(Defind_Spectrum_Axis,x,spectrum,x,smoothed_spectrum,'r');
            
            %plots all fit spectra
            if isempty(fit_spectrum) == 0
                %display the fit range
                plot(Defind_Spectrum_Axis,x_fit_range,y_fit_range,'g*');
                %display the fits
                n = size(fit_spectrum);
                for i = 1 : n(1)
                    if i == 1
                        plot(Defind_Spectrum_Axis,x,fit_spectrum(i,:),'m');
                    else
                        plot(Defind_Spectrum_Axis,x,fit_spectrum(i,:),'g');
                    end
                end
            end
            
            %plots the trend line
            if isempty(base_line) == 0
                switch app.BaselineDropDown.Value
                    case 'Linear'
                        %display the trend line range
                        plot(Defind_Spectrum_Axis,x(app.start_indexSpinner.Value:app.end_indexSpinner.Value),spectrum(app.start_indexSpinner.Value:app.end_indexSpinner.Value),'c*');
                        
                        %display the trend line
                        line(Defind_Spectrum_Axis,[min(x),max(x)],[base_line(1,1).*min(x)+base_line(2,1),base_line(1,1).*max(x)+base_line(2,1)],'color','k',...
                            'LineWidth', 1,'LineStyle','--');
                    case 'constant_mean'
                        %display the trend line range
                        plot(Defind_Spectrum_Axis,x(app.start_indexSpinner.Value:app.end_indexSpinner.Value),spectrum(app.start_indexSpinner.Value:app.end_indexSpinner.Value),'c*');
                        
                        %display the trend line
                        line(Defind_Spectrum_Axis,[min(x),max(x)],[base_line(1,1),base_line(1,1)],'color','k','LineWidth', 1,'LineStyle','--');
                    case 'constant'
                        %display the trend line
                        line(Defind_Spectrum_Axis,[min(x),max(x)],[base_line(1,1),base_line(1,1)],'color','k','LineWidth', 1,'LineStyle','--');
                end
            end
            
            hold(Defind_Spectrum_Axis,'off')
            
            %sets the range of axis to display the full range
            Defind_Spectrum_Axis.XLim = [-inf inf];
            Defind_Spectrum_Axis.YLim = [-inf inf];
        end
        
        function specify_display_style_spectrum_diff(~,Defind_Spectrum_Axis,x,spectrum,smoothed_spectrum,peak_x,peak_y)
            %checks if thare are peaks to be displayed (spectrum = 1 if not)
            if isempty(spectrum) == 0
                plot(Defind_Spectrum_Axis,x,spectrum,x,smoothed_spectrum,'r');
            else
                %displays the spectrum with peaks
                if isempty(peak_x) == 0
                    plot(Defind_Spectrum_Axis,x,smoothed_spectrum,'r',peak_x,peak_y,'g*');
                else
                    plot(Defind_Spectrum_Axis,x,smoothed_spectrum,'r');
                end
            end
            
            %draws zero line
            line(Defind_Spectrum_Axis,[min(x),max(x)],[0,0],'color','k', 'LineWidth', 1,'LineStyle','--');
            
            %sets the range of axis to display the full range
            Defind_Spectrum_Axis.XLim = [-inf inf];
            Defind_Spectrum_Axis.YLim = [-inf inf];
        end
        
        function specify_display_style_spectrum_residuals(app,Defind_Spectrum_Axis,x,y,Fit_Data_Dispaly,x_limits)
            data_size = size(Fit_Data_Dispaly);
            y = double(y);
            switch app.SweepDropDown.Value
                case {'PL_Map','all'}
                    x_energy = converter.energy_wavelength(x);
            end
            
            cla(Defind_Spectrum_Axis)
            hold(Defind_Spectrum_Axis,"on")
            
            for i = 1 : data_size(1)
                switch app.SweepDropDown.Value
                    case {'PL_Map','all'}
                        y_fit = Fit_Data_Dispaly{i,1}(x_energy);
                    otherwise
                        y_fit = Fit_Data_Dispaly{i,1}(x);
                end
                
                residuals = y'-y_fit;
                plot(Defind_Spectrum_Axis,x,residuals,':*')
            end
            
            %draws zero line
            line(Defind_Spectrum_Axis,[x_limits(1),x_limits(2)],[0,0],'color','k', 'LineWidth', 1,'LineStyle','--');
            
            hold(Defind_Spectrum_Axis,"off")
            %sets the range of axis to display the full range
            Defind_Spectrum_Axis.XLim = [x_limits(1) x_limits(2)];
            Defind_Spectrum_Axis.YLim = [-inf inf];
        end
        
        % ----------------------------------------------------------------------------------------------------------------
        % Display Map
        % ----------------------------------------------------------------------------------------------------------------
        
        function start_fit_map(app,fit_typ)
            switch fit_typ
                case 'PL_Map'
                    %start index
                    x = define_Spinner(app.changingvalue1_2Spinner,app.PLMap_Data.X_vector,app.starting_x);
                    y = define_Spinner(app.changingvalue2_2Spinner,app.PLMap_Data.Y_vector,app.starting_y);
                    p = define_Spinner(app.changingvalue3_2Spinner,app.PLMap_Data.Power,app.starting_power);
                    p = define_Spinner(app.changingvalue3_3Spinner,app.PLMap_Data.Power,app.starting_power);
                    p = define_Spinner(app.changingvalue3_4Spinner,app.PLMap_Data.Power,app.starting_power);
                    t = define_Spinner(app.changingvalue4_2Spinner,app.PLMap_Data.Temperature_index,app.starting_tamperature);
                    t = define_Spinner(app.changingvalue4_3Spinner,app.PLMap_Data.Temperature_index,app.starting_tamperature);
                    t = define_Spinner(app.changingvalue4_4Spinner,app.PLMap_Data.Temperature_index,app.starting_tamperature);
                    
                    x = define_Spinner(app.changingvalue1_1Spinner,app.PLMap_Data.X_vector,app.starting_x);
                    y = define_Spinner(app.changingvalue2_1Spinner,app.PLMap_Data.Y_vector,app.starting_y);
                    p = define_Spinner(app.changingvalue3_1Spinner,app.PLMap_Data.Power,app.starting_power);
                    t = define_Spinner(app.changingvalue4_1Spinner,app.PLMap_Data.Temperature_index,app.starting_tamperature);
                    
                    w_min = define_Spinner(app.x_min,app.PLMap_Data.Wavelength,'low');
                    w_max = define_Spinner(app.x_max,app.PLMap_Data.Wavelength,'upper');
                    
                    %get the size of the data array to check if a map is
                    %posible
                    size_data_array = size(app.PLMap_Data.data_array);
                    
                    %only display a map if it is posible
                    if size_data_array(3) ~= 1 && size_data_array(4) ~= 1
                        
                        %get info for map
                        label{1} = 'Map';
                        label{2} = 'X (mm)';
                        label{3} = 'Y (mm)';
                        label{4} = 'Intensity (Counts/s)';
                        
                        %displays Map
                        [app.x_min.Value,app.x_max.Value,app.changingvalue3_1Spinner.Value,app.changingvalue4_1Spinner.Value,app.data_map,app.c_map] = update_map(app.PLMap_Data,t,p,0,2000,app.Map5,...
                            app.starting_color_steps,app.map_typ_data,-inf,inf,label,'integrated',...
                            app.ContourCheckBox_5.Value,app.contour_lines,app.contourtext,app.area_dim_5,...
                            app.locktoareaCheckBox_5.Value);
                        app.last_map = app.Map5;
                    else
                        app.SweepDropDown.Value = 'all';
                        app.WaterfallDropDownLabel.Enable = 'on';
                        app.WaterfallDropDown.Enable = 'on';
                        app.OffsetSpinner_2Label.Enable = 'on';
                        app.OffsetSpinner_2.Enable = 'on';
                        app.MapappearanceDropDown_5.Enable = 'off';
                        app.MapMaxCountsEditField_5.Enable = 'off';
                        app.MapMinCountsEditField_5.Enable = 'off';
                        app.MapColorstepsEditField_5.Enable = 'off';
                        app.ContourCheckBox_5.Enable = 'off';
                        
                        %specify the changing parameter
                        switch app.WaterfallDropDown.Value
                            case 'changing value 1'
                                parameter = 'x';
                            case 'changing value 2'
                                parameter = 'y';
                            case 'changing value 3'
                                parameter = 'p';
                            case 'changing value 4'
                                parameter = 't';
                        end
                        
                        %create waterfall data
                        [spectra_x,spectra_y] = create_waterfall_data(app.PLMap_Data,parameter,t,p,x,y,app.x_min.Value,app.x_max.Value,app.SweepDropDown.Value);
                        
                        %declare the data array vor coppying
                        app.data_map = spectra_y;
                        
                        %display the Waterfall Data
                        [~,~] = Display_waterfall(app.Map5,spectra_x,spectra_y,app.OffsetSpinner_2.Value,'jet');
                    end
                    
                    [~,~] = display_all(app);
                case 'Fit_Data'
                    %start index
                    x = define_Spinner(app.changingvalue1_1Spinner,app.PLMap_Data.X_vector,app.starting_x);
                    y = define_Spinner(app.changingvalue2_1Spinner,app.PLMap_Data.Y_vector,app.starting_y);
                    p = define_Spinner(app.changingvalue3_1Spinner,app.PLMap_Data.Power,app.starting_power);
                    t = define_Spinner(app.changingvalue4_1Spinner,app.PLMap_Data.Temperature_index,app.starting_tamperature);
                    
                    w_min = define_Spinner(app.x_min,app.PLMap_Data.Wavelength,'low');
                    w_max = define_Spinner(app.x_max,app.PLMap_Data.Wavelength,'upper');
                    
                    x = define_Spinner(app.changingvalue1_2Spinner,app.PLMap_Data.X_vector,app.starting_x);
                    y = define_Spinner(app.changingvalue2_2Spinner,app.PLMap_Data.Y_vector,app.starting_y);
                    p = define_Spinner(app.changingvalue3_2Spinner,app.PLMap_Data.Power,app.starting_power);
                    t = define_Spinner(app.changingvalue4_2Spinner,app.PLMap_Data.Temperature_index,app.starting_tamperature);
                    
                    %get info for map
                    label{1} = 'Map';
                    label{2} = 'X (mm)';
                    label{3} = 'Y (mm)';
                    label{4} = 'Intensity (Counts/s)';
                    
                    %displays Map
                    [app.x_min.Value,app.x_max.Value,app.changingvalue3_1Spinner.Value,app.changingvalue4_1Spinner.Value,app.data_map,app.c_map] = update_map(app.PLMap_Data,t,p,0,2000,app.Map5, ...
                        app.starting_color_steps,app.map_typ_fit_data,-inf,inf,label,'integrated', ...
                        app.ContourCheckBox_5.Value,app.contour_lines,app.contourtext,app.area_dim_5, ...
                        app.locktoareaCheckBox_5.Value);
                    
                    app.last_map = app.Map5;
                    [~,~] = display_all(app);
                    
                    %get info for map
                    label{1} = 'Map';
                    label{2} = 'X (mm)';
                    label{3} = 'Y (mm)';
                    
                    %gets the correct c label
                    if Defind_Map_Axis == app.Map6
                        if app.displayed_fit_parameter == 2
                            label{4} = 'Wavelength (nm)';
                        elseif app.displayed_fit_parameter == 3
                            label{4} = 'FWHM (eV)';
                        elseif app.displayed_fit_parameter == 4
                            label{4} = 'Eta';
                        else
                            label{4} = 'Intensity (Counts/s)';
                        end
                    else
                        label{4} = 'Intensity (Counts/s)';
                    end
                    
                    %display the fited data
                    pre_processed_data_map(:,:) = app.Fit_Data_Map(t,p,:,:,app.displayed_fit_parameter,app.displayed_peak);
                    app.data_map = pre_processed_data_map;
                    
                    %displays the map
                    [app.last_fit_map,app.c_fit_map] = Display_Map(app.Map6,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,...
                        pre_processed_data_map,app.MapColorstepsEditField_6.Value,app.map_typ_fit_data,label,app.MapMinCountsEditField_6.Value,...
                        app.MapMaxCountsEditField_6.Value,app.ContourCheckBox_6.Value,app.contour_lines,app.contourtext,0);
                    
                    %change the peak drop down to the number of peaks
                    size_of_fit_map = size(app.Fit_Data_Map);
                    app.DisplayedPeakDropDown_1.Items = split(num2str(1:size_of_fit_map(6)))';
            end
        end
        
        function show_map(app)
            %define indeces of power and tamperature
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            app.changingvalue3_1Spinner.Value = app.PLMap_Data.Power(p);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
            app.changingvalue4_1Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            label{4} = 'Intensity (Counts/s)';
            
            switch app.wavelength_energy.Value
                case 'wavelegth'
                    x_minimum = app.x_min.Value;
                    x_maximum = app.x_max.Value;
                case 'energy'
                    x_minimum = converter.energy_wavelength(app.x_min.Value);
                    x_maximum = converter.energy_wavelength(app.x_max.Value);
            end
            
            [~,~,~,~,app.data_map,app.c_map] = update_map(app.PLMap_Data,t,p,x_minimum,x_maximum,app.Map5,...
                app.MapColorstepsEditField_5.Value,app.map_typ_data,app.MapMinCountsEditField_5.Value,...
                app.MapMaxCountsEditField_5.Value,label,app.MapappearanceDropDown_5.Value,...
                app.ContourCheckBox_5.Value,app.contour_lines,app.contourtext,app.area_dim_5,...
                app.locktoareaCheckBox_5.Value);
            
            app.last_map = app.Map5;
        end

        % ----------------------------------------------------------------------------------------------------------------
        % Analyse Fit Data
        % ----------------------------------------------------------------------------------------------------------------
        function sort_peaks(app,x_vector,y_vector,p_vector,t_vector,x_start,y_start,threshold_distance)
            %defines the starting Point
            starting_point = [x_start,y_start];
            number_of_different_peaks = 14;
            
            %prelocate a bigger array
            Fit_Data_Map_temp_temp = extend_fit_array(app,app.Fit_Data_Map,number_of_different_peaks, ...
                number_of_different_peaks);
            j = 0;
            
            %displays a statusBar indicationg the progras of importing the data into an matlab structure
            figure_ui = uifigure;
            d = uiprogressdlg(figure_ui,'Title','Sort Peaks','Message','1');
            
            %creat power and tamprature index
            for t = 1 : length(t_vector)
                for p = 1 : length(p_vector)
                    
                    %define point clound
                    i=0;
                    for y = 1:length(y_vector)
                        for x = 1:length(x_vector)
                            if all(app.PLMap_Data.data_array(t,p,y,x,:)==1) == 0
                                i=i+1;
                                point_cloud(i,1) = x_vector(x);
                                point_cloud(i,2) = y_vector(y);
                            end
                        end
                    end
                    
                    %gives the indexes for nearest points - starting with the closesest
                    [Idx,~] = knnsearch(point_cloud,starting_point,'K',length(point_cloud));
                    
                    %loops over all points - starting from the nearest
                    for i =2 : length(point_cloud)
                        try
                            j = j+1;
                            %display the persentage of Importing the Data
                            Persentage =  (j-1)/(length(t_vector)*length(p_vector)*length(point_cloud));
                            d.Value = Persentage;
                            d.Message = sprintf('Percentage: %4.1f',Persentage*100);
                            
                            point_of_itteration = point_cloud(Idx(i),:);
                            clear reference_cloud reference_line dx dy y_linear x y peak_centers
                            clear size_of_reverence_line jump_to_next_peak
                            clear indeces_peak_position distances_to_peak peak_heights peak_fwhms
                            %collect all points so far
                            reference_cloud = nan(i-1,2);
                            for cloud_index = 1 : i-1
                                reference_cloud(cloud_index,:) = point_cloud(Idx(cloud_index),:);
                            end
                            %creat linear function to the center
                            dx = point_cloud(Idx(i),1) - starting_point(1);
                            dy = point_cloud(Idx(i),2) - starting_point(2);
                            if dy == 0
                                reference_line = reference_cloud((starting_point(2) == reference_cloud(:,2)),:);
                            elseif dx == 0
                                reference_line = reference_cloud((starting_point(1) == reference_cloud(:,1)),:);
                            else
                                % gets the lenear_parameter
                                m = dy / dx;
                                b = starting_point(2) - m * starting_point(1);
                                %check which points are on the linear function
                                y_linear = round(m .* reference_cloud(:,1) + b,3);
                                
                                size_of_reference_cloud = size(reference_cloud);
                                reference_line_index = 0;
                                for reference_cloud_index = 1 : size_of_reference_cloud(1)
                                    if reference_cloud(reference_cloud_index,2) == y_linear(reference_cloud_index)
                                        reference_line_index = reference_line_index + 1;
                                        reference_line(reference_line_index,:) = reference_cloud(reference_cloud_index,:);
                                    end
                                end
                            end
                            reference_line = flip(reference_line,1);
                            %only choose the points in the right quadrand
                            if point_cloud(Idx(i),1)>starting_point(1)
                                if point_cloud(Idx(i),2)>starting_point(2)
                                    reference_line = reference_line(reference_line(:,1)>=starting_point(1),:);
                                    reference_line = reference_line(reference_line(:,2)>=starting_point(2),:);
                                else
                                    reference_line = reference_line(reference_line(:,1)>=starting_point(1),:);
                                    reference_line = reference_line(reference_line(:,2)<=starting_point(2),:);
                                end
                            else
                                if point_cloud(Idx(i),2)>starting_point(2)
                                    reference_line = reference_line(reference_line(:,1)<=starting_point(1),:);
                                    reference_line = reference_line(reference_line(:,2)>=starting_point(2),:);
                                else
                                    reference_line = reference_line(reference_line(:,1)<=starting_point(1),:);
                                    reference_line = reference_line(reference_line(:,2)<=starting_point(2),:);
                                end
                            end
                            %add sourounding Peaks
                            if i>21+app.NearestneighboursSpinner.Value
                                [index_surrounding,~] = knnsearch(reference_cloud,point_of_itteration,'K', ...
                                    app.NearestneighboursSpinner.Value);
                                reference_line = unique([reference_line(1,:);reference_cloud(index_surrounding(2:end),:); ...
                                    reference_line(2:end,:)],'rows','stable');
                            end
                            [index_closest,~] = knnsearch(reference_line,point_of_itteration,'K',length(reference_line));
                            reference_line = reference_line(index_closest,:);
                            %define the peak centers of the point of itteration
                            x = find_index(x_vector,point_cloud(Idx(i),1),1);
                            y = find_index(y_vector,point_cloud(Idx(i),2),1);
                            peak_centers(:) = Fit_Data_Map_temp_temp(t,p,y,x,2,:);
                            peak_heights(:) = Fit_Data_Map_temp_temp(t,p,y,x,1,:);
                            peak_fwhms(:) = Fit_Data_Map_temp_temp(t,p,y,x,3,:);
                            %sets all Values at the data set point to NaN to prepare for
                            %reeranging
                            Fit_Data_Map_temp_temp(t,p,y,x,1,:) = NaN(1,1,1,1,1,number_of_different_peaks);
                            Fit_Data_Map_temp_temp(t,p,y,x,2,:) = NaN(1,1,1,1,1,number_of_different_peaks);
                            Fit_Data_Map_temp_temp(t,p,y,x,3,:) = NaN(1,1,1,1,1,number_of_different_peaks);
                            %iterate over all point on the line
                            size_of_reverence_line = size(reference_line);
                            %checks if there was anny peaks detected if not -> jump to next step
                            if isempty(peak_centers) == 0
                                %goes through all Peaks
                                for ii = 1:numel(peak_centers)
                                    jump_to_next_peak = 0;
                                    %if there is a emty peak position jump to next peak
                                    if isnan(peak_centers(ii)) == 0
                                        for line_pont_index = 1 : size_of_reverence_line(1)
                                            error_index = 1;
                                            while 1
                                                if jump_to_next_peak
                                                    break
                                                end
                                                error_index = error_index +1;
                                                %define the peak center values of the reverance value
                                                clear peak_centers_reverence x_reference y_reference
                                                x_reference = find_index(x_vector,reference_line(line_pont_index,1),1);
                                                y_reference = find_index(y_vector,reference_line(line_pont_index,2),1);
                                                peak_centers_reverence(:) = Fit_Data_Map_temp_temp(t,p,y_reference, ...
                                                    x_reference,2,:);
                                                %defines the distance of the peak of iteration to the
                                                %nearest neighbor peak cloud
                                                [indeces_peak_position,distances_to_peak] = ...
                                                    knnsearch(peak_centers_reverence',peak_centers(ii),'K',2);
                                                %if the peak has allready been positioned continue
                                                if distances_to_peak(1) < threshold_distance
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)) = ...
                                                        peak_fwhms(ii);
                                                    jump_to_next_peak = 1;
                                                elseif line_pont_index < size_of_reverence_line(1)
                                                    break
                                                else
                                                    test_index = 1;
                                                    not_nan_index_find = 0;
                                                    while test_index
                                                        not_nan_index_find = not_nan_index_find +1;
                                                        %define the peak center values of the reverance value
                                                        x_reference = find_index(x_vector, ...
                                                            reference_line(not_nan_index_find,1),1);
                                                        y_reference = find_index(y_vector, ...
                                                            reference_line(not_nan_index_find,2),1);
                                                        peak_centers_reverence(:) = Fit_Data_Map_temp_temp(t,p, ...
                                                            y_reference,x_reference,2,:);
                                                        if any(~isnan(peak_centers_reverence))
                                                            test_index = 0;
                                                        end
                                                    end
                                                    %defines the distance of the peak of iteration to the
                                                    %nearest neighbor peak cloud
                                                    [indeces_peak_position,~] = knnsearch(peak_centers_reverence', ...
                                                        peak_centers(ii),'K',2);
                                                    
                                                    if (peak_centers_reverence(indeces_peak_position(1)) > peak_centers(ii)) && (indeces_peak_position(1)==1)
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks,1);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,1) = peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,1) = peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,1) = peak_fwhms(ii);
                                                        jump_to_next_peak = 1;
                                                    elseif  (peak_centers_reverence(indeces_peak_position(1)) > ...
                                                            peak_centers(ii)) && (peak_centers_reverence( ...
                                                            indeces_peak_position(2)) > peak_centers(ii))
                                                    if isnan(Fit_Data_Map_temp_temp(t,p,y_reference,x_reference, ...
                                                            2,indeces_peak_position(1)-1))
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)-1) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)-1) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)-1) = ...
                                                        peak_fwhms(ii);
                                                    else
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks, ...
                                                            indeces_peak_position(1)-1);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)) = ...
                                                            peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)) = ...
                                                            peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)) = ...
                                                            peak_fwhms(ii);
                                                    end
                                                    jump_to_next_peak = 1;
                                                    elseif  (peak_centers_reverence(indeces_peak_position(1)) > ...
                                                            peak_centers(ii)) && (isnan(peak_centers_reverence( ...
                                                            indeces_peak_position(2))))
                                                    if isnan(Fit_Data_Map_temp_temp(t,p,y_reference,x_reference, ...
                                                            2,indeces_peak_position(1)-1))
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)-1) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)-1) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)-1) = ...
                                                        peak_fwhms(ii);
                                                    else
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks, ...
                                                            indeces_peak_position(1)-1);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)) = ...
                                                            peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)) = ...
                                                            peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)) = ...
                                                            peak_fwhms(ii);
                                                    end
                                                    jump_to_next_peak = 1;
                                                    elseif (peak_centers_reverence(indeces_peak_position(1)) < ...
                                                            peak_centers(ii)) && (peak_centers(ii) < ...
                                                            peak_centers_reverence(indeces_peak_position(2)))
                                                    if isnan(Fit_Data_Map_temp_temp(t,p,y_reference,x_reference,2, ...
                                                            indeces_peak_position(2)-1))
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(2)-1) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(2)-1) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(2)-1) = ...
                                                        peak_fwhms(ii);
                                                    else
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks, ...
                                                            indeces_peak_position(2));
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)+1) = ...
                                                            peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)+1) = ...
                                                            peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)+1) = ...
                                                            peak_fwhms(ii);
                                                    end
                                                    jump_to_next_peak = 1;
                                                    elseif (peak_centers_reverence(indeces_peak_position(1)) > ...
                                                            peak_centers(ii)) && (peak_centers(ii) > ...
                                                            peak_centers_reverence(indeces_peak_position(2)))
                                                    if isnan(Fit_Data_Map_temp_temp(t,p,y_reference,x_reference,2 ...
                                                            ,indeces_peak_position(1)-1))
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)-1) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)-1) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)-1) = ...
                                                        peak_fwhms(ii);
                                                    else
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks, ...
                                                            indeces_peak_position(1));
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)) = ...
                                                            peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)) = ...
                                                            peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)) = ...
                                                            peak_fwhms(ii);
                                                    end
                                                    jump_to_next_peak = 1;
                                                    elseif (peak_centers_reverence(indeces_peak_position(1)) < ...
                                                            peak_centers(ii)) && (peak_centers_reverence( ...
                                                            indeces_peak_position(2)) < peak_centers(ii))
                                                    if number_of_different_peaks < indeces_peak_position(1)+1
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks, ...
                                                            indeces_peak_position(1)+1);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)+1) = ...
                                                            peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)+1) = ...
                                                            peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)+1) = ...
                                                            peak_fwhms(ii);
                                                    elseif isnan(Fit_Data_Map_temp_temp(t,p,y_reference,x_reference, ...
                                                            2,indeces_peak_position(1)+1))
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)+1) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)+1) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)+1) = ...
                                                        peak_fwhms(ii);
                                                    end
                                                    jump_to_next_peak = 1;
                                                    elseif (peak_centers_reverence(indeces_peak_position(1)) < ...
                                                            peak_centers(ii)) && (isnan(peak_centers_reverence( ...
                                                            indeces_peak_position(2))))
                                                    if number_of_different_peaks < indeces_peak_position(1)+1
                                                        number_of_different_peaks = number_of_different_peaks+1;
                                                        Fit_Data_Map_temp_temp = extend_fit_array(app, ...
                                                            Fit_Data_Map_temp_temp,number_of_different_peaks, ...
                                                            indeces_peak_position(1)+1);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)+1) = ...
                                                            peak_heights(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)+1) = ...
                                                            peak_centers(ii);
                                                        Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)+1) = ...
                                                            peak_fwhms(ii);
                                                    elseif isnan(Fit_Data_Map_temp_temp(t,p,y_reference,x_reference, ...
                                                            2,indeces_peak_position(1)+1))
                                                    Fit_Data_Map_temp_temp(t,p,y,x,1,indeces_peak_position(1)+1) = ...
                                                        peak_heights(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,2,indeces_peak_position(1)+1) = ...
                                                        peak_centers(ii);
                                                    Fit_Data_Map_temp_temp(t,p,y,x,3,indeces_peak_position(1)+1) = ...
                                                        peak_fwhms(ii);
                                                    end
                                                    jump_to_next_peak = 1;
                                                    end
                                                end
                                                if error_index > 1000
                                                    test = 1;
                                                    %error('Error at itteration %f.',i)
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        catch ME
                            disp(['ID: ' ME.identifier])
                            disp(i)
                            rethrow(ME)
                        end
                    end
                end
            end
            close(d);
            close(figure_ui);
            app.Fit_Data_Map_temp = Fit_Data_Map_temp_temp;
            app.DisplayedPeakDropDown_1.Items = split(num2str(1:number_of_different_peaks))';
        end
        
        function Fit_Data_Map_temp = extend_fit_array(~,Fit_Data_Map,number_of_different_peaks,extend_position)
            %predefine empty array for the peaks
            dimensions = size(Fit_Data_Map);
            Fit_Data_Map_temp = NaN(dimensions(1),dimensions(2),dimensions(3),dimensions(4),dimensions(5),number_of_different_peaks);
            for t_i = 1 : dimensions(1)
                for p_i = 1 : dimensions(2)
                    for y_i = 1 : dimensions(3)
                        for x_i = 1 : dimensions(4)
                            for type_i = 1 : dimensions(5)
                                for n_i = 1 : dimensions(6)
                                    if n_i < extend_position
                                        Fit_Data_Map_temp(t_i,p_i,y_i,x_i,type_i,n_i) = Fit_Data_Map(t_i,p_i,y_i,x_i,type_i,n_i);
                                    elseif n_i >= extend_position
                                        Fit_Data_Map_temp(t_i,p_i,y_i,x_i,type_i,n_i+1) = Fit_Data_Map(t_i,p_i,y_i,x_i,type_i,n_i);
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end
        
        function update_fit_map(app)
            [pre_processed_data_map,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,app.displayed_fit_parameter,app.n_fitparameter);
            
            if app.displayed_fit_parameter == 2
                if app.display_peak_as_wavelengthCheckBox.Value
                    pre_processed_data_map = converter.energy_wavelength(pre_processed_data_map);
                end
            elseif app.displayed_fit_parameter == 3
                if app.n_fitparameter == 4
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'pseudo-voigt');
                else
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'gauss');
                end
                pre_processed_data_map = pre_processed_data_map * 1000;
            end
            
            %converts k_min smalest and k_max largest values to NaN
            if app.k_minSpinner.Value >0 || app.k_maxSpinner.Value >0
                pre_processed_data_map = clean_up_map(pre_processed_data_map,app.k_minSpinner.Value,app.k_maxSpinner.Value);
            end
            
            %exclude
            if app.excludeCheckBox.Value
                pre_processed_data_map(app.contour_map<app.lower_boundEditField.Value) = nan;
                pre_processed_data_map(app.contour_map>app.upper_boundEditField.Value) = nan;
                pre_processed_data_map(isnan(app.contour_map)) = nan;
            end
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            
            %gets the correct c label
            if app.displayed_fit_parameter == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter == 3
                label{4} = 'FWHM (meV)';
            elseif app.displayed_fit_parameter == 4
                label{4} = 'Eta';
            elseif app.displayed_fit_parameter == 5
                label{4} = 'rmse';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %Display map
            [pre_processed_data_map,app.last_fit_map,app.c_fit_map] = Display_Fit_Map(app.PLMap_Data,app.area_dim_6,pre_processed_data_map,0,...
                app.Map6,app.MapColorstepsEditField_6.Value,app.map_typ_fit_data,label,app.MapMinCountsEditField_6.Value,...
                app.MapMaxCountsEditField_6.Value,app.ContourCheckBox_6.Value,app.contour_lines,app.contourtext,...
                app.locktoareaCheckBox_6.Value);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
            
            %display histogram
            update_histogram(app.Spectrum8_2,pre_processed_data_map,app.bin,app.show_histogram_statistics);
            xlabel(app.Spectrum8_2, label{4});
            
            %saves the data to the copy data
            app.histogramm_data = pre_processed_data_map(:);
        end
        
        function [height,FWHM,peakpos,Eta,rmse] = build_correlation(app)
            %get the area size
            [x_squer,y_squer,window_x,window_y] = define_area_size(app.PLMap_Data,app.area_dim_6);
            
            %get the data from array
            [height_temp,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,1,app.n_fitparameter);
            
            [peakpos_temp,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,2,app.n_fitparameter);
            
            [FWHM_temp,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,3,app.n_fitparameter);
            
            if app.n_fitparameter == 4
                FWHM_temp = converter.FWHM_sigma(FWHM_temp,'pseudo-voigt');
                [Eta_temp,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,4,app.n_fitparameter);
               
                if app.excludeCheckBox.Value
                    Eta_temp(app.contour_map<app.lower_boundEditField.Value) = nan;
                    Eta_temp(app.contour_map>app.upper_boundEditField.Value) = nan;
                    Eta_temp(isnan(app.contour_map)) = nan;
                end
                Eta = Eta_temp(y_squer:window_y,x_squer:window_x);
                Eta = Eta_temp(:);
            else
                FWHM_temp = converter.FWHM_sigma(FWHM_temp,'gauss');
                Eta = [];
                
            end
            [rmse_temp,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,5,app.n_fitparameter);
            
            %exclude
            if app.excludeCheckBox.Value
                height_temp(app.contour_map<app.lower_boundEditField.Value) = nan;
                height_temp(app.contour_map>app.upper_boundEditField.Value) = nan;
                height_temp(isnan(app.contour_map)) = nan;
                peakpos_temp(app.contour_map<app.lower_boundEditField.Value) = nan;
                peakpos_temp(app.contour_map>app.upper_boundEditField.Value) = nan;
                peakpos_temp(isnan(app.contour_map)) = nan;
                FWHM_temp(app.contour_map<app.lower_boundEditField.Value) = nan;
                FWHM_temp(app.contour_map>app.upper_boundEditField.Value) = nan;
                FWHM_temp(isnan(app.contour_map)) = nan;
                rmse_temp(app.contour_map<app.lower_boundEditField.Value) = nan;
                rmse_temp(app.contour_map>app.upper_boundEditField.Value) = nan;
                rmse_temp(isnan(app.contour_map)) = nan;
            end
            
            height_temp = height_temp(y_squer:window_y,x_squer:window_x);
            height = height_temp(:);
            peakpos_temp = peakpos_temp(y_squer:window_y,x_squer:window_x);
            peakpos = peakpos_temp(:);
            FWHM_temp = FWHM_temp(y_squer:window_y,x_squer:window_x);
            FWHM = FWHM_temp(:);
            rmse_temp = rmse_temp(y_squer:window_y,x_squer:window_x);
            rmse = rmse_temp(:);
        end
        
        function update_fit_gradient(app)
            %find the current changing value indexes
            x = find_index(app.PLMap_Data.X_vector,app.changingvalue1_2Spinner.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.changingvalue2_2Spinner.Value,1);
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_2Spinner.Value,1);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_2Spinner.Value,1);
            
            %define the gradient value
            switch app.DisplaydataalongvalueDropDown.Value
                case 'changing value 1'
                    %find indexes
                    x_vector(:) = app.PLMap_Data.X_vector;
                    if app.displayed_fit_parameter == 5
                        size_data = size(app.Fit_rmse);
                        y_vector = nan(1,size_data(5));
                        y_vector(1,:) = app.Fit_rmse(app.displayed_fit,t,p,y,:);
                    else
                        size_data = size(app.Fit_Data_Map);
                        y_vector = nan(1,size_data(5));
                        if app.displayerrorbarsCheckBox.Value
                            app.y_err_pos = nan(1,size_data(5));
                            app.y_err_neg = nan(1,size_data(5));
                        else
                            app.y_err_neg = [];
                            app.y_err_pos = [];
                        end
                        for x = 1 : size_data(5)
                            if ~all(isnan(app.Fit_Data_Map(app.displayed_fit,t,p,y,x,:)))
                                coeffvals(:) = app.Fit_Data_Map(app.displayed_fit,t,p,y,x,:);
                                y_vector(x) = coeffvals(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                if app.displayerrorbarsCheckBox.Value
                                    switch app.error_intervalDropDown.Value
                                        case '0.997'
                                            error_coeff(:) = app.Fit_Data_error_997_pos(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_pos(x) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                            error_coeff(:) = app.Fit_Data_error_997_neg(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_neg(x) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                        case '0.95'
                                            error_coeff(:) = app.Fit_Data_error_95_pos(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_pos(x) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                            error_coeff(:) = app.Fit_Data_error_95_neg(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_neg(x) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                        case '0.68'
                                            error_coeff(:) = app.Fit_Data_error_68_pos(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_pos(x) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                            error_coeff(:) = app.Fit_Data_error_68_neg(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_neg(x) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                    end
                                end
                            end
                        end
                    end
                    xlabel(app.Spectrum8, 'x (mm)');
                case 'changing value 2'
                    x_vector(:) = app.PLMap_Data.Y_vector;
                    if app.displayed_fit_parameter == 5
                        size_data = size(app.Fit_rmse);
                        y_vector = nan(1,size_data(4));
                        y_vector(1,:) = app.Fit_rmse(app.displayed_fit,t,p,:,x);
                    else
                        size_data = size(app.Fit_Data_Map);
                        y_vector = nan(1,size_data(4));
                        if app.displayerrorbarsCheckBox.Value
                            app.y_err_pos = nan(1,size_data(4));
                            app.y_err_neg = nan(1,size_data(4));
                        else
                            app.y_err_neg = [];
                            app.y_err_pos = [];
                        end
                        for y = 1 : size_data(4)
                            if ~isnan(app.Fit_Data_Map(app.displayed_fit,t,p,y,x,:))
                                coeffvals(:) = app.Fit_Data_Map(app.displayed_fit,t,p,y,x,:);
                                y_vector(y) = coeffvals(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                if app.displayerrorbarsCheckBox.Value
                                    switch app.error_intervalDropDown.Value
                                        case '0.997'
                                            error_coeff(:) = app.Fit_Data_error_997_pos(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_pos(y) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                            error_coeff(:) = app.Fit_Data_error_997_neg(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_neg(y) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                        case '0.95'
                                            error_coeff(:) = app.Fit_Data_error_95_pos(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_pos(y) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                            error_coeff(:) = app.Fit_Data_error_95_neg(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_neg(y) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                        case '0.68'
                                            error_coeff(:) = app.Fit_Data_error_68_pos(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_pos(y) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                            error_coeff(:) = app.Fit_Data_error_68_neg(app.displayed_fit,t,p,y,x,:);
                                            app.y_err_neg(y) = error_coeff(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                    end
                                end
                            end
                        end
                    end
                    xlabel(app.Spectrum8, 'x (mm)');
                case 'changing value 3'
                    %find indexes
                    x_vector(:) = app.PLMap_Data.Power;
                    if app.displayed_fit_parameter == 5
                        size_data = size(app.gof_Map);
                        y_vector = nan(1,size_data(3));
                        for p = 1 : size_data(3)
                            if ~isempty(app.gof_Map{app.displayed_fit,t,p,y,x})
                                y_vector(p) = app.gof_Map{app.displayed_fit,t,p,y,x}.rmse;
                            end
                        end
                    else
                        size_data = size(app.Fit_Data_Map);
                        y_vector = nan(1,size_data(3));
                        if app.displayerrorbarsCheckBox.Value
                            app.y_err_pos = nan(1,size_data(3));
                            app.y_err_neg = nan(1,size_data(3));
                        else
                            app.y_err_neg = [];
                            app.y_err_pos = [];
                        end
                        for p = 1 : size_data(3)
                            if ~isempty(app.Fit_Data_Map{app.displayed_fit,t,p,y,x})
                                coeffvals = coeffvalues(app.Fit_Data_Map{app.displayed_fit,t,p,y,x});
                                y_vector(p) = coeffvals(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                if app.displayerrorbarsCheckBox.Value
                                    ci = confint(app.Fit_Data_Map{app.displayed_fit,t,p,y,x},str2num(app.error_intervalDropDown.Value));
                                    app.y_err_pos(p) = ci(1,(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter));
                                    app.y_err_neg(p) = ci(2,(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter));
                                end
                            end
                        end
                    end
                    xlabel(app.Spectrum8, 'Power [mW]');
                case 'changing value 4'
                    %find indexes
                    x_vector(:) = app.PLMap_Data.Temperature_index;
                    if app.displayed_fit_parameter == 5
                        size_data = size(app.gof_Map);
                        y_vector = nan(1,size_data(2));
                        for t = 1 : size_data(2)
                            if ~isempty(app.gof_Map{app.displayed_fit,t,p,y,x})
                                y_vector(t) = app.gof_Map{app.displayed_fit,t,p,y,x}.rmse;
                            end
                        end
                    else
                        size_data = size(app.Fit_Data_Map);
                        y_vector = nan(1,size_data(2));
                        if app.displayerrorbarsCheckBox.Value
                            app.y_err_pos = nan(1,size_data(2));
                            app.y_err_neg = nan(1,size_data(2));
                        else
                            app.y_err_neg = [];
                            app.y_err_pos = [];
                        end
                        for t = 1 : size_data(2)
                            if ~isempty(app.Fit_Data_Map{app.displayed_fit,t,p,y,x})
                                coeffvals = coeffvalues(app.Fit_Data_Map{app.displayed_fit,t,p,y,x});
                                y_vector(t) = coeffvals(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter);
                                if app.displayerrorbarsCheckBox.Value
                                    ci = confint(app.Fit_Data_Map{app.displayed_fit,t,p,y,x},str2num(app.error_intervalDropDown.Value));
                                    app.y_err_pos(t) = ci(1,(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter));
                                    app.y_err_neg(t) = ci(2,(app.displayed_fit_parameter+(app.displayed_peak-1)*app.n_fitparameter));
                                end
                            end
                        end
                    end
                    xlabel(app.Spectrum8, 'temperature index');
            end
            
            switch app.DisplayedValueDropDown_1.Value
                case 'Height'
                    ylabel(app.Spectrum8, 'Intensity [counts/s]');
                case 'Position'
                    ylabel(app.Spectrum8, 'Wavelength [nm]');
                    if app.display_peak_as_wavelengthCheckBox.Value
                        y_vector = converter.energy_wavelength(y_vector);
                        app.y_err_pos = converter.energy_wavelength(app.y_err_pos);
                        app.y_err_neg = converter.energy_wavelength(app.y_err_neg);
                    end
                case 'FWHM'
                    if app.n_fitparameter == 3
                        y_vector = converter.FWHM_sigma(y_vector,'gauss');
                        app.y_err_pos = converter.FWHM_sigma(app.y_err_pos,'gauss');
                        app.y_err_neg = converter.FWHM_sigma(app.y_err_neg,'gauss');
                    else
                        y_vector = converter.FWHM_sigma(y_vector,'pseudo-voigt');
                        app.y_err_pos = converter.FWHM_sigma(app.y_err_pos,'pseudo-voigt');
                        app.y_err_neg = converter.FWHM_sigma(app.y_err_neg,'pseudo-voigt');
                    end
                    ylabel(app.Spectrum8, 'FWHM [eV]');
            end
            
            %create Clipboard Data
            app.data_map = [];
            app.data_map(:,1) = x_vector;
            app.data_map(:,2) = y_vector;
            
            %dispaly the fit data
            [~,~] = specify_display_style_spectrum(app.Spectrum8,x_vector,y_vector,app.y_err_neg,app.y_err_pos,...
                app.displayerrorbarsCheckBox.Value,app.Line_StyleDropDown.Value,0);
        end
    end
    
    methods (Access = public)
        function import_data_public(app,Data_Map,Data_Fit,data_type)
            %start fit data
            %clear all data
            app.PLMap_Data = [];
            app.Fit_Data_Map = [];
            app.rmse_Map = [];
            app.rsquare_Map = [];
            app.fit_parameter = {};
            app.Fit_Data_error_68_pos = [];
            app.Fit_Data_error_68_neg = [];
            app.Fit_Data_error_95_pos = [];
            app.Fit_Data_error_95_neg = [];
            app.Fit_Data_error_997_pos = [];
            app.Fit_Data_error_997_neg = [];
            app.Fit_Data_function_parameter = [];
            app.Fit_Data_function_grade = [];
            
            %set raw data an display all in the peak fitting tab
            app.PLMap_Data = Data_Map;
            start_fit_map(app,data_type);
            
            %sets Fit_Data_function_parameter
            if ~isempty(Data_Fit)
                app.Fit_Data_function_parameter = Data_Fit.Fit_Data_function_parameter;
            else
                app.Fit_Data_function_parameter = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets Fit_Data_function_grade
            if ~isempty(Data_Fit)
                app.Fit_Data_function_grade = Data_Fit.Fit_Data_function_grade;
            else
                app.Fit_Data_function_grade = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets Fit_Data_Map
            if ~isempty(Data_Fit)
                app.Fit_Data_Map = Data_Fit.Fit_Data_Map;
                
                app.displayed_fit = 1;
                
                %define indeces of power and tamperature
                [~, p] = min(abs(app.PLMap_Data.Power-app.changingvalue3_2Spinner.Value));
                app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
                [~, t] = min(abs(app.PLMap_Data.Temperature_index-app.changingvalue4_2Spinner.Value));
                app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
                
                parameter_array(:,:) = app.Fit_Data_function_parameter(app.displayed_fit,t,p,:,:);
                grade_array(:,:) = app.Fit_Data_function_grade(app.displayed_fit,t,p,:,:);
                
                app.n_fitparameter = [];
                app.grade = [];
                app.n_fitparameter = max(parameter_array,[],'all');
                app.grade = max(grade_array,[],'all');
                
                if str2num(app.DisplayedPeakDropDown_1.Value)>app.grade
                    app.DisplayedPeakDropDown_1.Value = '1';
                end
                if str2num(app.DisplayedPeakDropDown_2.Value)>app.grade
                    app.DisplayedPeakDropDown_1.Value = '1';
                end
                if str2num(app.DisplayedPeakDropDown_3.Value)>app.grade
                    app.DisplayedPeakDropDown_1.Value = '1';
                end
                
                app.DisplayedPeakDropDown_1.Items = split(num2str(1:app.grade))';
                app.DisplayedPeakDropDown_2.Items = split(num2str(1:app.grade))';
                app.DisplayedPeakDropDown_3.Items = split(num2str(1:app.grade))';
                
                switch app.n_fitparameter
                    case 3
                        app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM'};
                        app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM'};
                        app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM'};
                        app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Position(Height)','Position(FWHM)',...
                            'FWHM(Height)','FWHM(Position)'};
                    case 4
                        app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                        app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                        app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                        app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Height(Eta)','Position(Height)','Position(FWHM)','Position(Eta)'...
                            'FWHM(Height)','FWHM(Position)','FWHM(Eta)','Eta(height)','Eta(Position)','Eta(FWHM)'};
                end
                
                app.displayed_peak  = str2num(app.DisplayedPeakDropDown_1.Value);
                
                switch app.DisplayedValueDropDown_1.Value
                    case 'Height'
                        app.displayed_fit_parameter = 1;
                    case 'Position'
                        app.displayed_fit_parameter = 2;
                    case 'FWHM'
                        app.displayed_fit_parameter = 3;
                    case 'Eta'
                        app.displayed_fit_parameter = 4;
                    case 'rmse'
                        app.displayed_fit_parameter = 5;
                        app.displayerrorbarsCheckBox.Value = 0;
                        app.displayerrorbarsCheckBox.Enable = 'off';
                end
                
                %displays map
                update_fit_map(app);
            else
                app.Fit_Data_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets fit rmse_Map
            if ~isempty(Data_Fit)
                app.rmse_Map = Data_Fit.rmse_Map;
            else
                app.rmse_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets fit rsquare_Map
            if ~isempty(Data_Fit)
                app.rsquare_Map = Data_Fit.rsquare_Map;
            else
                app.rsquare_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets Data_fit_parameter
            if ~isempty(Data_Fit)
                app.fit_parameter = Data_Fit.Data_fit_parameter;
            else
                app.fit_parameter = cell(numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power),8);
            end
            
            %sets Fit_Data_error_68_pos
            if ~isempty(Data_Fit)
                app.Fit_Data_error_68_pos = Data_Fit.Fit_Data_error_68_pos;
            else
                app.Fit_Data_error_68_pos = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_68_neg
            if ~isempty(Data_Fit)
                app.Fit_Data_error_68_neg = Data_Fit.Fit_Data_error_68_neg;
            else
                app.Fit_Data_error_68_neg = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_95_pos
            if ~isempty(Data_Fit)
                app.Fit_Data_error_95_pos = Data_Fit.Fit_Data_error_95_pos;
            else
                app.Fit_Data_error_95_pos = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_95_neg
            if ~isempty(Data_Fit)
                app.Fit_Data_error_95_neg = Data_Fit.Fit_Data_error_95_neg;
            else
                app.Fit_Data_error_95_neg = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_997_pos
            if ~isempty(Data_Fit)
                app.Fit_Data_error_997_pos = Data_Fit.Fit_Data_error_997_pos;
            else
                app.Fit_Data_error_997_pos = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets Fit_Data_error_997_neg
            if ~isempty(Data_Fit)
                app.Fit_Data_error_997_neg = Data_Fit.Fit_Data_error_997_neg;
            else
                app.Fit_Data_error_997_neg = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector),3);
            end
            
            %sets the same data vor the save data
            app.Fit_Data_Map_save = app.Fit_Data_Map;
            app.rmse_Map_save = app.rmse_Map;
            app.rsquare_Map_save = app.rsquare_Map;
        end
        
        function update_contour_lines(app,contour_lines,contourtext)
            app.contour_lines = contour_lines;
            app.contourtext = contourtext;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data_Map, Data_Fit, data_type)
            %Imports the Data to a Variable
            app.PLMap_Data = [];
            app.Fit_Data_Map = [];
            app.Fit_Data_error_68_pos = [];
            app.Fit_Data_error_68_neg = [];
            app.Fit_Data_error_95_pos = [];
            app.Fit_Data_error_95_neg = [];
            app.Fit_Data_error_997_pos = [];
            app.Fit_Data_error_997_neg = [];
            app.Fit_Data_function_parameter = [];
            app.Fit_Data_function_grade = [];
            app.rmse_Map = [];
            app.rsquare_Map = [];
            
            str = pwd; %path containing the data
            str = [str,'/'];
            app.programm_path = join(str,"");
            temp_import_Structure = load([app.programm_path, 'config']);
            config_data = temp_import_Structure.config;
            
            app.starting_x = config_data.x;
            app.starting_y = config_data.y;
            app.starting_power = 'low';
            app.starting_tamperature = 'low';
            app.starting_color_steps = config_data.color_steps;
            app.MapColorstepsEditField_5.Value = config_data.color_steps;
            app.MapColorstepsEditField_6.Value = config_data.color_steps;
            
            app.map_typ_data = config_data.map_typ;
            app.map_typ_fit_data = config_data.map_typ;
            app.map_typ_correlation_data = config_data.map_typ;
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black'};
            app.idx_list_data = find(contains(list,config_data.map_typ));
            app.idx_list_fit_data = app.idx_list_data;
            app.idx_list_correlation_data = app.idx_list_data;
        
            app.data_path = config_data.path;
            app.WindowsizeSpinner_5.Value = config_data.window_size_data;
            app.WindowsizeSpinner_6.Value = config_data.window_size_1diff;
            %app.deltaWavelengthnmSpinner.Value = config_data.delta_position;
            %app.deltaIntensitySpinner.Value = config_data.delta_height;
            %app.startFWHMSpinner.Value = config_data.delta_FWHM;
            app.min_file_size = config_data.min_file_size;
            app.contour_lines = config_data.contour_lines;
            app.contourtext = config_data.contourtext;
            app.bin = config_data.bin;
            app.show_histogram_statistics = config_data.show_histogram_statistics;
            app.absorptin_min = config_data.absorptin_min;
            app.absorptin_max = config_data.absorptin_max;
            app.batch_size = config_data.batch_size;
            
            app.area_dim_5 = [];
            app.displayed_fit_parameter = 2;
            app.displayed_peak = 1;
            app.displayed_fit = 1;
            app.selectedTab = app.TabGroup.SelectedTab;
            app.residual_data = 0;
            app.residual_1_diff = 0;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            if ~isempty(Data_Map)
                import_data(app,Data_Map,Data_Fit,data_type);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogImportApp)
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.DialogExportApp = DialogExportApp(app,app.map_typ_data);
        end

        % Value changed function: ProminenceEditField, 
        % applytovaluesCheckBox, changingvalue1_1Spinner, 
        % changingvalue2_1Spinner, constantEditField, 
        % end_indexSpinner, maxpeaksSpinner, 
        % minPeakdistanceSpinner, pointsDropDown_5, 
        % power_peak_distance, start_indexSpinner, start_xSpinner
        function changingvalue1SpinnerValueChanged(app, event)
            [diff2_x,diff2_spectrum] = display_all(app);
        end

        % Value changed function: ContourCheckBox_5, 
        % MapColorstepsEditField_5, MapMaxCountsEditField_5, 
        % MapMinCountsEditField_5, MapappearanceDropDown_5, 
        % OffsetSpinner_2, WaterfallDropDown, 
        % changingvalue3_1Spinner, changingvalue4_1Spinner, x_max, 
        % x_min
        function x_minValueChanged(app, event)
            %define indeces of power and tamperature
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            app.changingvalue3_1Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
            app.changingvalue4_1Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            switch app.SweepDropDown.Value
                case {'PL_Map','energy'}
                    show_map(app);
                otherwise
                    %find indexes
                    x = find_index(app.PLMap_Data.X_vector,app.changingvalue1_1Spinner.Value,1);
                    y = find_index(app.PLMap_Data.Y_vector,app.changingvalue2_1Spinner.Value,1);
                    
                    cla(app.Map5,'reset');
                    %specify the changing parameter
                    
                    switch app.WaterfallDropDown.Value
                        case 'changing value 1'
                            parameter = 'x';
                        case 'changing value 2'
                            parameter = 'y';
                        case 'changing value 3'
                            parameter = 'p';
                        case 'changing value 4'
                            parameter = 't';
                    end
                    
                    xlabel(app.Map5, 'wavelength [nm]');
                    ylabel(app.Map5, 'counts [1/s]');
                    
                    %create waterfall data
                    [spectra_x,spectra_y] = create_waterfall_data(app.PLMap_Data,parameter,t,p,x,y,app.x_min.Value,app.x_max.Value,app.SweepDropDown.Value);
                    
                    %declare the data array vor coppying
                    app.data_map = spectra_y;
                    
                    %display the Waterfall Data
                    [~,~] = Display_waterfall(app.Map5,spectra_x,spectra_y,app.OffsetSpinner_2.Value,'jet');
            end
            
            %display the spectal Data
            [diff2_x,diff2_spectrum] = display_all(app);
        end

        % Value changed function: SweepDropDown
        function SweepDropDownValueChanged(app, event)
            %opens display options for the data array
            switch app.SweepDropDown.Value
                case {'PL_Map','energy'}
                    app.WaterfallDropDownLabel.Enable = 'off';
                    app.WaterfallDropDown.Enable = 'off';
                    app.OffsetSpinner_2Label.Enable = 'off';
                    app.OffsetSpinner_2.Enable = 'off';
                    app.MapappearanceDropDown_5.Enable = 'on';
                    app.MapMaxCountsEditField_5.Enable = 'on';
                    app.MapMinCountsEditField_5.Enable = 'on';
                    app.MapColorstepsEditField_5.Enable = 'on';
                    app.ContourCheckBox_5.Enable = 'on';
                otherwise
                    app.WaterfallDropDownLabel.Enable = 'on';
                    app.WaterfallDropDown.Enable = 'on';
                    app.OffsetSpinner_2Label.Enable = 'on';
                    app.OffsetSpinner_2.Enable = 'on';
                    app.MapappearanceDropDown_5.Enable = 'off';
                    app.MapMaxCountsEditField_5.Enable = 'off';
                    app.MapMinCountsEditField_5.Enable = 'off';
                    app.MapColorstepsEditField_5.Enable = 'off';
                    app.ContourCheckBox_5.Enable = 'off';
            end
            
            %dispays all fit data
            x_minValueChanged(app, event)
        end

        % Button pushed function: Spectrum_RefreshButton_2
        function Spectrum_RefreshButton_2Pushed2(app, event)
            app.MapColorstepsEditField_5.Value =100;
            app.MapMinCountsEditField_5.Value = -inf;
            app.MapMaxCountsEditField_5.Value = inf;
            app.Map_switch_5.Value = 'linear';
            
            x_minValueChanged(app, event)
        end

        % Value changed function: SmoothingDropDown_5
        function SmoothingDropDown_5ValueChanged(app, event)
            [diff2_x,diff2_spectrum] = display_all(app);
            switch app.SmoothingDropDown_5.Value
                case 'sgolay'
                    app.sgolaydegreeSpinner_5Label.Enable = 'on';
                    app.sgolaydegreeSpinner_5.Enable = 'on';
                otherwise
                    app.sgolaydegreeSpinner_5Label.Enable = 'off';
                    app.sgolaydegreeSpinner_5.Enable = 'off';
            end
        end

        % Value changed function: WindowsizeSpinner_5, 
        % WindowsizeSpinner_6, sgolaydegreeSpinner_5, 
        % sgolaydegreeSpinner_6
        function WindowsizeSpinner_1ValueChanged(app, event)
            [diff2_x,diff2_spectrum] = display_all(app);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            value = app.Switch_6.Value;
            
        end

        % Value changed function: SmoothingDropDown_6
        function SmoothingDropDown_6ValueChanged(app, event)
            [diff2_x,diff2_spectrum] = display_all(app);
            switch app.SmoothingDropDown_6.Value
                case 'sgolay'
                    app.sgolaydegreeSpinner_6Label.Enable = 'on';
                    app.sgolaydegreeSpinner_6.Enable = 'on';
                otherwise
                    app.sgolaydegreeSpinner_6Label.Enable = 'off';
                    app.sgolaydegreeSpinner_6.Enable = 'off';
            end
        end

        % Button pushed function: FitsingledataButton
        function FitsingledataButtonPushed(app, event)
            %set the button state
            app.fit_button = 'single_fit';
            
            %% gets the indices for the current parameters
            x = find_index(app.PLMap_Data.X_vector,app.changingvalue1_1Spinner.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.changingvalue2_1Spinner.Value,1);
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
            
            %get the spectral data
            [app.x_vector,spectrum_smoothed,base_line,diff_x,diff_spectrum,diff_spectrum_smoothed,~,peak_x,...
                diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum] = fit_spectrum_parameter(app);
            
            %fit the data
            [Fit_Data_Map_Display,~,~,x_fit_range,y_fit_range] = fit_data(app);
            
            % gets the single fit curfs
            [app.fit_spectrum,npeaks] = Display_Fit(Fit_Data_Map_Display,app.Fit_modelDropDown.Value,app.x_vector,app.SweepDropDown.Value);
            
            size_data = size(Fit_Data_Map_Display);
            k = 0;
            app.FitValuesTextArea.Value = ' ';
            for s = 1 : size_data(1)
                %get the parameters
                coeffvals = coeffvalues(Fit_Data_Map_Display{s});
                
                %computes the text for the fit data
                for i = 1 : npeaks(s)
                    k = k+1;
                    switch app.Fit_modelDropDown.Value
                        case 'gauss'
                            hight = coeffvals(1+(i-1)*3);
                            position = coeffvals(2+(i-1)*3);
                            sigma = coeffvals(3+(i-1)*3);
                        case 'pseudo-voigt'
                            hight = coeffvals(1+(i-1)*4);
                            position = coeffvals(2+(i-1)*4);
                            sigma = coeffvals(3+(i-1)*4);
                            eta = coeffvals(4+(i-1)*4);
                    end
                    
                    FWHM = converter.FWHM_sigma(sigma,app.Fit_modelDropDown.Value);
                    
                    switch app.SweepDropDown.Value
                        case {'PL_Map','all'}
                            position = converter.energy_wavelength(position);
                    end
                    
                    switch app.Fit_modelDropDown.Value
                        case 'gauss'
                            app.FitValuesTextArea.Value{k} = char(join([app.Fit_modelDropDown.Value,num2str(k),': Peak height: ',...
                                num2str(hight,'%.4e'),', Peak Position: ',num2str(position,'%.4e'),...
                                ', FWHM: ',num2str(FWHM,'%.2e')]));
                        case 'pseudo-voigt'
                            app.FitValuesTextArea.Value{k} = char(join([app.Fit_modelDropDown.Value,num2str(k),': Peak height: ',...
                                num2str(hight,'%.4e'),', Peak Position: ',num2str(position,'%.4e'),...
                                ', FWHM: ',num2str(FWHM,'%.2e'),', eta: ',num2str(eta,'%.3e')]));
                    end
                end
            end
            
            %dispaly the fit data
            display_fit_spectrum(app,app.x_vector,spectrum_smoothed,app.fit_spectrum,x_fit_range,y_fit_range,base_line,diff_x,diff_spectrum, ...
                diff_spectrum_smoothed,peak_x,diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum);
            
            %display the residuals
            x_limits(1) = app.x_min.Value;
            x_limits(2) = app.x_max.Value;
            specify_display_style_spectrum_residuals(app,app.Spectrum6,x_fit_range,y_fit_range,Fit_Data_Map_Display,x_limits);
        end

        % Button pushed function: FitmultidataButton
        function FitmultidataButtonPushed(app, event)
            %set the button state
            app.fit_button = 'multi_fit';
            
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
            
            %fit the data
            [~,~,~,~,~] = fit_data(app);
            
            % get the fit meta date an save them in a cell aray
            fit_data_parameter.BaselineDropDown = app.BaselineDropDown.Value;
            fit_data_parameter.start_index = app.start_indexSpinner.Value;
            fit_data_parameter.end_index = app.end_indexSpinner.Value;
            fit_data_parameter.applytovalues = app.applytovaluesCheckBox.Value;
            fit_data_parameter.points = app.pointsDropDown_5.Value;
            fit_data_parameter.start_x = app.start_xSpinner.Value;
            fit_data_parameter.Smoothing_diff1 = app.SmoothingDropDown_5.Value;
            fit_data_parameter.Windowsize_diff1 = app.WindowsizeSpinner_5.Value;
            fit_data_parameter.sgolaydegree_diff1 = app.sgolaydegreeSpinner_5.Value;
            fit_data_parameter.Smoothing_diff2 = app.SmoothingDropDown_6.Value;
            fit_data_parameter.Windowsize_diff2 = app.WindowsizeSpinner_6.Value;
            fit_data_parameter.sgolaydegree_diff2 = app.sgolaydegreeSpinner_6.Value;
            fit_data_parameter.setValuesCheckBox = app.set_parameterCheckBox.Value;
            fit_data_parameter.accuracy = app.accuracyEditField.Value;
            fit_data_parameter.maxiter = app.maxiterEditField.Value;
            fit_data_parameter.Numberofpointsleft = app.NumberofpointsleftSpinner.Value;
            fit_data_parameter.Numberofpointsright = app.NumberofpointsrightSpinner.Value;
            fit_data_parameter.Prominence = app.ProminenceEditField.Value;
            fit_data_parameter.minPeakdistance = app.minPeakdistanceSpinner.Value;
            fit_data_parameter.power_peak_distance = app.power_peak_distance.Value;
            fit_data_parameter.maxpeaks = app.maxpeaksSpinner.Value;
            fit_data_parameter.x_min = app.x_min.Value;
            fit_data_parameter.x_max = app.x_max.Value;
            fit_data_parameter.Sweep = app.SweepDropDown.Value;
            fit_data_parameter.subtractwindow = app.subtractwindowCheckBox.Value;
            fit_data_parameter.Fit_model = app.Fit_modelDropDown.Value;
            
            
            peak = app.atpositionSpinner.Value;
            app.fit_parameter{t,p,peak} = fit_data_parameter;
            
            fitDropDown_1ValueChanged(app, event);
            
            %system sound for ready
            beep
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            app.DialogPreferencesApp = DialogPreferencesApp(app);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            app.DialogImportApp = DialogImportApp(app);
        end

        % Button pushed function: SortPeaksButton
        function SortPeaksButtonPushed(app, event)
            sort_peaks(app,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.PLMap_Data.Power,app.PLMap_Data.Temperature_index,app.X_startSpinner.Value,app.Y_startSpinner.Value,app.ThresholddistanceSpinner.Value);
            app.DisplayedMapDropDown_1.Value = 'Sort_Map';
            app.MapMaxCountsEditField_6.Value = inf;
            app.MapMinCountsEditField_6.Value = -inf;
            app.MapColorstepsEditField_6.Value = 100;
            update_fit_map(app);
        end

        % Value changed function: DisplayedPeakDropDown_1
        function DisplayedPeakDropDown_1ValueChanged(app, event)
            %changes the displayed peak
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_1.Value);
            
            app.DisplayedPeakDropDown_2.Value = app.DisplayedPeakDropDown_1.Value;
            app.DisplayedPeakDropDown_3.Value = app.DisplayedPeakDropDown_1.Value;
            app.DisplayedPeakDropDown_4.Value = app.DisplayedPeakDropDown_1.Value;
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: DisplayedValueDropDown_1
        function DisplayedValueDropDown_1ValueChanged(app, event)
            app.displayerrorbarsCheckBox.Enable = 'on';
            
            switch app.DisplayedValueDropDown_1.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
                    app.displayerrorbarsCheckBox.Value = 0;
                    app.displayerrorbarsCheckBox.Enable = 'off';
            end
            
            app.DisplayedValueDropDown_2.Value = app.DisplayedValueDropDown_1.Value;
            app.DisplayedValueDropDown_3.Value = app.DisplayedValueDropDown_1.Value;
            app.DisplayedValueDropDown_4.Value = app.DisplayedValueDropDown_1.Value;
           
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Button pushed function: SaveDataButton_1, 
        % SaveDataButton_2, SaveDataButton_3, SaveDataButton_4, 
        % SaveDataButton_5
        function SaveDataButton_1Pushed(app, event)
            %save data into .mat file
            save_data = uigetdir(app.data_path,'choose the same Path as the PL-Data');
            
            figure_ui = uifigure;
            d = uiprogressdlg(figure_ui,'Title','Save Data','Indeterminate','on');
            pause(0.1);
            drawnow;
            
            Fit_Data_Map = app.Fit_Data_Map;
            Fit_Data_error_68_pos = app.Fit_Data_error_68_pos;
            Fit_Data_error_68_neg = app.Fit_Data_error_68_neg;
            Fit_Data_error_95_pos = app.Fit_Data_error_95_pos;
            Fit_Data_error_95_neg = app.Fit_Data_error_95_neg;
            Fit_Data_error_997_pos = app.Fit_Data_error_997_pos;
            Fit_Data_error_997_neg = app.Fit_Data_error_997_neg;
            Fit_Data_function_parameter = app.Fit_Data_function_parameter;
            Fit_Data_function_grade = app.Fit_Data_function_grade;
            rmse_Map = app.rmse_Map;
            rsquare_Map = app.rsquare_Map;
            Data_fit_parameter = app.fit_parameter;
            
            save(fullfile(save_data, 'Data_Fit'),'Fit_Data_Map','Fit_Data_error_68_pos','Fit_Data_error_68_neg','Fit_Data_error_95_pos','Fit_Data_error_95_neg',...
                'Fit_Data_error_997_pos','Fit_Data_error_997_neg','Fit_Data_function_parameter','Fit_Data_function_grade','rmse_Map','rsquare_Map','Data_fit_parameter','-v7.3','-nocompression');
            
            close(d)
            close(figure_ui)
        end

        % Value changed function: ContourCheckBox_6, 
        % MapColorstepsEditField_6, MapMaxCountsEditField_6, 
        % MapMinCountsEditField_6
        function MapMaxCountsEditField_6ValueChanged(app, event)
            update_fit_map(app);
            %draws range in the Graph
            mark_area(app.Spectrum8_2,app.area_count_range_handle8,app.data_map,app.spectrum,app.MapMinCountsEditField_6,app.MapMaxCountsEditField_6,1)
        end

        % Value changed function: CorrelationsDropDown
        function CorrelationsDropDownValueChanged(app, event)
            cla(app.Spectrum8,'reset')
            [height,FWHM,peakpos,Eta,rmse] = build_correlation(app);
            y_err_neg = [];
            y_err_pos = [];
            app.data_map = [];
            
            if app.display_peak_as_wavelengthCheckBox.Value
                peakpos = converter.energy_wavelength(peakpos);
            end
            
            switch app.CorrelationsDropDown.Value
                case 'Height(FWHM)'
                    app.data_map(:,1) = FWHM;
                    app.data_map(:,2) = height;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,FWHM,height,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'FWHM [eV]');
                    ylabel(app.Spectrum8, 'Intensity [counts/s]');
                case 'Height(Position)'
                    app.data_map(:,1) = peakpos;
                    app.data_map(:,2) = height;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,peakpos,height,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Wavelength [nm]');
                    ylabel(app.Spectrum8, 'Intensity [counts/s]');
                case 'Height(Eta)'
                    app.data_map(:,1) = Eta;
                    app.data_map(:,2) = height;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,Eta,height,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Eta');
                    ylabel(app.Spectrum8, 'Intensity [counts/s]');
                case 'Position(Height)'
                    app.data_map(:,1) = height;
                    app.data_map(:,2) = peakpos;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,height,peakpos,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Intensity [counts/s]');
                    ylabel(app.Spectrum8, 'Wavelength [nm]');
                case 'Position(FWHM)'
                    app.data_map(:,1) = FWHM;
                    app.data_map(:,2) = peakpos;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,FWHM,peakpos,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'FWHM [eV]');
                    ylabel(app.Spectrum8, 'Wavelength [nm]');
                case 'Position(Eta)'
                    app.data_map(:,1) = Eta;
                    app.data_map(:,2) = peakpos;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,Eta,peakpos,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Eta');
                    ylabel(app.Spectrum8, 'Wavelength [nm]');
                case 'FWHM(Height)'
                    app.data_map(:,1) = height;
                    app.data_map(:,2) = FWHM;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,height,FWHM,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Intensity [counts/s]');
                    ylabel(app.Spectrum8, 'FWHM [eV]');
                case 'FWHM(Position)'
                    app.data_map(:,1) = peakpos;
                    app.data_map(:,2) = FWHM;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,peakpos,FWHM,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Wavelength [nm]');
                    ylabel(app.Spectrum8, 'FWHM [eV]');
                case 'FWHM(Eta)'
                    app.data_map(:,1) = Eta;
                    app.data_map(:,2) = FWHM;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,Eta,FWHM,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Eta');
                    ylabel(app.Spectrum8, 'FWHM [eV]');
                case 'Eta(height)'
                    app.data_map(:,1) = height;
                    app.data_map(:,2) = Eta;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,height,Eta,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Eta');
                    ylabel(app.Spectrum8, 'Intensity [counts/s]');
                case 'Eta(Position)'
                    app.data_map(:,1) = peakpos;
                    app.data_map(:,2) = Eta;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,peakpos,Eta,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Eta');
                    ylabel(app.Spectrum8, 'Wavelength [nm]');
                case 'Eta(FWHM)'
                    app.data_map(:,1) = FWHM;
                    app.data_map(:,2) = Eta;
                    [~,~] = specify_display_style_spectrum(app.Spectrum8,FWHM,Eta,y_err_neg,y_err_pos,0,'*',0);
                    xlabel(app.Spectrum8, 'Eta');
                    ylabel(app.Spectrum8, 'FWHM [eV]');
            end
        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            copyed_array = mat2clip(app.data_map, '\t');
        end

        % Value changed function: BaselineDropDown
        function BaselineDropDownValueChanged(app, event)
            switch app.BaselineDropDown.Value
                case 'None'
                    app.start_indexSpinnerLabel.Enable = 'off';
                    app.start_indexSpinner.Enable = 'off';
                    app.end_indexSpinnerLabel.Enable = 'off';
                    app.end_indexSpinner.Enable = 'off';
                    app.applytovaluesCheckBox.Enable = 'off';
                    app.constantEditField.Enable = 'off';
                    app.constantEditFieldLabel.Enable = 'off';
                case {'Linear','constant_mean'}
                    app.start_indexSpinnerLabel.Enable = 'on';
                    app.start_indexSpinner.Enable = 'on';
                    app.end_indexSpinnerLabel.Enable = 'on';
                    app.end_indexSpinner.Enable = 'on';
                    app.applytovaluesCheckBox.Enable = 'on';
                    app.constantEditField.Enable = 'off';
                    app.constantEditFieldLabel.Enable = 'off';
                case 'constant'
                    app.start_indexSpinnerLabel.Enable = 'off';
                    app.start_indexSpinner.Enable = 'off';
                    app.end_indexSpinnerLabel.Enable = 'off';
                    app.end_indexSpinner.Enable = 'off';
                    app.applytovaluesCheckBox.Enable = 'on';
                    app.constantEditField.Enable = 'on';
                    app.constantEditFieldLabel.Enable = 'on';
            end
            [diff2_x,diff2_spectrum] = display_all(app);
        end

        % Button pushed function: singlefitandsaveButton
        function singlefitandsaveButtonPushed(app, event)
            %set the button state
            app.fit_button = 'single_fit_and_save';
            
            %% gets the indices for the current parameters
            x = find_index(app.PLMap_Data.X_vector,app.changingvalue1_1Spinner.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.changingvalue2_1Spinner.Value,1);
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
            
            %get the spectral data
            [x_vector,spectrum_smoothed,base_line,diff_x,diff_spectrum,diff_spectrum_smoothed,~,peak_x,...
                diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum] = fit_spectrum_parameter(app);
            
            %fit the data
            [Fit_Data_Map_Display,~,~,x_fit_range,y_fit_range] = fit_data(app);
            
            % gets the single fit curfs
            [fit_spectrum,npeaks] = Display_Fit(Fit_Data_Map_Display,app.Fit_modelDropDown.Value,x_vector,app.SweepDropDown.Value);
            
            size_data = size(Fit_Data_Map_Display);
            k = 0;
            app.FitValuesTextArea.Value = ' ';
            for s = 1 : size_data(1)
                %get the parameters
                coeffvals = coeffvalues(Fit_Data_Map_Display{s});
                
                %computes the text for the fit data
                for i = 1 : npeaks(s)
                    k = k+1;
                    switch app.Fit_modelDropDown.Value
                        case 'gauss'
                            hight = coeffvals(1+(i-1)*3);
                            position = coeffvals(2+(i-1)*3);
                            sigma = coeffvals(3+(i-1)*3);
                        case 'pseudo-voigt'
                            hight = coeffvals(1+(i-1)*4);
                            position = coeffvals(2+(i-1)*4);
                            sigma = coeffvals(3+(i-1)*4);
                            eta = coeffvals(4+(i-1)*4);
                    end
                    
                    FWHM = converter.FWHM_sigma(sigma,app.Fit_modelDropDown.Value);
                    
                    switch app.SweepDropDown.Value
                        case {'PL_Map','all'}
                            position = converter.energy_wavelength(position);
                    end
                    
                    switch app.Fit_modelDropDown.Value
                        case 'gauss'
                            app.FitValuesTextArea.Value{k} = char(join([app.Fit_modelDropDown.Value,num2str(k),': Peak height: ',...
                                num2str(hight,'%.4e'),', Peak Position: ',num2str(position,'%.4e'),...
                                ', FWHM: ',num2str(FWHM,'%.2e')]));
                        case 'pseudo-voigt'
                            app.FitValuesTextArea.Value{k} = char(join([app.Fit_modelDropDown.Value,num2str(k),': Peak height: ',...
                                num2str(hight,'%.4e'),', Peak Position: ',num2str(position,'%.4e'),...
                                ', FWHM: ',num2str(FWHM,'%.2e'),', eta: ',num2str(eta,'%.3e')]));
                    end
                end
            end
            
            %dispaly the fit data
            display_fit_spectrum(app,x_vector,spectrum_smoothed,fit_spectrum,x_fit_range,y_fit_range,base_line,diff_x,diff_spectrum, ...
                diff_spectrum_smoothed,peak_x,diff2_spectrum_peak_intensity,diff2_x,diff2_spectrum);
            
            %display the residuals
            x_limits(1) = app.x_min.Value;
            x_limits(2) = app.x_max.Value;
            specify_display_style_spectrum_residuals(app,app.Spectrum6,x_fit_range,y_fit_range,Fit_Data_Map_Display,x_limits);
        end

        % Value changed function: DisplaydataalongvalueDropDown, 
        % Line_StyleDropDown, displayerrorbarsCheckBox, 
        % error_intervalDropDown
        function DisplaydataalongvalueDropDownValueChanged(app, event)
            switch app.DisplaydataalongvalueDropDown.Value
                case 'changing value 1'
                    app.changingvalue1_2Spinner.BackgroundColor = [0 1 0];
                    app.changingvalue2_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue3_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue4_2Spinner.BackgroundColor = [1 1 1];
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = line(app.Map6,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.changingvalue2_2Spinner.Value app.changingvalue2_2Spinner.Value],'color','g','LineStyle','--', 'LineWidth', 2);
                case 'changing value 2'
                    app.changingvalue1_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue2_2Spinner.BackgroundColor = [0 1 0];
                    app.changingvalue3_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue4_2Spinner.BackgroundColor = [1 1 1];
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = line(app.Map6,[app.changingvalue1_2Spinner.Value app.changingvalue1_2Spinner.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);
                case 'changing value 3'
                    app.changingvalue1_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue2_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue3_2Spinner.BackgroundColor = [0 1 0];
                    app.changingvalue4_2Spinner.BackgroundColor = [1 1 1];
                case 'changing value 4'
                    app.changingvalue1_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue2_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue3_2Spinner.BackgroundColor = [1 1 1];
                    app.changingvalue4_2Spinner.BackgroundColor = [0 1 0];
            end
            app.show_sigle_fitCheckBox.Value = 0;
            update_fit_gradient(app);
        end

        % Button pushed function: deleteoutlierButton
        function deleteoutlierButtonPushed(app, event)
            %get the data at this current values
            [pre_processed_data_map,t,p] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,app.displayed_fit_parameter,app.n_fitparameter);
            
            %converts energy to wavelegth
            if app.displayed_fit_parameter == 2
                if app.display_peak_as_wavelengthCheckBox.Value
                    pre_processed_data_map = converter.energy_wavelength(pre_processed_data_map);
                end
            end
            
            %converts k_min smalest and k_max largest values to NaN
            if app.k_minSpinner.Value >0 || app.k_maxSpinner.Value >0
                pre_processed_data_map = clean_up_map(pre_processed_data_map,app.k_minSpinner.Value,app.k_maxSpinner.Value);
                TF = isnan(pre_processed_data_map);
            end
            
            size_data = size(app.Fit_Data_Map);
            
            %creates temporary data for faster process
            Fit_Data_Map_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_68_pos_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_68_neg_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_95_pos_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_95_neg_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_997_pos_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_997_neg_transfer = nan(size_data(4),size_data(5),size_data(6));
            rmse_Map_transfer = nan(size_data(4),size_data(5));
            rsquare_Map_transfer = nan(size_data(4),size_data(5));
            
            Fit_Data_Map_transfer(:,:,:) = app.Fit_Data_Map(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_68_pos_transfer(:,:,:) = app.Fit_Data_error_68_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_68_neg_transfer(:,:,:) = app.Fit_Data_error_68_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_95_pos_transfer(:,:,:) = app.Fit_Data_error_95_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_95_neg_transfer(:,:,:) = app.Fit_Data_error_95_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_997_pos_transfer(:,:,:) = app.Fit_Data_error_997_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_997_neg_transfer(:,:,:) = app.Fit_Data_error_997_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            rmse_Map_transfer(:,:) = app.rmse_Map(str2num(app.fitDropDown_1.Value),t,p,:,:);
            rsquare_Map_transfer(:,:) = app.rsquare_Map(str2num(app.fitDropDown_1.Value),t,p,:,:);
            
            %transfer the nan data
            for y = 1 : numel(app.PLMap_Data.Y_vector)
                for x = 1 : numel(app.PLMap_Data.X_vector)
                    if TF(y,x)
                        for i = 1 : size_data(6)
                            Fit_Data_Map_transfer(y,x,i) = nan;
                            Fit_Data_error_68_pos_transfer(y,x,i) = nan;
                            Fit_Data_error_68_neg_transfer(y,x,i) = nan;
                            Fit_Data_error_95_pos_transfer(y,x,i) = nan;
                            Fit_Data_error_95_neg_transfer(y,x,i) = nan;
                            Fit_Data_error_997_pos_transfer(y,x,i) = nan;
                            Fit_Data_error_997_neg_transfer(y,x,i) = nan;
                        end
                        rmse_Map_transfer(y,x) = nan;
                        rsquare_Map_transfer(y,x) = nan;
                    end
                end
            end
            
            %get the new data in the data structure
            app.Fit_Data_Map(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_Map_transfer(:,:,:);
            app.Fit_Data_error_68_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_68_pos_transfer(:,:,:);
            app.Fit_Data_error_68_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_68_neg_transfer(:,:,:);
            app.Fit_Data_error_95_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_95_pos_transfer(:,:,:);
            app.Fit_Data_error_95_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_95_neg_transfer(:,:,:);
            app.Fit_Data_error_997_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_997_pos_transfer(:,:,:);
            app.Fit_Data_error_997_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_997_neg_transfer(:,:,:);
            app.rmse_Map(str2num(app.fitDropDown_1.Value),t,p,:,:) = rmse_Map_transfer(:,:);
            app.rsquare_Map(str2num(app.fitDropDown_1.Value),t,p,:,:) = rsquare_Map_transfer(:,:);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
            
            %rest k Values
            app.k_minSpinner.Value = 0;
            app.k_maxSpinner.Value = 0;
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            
            %gets the correct c label
            if app.displayed_fit_parameter == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter == 3
                label{4} = 'FWHM (eV)';
            elseif app.displayed_fit_parameter == 4
                label{4} = 'Eta';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %displayes the map
            [app.last_fit_map,app.c_fit_map] = Display_Map(app.Map6,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,...
                pre_processed_data_map,app.MapColorstepsEditField_6.Value,app.map_typ_fit_data,label,app.MapMinCountsEditField_6.Value,...
                app.MapMaxCountsEditField_6.Value,app.ContourCheckBox_6.Value,app.contour_lines,app.contourtext,0);
            
            %display histogram
            update_histogram(app.Spectrum8_2,pre_processed_data_map,app.bin,app.show_histogram_statistics);
        end

        % Menu selected function: choose_regtangleMenu
        function choose_regtangleMenuSelected(app, event)
            [~, x, y, dx, dy] = select_data.selectDataRegtangle(app.Map6,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,1);
            if isempty(app.points_for_delt)
                k=0;
                for i = x : app.PLMap_Data.X_Steps : x+dx
                    for j = y : app.PLMap_Data.Y_Steps : y+dy
                        k = k+1;
                        app.points_for_delt(k,:) = [i,j];
                    end
                end
            end
        end

        % Menu selected function: delet_pointMenu
        function delet_pointMenuSelected(app, event)
            %get the data at this current values
            [pre_processed_data_map,t,p] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,app.displayed_fit_parameter,app.n_fitparameter);
            
            %converts energy to wavelegth
            if app.displayed_fit_parameter == 2
                if app.display_peak_as_wavelengthCheckBox.Value
                    pre_processed_data_map = converter.energy_wavelength(pre_processed_data_map);
                end
            end
            
            %overwrites the point with nan
            size_of_points_to_delet = size(app.points_for_delt);
            for i = 1:size_of_points_to_delet(1)
                x = find_index(app.PLMap_Data.X_vector,app.points_for_delt(i,1),1);
                y = find_index(app.PLMap_Data.Y_vector,app.points_for_delt(i,2),1);
                pre_processed_data_map(y,x) = nan;
            end
            TF = isnan(pre_processed_data_map);
            
            size_data = size(app.Fit_Data_Map);
            
            %creates temporary data for faster process
            Fit_Data_Map_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_68_pos_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_68_neg_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_95_pos_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_95_neg_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_997_pos_transfer = nan(size_data(4),size_data(5),size_data(6));
            Fit_Data_error_997_neg_transfer = nan(size_data(4),size_data(5),size_data(6));
            rmse_Map_transfer = nan(size_data(4),size_data(5));
            rsquare_Map_transfer = nan(size_data(4),size_data(5));
            
            Fit_Data_Map_transfer(:,:,:) = app.Fit_Data_Map(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_68_pos_transfer(:,:,:) = app.Fit_Data_error_68_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_68_neg_transfer(:,:,:) = app.Fit_Data_error_68_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_95_pos_transfer(:,:,:) = app.Fit_Data_error_95_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_95_neg_transfer(:,:,:) = app.Fit_Data_error_95_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_997_pos_transfer(:,:,:) = app.Fit_Data_error_997_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            Fit_Data_error_997_neg_transfer(:,:,:) = app.Fit_Data_error_997_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:);
            rmse_Map_transfer(:,:) = app.rmse_Map(str2num(app.fitDropDown_1.Value),t,p,:,:);
            rsquare_Map_transfer(:,:) = app.rsquare_Map(str2num(app.fitDropDown_1.Value),t,p,:,:);
            
            %transfer the nan data
            for y = 1 : numel(app.PLMap_Data.Y_vector)
                for x = 1 : numel(app.PLMap_Data.X_vector)
                    if TF(y,x)
                        for i = 1 : size_data(6)
                            Fit_Data_Map_transfer(y,x,i) = nan;
                            Fit_Data_error_68_pos_transfer(y,x,i) = nan;
                            Fit_Data_error_68_neg_transfer(y,x,i) = nan;
                            Fit_Data_error_95_pos_transfer(y,x,i) = nan;
                            Fit_Data_error_95_neg_transfer(y,x,i) = nan;
                            Fit_Data_error_997_pos_transfer(y,x,i) = nan;
                            Fit_Data_error_997_neg_transfer(y,x,i) = nan;
                        end
                        rmse_Map_transfer(y,x) = nan;
                        rsquare_Map_transfer(y,x) = nan;
                    end
                end
            end
            
            %get the new data in the data structure
            app.Fit_Data_Map(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_Map_transfer(:,:,:);
            app.Fit_Data_error_68_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_68_pos_transfer(:,:,:);
            app.Fit_Data_error_68_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_68_neg_transfer(:,:,:);
            app.Fit_Data_error_95_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_95_pos_transfer(:,:,:);
            app.Fit_Data_error_95_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_95_neg_transfer(:,:,:);
            app.Fit_Data_error_997_pos(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_997_pos_transfer(:,:,:);
            app.Fit_Data_error_997_neg(str2num(app.fitDropDown_1.Value),t,p,:,:,:) = Fit_Data_error_997_neg_transfer(:,:,:);
            app.rmse_Map(str2num(app.fitDropDown_1.Value),t,p,:,:) = rmse_Map_transfer(:,:);
            app.rsquare_Map(str2num(app.fitDropDown_1.Value),t,p,:,:) = rsquare_Map_transfer(:,:);
            
            %reset points_for_delt
            app.points_for_delt = [];
            
            %get the area size
            [x_squer,y_squer,window_x,window_y] = define_area_size(app.PLMap_Data,app.area_dim_6);
            
            %sets the area for the data
            pre_processed_data_map = pre_processed_data_map(y_squer:window_y,x_squer:window_x);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            
            %gets the correct c label
            if app.displayed_fit_parameter == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter == 3
                label{4} = 'FWHM (eV)';
            elseif app.displayed_fit_parameter == 4
                label{4} = 'Eta';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %displayes the map
            [app.last_fit_map,app.c_fit_map] = Display_Map(app.Map6,app.PLMap_Data.X_vector(x_squer:window_x),app.PLMap_Data.Y_vector(y_squer:window_y),...
                pre_processed_data_map,app.MapColorstepsEditField_6.Value,app.map_typ_fit_data,label,app.MapMinCountsEditField_6.Value,...
                app.MapMaxCountsEditField_6.Value,app.ContourCheckBox_6.Value,app.contour_lines,app.contourtext,app.locktoareaCheckBox_6.Value);
            
            %display histogram
            update_histogram(app.Spectrum8_2,pre_processed_data_map,app.bin,app.show_histogram_statistics);
        end

        % Menu selected function: choose_pointMenu
        function choose_pointMenuSelected(app, event)
            [~, x, y] = select_data.selectDataPoint(app.Map6,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,1);
            if isempty(app.points_for_delt)
                app.points_for_delt(1,:) = [x,y];
            else
                app.points_for_delt(end+1,:) = [x,y];
            end
        end

        % Button pushed function: reset_points_to_deletButton
        function reset_points_to_deletButtonPushed(app, event)
            app.points_for_delt = [];
        end

        % Menu selected function: choose_pointsMenu
        function choose_pointsMenuSelected(app, event)
            [~, x, y] = select_data.selectDataPoints(app.Map6,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,1);
            if isempty(app.points_for_delt)
                app.points_for_delt = [x,y];
            else
                app.points_for_delt = [app.points_for_delt;x,y];
            end
        end

        % Menu selected function: single_graphMenu
        function single_graphMenuSelected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_single_graph(app.PLMap_Data,Data_Fit);
        end

        % Menu selected function: multi_graph_1Menu
        function multi_graph_1MenuSelected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_multi_graph(app.PLMap_Data,Data_Fit,[],[]);
        end

        % Menu selected function: multi_graph_2Menu
        function multi_graph_2MenuSelected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_multi_graph([],[],app.PLMap_Data,Data_Fit);
        end

        % Menu selected function: gradient_graphMenu
        function gradient_graphMenuSelected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_gradient_graph(app.PLMap_Data,Data_Fit);
        end

        % Menu selected function: multi_graph_bothMenu
        function multi_graph_bothMenuSelected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_multi_graph(app.PLMap_Data,Data_Fit,app.PLMap_Data,Data_Fit);
        end

        % Menu selected function: growth_simMenu
        function growth_simMenuSelected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_growth_sim(app.PLMap_Data, Data_Fit);
        end

        % Menu selected function: shift_pointsMenu
        function shift_pointsMenuSelected(app, event)
            %gets the position to shift to
            prompt = {'Enter the position to which the peaks should be moved.'};
            dlgtitle = 'Shift position';
            definput = {'2'};
            dims = [1 40];
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Handle response
            if isempty(answer)
                return
            else
                shift_peak = str2num(answer{1});
            end
            
            
            %define indeces of power and tamperature
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_2Spinner.Value,1);
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_2Spinner.Value,1);
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            %determine which data schould be displayed
            pre_processed_data_map_1(:,:) = app.Fit_Data_Map_temp(t,p,:,:,1,app.displayed_peak);
            pre_processed_data_map_2(:,:) = app.Fit_Data_Map_temp(t,p,:,:,2,app.displayed_peak);
            pre_processed_data_map_3(:,:) = app.Fit_Data_Map_temp(t,p,:,:,3,app.displayed_peak);
            pre_processed_data_map_4(:,:) = app.Fit_Data_Map_temp(t,p,:,:,4,app.displayed_peak);
            %                     pre_processed_data_map_rmse(:,:) = app.Fit_rmse_temp(t,p,:,:,app.displayed_peak);
            
            pre_processed_data_Map_conf_low_1(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,1,app.displayed_peak);
            pre_processed_data_Map_conf_low_2(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,2,app.displayed_peak);
            pre_processed_data_Map_conf_low_3(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,3,app.displayed_peak);
            pre_processed_data_Map_conf_low_4(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,4,app.displayed_peak);
            pre_processed_data_Map_conf_up_1(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,1,app.displayed_peak);
            pre_processed_data_Map_conf_up_2(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,2,app.displayed_peak);
            pre_processed_data_Map_conf_up_3(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,3,app.displayed_peak);
            pre_processed_data_Map_conf_up_4(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,4,app.displayed_peak);
            
            %place were the da gats shifted to
            pre_processed_data_map_1_shift(:,:) = app.Fit_Data_Map_temp(t,p,:,:,1,shift_peak);
            pre_processed_data_map_2_shift(:,:) = app.Fit_Data_Map_temp(t,p,:,:,2,shift_peak);
            pre_processed_data_map_3_shift(:,:) = app.Fit_Data_Map_temp(t,p,:,:,3,shift_peak);
            pre_processed_data_map_4_shift(:,:) = app.Fit_Data_Map_temp(t,p,:,:,4,shift_peak);
            %                     pre_processed_data_map_rmse(:,:) = app.Fit_rmse_temp(t,p,:,:,shift_peak);
            
            pre_processed_data_Map_conf_low_1_shift(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,1,shift_peak);
            pre_processed_data_Map_conf_low_2_shift(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,2,shift_peak);
            pre_processed_data_Map_conf_low_3_shift(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,3,shift_peak);
            pre_processed_data_Map_conf_low_4_shift(:,:) = app.Fit_Data_Map_conf_low_temp(t,p,:,:,4,shift_peak);
            pre_processed_data_Map_conf_up_1_shift(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,1,shift_peak);
            pre_processed_data_Map_conf_up_2_shift(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,2,shift_peak);
            pre_processed_data_Map_conf_up_3_shift(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,3,shift_peak);
            pre_processed_data_Map_conf_up_4_shift(:,:) = app.Fit_Data_Map_conf_up_temp(t,p,:,:,4,shift_peak);
            
            
            %sets the transfer matrix
            switch app.displayed_fit_parameter
                case 1
                    pre_processed_data_map = pre_processed_data_map_1;
                case 2
                    pre_processed_data_map = pre_processed_data_map_2;
                case 3
                    pre_processed_data_map = pre_processed_data_map_3;
                case 4
                    pre_processed_data_map = pre_processed_data_map_4;
            end
            
            %overwrites the point with nan
            size_of_points_to_delet = size(app.points_for_delt);
            for i = 1:size_of_points_to_delet(1)
                x = find_index(app.PLMap_Data.X_vector,app.points_for_delt(i,1),1);
                y = find_index(app.PLMap_Data.Y_vector,app.points_for_delt(i,2),1);
                pre_processed_data_map_1_shift(y,x) = pre_processed_data_map_1(y,x);
                pre_processed_data_map_2_shift(y,x) = pre_processed_data_map_2(y,x);
                pre_processed_data_map_3_shift(y,x) = pre_processed_data_map_3(y,x);
                pre_processed_data_map_4_shift(y,x) = pre_processed_data_map_4(y,x);
                pre_processed_data_Map_conf_low_1_shift(y,x) = pre_processed_data_Map_conf_low_1(y,x);
                pre_processed_data_Map_conf_low_2_shift(y,x) = pre_processed_data_Map_conf_low_2(y,x);
                pre_processed_data_Map_conf_low_3_shift(y,x) = pre_processed_data_Map_conf_low_3(y,x);
                pre_processed_data_Map_conf_low_4_shift(y,x) = pre_processed_data_Map_conf_low_4(y,x);
                pre_processed_data_Map_conf_up_1_shift(y,x) = pre_processed_data_Map_conf_up_1(y,x);
                pre_processed_data_Map_conf_up_2_shift(y,x) = pre_processed_data_Map_conf_up_2(y,x);
                pre_processed_data_Map_conf_up_3_shift(y,x) = pre_processed_data_Map_conf_up_3(y,x);
                pre_processed_data_Map_conf_up_4_shift(y,x) = pre_processed_data_Map_conf_up_4(y,x);
                pre_processed_data_map(y,x) = nan;
            end
            
            %transfers the nan values to the other fit Values
            switch app.displayed_fit_parameter
                case 1
                    pre_processed_data_map_1 = pre_processed_data_map;
                    pre_processed_data_map_2 = transfer_nan_between_arrayes(pre_processed_data_map_1,pre_processed_data_map_2);
                    pre_processed_data_map_3 = transfer_nan_between_arrayes(pre_processed_data_map_1,pre_processed_data_map_3);
                    pre_processed_data_map_4 = transfer_nan_between_arrayes(pre_processed_data_map_1,pre_processed_data_map_4);
                    %                     pre_processed_data_map_rmse = transfer_nan_between_arrayes(pre_processed_data_map_1,pre_processed_data_map_rmse);
                case 2
                    pre_processed_data_map_2 = pre_processed_data_map;
                    pre_processed_data_map_1 = transfer_nan_between_arrayes(pre_processed_data_map_2,pre_processed_data_map_1);
                    pre_processed_data_map_3 = transfer_nan_between_arrayes(pre_processed_data_map_2,pre_processed_data_map_3);
                    pre_processed_data_map_4 = transfer_nan_between_arrayes(pre_processed_data_map_2,pre_processed_data_map_4);
                    %                     pre_processed_data_map_rmse = transfer_nan_between_arrayes(pre_processed_data_map_2,pre_processed_data_map_rmse);
                case 3
                    pre_processed_data_map_3 = pre_processed_data_map;
                    pre_processed_data_map_1 = transfer_nan_between_arrayes(pre_processed_data_map_3,pre_processed_data_map_1);
                    pre_processed_data_map_2 = transfer_nan_between_arrayes(pre_processed_data_map_3,pre_processed_data_map_2);
                    pre_processed_data_map_4 = transfer_nan_between_arrayes(pre_processed_data_map_3,pre_processed_data_map_4);
                    %                     pre_processed_data_map_rmse = transfer_nan_between_arrayes(pre_processed_data_map_3,pre_processed_data_map_rmse);
                case 4
                    pre_processed_data_map_4 = pre_processed_data_map;
                    pre_processed_data_map_1 = transfer_nan_between_arrayes(pre_processed_data_map_4,pre_processed_data_map_1);
                    pre_processed_data_map_2 = transfer_nan_between_arrayes(pre_processed_data_map_4,pre_processed_data_map_2);
                    pre_processed_data_map_3 = transfer_nan_between_arrayes(pre_processed_data_map_4,pre_processed_data_map_3);
                    %                     pre_processed_data_map_rmse = transfer_nan_between_arrayes(pre_processed_data_map_3,pre_processed_data_map_rmse);
            end
            
            %reset points_for_delt
            app.points_for_delt = [];
            
            %overwrite data
            app.Fit_Data_Map_temp(t,p,:,:,1,app.displayed_peak) = pre_processed_data_map_1(:,:);
            app.Fit_Data_Map_temp(t,p,:,:,2,app.displayed_peak) = pre_processed_data_map_2(:,:);
            app.Fit_Data_Map_temp(t,p,:,:,3,app.displayed_peak) = pre_processed_data_map_3(:,:);
            app.Fit_Data_Map_temp(t,p,:,:,4,app.displayed_peak) = pre_processed_data_map_4(:,:);
            %                     app.Fit_rmse_temp(t,p,:,:,app.displayed_peak) = pre_processed_data_map_rmse(:,:);
            
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,1,app.displayed_peak) = pre_processed_data_Map_conf_low_1(:,:);
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,2,app.displayed_peak) = pre_processed_data_Map_conf_low_2(:,:);
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,3,app.displayed_peak) = pre_processed_data_Map_conf_low_3(:,:);
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,4,app.displayed_peak) = pre_processed_data_Map_conf_low_4(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,1,app.displayed_peak) = pre_processed_data_Map_conf_up_1(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,2,app.displayed_peak) = pre_processed_data_Map_conf_up_2(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,3,app.displayed_peak) = pre_processed_data_Map_conf_up_3(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,4,app.displayed_peak) = pre_processed_data_Map_conf_up_4(:,:);
            
            app.Fit_Data_Map_temp(t,p,:,:,1,shift_peak) = pre_processed_data_map_1_shift(:,:);
            app.Fit_Data_Map_temp(t,p,:,:,2,shift_peak) = pre_processed_data_map_2_shift(:,:);
            app.Fit_Data_Map_temp(t,p,:,:,3,shift_peak) = pre_processed_data_map_3_shift(:,:);
            app.Fit_Data_Map_temp(t,p,:,:,4,shift_peak) = pre_processed_data_map_4_shift(:,:);
            %                     app.Fit_rmse_temp(t,p,:,:,shift_peak) = pre_processed_data_map_rmse(:,:);
            
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,1,shift_peak) = pre_processed_data_Map_conf_low_1_shift(:,:);
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,2,shift_peak) = pre_processed_data_Map_conf_low_2_shift(:,:);
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,3,shift_peak) = pre_processed_data_Map_conf_low_3_shift(:,:);
            app.Fit_Data_Map_conf_low_temp(t,p,:,:,4,shift_peak) = pre_processed_data_Map_conf_low_4_shift(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,1,shift_peak) = pre_processed_data_Map_conf_up_1_shift(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,2,shift_peak) = pre_processed_data_Map_conf_up_2_shift(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,3,shift_peak) = pre_processed_data_Map_conf_up_3_shift(:,:);
            app.Fit_Data_Map_conf_up_temp(t,p,:,:,4,shift_peak) = pre_processed_data_Map_conf_up_4_shift(:,:);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            
            %gets the correct c label
            if app.displayed_fit_parameter == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter == 3
                label{4} = 'FWHM (eV)';
            elseif app.displayed_fit_parameter == 4
                label{4} = 'Eta';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %displayes the map
            [app.last_fit_map,app.c_fit_map] = Display_Map(app.Map6,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,...
                pre_processed_data_map,app.MapColorstepsEditField_6.Value,app.map_typ_fit_data,label,app.MapMinCountsEditField_6.Value,...
                app.MapMaxCountsEditField_6.Value,app.ContourCheckBox_6.Value,app.contour_lines,app.contourtext,0);
            
            %display histogram
            update_histogram(app.Spectrum8_2,pre_processed_data_map,app.bin,app.show_histogram_statistics);
        end

        % Value changed function: locktoareaCheckBox_5
        function locktoareaCheckBox_5ValueChanged(app, event)
            if app.locktoareaCheckBox_5.Value
                prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                dlgtitle = 'Input area';
                dims = [1 35];
                definput = {'38','38','10','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                % Handle response
                if isempty(answer)
                    app.locktoareaCheckBox_5.Value = 0;
                    return
                else
                    app.area_dim_5(1) = str2num(answer{1});
                    app.area_dim_5(2) = str2num(answer{2});
                    app.area_dim_5(3) = str2num(answer{3});
                    app.area_dim_5(4) = str2num(answer{4});
                end
            else
                app.area_dim_5 = [];
            end
            x_minValueChanged(app, event);
        end

        % Button pushed function: compare_two_peaksButton
        function compare_two_peaksButtonPushed(app, event)
            prompt = {'Enter fit 1:','Enter peak 1:','Enter fit 2:','Enter peak 2:'};
            dlgtitle = 'Input peaks to compare';
            dims = [1 35];
            definput = {'1','1','1','2'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Handle response
            if isempty(answer)
                return
            else
                fit_1 = str2num(answer{1});
                peak_1 = str2num(answer{2});
                fit_2 = str2num(answer{3});
                peak_2 = str2num(answer{4});
            end
            
            [data_map_1,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,fit_1,peak_1,app.displayed_fit_parameter,app.n_fitparameter);
            [data_map_2,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,fit_2,peak_2,app.displayed_fit_parameter,app.n_fitparameter);
            
            %gets only that values where boath peaks have values
            data_map_2 = transfer_nan_between_arrayes(data_map_1,data_map_2);
            data_map_1 = transfer_nan_between_arrayes(data_map_2,data_map_1);
            
            if app.displayed_fit_parameter == 2
                if app.display_peak_as_wavelengthCheckBox.Value
                    data_map_1 = converter.energy_wavelength(data_map_1);
                    data_map_2 = converter.energy_wavelength(data_map_2);
                end
            end
            
            %gets the diference between both maps
            pre_processed_data_map = abs(data_map_1-data_map_2);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            
            %gets the correct c label
            if app.displayed_fit_parameter == 2
                label{4} = 'Wavelength difference (nm)';
            elseif app.displayed_fit_parameter == 3
                label{4} = 'FWHM difference (eV)';
            elseif app.displayed_fit_parameter == 4
                label{4} = 'Eta';
            else
                label{4} = 'Intensity difference (Counts/s)';
            end
            
            %displayes the map
            [app.last_fit_map,app.c_fit_map] = Display_Map(app.Map6,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,...
                pre_processed_data_map,app.MapColorstepsEditField_6.Value,app.map_typ_fit_data,label,app.MapMinCountsEditField_6.Value,...
                app.MapMaxCountsEditField_6.Value,app.ContourCheckBox_6.Value,app.contour_lines,app.contourtext,0);
            
            %display histogram
            update_histogram(app.Spectrum8_2,pre_processed_data_map,app.bin,app.show_histogram_statistics);
            
            %saves the data to the copy data
            app.histogramm_data = pre_processed_data_map(:);
        end

        % Menu selected function: map_colorMenu_1
        function map_colorMenu_1Selected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list_data,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list_data = idx;
                app.map_typ_data = list{idx};
            end
            show_map(app);
        end

        % Menu selected function: map_colorMenu_2
        function map_colorMenu_2Selected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list_fit_data,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list_fit_data = idx;
                app.map_typ_fit_data = list{idx};
            end
            update_fit_map(app);
        end

        % Menu selected function: mapMenu_2
        function mapMenu_2Selected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map,ax, 'colorbar', app.c_map);
        end

        % Menu selected function: fit_dataMenu_2
        function fit_dataMenu_2Selected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_fit_map,ax, 'colorbar', app.c_fit_map);
        end

        % Menu selected function: spectrumMenu
        function spectrumMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.Spectrum5,ax);
        end

        % Menu selected function: first_derivativeMenu
        function first_derivativeMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.Spectrum6,ax);
        end

        % Menu selected function: second_derivativeMenu
        function second_derivativeMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.Spectrum7,ax);
        end

        % Menu selected function: fit_graphMenu
        function fit_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.Spectrum8,ax);
        end

        % Menu selected function: fit_histogrammMenu
        function fit_histogrammMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.Spectrum8_2,ax);
        end

        % Value changed function: changingvalue1_2Spinner, 
        % changingvalue2_2Spinner, changingvalue3_2Spinner, 
        % changingvalue4_2Spinner
        function changingvalue1_2SpinnerValueChanged(app, event)
            hold(app.Map6,"on")
            switch app.DisplaydataalongvalueDropDown.Value
                case 'changing value 1'
                    delete(app.line_handle);
                    delete(app.cross_handle);
                    app.line_handle = line(app.Map6,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.changingvalue2_2Spinner.Value app.changingvalue2_2Spinner.Value],'color','g','LineStyle','--', 'LineWidth', 2);
                case 'changing value 2'
                    delete(app.line_handle);
                    delete(app.cross_handle);
                    app.line_handle = line(app.Map6,[app.changingvalue1_2Spinner.Value app.changingvalue1_2Spinner.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);
                case 'changing value 3'
                    delete(app.line_handle);
                case 'changing value 4'
                    delete(app.line_handle);
            end
            
            if app.show_sigle_fitCheckBox.Value
                delete(app.cross_handle);
                delete(app.line_handle);
                app.cross_handle = plot(app.Map6,app.changingvalue1_2Spinner.Value,app.changingvalue2_2Spinner.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
                
                %find the current changing value indexes
                x = find_index(app.PLMap_Data.X_vector,app.changingvalue1_2Spinner.Value,1);
                y = find_index(app.PLMap_Data.Y_vector,app.changingvalue2_2Spinner.Value,1);
                p = find_index(app.PLMap_Data.Power,app.changingvalue3_2Spinner.Value,1);
                app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
                app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
                app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
                t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_2Spinner.Value,1);
                app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
                app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
                app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
                
                if ~isempty(app.Fit_Data_Map{app.displayed_fit,t,p,y,x})
                    fit_formula = formula(app.Fit_Data_Map{app.displayed_fit,t,p,y,x});
                else
                    f = errordlg('No fit at this position','No fit error');
                    return
                end
                
                [parameter,~] = get_fit_function_model(fit_formula);
                
                if parameter == 3
                    fit_mode = 'gauss';
                else
                    fit_mode = 'pseudo-voigt';
                end
                
                %gets the spectrum from the data array
                spectrum = get_spectrum_from_data_map(app.PLMap_Data.data_array,t,p,y,x);
                x_vector = app.PLMap_Data.Wavelength;
                
                % gets the single fit curfs
                [fit_spectrum,~] = Display_Fit(app.Fit_Data_Map(app.displayed_fit,t,p,y,x),fit_mode,x_vector,app.SweepDropDown.Value);
                
                %displays the spectrum
                cla(app.Spectrum8)
                hold(app.Spectrum8,'on')
                plot(app.Spectrum8,x_vector,spectrum,'*');
                
                %plots all fit spectra
                if isempty(fit_spectrum) == 0
                    %display the fits
                    n = size(fit_spectrum);
                    for i = 1 : n(1)
                        if i == 1
                            plot(app.Spectrum8,x_vector,fit_spectrum(i,:),'m');
                        else
                            plot(app.Spectrum8,x_vector,fit_spectrum(i,:),'g');
                        end
                    end
                end
                
                hold(app.Spectrum8,'off')
                
                %sets the range of axis to display the full range
                app.Spectrum8.XLim = [-inf inf];
                app.Spectrum8.YLim = [-inf inf];
            else
                update_fit_gradient(app)
            end
            hold(app.Map6,"off")
        end

        % Value changed function: DisplayedPeakDropDown_2
        function DisplayedPeakDropDown_2ValueChanged(app, event)
            %changes the displayed peak
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_2.Value);
            
            app.DisplayedPeakDropDown_1.Value = app.DisplayedPeakDropDown_2.Value;
            app.DisplayedPeakDropDown_3.Value = app.DisplayedPeakDropDown_2.Value;
            app.DisplayedPeakDropDown_4.Value = app.DisplayedPeakDropDown_2.Value;
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: DisplayedValueDropDown_2
        function DisplayedValueDropDown_2ValueChanged(app, event)
            app.displayerrorbarsCheckBox.Enable = 'on';
            
            switch app.DisplayedValueDropDown_2.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
                    app.displayerrorbarsCheckBox.Value = 0;
                    app.displayerrorbarsCheckBox.Enable = 'off';
            end
            
            app.DisplayedValueDropDown_1.Value = app.DisplayedValueDropDown_2.Value;
            app.DisplayedValueDropDown_3.Value = app.DisplayedValueDropDown_2.Value;
            app.DisplayedValueDropDown_4.Value = app.DisplayedValueDropDown_2.Value;
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: DisplayedPeakDropDown_3
        function DisplayedPeakDropDown_3ValueChanged(app, event)
            %changes the displayed peak
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_3.Value);
            
            app.DisplayedPeakDropDown_1.Value = app.DisplayedPeakDropDown_3.Value;
            app.DisplayedPeakDropDown_2.Value = app.DisplayedPeakDropDown_3.Value;
            app.DisplayedPeakDropDown_4.Value = app.DisplayedPeakDropDown_3.Value;
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: DisplayedValueDropDown_3
        function DisplayedValueDropDown_3ValueChanged(app, event)
            app.displayerrorbarsCheckBox.Enable = 'on';
            
            switch app.DisplayedValueDropDown_3.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
                    app.displayerrorbarsCheckBox.Value = 0;
                    app.displayerrorbarsCheckBox.Enable = 'off';
            end
            
            app.DisplayedValueDropDown_1.Value = app.DisplayedValueDropDown_3.Value;
            app.DisplayedValueDropDown_2.Value = app.DisplayedValueDropDown_3.Value;
            app.DisplayedValueDropDown_4.Value = app.DisplayedValueDropDown_3.Value;
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Menu selected function: fit_graphMenu_2
        function fit_graphMenu_2Selected(app, event)
            Data_Fit = create_Fit_Data(app);
            app_fit_graph(app.PLMap_Data,Data_Fit,'PL_Map');
        end

        % Value changed function: wavelength_energy
        function wavelength_energyValueChanged(app, event)
            switch app.wavelength_energy.Value
                case 'wavelegth'
                    app.SweepDropDown.Value = 'PL_Map';
                    app.Spectrum5.XLabel.String = 'Wavelength [nm]';
                    app.Spectrum6.XLabel.String = 'Wavelength [nm]';
                    app.Spectrum7.XLabel.String = 'Wavelength [nm]';
                    w_min = define_Spinner(app.x_min,app.PLMap_Data.Wavelength,'low');
                    w_max = define_Spinner(app.x_max,app.PLMap_Data.Wavelength,'upper');
                case 'energy'
                    app.SweepDropDown.Value = 'energy';
                    app.Spectrum5.XLabel.String = 'Energy [eV]';
                    app.Spectrum6.XLabel.String = 'Energy [eV]';
                    app.Spectrum7.XLabel.String = 'Energy [eV]';
                    energy = converter.energy_wavelength(app.PLMap_Data.Wavelength);
                    energy_min = converter.energy_wavelength(app.x_min.Value);
                    energy_max = converter.energy_wavelength(app.x_max.Value);
                    w_min = define_Spinner(app.x_min,energy,energy_min);
                    w_max = define_Spinner(app.x_max,energy,energy_max);
            end
            
            %display the spectal Data
            [diff2_x,diff2_spectrum] = display_all(app);
        end

        % Value changed function: reset_dataButton
        function reset_dataButtonValueChanged(app, event)
            app.Fit_Data_Map_temp = app.Fit_Data_Map(:,:,:,:,:,:);
            app.Fit_Data_Map_conf_low_temp = app.Fit_Data_Map_conf_low(:,:,:,:,:,:);
            app.Fit_Data_Map_conf_up_temp = app.Fit_Data_Map_conf_up(:,:,:,:,:,:);
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: locktoareaCheckBox_6
        function locktoareaCheckBox_6ValueChanged(app, event)
            if app.locktoareaCheckBox_6.Value
                prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                dlgtitle = 'Input area';
                dims = [1 35];
                definput = {'38','38','10','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                % Handle response
                if isempty(answer)
                    app.locktoareaCheckBox_6.Value = 0;
                    return
                else
                    app.area_dim_6(1) = str2num(answer{1});
                    app.area_dim_6(2) = str2num(answer{2});
                    app.area_dim_6(3) = str2num(answer{3});
                    app.area_dim_6(4) = str2num(answer{4});
                end
            else
                app.area_dim_6 = [];
            end
            update_fit_map(app)
        end

        % Value changed function: fit_parameterButton
        function fit_parameterButtonValueChanged(app, event)
            app.DialogFitApp = DialogFitApp(app);
            app.fit_parameterButton.Value = 0;
        end

        % Value changed function: excluded_pontsButton
        function excluded_pontsButtonValueChanged(app, event)
            app.DialogExcluededPoints = DialogExcluededPoints(app);
            app.excluded_pontsButton.Value = 0;
        end

        % Callback function: MapRefreshButton_6, MapRefreshButton_7, 
        % display_peak_as_wavelengthCheckBox, k_maxSpinner, 
        % k_minSpinner
        function MapRefreshButton_6Pushed(app, event)
            app.MapMaxCountsEditField_6.Value = inf;
            app.MapMinCountsEditField_6.Value = -inf;
            app.MapColorstepsEditField_6.Value = 100;
            delete(app.line_handle);
            update_fit_map(app)
        end

        % Menu selected function: enableMenu
        function enableMenuSelected(app, event)
            app.show_histogram_statistics = 1;
        end

        % Menu selected function: disableMenu
        function disableMenuSelected(app, event)
            app.show_histogram_statistics = 0;
        end

        % Menu selected function: binsMenu
        function binsMenuSelected(app, event)
            prompt = {'Enter number of bins:'};
            dlgtitle = 'define the number of bins';
            dims = [1 25];
            s = num2str(app.bin,'%u');
            definput = {s};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.bin = ceil(str2num(answer{1}));
        end

        % Button pushed function: save_changesButton, 
        % save_changesButton_2
        function save_changesButtonPushed(app, event)
            app.Fit_Data_Map = app.Fit_Data_Map_temp(:,:,:,:,:,:);
            app.Fit_Data_Map_conf_low = app.Fit_Data_Map_conf_low_temp(:,:,:,:,:,:);
            app.Fit_Data_Map_conf_up = app.Fit_Data_Map_conf_up_temp(:,:,:,:,:,:);
        end

        % Button pushed function: get_parametersButton
        function get_parametersButtonPushed(app, event)
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_1Spinner.Value,1);
            peak = app.atpositionSpinner.Value;
            
            fit_data_parameter = app.fit_parameter{t,p,peak};
            
            app.BaselineDropDown.Value = fit_data_parameter.BaselineDropDown;
            app.start_indexSpinner.Value = fit_data_parameter.start_index;
            app.end_indexSpinner.Value = fit_data_parameter.end_index;
            app.applytovaluesCheckBox.Value = fit_data_parameter.applytovalues;
            app.pointsDropDown_5.Value = fit_data_parameter.points;
            app.start_xSpinner.Value = fit_data_parameter.start_x;
            app.SmoothingDropDown_5.Value = fit_data_parameter.Smoothing_diff1;
            app.WindowsizeSpinner_5.Value = fit_data_parameter.Windowsize_diff1;
            app.sgolaydegreeSpinner_5.Value = fit_data_parameter.sgolaydegree_diff1;
            app.SmoothingDropDown_6.Value = fit_data_parameter.Smoothing_diff2;
            app.WindowsizeSpinner_6.Value = fit_data_parameter.Windowsize_diff2;
            app.sgolaydegreeSpinner_6.Value = fit_data_parameter.sgolaydegree_diff2;
            app.set_parameterCheckBox.Value = fit_data_parameter.setValuesCheckBox;
            app.accuracyEditField.Value = fit_data_parameter.accuracy;
            app.maxiterEditField.Value = fit_data_parameter.maxiter;
            app.NumberofpointsleftSpinner.Value = fit_data_parameter.Numberofpointsleft;
            app.NumberofpointsrightSpinner.Value = fit_data_parameter.Numberofpointsright;
            app.ProminenceEditField.Value = fit_data_parameter.Prominence;
            app.minPeakdistanceSpinner.Value = fit_data_parameter.minPeakdistance;
            app.power_peak_distance.Value = fit_data_parameter.power_peak_distance;
            app.maxpeaksSpinner.Value = fit_data_parameter.maxpeaks;
            app.x_min.Value = fit_data_parameter.x_min;
            app.x_max.Value = fit_data_parameter.x_max;
            app.SweepDropDown.Value = fit_data_parameter.Sweep;
            app.subtractwindowCheckBox.Value = fit_data_parameter.subtractwindow;
            
            x_minValueChanged(app, event);
        end

        % Value changed function: fitDropDown_2
        function fitDropDown_2ValueChanged(app, event)
            app.displayed_fit = str2num(app.fitDropDown_2.Value);
            
            app.fitDropDown_1.Value = app.fitDropDown_2.Value;
            app.fitDropDown_3.Value = app.fitDropDown_2.Value;
            app.fitDropDown_4.Value = app.fitDropDown_2.Value;
            
            %define indeces of power and tamperature
            [~, p] = min(abs(app.PLMap_Data.Power-app.changingvalue3_2Spinner.Value));
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
            [~, t] = min(abs(app.PLMap_Data.Temperature_index-app.changingvalue4_2Spinner.Value));
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            parameter_array(:,:) = app.Fit_Data_function_parameter(app.displayed_fit,t,p,:,:);
            grade_array(:,:) = app.Fit_Data_function_grade(app.displayed_fit,t,p,:,:);
            
            app.n_fitparameter = [];
            app.grade = [];
            app.n_fitparameter = max(parameter_array,[],'all');
            app.grade = max(grade_array,[],'all');
            
            if str2num(app.DisplayedPeakDropDown_1.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            if str2num(app.DisplayedPeakDropDown_2.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            if str2num(app.DisplayedPeakDropDown_3.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            
            app.DisplayedPeakDropDown_1.Items = split(num2str(1:app.grade))';
            app.DisplayedPeakDropDown_2.Items = split(num2str(1:app.grade))';
            app.DisplayedPeakDropDown_3.Items = split(num2str(1:app.grade))';
            
            switch app.n_fitparameter
                case 3
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Position(Height)','Position(FWHM)',...
                        'FWHM(Height)','FWHM(Position)'};
                case 4
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Height(Eta)','Position(Height)','Position(FWHM)','Position(Eta)'...
                        'FWHM(Height)','FWHM(Position)','FWHM(Eta)','Eta(height)','Eta(Position)','Eta(FWHM)'};
            end
            
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_2.Value);
            
            switch app.DisplayedValueDropDown_2.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
                    app.displayerrorbarsCheckBox.Value = 0;
                    app.displayerrorbarsCheckBox.Enable = 'off';
            end
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: fitDropDown_1
        function fitDropDown_1ValueChanged(app, event)
            app.displayed_fit = str2num(app.fitDropDown_1.Value);
            
            app.fitDropDown_2.Value = app.fitDropDown_1.Value;
            app.fitDropDown_3.Value = app.fitDropDown_1.Value;
            app.fitDropDown_4.Value = app.fitDropDown_1.Value;
            
            %define indeces of power and tamperature
            [~, p] = min(abs(app.PLMap_Data.Power-app.changingvalue3_2Spinner.Value));
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
            [~, t] = min(abs(app.PLMap_Data.Temperature_index-app.changingvalue4_2Spinner.Value));
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            parameter_array(:,:) = app.Fit_Data_function_parameter(app.displayed_fit,t,p,:,:);
            grade_array(:,:) = app.Fit_Data_function_grade(app.displayed_fit,t,p,:,:);
            
            app.n_fitparameter = [];
            app.grade = [];
            app.n_fitparameter = max(parameter_array,[],'all');
            app.grade = max(grade_array,[],'all');
            
            if str2num(app.DisplayedPeakDropDown_1.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            if str2num(app.DisplayedPeakDropDown_2.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            if str2num(app.DisplayedPeakDropDown_3.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            
            app.DisplayedPeakDropDown_1.Items = split(num2str(1:app.grade))';
            app.DisplayedPeakDropDown_2.Items = split(num2str(1:app.grade))';
            app.DisplayedPeakDropDown_3.Items = split(num2str(1:app.grade))';
            
            switch app.n_fitparameter
                case 2
                    app.DisplayedValueDropDown_1.Items = {'Height', 'Position'};
                    app.DisplayedValueDropDown_2.Items = {'Height', 'Position'};
                    app.DisplayedValueDropDown_3.Items = {'Height', 'Position'};
                    app.CorrelationsDropDown.Items = {'Height(Position)','Position(Height)'};
                case 3
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Position(Height)','Position(FWHM)',...
                        'FWHM(Height)','FWHM(Position)'};
                case 4
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Height(Eta)','Position(Height)','Position(FWHM)','Position(Eta)'...
                        'FWHM(Height)','FWHM(Position)','FWHM(Eta)','Eta(height)','Eta(Position)','Eta(FWHM)'};
            end
            
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_1.Value);
            
            switch app.DisplayedValueDropDown_1.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
                    app.displayerrorbarsCheckBox.Value = 0;
                    app.displayerrorbarsCheckBox.Enable = 'off';
            end
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: fitDropDown_3
        function fitDropDown_3ValueChanged(app, event)
            app.displayed_fit = str2num(app.fitDropDown_3.Value);
            
            app.fitDropDown_1.Value = app.fitDropDown_3.Value;
            app.fitDropDown_2.Value = app.fitDropDown_3.Value;
            app.fitDropDown_4.Value = app.fitDropDown_3.Value;
            
            %define indeces of power and tamperature
            [~, p] = min(abs(app.PLMap_Data.Power-app.changingvalue3_2Spinner.Value));
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
            [~, t] = min(abs(app.PLMap_Data.Temperature_index-app.changingvalue4_2Spinner.Value));
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            parameter_array(:,:) = app.Fit_Data_function_parameter(app.displayed_fit,t,p,:,:);
            grade_array(:,:) = app.Fit_Data_function_grade(app.displayed_fit,t,p,:,:);
            
            app.n_fitparameter = [];
            app.grade = [];
            app.n_fitparameter = max(parameter_array,[],'all');
            app.grade = max(grade_array,[],'all');
            
            
            if str2num(app.DisplayedPeakDropDown_1.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            if str2num(app.DisplayedPeakDropDown_2.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            if str2num(app.DisplayedPeakDropDown_3.Value)>app.grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            
            app.DisplayedPeakDropDown_1.Items = split(num2str(1:app.grade))';
            app.DisplayedPeakDropDown_2.Items = split(num2str(1:app.grade))';
            app.DisplayedPeakDropDown_3.Items = split(num2str(1:app.grade))';
            
            switch app.n_fitparameter
                case 3
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM'};
                    app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Position(Height)','Position(FWHM)',...
                        'FWHM(Height)','FWHM(Position)'};
                case 4
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.DisplayedValueDropDown_3.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
                    app.CorrelationsDropDown.Items = {'Height(FWHM)','Height(Position)','Height(Eta)','Position(Height)','Position(FWHM)','Position(Eta)'...
                        'FWHM(Height)','FWHM(Position)','FWHM(Eta)','Eta(height)','Eta(Position)','Eta(FWHM)'};
            end
            
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_3.Value);
            
            switch app.DisplayedValueDropDown_3.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
                    app.displayerrorbarsCheckBox.Value = 0;
                    app.displayerrorbarsCheckBox.Enable = 'off';
            end
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: show_sigle_fitCheckBox
        function show_sigle_fitCheckBoxValueChanged(app, event)
            app.changingvalue1_2Spinner.BackgroundColor = [1 1 1];
            app.changingvalue2_2Spinner.BackgroundColor = [1 1 1];
            app.changingvalue3_2Spinner.BackgroundColor = [1 1 1];
            app.changingvalue4_2Spinner.BackgroundColor = [1 1 1];
            
            changingvalue1_2SpinnerValueChanged(app, event);
        end

        % Button pushed function: show_sample_infoButton
        function show_sample_infoButtonPushed(app, event)
            power_index = find_index(app.PLMap_Data.Power,app.changingvalue3_1Spinner.Value,1);
            temperature = meta_data_text_average_tamperature(app.PLMap_Data.Temperature_array,app.changingvalue4_1Spinner.Value,power_index);
            app.FitValuesTextArea.Value = {app.PLMap_Data.Sample;app.PLMap_Data.Date;app.PLMap_Data.Measurment_time;temperature};
        end

        % Menu selected function: fit_histogrammMenu_2
        function fit_histogrammMenu_2Selected(app, event)
            copyed_array = mat2clip(app.histogramm_data, '\t');
        end

        % Value changed function: changingvalue3_4Spinner
        function changingvalue3_4SpinnerValueChanged(app, event)
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_4Spinner.Value,1);
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: changingvalue3_3Spinner
        function changingvalue3_3SpinnerValueChanged(app, event)
            p = find_index(app.PLMap_Data.Power,app.changingvalue3_3Spinner.Value,1);
            app.changingvalue3_2Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_3Spinner.Value = app.PLMap_Data.Power(p);
            app.changingvalue3_4Spinner.Value = app.PLMap_Data.Power(p);
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: changingvalue4_4Spinner
        function changingvalue4_4SpinnerValueChanged(app, event)
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_4Spinner.Value,1);
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: changingvalue4_3Spinner
        function changingvalue4_3SpinnerValueChanged(app, event)
            t = find_index(app.PLMap_Data.Temperature_index,app.changingvalue4_3Spinner.Value,1);
            app.changingvalue4_2Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_3Spinner.Value = app.PLMap_Data.Temperature_index(t);
            app.changingvalue4_4Spinner.Value = app.PLMap_Data.Temperature_index(t);
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Button pushed function: peak_position_2_diffButton
        function peak_position_2_diffButtonPushed(app, event)
            [diff2_x,diff2_spectrum] = display_all(app);
            
            %interpolate the data
            steps = (diff2_x(2)-diff2_x(1))/100;
            xq = [diff2_x(1) : steps : max(diff2_x)];
            yq = makima(diff2_x,diff2_spectrum,xq);
            
            %find the minima in the interpolated data
            [Index_of_peak,~,peak_x_temp] = find_minima(app,xq',yq');
            test = peak_x_temp(Index_of_peak);
            
            %output for the peakposition
            if isempty(Index_of_peak) == 0
                %displayes the prominance in the text area
                app.FitValuesTextArea.Value = char(join(['the peak positions are: ',num2str(peak_x_temp(Index_of_peak)','%.5e\t')]));
            end
        end

        % Menu selected function: enableMenu_2
        function enableMenu_2Selected(app, event)
            app.residual_data = 1;
        end

        % Menu selected function: disableMenu_2
        function disableMenu_2Selected(app, event)
            app.residual_data = 0;
        end

        % Menu selected function: enableMenu_3
        function enableMenu_3Selected(app, event)
            app.residual_1_diff = 1;
        end

        % Menu selected function: disableMenu_3
        function disableMenu_3Selected(app, event)
            app.residual_1_diff = 0;
        end

        % Value changed function: X1EditField, X2EditField, 
        % Y1EditField, Y2EditField, steps_widthSpinner
        function X1EditFieldValueChanged(app, event)
            %defines the start positions for the line
            start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
            
            [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                0,app.start_indexSpinner_2,app.end_indexSpinner_2,app.index_stepsSpinner.Value,app.startingSwitch.Value,...
                app.get_line_distanceCheckBox.Value);
            
            [pre_processed_data_map,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,app.displayed_fit_parameter,app.n_fitparameter);
            
            for i = 1 : length(app.x_free_line)
                ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                data_on_line(i) = pre_processed_data_map(iy,ix);
            end
            
            plot(app.Spectrum8,Position,data_on_line,'*');
            %draws the waferadges in the Graph
            delete(app.fit_line_handle);
            app.fit_line_handle = drawpolyline(app.Map6,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
        end

        % Value changed function: set_lineButton
        function set_lineButtonValueChanged(app, event)
            %gets the second point
            [roi, x, y] = select_data.selectDataLine(app.Map6,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,app.snapp_to_gridCheckBox.Value);
            
            %delets the points von the map an sets the value
            delete(roi);
            app.X1EditField.Value = x(1,1);
            app.X2EditField.Value = x(2,1);
            app.Y1EditField.Value = y(1,1);
            app.Y2EditField.Value = y(2,1);
            
            %displays the data
            X1EditFieldValueChanged(app, event)
            
            %reset the button
            app.set_lineButton.Value = 0;
        end

        % Value changed function: set_line_from_centerButton
        function set_line_from_centerButtonValueChanged(app, event)
            %sets the start value
            start_point = images.roi.Point(app.Map6,'Position',[37.5 37]);
            
            %gets the second point
            [roi, x, y] = select_data.selectDataPoint(app.Map6,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,app.snapp_to_gridCheckBox.Value);
            
            %delets the points von the map an sets the value
            delete(roi);
            delete(start_point);
            app.X1EditField.Value = 37.5;
            app.X2EditField.Value = x(1,1);
            app.Y1EditField.Value = 37;
            app.Y2EditField.Value = y(1,1);
            
            %get the points on the line an drwas the spectra
            X1EditFieldValueChanged(app, event);
            
            %reset the button
            app.set_line_from_centerButton.Value = 0;
        end

        % Value changed function: Switch_7, end_indexSpinner_2, 
        % get_line_distanceCheckBox, index_stepsSpinner, 
        % snapp_to_gridCheckBox, start_indexSpinner_2, 
        % startingSwitch
        function start_indexSpinner_2ValueChanged(app, event)
            start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
            
            [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                1,app.start_indexSpinner_2,app.end_indexSpinner_2,app.index_stepsSpinner.Value,app.startingSwitch.Value,...
                app.get_line_distanceCheckBox.Value);
            
            [pre_processed_data_map,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.changingvalue3_2Spinner.Value,app.changingvalue4_2Spinner.Value,app.displayed_fit,app.displayed_peak,app.displayed_fit_parameter,app.n_fitparameter);
            
            for i = 1 : length(app.x_free_line)
                ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                data_on_line(i) = pre_processed_data_map(iy,ix);
            end
            
            plot(app.Spectrum8,Position,data_on_line,'*');
            
            %draws the waferadges in the Graph
            delete(app.fit_line_handle);
            app.fit_line_handle = drawpolyline(app.Map6,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
        end

        % Menu selected function: spectrum_dataMenu
        function spectrum_dataMenuSelected(app, event)
            save_array = [app.x_vector,double(app.spectrum'),double(app.fit_spectrum')];
            copyed_array = mat2clip(save_array, '\t');
        end

        % Callback function: Correlations_colorDropDown, 
        % Correlations_x_axisDropDown, Correlations_y_axisDropDown, 
        % Correlations_z_axisDropDown, HistogramButtonGroup, 
        % RefreshButton, d1Spinner, d2Spinner, 
        % only_points_in_rangeCheckBox, xSpinner, ySpinner
        function Correlations_x_axisDropDownValueChanged(app, event)
            %get all rawe data in do a clean start
            cla(app.Spectrum8,'reset')
            [height,FWHM,peakpos,Eta,rmse] = build_correlation(app);
            FWHM = FWHM.*1000;
            y_err_neg = [];
            y_err_pos = [];
            app.data_map = [];
            delete(app.cross_handle_fit);
            delete(app.circle1_handle);
            delete(app.circle2_handle);
            d = [];
            
            %convert energy in wavelength
            if app.display_peak_as_wavelengthCheckBox.Value
                peakpos = converter.energy_wavelength(peakpos);
                position_label = 'Wavelength [nm]';
            else
                position_label = 'Energy [eV]';
            end
            
            %get all coordinats to the grid points
            j = 0;
            for i = 1 : length(app.PLMap_Data.X_vector)
                for ii = 1 : length(app.PLMap_Data.Y_vector)
                    j = j+1;
                    v(j,1) = app.PLMap_Data.X_vector(i);
                    v(j,2) = app.PLMap_Data.Y_vector(ii);
                end
            end
            
            %get x data
            switch app.Correlations_x_axisDropDown.Value
                case 'Height'
                    x = height;
                    x_label = 'Intensity [counts/s]';
                case 'Position'
                    x = peakpos;
                    x_label = position_label;
                case 'FWHM'
                    x = FWHM;
                    x_label = 'FWHM [meV]';
                case 'Gaussian_cont'
                    x = Eta;
                    x_label = 'Eta';
                case 'Distance_to_position'
                    x = sqrt((v(:,1)-app.xSpinner.Value).^2+(v(:,2)-app.ySpinner.Value).^2);
                    x_label = 'Distance to Position [mm]';
                case 'rmse'
                    x = rmse;
                    x_label = 'rmse';
            end
            
            %get y data
            switch app.Correlations_y_axisDropDown.Value
                case 'Height'
                    y = height;
                    y_label = 'Intensity [counts/s]';
                case 'Position'
                    y = peakpos;
                    y_label = position_label;
                case 'FWHM'
                    y = FWHM;
                    y_label = 'FWHM [meV]';
                case 'Gaussian_cont'
                    y = Eta;
                    y_label = 'Eta';
                case 'Distance_to_position'
                    y = sqrt((v(:,1)-app.xSpinner.Value).^2+(v(:,2)-app.ySpinner.Value).^2);
                    y_label = 'Distance to Position [mm]';
                case 'rmse'
                    y = rmse;
                    y_label = 'rmse';
            end
            
            %get z data
            switch app.Correlations_z_axisDropDown.Value
                case 'Height'
                    z = height;
                    z_label = 'Intensity [counts/s]';
                case 'Position'
                    z = peakpos;
                    z_label = position_label;
                case 'FWHM'
                    z = FWHM;
                    z_label = 'FWHM [meV]';
                case 'Gaussian_cont'
                    z = Eta;
                    z_label = 'Eta';
                case 'Distance_to_position'
                    z = sqrt((v(:,1)-app.xSpinner.Value).^2+(v(:,2)-app.ySpinner.Value).^2);
                    z_label = 'Distance to Position [mm]';
                case 'rmse'
                    z = rmse;
                    z_label = 'rmse';
            end
            
            %get color data
            switch app.Correlations_colorDropDown.Value
                case 'Height'
                    c_data = height;
                    color_label = 'Intensity [counts/s]';
                case 'Position'
                    c_data = peakpos;
                    color_label = position_label;
                case 'FWHM'
                    c_data = FWHM;
                    color_label = 'FWHM [meV]';
                case 'Gaussian_cont'
                    c_data = Eta;
                    color_label = 'Eta';
                case 'Distance_to_position'
                    c_data = sqrt((v(:,1)-app.xSpinner.Value).^2+(v(:,2)-app.ySpinner.Value).^2);
                    c_data(isnan(x)) = nan;
                    c_data(isnan(y)) = nan;
                    color_label = 'Distance to Position [mm]';
                case 'rmse'
                    c_data = rmse;
                    color_label = 'rmse';
            end
            
            %only use points between d1 and d1
            if app.only_points_in_rangeCheckBox.Value
                d = sqrt((v(:,1)-app.xSpinner.Value).^2+(v(:,2)-app.ySpinner.Value).^2);
                d(isnan(x)) = nan;
                d(isnan(y)) = nan;
                d(d<=app.d1Spinner.Value) = nan;
                d(d>=app.d2Spinner.Value) = nan;
                
                x(isnan(d)) = nan;
                y(isnan(d)) = nan;
                switch app.Correlations_z_axisDropDown.Value
                    case 'none'
                    otherwise
                        z(isnan(d)) = nan;
                end
                switch app.Correlations_colorDropDown.Value
                    case 'none'
                    otherwise
                        c_data(isnan(d)) = nan;
                end
            end
            update_fit_map(app);
            
            %plot used data in histogramm
            switch app.HistogramButtonGroup.SelectedObject.Text
                case 'none'
                    %displays old data
                    MapRefreshButton_6Pushed(app, event);
                otherwise
                    switch app.HistogramButtonGroup.SelectedObject.Text
                        case 'Height'
                            h_data = height;
                            h_label = 'Intensity [counts/s]';
                        case 'Position'
                            h_data = peakpos;
                            h_label = position_label;
                        case 'FWHM'
                            h_data = FWHM;
                            h_label = 'FWHM [meV]';
                        case 'Gaussian_cont'
                            h_data = Eta;
                            h_label = 'Eta';
                        case 'Distance_to_position'
                            h_data = sqrt((v(:,1)-app.xSpinner.Value).^2+(v(:,2)-app.ySpinner.Value).^2);
                            h_data(isnan(x)) = nan;
                            h_data(isnan(y)) = nan;
                            h_label = 'Distance to Position [mm]';
                        case 'rmse'
                            h_data = rmse;
                            h_label = 'rmse';
                    end
                    if ~isempty(d)
                        h_data(isnan(d)) = nan;
                    end
                    update_histogram(app.Spectrum8_2,h_data,app.bin,app.show_histogram_statistics);
                    xlabel(app.Spectrum8_2, h_label);
                    
                    %saves the data to the copy data
                    app.histogramm_data = h_data(:);
            end
            
            
            hold(app.Map6,'on')
            %draws Marker in the Graph
            app.cross_handle_fit = plot(app.Map6,app.xSpinner.Value,app.ySpinner.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            
            if app.only_points_in_rangeCheckBox.Value
                %Display circles with radius d1 and d2
                if app.d1Spinner.Value > 0
                    app.circle1_handle = draw_circle(app.Map6,app.xSpinner.Value,app.ySpinner.Value,app.d1Spinner.Value);
                end
                app.circle2_handle = draw_circle(app.Map6,app.xSpinner.Value,app.ySpinner.Value,app.d2Spinner.Value);
            end
            hold(app.Map6,'off')
            
            %Plot the correlation
            switch app.Correlations_z_axisDropDown.Value
                case 'none'
                    switch app.Correlations_colorDropDown.Value
                        case 'none'
                            scatter(app.Spectrum8,x,y,[],'filled')
                            app.c_correlation = [];
                        otherwise
                            scatter(app.Spectrum8,x,y,[],c_data,'filled')
                            %changes the colormap wie the number of colors in the ()
                            colors = colormap_styles.compute_colarmap(app.map_typ_correlation_data,100);
                            colormap(app.Spectrum8,colors);
                            app.c_correlation = colorbar(app.Spectrum8);
                            %caxis(app.Spectrum8,[lower_count_limit,upper_count_limit]);
                            app.c_correlation.Label.String = color_label;
                    end
                    xlabel(app.Spectrum8, x_label);
                    ylabel(app.Spectrum8, y_label);
                otherwise
                    switch app.Correlations_colorDropDown.Value
                        case 'none'
                            scatter3(app.Spectrum8,x,y,z,[],'filled')
                            app.c_correlation = [];
                        otherwise
                            scatter3(app.Spectrum8,x,y,z,[],c_data,'filled')
                            %changes the colormap wie the number of colors in the ()
                            colors = colormap_styles.compute_colarmap(app.map_typ_correlation_data,100);
                            colormap(app.Spectrum8,colors);
                            app.c_correlation = colorbar(app.Spectrum8);
                            %caxis(app.Spectrum8,[lower_count_limit,upper_count_limit]);
                            app.c_correlation.Label.String = color_label;
                    end
                    xlabel(app.Spectrum8, x_label);
                    ylabel(app.Spectrum8, y_label);
                    zlabel(app.Spectrum8, z_label);
            end
        end

        % Menu selected function: map_colorMenu_3
        function map_colorMenu_3Selected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list_correlation_data,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list_correlation_data = idx;
                app.map_typ_correlation_data = list{idx};
            end
        end

        % Menu selected function: correlation_graphMenu
        function correlation_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.Spectrum8,ax, 'colorbar', app.c_correlation);
        end

        % Value changed function: fitDropDown_4
        function fitDropDown_4ValueChanged(app, event)
            app.displayed_fit = str2num(app.fitDropDown_4.Value);
            
            app.fitDropDown_1.Value = app.fitDropDown_4.Value;
            app.fitDropDown_2.Value = app.fitDropDown_4.Value;
            app.fitDropDown_3.Value = app.fitDropDown_4.Value;
            
            fitDropDown_1ValueChanged(app, event)
        end

        % Value changed function: DisplayedPeakDropDown_4
        function DisplayedPeakDropDown_4ValueChanged(app, event)
            %changes the displayed peak
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown_4.Value);
            
            app.DisplayedPeakDropDown_1.Value = app.DisplayedPeakDropDown_4.Value;
            app.DisplayedPeakDropDown_2.Value = app.DisplayedPeakDropDown_4.Value;
            app.DisplayedPeakDropDown_3.Value = app.DisplayedPeakDropDown_4.Value;
            
            %displays in the correct map
            MapRefreshButton_6Pushed(app, event);
        end

        % Value changed function: DisplayedValueDropDown_4
        function DisplayedValueDropDown_4ValueChanged(app, event)
            app.DisplayedValueDropDown_2.Value = app.DisplayedValueDropDown_4.Value;
            app.DisplayedValueDropDown_3.Value = app.DisplayedValueDropDown_4.Value;
            app.DisplayedValueDropDown_1.Value = app.DisplayedValueDropDown_4.Value;
           
            DisplayedValueDropDown_1ValueChanged(app, event)
        end

        % Value changed function: 
        % display_peak_as_wavelengthCheckBox_2
        function display_peak_as_wavelengthCheckBox_2ValueChanged(app, event)
            app.display_peak_as_wavelengthCheckBox.Value = app.display_peak_as_wavelengthCheckBox_2.Value;
            MapRefreshButton_6Pushed(app, event)
        end

        % Menu selected function: build_correlation_lineMenu
        function build_correlation_lineMenuSelected(app, event)
            delte_neg = abs(app.data_map(:,2)-app.y_err_neg');
            delte_pos = abs(app.data_map(:,2)-app.y_err_pos');
            if app.display_peak_as_wavelengthCheckBox.Value
                save_array = [app.data_map(:,1),app.y_err_neg',app.data_map(:,2),app.y_err_pos',delte_neg,delte_pos];
            else
                save_array = [app.data_map(:,1),app.y_err_pos',app.data_map(:,2),app.y_err_neg',delte_pos,delte_neg];
            end
            save_array = num2cell(save_array);
            data_label_columns = {'position','y_value_error_max','y_value','y_value_error_min','y_error_delta_max','y_error_delta_min'};
            save_array = [data_label_columns; save_array];
            copyed_array = mat2clip(save_array, '\t');
        end

        % Button pushed function: Load_sim_dataButton
        function Load_sim_dataButtonPushed(app, event)
            dim = [];
            
            %gets the path of the data to be imported
            path_fit_data = uigetdir;
            path_fit_data = convertCharsToStrings(path_fit_data);
            path_fit_data = join([path_fit_data,"/"],'');
            path_fit_data = convertStringsToChars(path_fit_data);
            
            %change folder to surch for fit data
            oldFolder = cd(path_fit_data);
            file = ls('*sim_data*');
            
            %imports the data
            if ~isempty(file)
               sim_data = load([path_fit_data, file]);
            end
            
            app.Al_thickness_matrix = sim_data.sim_data.Al_thickness_matrix;
            app.Ga_thickness_matrix = sim_data.sim_data.Ga_thickness_matrix;
            app.In_thickness_matrix = sim_data.sim_data.In_thickness_matrix;
            
            %go to old folder
            cd(oldFolder);
            
            %enable Interface
            app.ML_maxSpinner.Enable = 'on';
            app.ML_maxSpinnerLabel.Enable = 'on';
            app.ML_minSpinner.Enable = 'on';
            app.ML_minSpinnerLabel.Enable = 'on';
            app.ML_contour_stepsEditField.Enable = 'on';
            app.ML_contour_stepsEditFieldLabel.Enable = 'on';
            app.DisplayedLayerDropDown.Enable = 'on';
            app.DisplayedLayerDropDownLabel.Enable = 'on';
            app.DisplayedmaterialDropDown.Enable = 'on';
            app.DisplayedmaterialDropDownLabel.Enable = 'on';
            app.n_contour_linesSpinner.Enable = 'on';
            app.n_contour_linesSpinnerLabel.Enable = 'on';
            app.auto_MLCheckBox.Enable = 'on';
            app.display_typeSwitch.Enable = 'on';
            app.display_modeSwitch.Enable = 'on';
            app.overlay_contourButton.Enable = 'on';
            app.MapRefreshButton_7.Enable = 'on';
            app.sumCheckBox.Enable = 'on';
            
            %get dimentions
            
            size_of_thickness = size(app.Ga_thickness_matrix);
            for i = 1:size_of_thickness(1)
                dim{end+1} = num2str(i);
            end
            
            %sets the layer number
            app.DisplayedLayerDropDown.Items = dim;
            app.DisplayedLayerDropDown.Value = num2str(size_of_thickness(1));
        end

        % Callback function: DisplayedLayerDropDown, 
        % DisplayedmaterialDropDown, ML_contour_stepsEditField, 
        % ML_maxSpinner, ML_minSpinner, auto_MLCheckBox, 
        % display_modeSwitch, display_typeSwitch, 
        % n_contour_linesSpinner, overlay_contourButton
        function overlay_contourButtonPushed(app, event)
            temp_x = [];
            x_matrix = [];
            
            %gets witch material to be displayed
            switch app.DisplayedmaterialDropDown.Value
                case 'GaAs'
                    temp_thickness_matrix = app.Ga_thickness_matrix;
                case 'AlAs'
                    temp_thickness_matrix = app.Al_thickness_matrix;
                case 'InAs'
                    temp_thickness_matrix = app.In_thickness_matrix;
                case 'AlGaAs'
                    temp_ga_thickness_matrix = app.Ga_thickness_matrix;
                    temp_al_thickness_matrix = app.Al_thickness_matrix;
                    size_tensor = size(temp_ga_thickness_matrix);
                    temp_thickness_matrix = reshape((temp_ga_thickness_matrix(:)+temp_al_thickness_matrix(:)),size_tensor);
                    
                    temp_x = reshape(temp_al_thickness_matrix./(temp_al_thickness_matrix+temp_ga_thickness_matrix),size_tensor);
                case 'InGaAs'
                    temp_ga_thickness_matrix = app.Ga_thickness_matrix;
                    temp_in_thickness_matrix = app.In_thickness_matrix;
                    size_tensor = size(temp_ga_thickness_matrix);
                    temp_thickness_matrix = reshape((temp_ga_thickness_matrix(:)+temp_in_thickness_matrix(:)),size_tensor);
                    
                    temp_x = reshape(temp_in_thickness_matrix./(temp_in_thickness_matrix+temp_ga_thickness_matrix),size_tensor);
            end
            
            %gets value to be displayed
            if app.sumCheckBox.Value
                thickness_matrix(:,:) = sum(temp_thickness_matrix,1);
                if ~isempty(temp_x)
                    x_matrix(:,:) = sum(temp_x,1);
                end
            else
                j = str2num(app.DisplayedLayerDropDown.Value);
                thickness_matrix(:,:) = temp_thickness_matrix(j,:,:);
                if ~isempty(temp_x)
                    x_matrix(:,:) = temp_x(j,:,:);
                end
            end
            
            switch app.display_typeSwitch.Value
                %dasplaying the composition map
                case 'composition'
                    if ~isempty(x_matrix)
                        
                        %declares current map vor contour_map
                        app.contour_map = x_matrix;
                    end
                case 'thickness'
                    if ~isempty(thickness_matrix)

                        %dasplaying the thickness map
                        switch app.display_modeSwitch.Value
                            case 'nm'
                            case 'ML'
                                %convert nm to ML
                                thickness_matrix = converter.nm_to_ML(app.DisplayedmaterialDropDown.Value,thickness_matrix,x_matrix,300);
                        end
                    end
                    %declares current map vor copy
                    app.contour_map = thickness_matrix;
            end
            
            ML_min = min(app.contour_map,[],'all');
            ML_min_round = round(ML_min,1);
            if ML_min_round > ML_min
                ML_min_round = ML_min_round - 0.1;
            end
            
            ML_max = max(app.contour_map,[],'all');
            ML_max_round = round(ML_max,1);
            if ML_max_round < ML_max
                ML_max_round = ML_max_round + 0.1;
            end
            
            if app.auto_MLCheckBox.Value
                app.ML_minSpinner.Value = ML_min_round;
                app.ML_maxSpinner.Value = ML_max_round;
            else
                if app.ML_minSpinner.Value < ML_min_round
                    app.ML_minSpinner.Value = ML_min_round;
                end
                if app.ML_maxSpinner.Value > ML_max_round
                    app.ML_maxSpinner.Value = ML_max_round;
                end
            end
            % ML_min = round(min(thickness_matrix,[],'all'),2);
            %ML_max = round(max(thickness_matrix,[],'all'),2);
            if app.ML_contour_stepsEditField.Value > 0
                contour_lines_temp = [app.ML_minSpinner.Value : app.ML_contour_stepsEditField.Value : app.ML_maxSpinner.Value];
            else
                contour_lines_temp = app.n_contour_linesSpinner.Value;
            end
            
            update_fit_map(app);
            pre_processed_data_map = app.data_map;
            hold(app.Map6,"on");
            contour(app.Map6,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.contour_map,contour_lines_temp,'k-','ShowText','on');
            hold(app.Map6,"off");
            caxis(app.Map6,([min(pre_processed_data_map,[],'all') max(pre_processed_data_map,[],'all')]));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'hyperspectral analyzer by Marcel Schmidt at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.menuMenu = uimenu(app.UIFigure);
            app.menuMenu.Text = 'menu';
            app.LoadDataMenu = uimenu(app.menuMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Accelerator = 'l';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExportMenu = uimenu(app.menuMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.CopytoclipboardMenu = uimenu(app.menuMenu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Accelerator = 'c';
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.PreferencesMenu = uimenu(app.menuMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Accelerator = 'p';
            app.PreferencesMenu.Text = 'Preferences';
            app.copy_mapMenu = uimenu(app.menuMenu);
            app.copy_mapMenu.Text = 'copy_map';
            app.mapMenu_2 = uimenu(app.copy_mapMenu);
            app.mapMenu_2.MenuSelectedFcn = createCallbackFcn(app, @mapMenu_2Selected, true);
            app.mapMenu_2.Text = 'map';
            app.fit_dataMenu_2 = uimenu(app.copy_mapMenu);
            app.fit_dataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @fit_dataMenu_2Selected, true);
            app.fit_dataMenu_2.Text = 'fit_data';
            app.copy_graphMenu = uimenu(app.menuMenu);
            app.copy_graphMenu.Text = 'copy_graph';
            app.spectrumMenu = uimenu(app.copy_graphMenu);
            app.spectrumMenu.MenuSelectedFcn = createCallbackFcn(app, @spectrumMenuSelected, true);
            app.spectrumMenu.Text = 'spectrum';
            app.first_derivativeMenu = uimenu(app.copy_graphMenu);
            app.first_derivativeMenu.MenuSelectedFcn = createCallbackFcn(app, @first_derivativeMenuSelected, true);
            app.first_derivativeMenu.Text = 'first_derivative';
            app.second_derivativeMenu = uimenu(app.copy_graphMenu);
            app.second_derivativeMenu.MenuSelectedFcn = createCallbackFcn(app, @second_derivativeMenuSelected, true);
            app.second_derivativeMenu.Text = 'second_derivative';
            app.fit_graphMenu = uimenu(app.copy_graphMenu);
            app.fit_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @fit_graphMenuSelected, true);
            app.fit_graphMenu.Text = 'fit_graph';
            app.fit_histogrammMenu = uimenu(app.copy_graphMenu);
            app.fit_histogrammMenu.MenuSelectedFcn = createCallbackFcn(app, @fit_histogrammMenuSelected, true);
            app.fit_histogrammMenu.Text = 'fit_histogramm';
            app.correlation_graphMenu = uimenu(app.copy_graphMenu);
            app.correlation_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @correlation_graphMenuSelected, true);
            app.correlation_graphMenu.Text = 'correlation_graph';
            app.coppy_dataMenu = uimenu(app.menuMenu);
            app.coppy_dataMenu.Text = 'coppy_data';
            app.spectrum_dataMenu = uimenu(app.coppy_dataMenu);
            app.spectrum_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @spectrum_dataMenuSelected, true);
            app.spectrum_dataMenu.Text = 'spectrum_data';
            app.fit_histogrammMenu_2 = uimenu(app.coppy_dataMenu);
            app.fit_histogrammMenu_2.MenuSelectedFcn = createCallbackFcn(app, @fit_histogrammMenu_2Selected, true);
            app.fit_histogrammMenu_2.Text = 'fit_histogramm';
            app.build_correlation_lineMenu = uimenu(app.coppy_dataMenu);
            app.build_correlation_lineMenu.MenuSelectedFcn = createCallbackFcn(app, @build_correlation_lineMenuSelected, true);
            app.build_correlation_lineMenu.Text = 'build_correlation_line';
            app.send_data_toMenu = uimenu(app.UIFigure);
            app.send_data_toMenu.Text = 'send_data_to';
            app.single_graphMenu = uimenu(app.send_data_toMenu);
            app.single_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @single_graphMenuSelected, true);
            app.single_graphMenu.Text = 'single_graph';
            app.multi_graph_1Menu = uimenu(app.send_data_toMenu);
            app.multi_graph_1Menu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_1MenuSelected, true);
            app.multi_graph_1Menu.Text = 'multi_graph_1';
            app.multi_graph_2Menu = uimenu(app.send_data_toMenu);
            app.multi_graph_2Menu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_2MenuSelected, true);
            app.multi_graph_2Menu.Text = 'multi_graph_2';
            app.multi_graph_bothMenu = uimenu(app.send_data_toMenu);
            app.multi_graph_bothMenu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_bothMenuSelected, true);
            app.multi_graph_bothMenu.Text = 'multi_graph_both';
            app.gradient_graphMenu = uimenu(app.send_data_toMenu);
            app.gradient_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @gradient_graphMenuSelected, true);
            app.gradient_graphMenu.Text = 'gradient_graph';
            app.fit_graphMenu_2 = uimenu(app.send_data_toMenu);
            app.fit_graphMenu_2.MenuSelectedFcn = createCallbackFcn(app, @fit_graphMenu_2Selected, true);
            app.fit_graphMenu_2.Text = 'fit_graph';
            app.growth_simMenu = uimenu(app.send_data_toMenu);
            app.growth_simMenu.MenuSelectedFcn = createCallbackFcn(app, @growth_simMenuSelected, true);
            app.growth_simMenu.Text = 'growth_sim';
            app.data_analysisMenu = uimenu(app.UIFigure);
            app.data_analysisMenu.Text = 'data_ analysis';
            app.residuals_dataMenu = uimenu(app.data_analysisMenu);
            app.residuals_dataMenu.Text = 'residuals_data';
            app.enableMenu_2 = uimenu(app.residuals_dataMenu);
            app.enableMenu_2.MenuSelectedFcn = createCallbackFcn(app, @enableMenu_2Selected, true);
            app.enableMenu_2.Text = 'enable';
            app.disableMenu_2 = uimenu(app.residuals_dataMenu);
            app.disableMenu_2.MenuSelectedFcn = createCallbackFcn(app, @disableMenu_2Selected, true);
            app.disableMenu_2.Text = 'disable';
            app.residuals_1_diffMenu = uimenu(app.data_analysisMenu);
            app.residuals_1_diffMenu.Text = 'residuals_1_diff';
            app.enableMenu_3 = uimenu(app.residuals_1_diffMenu);
            app.enableMenu_3.MenuSelectedFcn = createCallbackFcn(app, @enableMenu_3Selected, true);
            app.enableMenu_3.Text = 'enable';
            app.disableMenu_3 = uimenu(app.residuals_1_diffMenu);
            app.disableMenu_3.MenuSelectedFcn = createCallbackFcn(app, @disableMenu_3Selected, true);
            app.disableMenu_3.Text = 'disable';
            app.Fit_dataMenu = uimenu(app.UIFigure);
            app.Fit_dataMenu.Text = 'Fit_data';
            app.choose_regtangleMenu = uimenu(app.Fit_dataMenu);
            app.choose_regtangleMenu.MenuSelectedFcn = createCallbackFcn(app, @choose_regtangleMenuSelected, true);
            app.choose_regtangleMenu.Accelerator = 'a';
            app.choose_regtangleMenu.Text = 'choose_regtangle';
            app.choose_pointMenu = uimenu(app.Fit_dataMenu);
            app.choose_pointMenu.MenuSelectedFcn = createCallbackFcn(app, @choose_pointMenuSelected, true);
            app.choose_pointMenu.Accelerator = 'q';
            app.choose_pointMenu.Text = 'choose_point';
            app.choose_pointsMenu = uimenu(app.Fit_dataMenu);
            app.choose_pointsMenu.MenuSelectedFcn = createCallbackFcn(app, @choose_pointsMenuSelected, true);
            app.choose_pointsMenu.Accelerator = 'W';
            app.choose_pointsMenu.Text = 'choose_points';
            app.delet_pointMenu = uimenu(app.Fit_dataMenu);
            app.delet_pointMenu.MenuSelectedFcn = createCallbackFcn(app, @delet_pointMenuSelected, true);
            app.delet_pointMenu.Accelerator = 's';
            app.delet_pointMenu.Text = 'delet_point';
            app.shift_pointsMenu = uimenu(app.Fit_dataMenu);
            app.shift_pointsMenu.MenuSelectedFcn = createCallbackFcn(app, @shift_pointsMenuSelected, true);
            app.shift_pointsMenu.Text = 'shift_points';
            app.map_settingsMenu = uimenu(app.UIFigure);
            app.map_settingsMenu.Text = 'map_settings';
            app.mapMenu = uimenu(app.map_settingsMenu);
            app.mapMenu.Text = 'map';
            app.map_colorMenu_1 = uimenu(app.mapMenu);
            app.map_colorMenu_1.MenuSelectedFcn = createCallbackFcn(app, @map_colorMenu_1Selected, true);
            app.map_colorMenu_1.Text = 'map_color';
            app.fit_dataMenu = uimenu(app.map_settingsMenu);
            app.fit_dataMenu.Text = 'fit_data';
            app.map_colorMenu_2 = uimenu(app.fit_dataMenu);
            app.map_colorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @map_colorMenu_2Selected, true);
            app.map_colorMenu_2.Text = 'map_color';
            app.CorrelationMenu = uimenu(app.map_settingsMenu);
            app.CorrelationMenu.Text = 'Correlation';
            app.map_colorMenu_3 = uimenu(app.CorrelationMenu);
            app.map_colorMenu_3.MenuSelectedFcn = createCallbackFcn(app, @map_colorMenu_3Selected, true);
            app.map_colorMenu_3.Text = 'map_color';
            app.histogram_settingsMenu = uimenu(app.UIFigure);
            app.histogram_settingsMenu.Text = 'histogram_settings';
            app.show_statisticsMenu = uimenu(app.histogram_settingsMenu);
            app.show_statisticsMenu.Text = 'show_statistics';
            app.enableMenu = uimenu(app.show_statisticsMenu);
            app.enableMenu.MenuSelectedFcn = createCallbackFcn(app, @enableMenuSelected, true);
            app.enableMenu.Text = 'enable';
            app.disableMenu = uimenu(app.show_statisticsMenu);
            app.disableMenu.MenuSelectedFcn = createCallbackFcn(app, @disableMenuSelected, true);
            app.disableMenu.Text = 'disable';
            app.binsMenu = uimenu(app.histogram_settingsMenu);
            app.binsMenu.MenuSelectedFcn = createCallbackFcn(app, @binsMenuSelected, true);
            app.binsMenu.Text = '#bins';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PeakFittingToolTab = uitab(app.TabGroup);
            app.PeakFittingToolTab.Title = 'Peak Fitting Tool';
            app.FitValuesLabel = uilabel(app.PeakFittingToolTab);
            app.FitValuesLabel.Text = 'Fit Values:';
            app.FitValuesTextArea = uitextarea(app.PeakFittingToolTab);
            app.FitValuesTextArea.Value = {'no fitted data ...'};
            app.DataSpectrumPanel = uipanel(app.PeakFittingToolTab);
            app.DataSpectrumPanel.Title = 'Data Spectrum';
            app.SmoothingDropDown_5Label = uilabel(app.DataSpectrumPanel);
            app.SmoothingDropDown_5Label.Text = 'Smoothing:';
            app.SmoothingDropDown_5 = uidropdown(app.DataSpectrumPanel);
            app.SmoothingDropDown_5.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.SmoothingDropDown_5.ValueChangedFcn = createCallbackFcn(app, @SmoothingDropDown_5ValueChanged, true);
            app.SmoothingDropDown_5.Tooltip = {'''movmean'' — Moving average over each window of A. This method is useful for reducing periodic trends in data.'; '''movmedian'' — Moving median over each window of A. This method is useful for reducing periodic trends in data when outliers are present.'; '''gaussian'' — Gaussian-weighted moving average over each window of A.'; '''lowess'' — Linear regression over each window of A. This method can be computationally expensive'; ' but results in fewer discontinuities.'; '''loess'' — Quadratic regression over each window of A. This method is slightly more computationally expensive than ''lowess''.'; '''rlowess'' — Robust linear regression over each window of A. This method is a more computationally expensive version of the method ''lowess'''; ' but it is more robust to outliers.'; '''rloess'' — Robust quadratic regression over each window of A. This method is a more computationally expensive version of the method ''loess'''; ' but it is more robust to outliers.'; '''sgolay'' — Savitzky-Golay filter'; ' which smooths according to a quadratic polynomial that is fitted over each window of A. This method can be more effective than other methods when the data varies rapidly.'};
            app.SmoothingDropDown_5.Value = 'gaussian';
            app.WindowsizeSpinner_5Label = uilabel(app.DataSpectrumPanel);
            app.WindowsizeSpinner_5Label.Text = 'Window size';
            app.WindowsizeSpinner_5 = uispinner(app.DataSpectrumPanel);
            app.WindowsizeSpinner_5.Limits = [1 Inf];
            app.WindowsizeSpinner_5.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSpinner_1ValueChanged, true);
            app.WindowsizeSpinner_5.Tooltip = {'When window is a positive integer scalar, then the window is centered about the current element and contains window-1 neighboring elements. If window is even, then the window is centered about the current and previous elements.'};
            app.WindowsizeSpinner_5.Value = 21;
            app.sgolaydegreeSpinner_5Label = uilabel(app.DataSpectrumPanel);
            app.sgolaydegreeSpinner_5Label.Enable = 'off';
            app.sgolaydegreeSpinner_5Label.Text = 'sgolay degree';
            app.sgolaydegreeSpinner_5 = uispinner(app.DataSpectrumPanel);
            app.sgolaydegreeSpinner_5.Limits = [1 Inf];
            app.sgolaydegreeSpinner_5.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSpinner_1ValueChanged, true);
            app.sgolaydegreeSpinner_5.Enable = 'off';
            app.sgolaydegreeSpinner_5.Tooltip = {'The value of ''Degree'' corresponds to the degree of the polynomial in the Savitzky-Golay filter that fits the data within each window, which is 2 by default.'};
            app.sgolaydegreeSpinner_5.Value = 3;
            app.BaselineDropDownLabel = uilabel(app.DataSpectrumPanel);
            app.BaselineDropDownLabel.Text = 'Baseline:';
            app.BaselineDropDown = uidropdown(app.DataSpectrumPanel);
            app.BaselineDropDown.Items = {'None', 'constant', 'constant_mean', 'Linear'};
            app.BaselineDropDown.ValueChangedFcn = createCallbackFcn(app, @BaselineDropDownValueChanged, true);
            app.BaselineDropDown.Value = 'None';
            app.start_indexSpinnerLabel = uilabel(app.DataSpectrumPanel);
            app.start_indexSpinnerLabel.Enable = 'off';
            app.start_indexSpinnerLabel.Text = 'start_index';
            app.start_indexSpinner = uispinner(app.DataSpectrumPanel);
            app.start_indexSpinner.Limits = [1 Inf];
            app.start_indexSpinner.RoundFractionalValues = 'on';
            app.start_indexSpinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.start_indexSpinner.Enable = 'off';
            app.start_indexSpinner.Value = 1;
            app.end_indexSpinnerLabel = uilabel(app.DataSpectrumPanel);
            app.end_indexSpinnerLabel.Enable = 'off';
            app.end_indexSpinnerLabel.Text = 'end_index';
            app.end_indexSpinner = uispinner(app.DataSpectrumPanel);
            app.end_indexSpinner.Limits = [1 Inf];
            app.end_indexSpinner.RoundFractionalValues = 'on';
            app.end_indexSpinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.end_indexSpinner.Enable = 'off';
            app.end_indexSpinner.Value = 10;
            app.applytovaluesCheckBox = uicheckbox(app.DataSpectrumPanel);
            app.applytovaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.applytovaluesCheckBox.Enable = 'off';
            app.applytovaluesCheckBox.Text = 'apply to values';
            app.pointsDropDownLabel = uilabel(app.DataSpectrumPanel);
            app.pointsDropDownLabel.Text = 'points';
            app.pointsDropDown_5 = uidropdown(app.DataSpectrumPanel);
            app.pointsDropDown_5.Items = {'All_points', 'even', 'odd'};
            app.pointsDropDown_5.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.pointsDropDown_5.Value = 'All_points';
            app.start_xSpinnerLabel = uilabel(app.DataSpectrumPanel);
            app.start_xSpinnerLabel.Enable = 'off';
            app.start_xSpinnerLabel.Text = 'start_x';
            app.start_xSpinner = uispinner(app.DataSpectrumPanel);
            app.start_xSpinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.start_xSpinner.Enable = 'off';
            app.start_xSpinner.Value = 1000;
            app.constantEditFieldLabel = uilabel(app.DataSpectrumPanel);
            app.constantEditFieldLabel.Enable = 'off';
            app.constantEditFieldLabel.Text = 'constant';
            app.constantEditField = uieditfield(app.DataSpectrumPanel, 'numeric');
            app.constantEditField.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.constantEditField.Enable = 'off';
            app.DiffPanel = uipanel(app.PeakFittingToolTab);
            app.DiffPanel.Title = '1. Diff.';
            app.SmoothingDropDown_6Label = uilabel(app.DiffPanel);
            app.SmoothingDropDown_6Label.Text = 'Smoothing:';
            app.SmoothingDropDown_6 = uidropdown(app.DiffPanel);
            app.SmoothingDropDown_6.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.SmoothingDropDown_6.ValueChangedFcn = createCallbackFcn(app, @SmoothingDropDown_6ValueChanged, true);
            app.SmoothingDropDown_6.Tooltip = {'''movmean'' — Moving average over each window of A. This method is useful for reducing periodic trends in data.'; '''movmedian'' — Moving median over each window of A. This method is useful for reducing periodic trends in data when outliers are present.'; '''gaussian'' — Gaussian-weighted moving average over each window of A.'; '''lowess'' — Linear regression over each window of A. This method can be computationally expensive'; ' but results in fewer discontinuities.'; '''loess'' — Quadratic regression over each window of A. This method is slightly more computationally expensive than ''lowess''.'; '''rlowess'' — Robust linear regression over each window of A. This method is a more computationally expensive version of the method ''lowess'''; ' but it is more robust to outliers.'; '''rloess'' — Robust quadratic regression over each window of A. This method is a more computationally expensive version of the method ''loess'''; ' but it is more robust to outliers.'; '''sgolay'' — Savitzky-Golay filter'; ' which smooths according to a quadratic polynomial that is fitted over each window of A. This method can be more effective than other methods when the data varies rapidly.'};
            app.SmoothingDropDown_6.Value = 'gaussian';
            app.WindowsizeSpinner_6Label = uilabel(app.DiffPanel);
            app.WindowsizeSpinner_6Label.Text = 'Window size';
            app.WindowsizeSpinner_6 = uispinner(app.DiffPanel);
            app.WindowsizeSpinner_6.Limits = [1 Inf];
            app.WindowsizeSpinner_6.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSpinner_1ValueChanged, true);
            app.WindowsizeSpinner_6.Tooltip = {'When window is a positive integer scalar, then the window is centered about the current element and contains window-1 neighboring elements. If window is even, then the window is centered about the current and previous elements.'};
            app.WindowsizeSpinner_6.Value = 21;
            app.sgolaydegreeSpinner_6Label = uilabel(app.DiffPanel);
            app.sgolaydegreeSpinner_6Label.Enable = 'off';
            app.sgolaydegreeSpinner_6Label.Text = 'sgolay degree';
            app.sgolaydegreeSpinner_6 = uispinner(app.DiffPanel);
            app.sgolaydegreeSpinner_6.Limits = [1 Inf];
            app.sgolaydegreeSpinner_6.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSpinner_1ValueChanged, true);
            app.sgolaydegreeSpinner_6.Enable = 'off';
            app.sgolaydegreeSpinner_6.Tooltip = {'The value of ''Degree'' corresponds to the degree of the polynomial in the Savitzky-Golay filter that fits the data within each window, which is 2 by default.'};
            app.sgolaydegreeSpinner_6.Value = 3;
            app.FittingOptionsPanel = uipanel(app.PeakFittingToolTab);
            app.FittingOptionsPanel.Title = 'Fitting Options';
            app.FitsingledataButton = uibutton(app.FittingOptionsPanel, 'push');
            app.FitsingledataButton.ButtonPushedFcn = createCallbackFcn(app, @FitsingledataButtonPushed, true);
            app.FitsingledataButton.Tooltip = {'Fits the data for the current position and the selected data range'};
            app.FitsingledataButton.Text = 'Fit single data';
            app.set_parameterCheckBox = uicheckbox(app.FittingOptionsPanel);
            app.set_parameterCheckBox.Text = 'set_parameter';
            app.minPeakdistanceSpinnerLabel = uilabel(app.FittingOptionsPanel);
            app.minPeakdistanceSpinnerLabel.Text = 'min Peak distance';
            app.minPeakdistanceSpinner = uispinner(app.FittingOptionsPanel);
            app.minPeakdistanceSpinner.Step = 0.1;
            app.minPeakdistanceSpinner.Limits = [0 Inf];
            app.minPeakdistanceSpinner.ValueDisplayFormat = '%.4f';
            app.minPeakdistanceSpinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.minPeakdistanceSpinner.Value = 8;
            app.NumberofpointsleftSpinnerLabel = uilabel(app.FittingOptionsPanel);
            app.NumberofpointsleftSpinnerLabel.Text = 'Number of points left';
            app.NumberofpointsleftSpinner = uispinner(app.FittingOptionsPanel);
            app.NumberofpointsleftSpinner.Limits = [1 Inf];
            app.NumberofpointsleftSpinner.RoundFractionalValues = 'on';
            app.NumberofpointsleftSpinner.Tooltip = {'number of points to the left of the minimum in the second derivative that are included in the fit'};
            app.NumberofpointsleftSpinner.Value = 100;
            app.NumberofpointsrightSpinnerLabel = uilabel(app.FittingOptionsPanel);
            app.NumberofpointsrightSpinnerLabel.Text = 'Number of points right';
            app.NumberofpointsrightSpinner = uispinner(app.FittingOptionsPanel);
            app.NumberofpointsrightSpinner.Limits = [1 Inf];
            app.NumberofpointsrightSpinner.RoundFractionalValues = 'on';
            app.NumberofpointsrightSpinner.Tooltip = {'number of points to the right of the minimum in the second derivative that are included in the fit'};
            app.NumberofpointsrightSpinner.Value = 40;
            app.FitmultidataButton = uibutton(app.FittingOptionsPanel, 'push');
            app.FitmultidataButton.ButtonPushedFcn = createCallbackFcn(app, @FitmultidataButtonPushed, true);
            app.FitmultidataButton.Tooltip = {'fits the data for all position in the selected data range and stores them in the temporary database'};
            app.FitmultidataButton.Text = 'Fit multi data';
            app.eSpinner_2Label = uilabel(app.FittingOptionsPanel);
            app.eSpinner_2Label.Text = 'e-';
            app.power_peak_distance = uispinner(app.FittingOptionsPanel);
            app.power_peak_distance.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.subtractwindowCheckBox = uicheckbox(app.FittingOptionsPanel);
            app.subtractwindowCheckBox.Tooltip = {'Here you can exclude a specific window from the data.'};
            app.subtractwindowCheckBox.Text = 'subtract window';
            app.ProminenceEditFieldLabel = uilabel(app.FittingOptionsPanel);
            app.ProminenceEditFieldLabel.Text = 'Prominence';
            app.ProminenceEditField = uieditfield(app.FittingOptionsPanel, 'numeric');
            app.ProminenceEditField.Limits = [0 Inf];
            app.ProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.ProminenceEditField.Tooltip = {'depth from a minimum in the second derivative to the smallest next maximum. Can be used to set a threshold for positions not to be fitted'};
            app.accuracyEditFieldLabel = uilabel(app.FittingOptionsPanel);
            app.accuracyEditFieldLabel.Text = 'accuracy';
            app.accuracyEditField = uieditfield(app.FittingOptionsPanel, 'numeric');
            app.accuracyEditField.Limits = [0 Inf];
            app.accuracyEditField.ValueDisplayFormat = '%e';
            app.accuracyEditField.Value = 1e-100;
            app.singlefitandsaveButton = uibutton(app.FittingOptionsPanel, 'push');
            app.singlefitandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @singlefitandsaveButtonPushed, true);
            app.singlefitandsaveButton.Tooltip = {'fits the data for the current position and the selected data range and stores them in the temporary database'};
            app.singlefitandsaveButton.Text = 'single fit and save';
            app.maxiterEditFieldLabel = uilabel(app.FittingOptionsPanel);
            app.maxiterEditFieldLabel.Text = 'max iter';
            app.maxiterEditField = uieditfield(app.FittingOptionsPanel, 'numeric');
            app.maxiterEditField.Limits = [1 Inf];
            app.maxiterEditField.Value = 100;
            app.maxpeaksSpinnerLabel = uilabel(app.FittingOptionsPanel);
            app.maxpeaksSpinnerLabel.Tooltip = {'Fits the data for the current position and the selected data range'};
            app.maxpeaksSpinnerLabel.Text = 'max # peaks';
            app.maxpeaksSpinner = uispinner(app.FittingOptionsPanel);
            app.maxpeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.maxpeaksSpinner.Tooltip = {'Maximum number of peaks to be filtered'};
            app.atpositionSpinnerLabel = uilabel(app.FittingOptionsPanel);
            app.atpositionSpinnerLabel.Text = 'at position';
            app.atpositionSpinner = uispinner(app.FittingOptionsPanel);
            app.atpositionSpinner.Limits = [1 Inf];
            app.atpositionSpinner.RoundFractionalValues = 'on';
            app.atpositionSpinner.ValueDisplayFormat = '%.0f';
            app.atpositionSpinner.Tooltip = {'In the Analyze Fit Data tab, this number describes the index for the "fit" dropdown where you can select which of the fit runs should be displayed.'};
            app.atpositionSpinner.Value = 1;
            app.fit_parameterButton = uibutton(app.FittingOptionsPanel, 'state');
            app.fit_parameterButton.ValueChangedFcn = createCallbackFcn(app, @fit_parameterButtonValueChanged, true);
            app.fit_parameterButton.Text = 'fit_parameter';
            app.Fit_modeDropDownLabel = uilabel(app.FittingOptionsPanel);
            app.Fit_modeDropDownLabel.Text = 'Fit_mode';
            app.Fit_modeDropDown = uidropdown(app.FittingOptionsPanel);
            app.Fit_modeDropDown.Items = {'all', 'constant_t', 'constant_p', 'constant_x', 'constant_y'};
            app.Fit_modeDropDown.Value = 'all';
            app.exclude_pointsCheckBox = uicheckbox(app.FittingOptionsPanel);
            app.exclude_pointsCheckBox.Text = 'exclude_points';
            app.excluded_pontsButton = uibutton(app.FittingOptionsPanel, 'state');
            app.excluded_pontsButton.ValueChangedFcn = createCallbackFcn(app, @excluded_pontsButtonValueChanged, true);
            app.excluded_pontsButton.Text = 'excluded_ponts';
            app.Fit_modelDropDownLabel = uilabel(app.FittingOptionsPanel);
            app.Fit_modelDropDownLabel.Text = 'Fit_model';
            app.Fit_modelDropDown = uidropdown(app.FittingOptionsPanel);
            app.Fit_modelDropDown.Items = {'gauss', 'pseudo-voigt', '2nd_diff'};
            app.Fit_modelDropDown.Value = 'gauss';
            app.get_parametersButton = uibutton(app.FittingOptionsPanel, 'push');
            app.get_parametersButton.ButtonPushedFcn = createCallbackFcn(app, @get_parametersButtonPushed, true);
            app.get_parametersButton.Tooltip = {'takes the parameters used in this fit position'};
            app.get_parametersButton.Text = 'get_parameters';
            app.peak_position_2_diffButton = uibutton(app.FittingOptionsPanel, 'push');
            app.peak_position_2_diffButton.ButtonPushedFcn = createCallbackFcn(app, @peak_position_2_diffButtonPushed, true);
            app.peak_position_2_diffButton.Text = 'peak_position_2_diff';
            app.SpectrumMapParameterPanel = uipanel(app.PeakFittingToolTab);
            app.SpectrumMapParameterPanel.Title = 'Spectrum/Map Parameter';
            app.changingvalue1SpinnerLabel = uilabel(app.SpectrumMapParameterPanel);
            app.changingvalue1SpinnerLabel.Text = 'changing value 1';
            app.changingvalue1_1Spinner = uispinner(app.SpectrumMapParameterPanel);
            app.changingvalue1_1Spinner.Step = 0.5;
            app.changingvalue1_1Spinner.Limits = [0 10];
            app.changingvalue1_1Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.changingvalue1_1Spinner.Tooltip = {'For PL-Maps this is the x-Value'};
            app.changingvalue1_1Spinner.Value = 1;
            app.Spectrum_RefreshButton_2 = uibutton(app.SpectrumMapParameterPanel, 'push');
            app.Spectrum_RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @Spectrum_RefreshButton_2Pushed2, true);
            app.Spectrum_RefreshButton_2.Text = 'Refresh';
            app.Map_switch_5 = uiswitch(app.SpectrumMapParameterPanel, 'slider');
            app.Map_switch_5.Items = {'log', 'linear'};
            app.Map_switch_5.Value = 'linear';
            app.xminLabel = uilabel(app.SpectrumMapParameterPanel);
            app.xminLabel.Text = 'x min';
            app.x_min = uispinner(app.SpectrumMapParameterPanel);
            app.x_min.ValueDisplayFormat = '%.3f';
            app.x_min.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.x_min.Tooltip = {'Minimum value of spectral x-axis Parameter. For PL this is the wavelength.'};
            app.x_min.Value = 1;
            app.xmaxLabel = uilabel(app.SpectrumMapParameterPanel);
            app.xmaxLabel.Text = 'x max';
            app.x_max = uispinner(app.SpectrumMapParameterPanel);
            app.x_max.ValueDisplayFormat = '%.3f';
            app.x_max.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.x_max.Tooltip = {'Maximum value of spectral x-axis Parameter. For PL this is the wavelength.'};
            app.x_max.Value = 2;
            app.SweepDropDownLabel = uilabel(app.SpectrumMapParameterPanel);
            app.SweepDropDownLabel.Text = 'Sweep';
            app.SweepDropDown = uidropdown(app.SpectrumMapParameterPanel);
            app.SweepDropDown.Items = {'PL_Map', 'energy', 'all', 'bidirectional', 'forward', 'backward'};
            app.SweepDropDown.ValueChangedFcn = createCallbackFcn(app, @SweepDropDownValueChanged, true);
            app.SweepDropDown.Value = 'PL_Map';
            app.changingvalue2SpinnerLabel = uilabel(app.SpectrumMapParameterPanel);
            app.changingvalue2SpinnerLabel.Text = 'changing value 2';
            app.changingvalue2_1Spinner = uispinner(app.SpectrumMapParameterPanel);
            app.changingvalue2_1Spinner.Step = 0.5;
            app.changingvalue2_1Spinner.Limits = [0 10];
            app.changingvalue2_1Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1SpinnerValueChanged, true);
            app.changingvalue2_1Spinner.Tooltip = {'For PL-Maps this is the y-Value'};
            app.changingvalue2_1Spinner.Value = 1;
            app.MaxzaxisLabel = uilabel(app.SpectrumMapParameterPanel);
            app.MaxzaxisLabel.Text = 'Max z-axis';
            app.MapMaxCountsEditField_5 = uieditfield(app.SpectrumMapParameterPanel, 'numeric');
            app.MapMaxCountsEditField_5.ValueDisplayFormat = '%1.2e';
            app.MapMaxCountsEditField_5.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.MapMaxCountsEditField_5.Value = Inf;
            app.MinzaxisLabel = uilabel(app.SpectrumMapParameterPanel);
            app.MinzaxisLabel.Text = 'Min z-axis';
            app.MapMinCountsEditField_5 = uieditfield(app.SpectrumMapParameterPanel, 'numeric');
            app.MapMinCountsEditField_5.ValueDisplayFormat = '%1.2e';
            app.MapMinCountsEditField_5.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.MapMinCountsEditField_5.Value = -Inf;
            app.changingvalue3SpinnerLabel = uilabel(app.SpectrumMapParameterPanel);
            app.changingvalue3SpinnerLabel.Text = 'changing value 3';
            app.changingvalue3_1Spinner = uispinner(app.SpectrumMapParameterPanel);
            app.changingvalue3_1Spinner.Step = 0.5;
            app.changingvalue3_1Spinner.Limits = [0 10];
            app.changingvalue3_1Spinner.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.changingvalue3_1Spinner.Tooltip = {'For PL-Maps this is the Power-Value'};
            app.changingvalue3_1Spinner.Value = 1;
            app.changingvalue4SpinnerLabel = uilabel(app.SpectrumMapParameterPanel);
            app.changingvalue4SpinnerLabel.Text = 'changing value 4';
            app.changingvalue4_1Spinner = uispinner(app.SpectrumMapParameterPanel);
            app.changingvalue4_1Spinner.Step = 0.5;
            app.changingvalue4_1Spinner.Limits = [0 10];
            app.changingvalue4_1Spinner.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.changingvalue4_1Spinner.Tooltip = {'For PL-Maps this is the Tamperature-Value'};
            app.changingvalue4_1Spinner.Value = 1;
            app.MapappearanceDropDown_5 = uidropdown(app.SpectrumMapParameterPanel);
            app.MapappearanceDropDown_5.Items = {'integrated', 'averaged'};
            app.MapappearanceDropDown_5.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.MapappearanceDropDown_5.Value = 'integrated';
            app.ContourCheckBox_5 = uicheckbox(app.SpectrumMapParameterPanel);
            app.ContourCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.ContourCheckBox_5.Text = 'Contour';
            app.Switch_5 = uiswitch(app.SpectrumMapParameterPanel, 'slider');
            app.Switch_5.Items = {'log', 'linear'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'linear';
            app.XLabel = uilabel(app.SpectrumMapParameterPanel);
            app.XLabel.Text = 'X';
            app.Switch_6 = uiswitch(app.SpectrumMapParameterPanel, 'slider');
            app.Switch_6.Items = {'log', 'linear'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Value = 'linear';
            app.YLabel = uilabel(app.SpectrumMapParameterPanel);
            app.YLabel.Text = '|| Y';
            app.WaterfallDropDownLabel = uilabel(app.SpectrumMapParameterPanel);
            app.WaterfallDropDownLabel.Enable = 'off';
            app.WaterfallDropDownLabel.Text = 'Waterfall';
            app.WaterfallDropDown = uidropdown(app.SpectrumMapParameterPanel);
            app.WaterfallDropDown.Items = {'changing value 1', 'changing value 2', 'changing value 3', 'changing value 4'};
            app.WaterfallDropDown.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.WaterfallDropDown.Enable = 'off';
            app.WaterfallDropDown.Value = 'changing value 1';
            app.ColorstepsEditField_4Label_2 = uilabel(app.SpectrumMapParameterPanel);
            app.ColorstepsEditField_4Label_2.Text = 'Color steps';
            app.MapColorstepsEditField_5 = uieditfield(app.SpectrumMapParameterPanel, 'numeric');
            app.MapColorstepsEditField_5.Limits = [1 Inf];
            app.MapColorstepsEditField_5.RoundFractionalValues = 'on';
            app.MapColorstepsEditField_5.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.MapColorstepsEditField_5.Value = 100;
            app.OffsetSpinner_2Label = uilabel(app.SpectrumMapParameterPanel);
            app.OffsetSpinner_2Label.Enable = 'off';
            app.OffsetSpinner_2Label.Text = 'Off-set';
            app.OffsetSpinner_2 = uispinner(app.SpectrumMapParameterPanel);
            app.OffsetSpinner_2.Limits = [0 Inf];
            app.OffsetSpinner_2.ValueChangedFcn = createCallbackFcn(app, @x_minValueChanged, true);
            app.OffsetSpinner_2.Enable = 'off';
            app.OffsetSpinner_2.Value = 10;
            app.locktoareaCheckBox_5 = uicheckbox(app.SpectrumMapParameterPanel);
            app.locktoareaCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBox_5ValueChanged, true);
            app.locktoareaCheckBox_5.Text = 'lock to area';
            app.show_sample_infoButton = uibutton(app.SpectrumMapParameterPanel, 'push');
            app.show_sample_infoButton.ButtonPushedFcn = createCallbackFcn(app, @show_sample_infoButtonPushed, true);
            app.show_sample_infoButton.Text = 'show_sample_info';
            app.wavelength_energy = uiswitch(app.PeakFittingToolTab, 'slider');
            app.wavelength_energy.Items = {'wavelegth', 'energy'};
            app.wavelength_energy.ValueChangedFcn = createCallbackFcn(app, @wavelength_energyValueChanged, true);
            app.wavelength_energy.Value = 'wavelegth';
            app.find_peaks_in_2_diffCheckBox = uicheckbox(app.PeakFittingToolTab);
            app.find_peaks_in_2_diffCheckBox.Text = 'find_peaks_in_2_diff';
            app.Map5 = uiaxes(app.PeakFittingToolTab);
            title(app.Map5, 'Map')
            xlabel(app.Map5, 'X (mm)')
            ylabel(app.Map5, 'Y (mm)')
            app.Spectrum5 = uiaxes(app.PeakFittingToolTab);
            title(app.Spectrum5, 'Spectrum')
            xlabel(app.Spectrum5, 'Wavelength (nm)')
            ylabel(app.Spectrum5, 'Intensity (counts/s)')
            app.Spectrum6 = uiaxes(app.PeakFittingToolTab);
            title(app.Spectrum6, '1. Derivative')
            xlabel(app.Spectrum6, 'Wavelength (nm)')
            ylabel(app.Spectrum6, 'Intensity (counts/s)')
            app.Spectrum7 = uiaxes(app.PeakFittingToolTab);
            title(app.Spectrum7, '2. Derivative')
            xlabel(app.Spectrum7, 'Wavelength (nm)')
            ylabel(app.Spectrum7, 'Intensity (counts/s)')
            app.AnalyzeFitDataTab = uitab(app.TabGroup);
            app.AnalyzeFitDataTab.Title = ' Analyze Fit Data';
            app.TabGroup2 = uitabgroup(app.AnalyzeFitDataTab);
            app.display_peaksTab = uitab(app.TabGroup2);
            app.display_peaksTab.Title = 'display_peaks';
            app.MaxzaxisLabel_2 = uilabel(app.display_peaksTab);
            app.MaxzaxisLabel_2.Text = 'Max z-axis';
            app.MapMaxCountsEditField_6 = uieditfield(app.display_peaksTab, 'numeric');
            app.MapMaxCountsEditField_6.ValueDisplayFormat = '%1.2e';
            app.MapMaxCountsEditField_6.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_6ValueChanged, true);
            app.MapMaxCountsEditField_6.Value = Inf;
            app.MinzaxisLabel_2 = uilabel(app.display_peaksTab);
            app.MinzaxisLabel_2.Text = 'Min z-axis';
            app.MapMinCountsEditField_6 = uieditfield(app.display_peaksTab, 'numeric');
            app.MapMinCountsEditField_6.ValueDisplayFormat = '%1.2e';
            app.MapMinCountsEditField_6.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_6ValueChanged, true);
            app.MapMinCountsEditField_6.Value = -Inf;
            app.ColorstepsEditField_4Label_3 = uilabel(app.display_peaksTab);
            app.ColorstepsEditField_4Label_3.Text = 'Color steps';
            app.MapColorstepsEditField_6 = uieditfield(app.display_peaksTab, 'numeric');
            app.MapColorstepsEditField_6.Limits = [1 Inf];
            app.MapColorstepsEditField_6.RoundFractionalValues = 'on';
            app.MapColorstepsEditField_6.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_6ValueChanged, true);
            app.MapColorstepsEditField_6.Value = 100;
            app.MapRefreshButton_6 = uibutton(app.display_peaksTab, 'push');
            app.MapRefreshButton_6.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButton_6Pushed, true);
            app.MapRefreshButton_6.Text = 'Refresh';
            app.ContourCheckBox_6 = uicheckbox(app.display_peaksTab);
            app.ContourCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_6ValueChanged, true);
            app.ContourCheckBox_6.Text = 'Contour';
            app.Map_switch_6 = uiswitch(app.display_peaksTab, 'slider');
            app.Map_switch_6.Items = {'log', 'linear'};
            app.Map_switch_6.Value = 'linear';
            app.DisplayedPeakDropDown_1Label = uilabel(app.display_peaksTab);
            app.DisplayedPeakDropDown_1Label.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown_1 = uidropdown(app.display_peaksTab);
            app.DisplayedPeakDropDown_1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown_1.ValueChangedFcn = createCallbackFcn(app, @DisplayedPeakDropDown_1ValueChanged, true);
            app.DisplayedPeakDropDown_1.Value = '1';
            app.DisplayedValueDropDown_1Label = uilabel(app.display_peaksTab);
            app.DisplayedValueDropDown_1Label.Text = 'Displayed Value';
            app.DisplayedValueDropDown_1 = uidropdown(app.display_peaksTab);
            app.DisplayedValueDropDown_1.Items = {'rmse', 'Height', 'Position', 'FWHM', 'Eta'};
            app.DisplayedValueDropDown_1.ValueChangedFcn = createCallbackFcn(app, @DisplayedValueDropDown_1ValueChanged, true);
            app.DisplayedValueDropDown_1.Value = 'Position';
            app.compare_two_peaksButton = uibutton(app.display_peaksTab, 'push');
            app.compare_two_peaksButton.ButtonPushedFcn = createCallbackFcn(app, @compare_two_peaksButtonPushed, true);
            app.compare_two_peaksButton.Text = 'compare_two_peaks';
            app.SaveDataButton_2 = uibutton(app.display_peaksTab, 'push');
            app.SaveDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_1Pushed, true);
            app.SaveDataButton_2.Text = 'Save Data';
            app.display_peak_as_wavelengthCheckBox = uicheckbox(app.display_peaksTab);
            app.display_peak_as_wavelengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapRefreshButton_6Pushed, true);
            app.display_peak_as_wavelengthCheckBox.Text = 'display_peak_as_wavelength';
            app.display_peak_as_wavelengthCheckBox.Value = true;
            app.locktoareaCheckBox_6 = uicheckbox(app.display_peaksTab);
            app.locktoareaCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBox_6ValueChanged, true);
            app.locktoareaCheckBox_6.Text = 'lock to area';
            app.fitDropDown_1Label = uilabel(app.display_peaksTab);
            app.fitDropDown_1Label.Text = 'fit';
            app.fitDropDown_1 = uidropdown(app.display_peaksTab);
            app.fitDropDown_1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown_1.ValueChangedFcn = createCallbackFcn(app, @fitDropDown_1ValueChanged, true);
            app.fitDropDown_1.Value = '1';
            app.changingvalue3Label_3 = uilabel(app.display_peaksTab);
            app.changingvalue3Label_3.Text = 'changingvalue 3';
            app.changingvalue3_4Spinner = uispinner(app.display_peaksTab);
            app.changingvalue3_4Spinner.Step = 0.5;
            app.changingvalue3_4Spinner.Limits = [0 76];
            app.changingvalue3_4Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue3_4SpinnerValueChanged, true);
            app.changingvalue3_4Spinner.Value = 1;
            app.changingvalue4Label_3 = uilabel(app.display_peaksTab);
            app.changingvalue4Label_3.Text = 'changingvalue 4';
            app.changingvalue4_4Spinner = uispinner(app.display_peaksTab);
            app.changingvalue4_4Spinner.Step = 0.5;
            app.changingvalue4_4Spinner.Limits = [0 76];
            app.changingvalue4_4Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue4_4SpinnerValueChanged, true);
            app.changingvalue4_4Spinner.Value = 1;
            app.FreeScanPanel = uipanel(app.display_peaksTab);
            app.FreeScanPanel.Title = 'Free Scan';
            app.startingSwitch = uiswitch(app.FreeScanPanel, 'slider');
            app.startingSwitch.Items = {'left', 'right'};
            app.startingSwitch.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.startingSwitch.Value = 'left';
            app.startingSwitchLabel = uilabel(app.FreeScanPanel);
            app.startingSwitchLabel.Text = 'starting';
            app.set_lineButton = uibutton(app.FreeScanPanel, 'state');
            app.set_lineButton.ValueChangedFcn = createCallbackFcn(app, @set_lineButtonValueChanged, true);
            app.set_lineButton.Text = 'set_line';
            app.set_line_from_centerButton = uibutton(app.FreeScanPanel, 'state');
            app.set_line_from_centerButton.ValueChangedFcn = createCallbackFcn(app, @set_line_from_centerButtonValueChanged, true);
            app.set_line_from_centerButton.Text = 'set_line_from_center';
            app.snapp_to_gridCheckBox = uicheckbox(app.FreeScanPanel);
            app.snapp_to_gridCheckBox.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.snapp_to_gridCheckBox.Text = 'snapp_to_grid';
            app.snapp_to_gridCheckBox.Value = true;
            app.Switch_7 = uiswitch(app.FreeScanPanel, 'slider');
            app.Switch_7.Items = {'exactly', 'close_to'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.Switch_7.Value = 'close_to';
            app.X1EditFieldLabel = uilabel(app.FreeScanPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.X1EditField.Limits = [0 76];
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X1EditField.Value = 37.5;
            app.X2EditFieldLabel = uilabel(app.FreeScanPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.X2EditField.Limits = [0 76];
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditField.Value = 38.5;
            app.Y1EditFieldLabel = uilabel(app.FreeScanPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.Y1EditField.Limits = [0 76];
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.Y1EditField.Value = 37;
            app.Y2EditFieldLabel = uilabel(app.FreeScanPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.Y2EditField.Limits = [0 76];
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.Y2EditField.Value = 38;
            app.get_line_distanceCheckBox = uicheckbox(app.FreeScanPanel);
            app.get_line_distanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.get_line_distanceCheckBox.Text = 'get_line_distance';
            app.steps_widthSpinnerLabel = uilabel(app.FreeScanPanel);
            app.steps_widthSpinnerLabel.Text = 'steps_width';
            app.steps_widthSpinner = uispinner(app.FreeScanPanel);
            app.steps_widthSpinner.Step = 0.1;
            app.steps_widthSpinner.LowerLimitInclusive = 'off';
            app.steps_widthSpinner.Limits = [0 Inf];
            app.steps_widthSpinner.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.steps_widthSpinner.Value = 0.5;
            app.start_indexSpinner_2Label = uilabel(app.FreeScanPanel);
            app.start_indexSpinner_2Label.Text = 'start_index';
            app.start_indexSpinner_2 = uispinner(app.FreeScanPanel);
            app.start_indexSpinner_2.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.end_indexSpinner_2Label = uilabel(app.FreeScanPanel);
            app.end_indexSpinner_2Label.Text = 'end_index';
            app.end_indexSpinner_2 = uispinner(app.FreeScanPanel);
            app.end_indexSpinner_2.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.index_stepsSpinnerLabel = uilabel(app.FreeScanPanel);
            app.index_stepsSpinnerLabel.Text = 'index_steps';
            app.index_stepsSpinner = uispinner(app.FreeScanPanel);
            app.index_stepsSpinner.Limits = [1 Inf];
            app.index_stepsSpinner.ValueChangedFcn = createCallbackFcn(app, @start_indexSpinner_2ValueChanged, true);
            app.index_stepsSpinner.Value = 1;
            app.sorting_peaksTab = uitab(app.TabGroup2);
            app.sorting_peaksTab.Title = 'sorting_peaks';
            app.SortPeaksButton = uibutton(app.sorting_peaksTab, 'push');
            app.SortPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SortPeaksButtonPushed, true);
            app.SortPeaksButton.Text = 'Sort Peaks';
            app.SaveDataButton_1 = uibutton(app.sorting_peaksTab, 'push');
            app.SaveDataButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_1Pushed, true);
            app.SaveDataButton_1.Text = 'Save Data';
            app.X_startSpinnerLabel = uilabel(app.sorting_peaksTab);
            app.X_startSpinnerLabel.Text = 'X_start';
            app.X_startSpinner = uispinner(app.sorting_peaksTab);
            app.Y_startSpinnerLabel = uilabel(app.sorting_peaksTab);
            app.Y_startSpinnerLabel.Text = 'Y_start';
            app.Y_startSpinner = uispinner(app.sorting_peaksTab);
            app.ThresholddistanceSpinnerLabel = uilabel(app.sorting_peaksTab);
            app.ThresholddistanceSpinnerLabel.Text = 'Threshold distance';
            app.ThresholddistanceSpinner = uispinner(app.sorting_peaksTab);
            app.ThresholddistanceSpinner.Limits = [0 Inf];
            app.ThresholddistanceSpinner.Value = 10;
            app.NearestneighboursSpinnerLabel = uilabel(app.sorting_peaksTab);
            app.NearestneighboursSpinnerLabel.Text = 'Nearest neighbours';
            app.NearestneighboursSpinner = uispinner(app.sorting_peaksTab);
            app.NearestneighboursSpinner.Limits = [2 Inf];
            app.NearestneighboursSpinner.Value = 5;
            app.save_changesButton_2 = uibutton(app.sorting_peaksTab, 'push');
            app.save_changesButton_2.ButtonPushedFcn = createCallbackFcn(app, @save_changesButtonPushed, true);
            app.save_changesButton_2.Text = 'save_changes';
            app.delet_pointsTab = uitab(app.TabGroup2);
            app.delet_pointsTab.Title = 'delet_points';
            app.deleteoutlierButton = uibutton(app.delet_pointsTab, 'push');
            app.deleteoutlierButton.ButtonPushedFcn = createCallbackFcn(app, @deleteoutlierButtonPushed, true);
            app.deleteoutlierButton.Text = 'delete outlier';
            app.k_minSpinnerLabel = uilabel(app.delet_pointsTab);
            app.k_minSpinnerLabel.Text = 'k_min';
            app.k_minSpinner = uispinner(app.delet_pointsTab);
            app.k_minSpinner.Limits = [0 Inf];
            app.k_minSpinner.RoundFractionalValues = 'on';
            app.k_minSpinner.ValueDisplayFormat = '%.0f';
            app.k_minSpinner.ValueChangedFcn = createCallbackFcn(app, @MapRefreshButton_6Pushed, true);
            app.k_maxSpinnerLabel = uilabel(app.delet_pointsTab);
            app.k_maxSpinnerLabel.Text = 'k_max';
            app.k_maxSpinner = uispinner(app.delet_pointsTab);
            app.k_maxSpinner.Limits = [0 Inf];
            app.k_maxSpinner.RoundFractionalValues = 'on';
            app.k_maxSpinner.ValueDisplayFormat = '%.0f';
            app.k_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @MapRefreshButton_6Pushed, true);
            app.reset_points_to_deletButton = uibutton(app.delet_pointsTab, 'push');
            app.reset_points_to_deletButton.ButtonPushedFcn = createCallbackFcn(app, @reset_points_to_deletButtonPushed, true);
            app.reset_points_to_deletButton.Text = 'reset_points_to_delet';
            app.SaveDataButton_3 = uibutton(app.delet_pointsTab, 'push');
            app.SaveDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_1Pushed, true);
            app.SaveDataButton_3.Text = 'Save Data';
            app.DisplayedPeakDropDown_3Label = uilabel(app.delet_pointsTab);
            app.DisplayedPeakDropDown_3Label.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown_3 = uidropdown(app.delet_pointsTab);
            app.DisplayedPeakDropDown_3.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown_3.ValueChangedFcn = createCallbackFcn(app, @DisplayedPeakDropDown_3ValueChanged, true);
            app.DisplayedPeakDropDown_3.Value = '1';
            app.DisplayedValueDropDown_3Label = uilabel(app.delet_pointsTab);
            app.DisplayedValueDropDown_3Label.Text = 'Displayed Value';
            app.DisplayedValueDropDown_3 = uidropdown(app.delet_pointsTab);
            app.DisplayedValueDropDown_3.Items = {'rmse', 'Height', 'Position', 'FWHM', 'Eta'};
            app.DisplayedValueDropDown_3.ValueChangedFcn = createCallbackFcn(app, @DisplayedValueDropDown_3ValueChanged, true);
            app.DisplayedValueDropDown_3.Value = 'Position';
            app.reset_dataButton = uibutton(app.delet_pointsTab, 'state');
            app.reset_dataButton.ValueChangedFcn = createCallbackFcn(app, @reset_dataButtonValueChanged, true);
            app.reset_dataButton.Tooltip = {'sets the sort_data at the displayed Peak back to the fit_data'};
            app.reset_dataButton.Text = 'reset_data';
            app.save_changesButton = uibutton(app.delet_pointsTab, 'push');
            app.save_changesButton.ButtonPushedFcn = createCallbackFcn(app, @save_changesButtonPushed, true);
            app.save_changesButton.Text = 'save_changes';
            app.fitDropDown_2Label = uilabel(app.delet_pointsTab);
            app.fitDropDown_2Label.Text = 'fit';
            app.fitDropDown_2 = uidropdown(app.delet_pointsTab);
            app.fitDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown_2.ValueChangedFcn = createCallbackFcn(app, @fitDropDown_2ValueChanged, true);
            app.fitDropDown_2.Value = '1';
            app.changingvalue3Label_2 = uilabel(app.delet_pointsTab);
            app.changingvalue3Label_2.Text = 'changingvalue 3';
            app.changingvalue3_3Spinner = uispinner(app.delet_pointsTab);
            app.changingvalue3_3Spinner.Step = 0.5;
            app.changingvalue3_3Spinner.Limits = [0 76];
            app.changingvalue3_3Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue3_3SpinnerValueChanged, true);
            app.changingvalue3_3Spinner.Value = 1;
            app.changingvalue4Label_2 = uilabel(app.delet_pointsTab);
            app.changingvalue4Label_2.Text = 'changingvalue 4';
            app.changingvalue4_3Spinner = uispinner(app.delet_pointsTab);
            app.changingvalue4_3Spinner.Step = 0.5;
            app.changingvalue4_3Spinner.Limits = [0 76];
            app.changingvalue4_3Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue4_3SpinnerValueChanged, true);
            app.changingvalue4_3Spinner.Value = 1;
            app.build_correlation_lineTab = uitab(app.TabGroup2);
            app.build_correlation_lineTab.Title = 'build_correlation_line';
            app.displayerrorbarsCheckBox = uicheckbox(app.build_correlation_lineTab);
            app.displayerrorbarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaydataalongvalueDropDownValueChanged, true);
            app.displayerrorbarsCheckBox.Text = 'display error bars';
            app.CorrelationsDropDownLabel = uilabel(app.build_correlation_lineTab);
            app.CorrelationsDropDownLabel.Text = 'Correlations';
            app.CorrelationsDropDown = uidropdown(app.build_correlation_lineTab);
            app.CorrelationsDropDown.Items = {'Height(FWHM)', 'Height(Position)', 'Position(Height)', 'Position(FWHM)', 'FWHM(Height)', 'FWHM(Position)'};
            app.CorrelationsDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrelationsDropDownValueChanged, true);
            app.CorrelationsDropDown.Value = 'Height(FWHM)';
            app.Line_StyleDropDownLabel = uilabel(app.build_correlation_lineTab);
            app.Line_StyleDropDownLabel.Text = 'Line_Style';
            app.Line_StyleDropDown = uidropdown(app.build_correlation_lineTab);
            app.Line_StyleDropDown.Items = {'*', '-*', '--*', ':*', '-.*', 'o', '-o', '--o', ':o', '-.o', '-'};
            app.Line_StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydataalongvalueDropDownValueChanged, true);
            app.Line_StyleDropDown.Value = '*';
            app.changingvalue3Label = uilabel(app.build_correlation_lineTab);
            app.changingvalue3Label.Text = 'changingvalue 3';
            app.changingvalue3_2Spinner = uispinner(app.build_correlation_lineTab);
            app.changingvalue3_2Spinner.Step = 0.5;
            app.changingvalue3_2Spinner.Limits = [0 76];
            app.changingvalue3_2Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1_2SpinnerValueChanged, true);
            app.changingvalue3_2Spinner.Value = 1;
            app.changingvalue4Label = uilabel(app.build_correlation_lineTab);
            app.changingvalue4Label.Text = 'changingvalue 4';
            app.changingvalue4_2Spinner = uispinner(app.build_correlation_lineTab);
            app.changingvalue4_2Spinner.Step = 0.5;
            app.changingvalue4_2Spinner.Limits = [0 76];
            app.changingvalue4_2Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1_2SpinnerValueChanged, true);
            app.changingvalue4_2Spinner.Value = 1;
            app.changingvalue1Label = uilabel(app.build_correlation_lineTab);
            app.changingvalue1Label.Text = 'changingvalue 1';
            app.changingvalue1_2Spinner = uispinner(app.build_correlation_lineTab);
            app.changingvalue1_2Spinner.Step = 0.5;
            app.changingvalue1_2Spinner.Limits = [0 76];
            app.changingvalue1_2Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1_2SpinnerValueChanged, true);
            app.changingvalue1_2Spinner.Value = 1;
            app.changingvalue2Label = uilabel(app.build_correlation_lineTab);
            app.changingvalue2Label.Text = 'changingvalue 2';
            app.changingvalue2_2Spinner = uispinner(app.build_correlation_lineTab);
            app.changingvalue2_2Spinner.Step = 0.5;
            app.changingvalue2_2Spinner.ValueChangedFcn = createCallbackFcn(app, @changingvalue1_2SpinnerValueChanged, true);
            app.changingvalue2_2Spinner.Value = 1;
            app.DisplayedPeakDropDown_2Label = uilabel(app.build_correlation_lineTab);
            app.DisplayedPeakDropDown_2Label.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown_2 = uidropdown(app.build_correlation_lineTab);
            app.DisplayedPeakDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayedPeakDropDown_2ValueChanged, true);
            app.DisplayedPeakDropDown_2.Value = '1';
            app.DisplayedValueDropDown_2Label = uilabel(app.build_correlation_lineTab);
            app.DisplayedValueDropDown_2Label.Text = 'Displayed Value';
            app.DisplayedValueDropDown_2 = uidropdown(app.build_correlation_lineTab);
            app.DisplayedValueDropDown_2.Items = {'rmse', 'Height', 'Position', 'FWHM', 'Eta'};
            app.DisplayedValueDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayedValueDropDown_2ValueChanged, true);
            app.DisplayedValueDropDown_2.Value = 'Position';
            app.SaveDataButton_4 = uibutton(app.build_correlation_lineTab, 'push');
            app.SaveDataButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_1Pushed, true);
            app.SaveDataButton_4.Text = 'Save Data';
            app.fitDropDown_3Label = uilabel(app.build_correlation_lineTab);
            app.fitDropDown_3Label.Text = 'fit';
            app.fitDropDown_3 = uidropdown(app.build_correlation_lineTab);
            app.fitDropDown_3.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown_3.ValueChangedFcn = createCallbackFcn(app, @fitDropDown_3ValueChanged, true);
            app.fitDropDown_3.Value = '1';
            app.error_intervalDropDownLabel = uilabel(app.build_correlation_lineTab);
            app.error_intervalDropDownLabel.Text = 'error_interval';
            app.error_intervalDropDown = uidropdown(app.build_correlation_lineTab);
            app.error_intervalDropDown.Items = {'0.997', '0.95', '0.68'};
            app.error_intervalDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydataalongvalueDropDownValueChanged, true);
            app.error_intervalDropDown.Value = '0.68';
            app.show_sigle_fitCheckBox = uicheckbox(app.build_correlation_lineTab);
            app.show_sigle_fitCheckBox.ValueChangedFcn = createCallbackFcn(app, @show_sigle_fitCheckBoxValueChanged, true);
            app.show_sigle_fitCheckBox.Text = 'show_sigle_fit';
            app.DisplaydataalongvalueDropDownLabel = uilabel(app.build_correlation_lineTab);
            app.DisplaydataalongvalueDropDownLabel.Text = 'Display data along value';
            app.DisplaydataalongvalueDropDown = uidropdown(app.build_correlation_lineTab);
            app.DisplaydataalongvalueDropDown.Items = {'changing value 1', 'changing value 2', 'changing value 3', 'changing value 4'};
            app.DisplaydataalongvalueDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydataalongvalueDropDownValueChanged, true);
            app.DisplaydataalongvalueDropDown.Value = 'changing value 1';
            app.build_correlation_statisticTab = uitab(app.TabGroup2);
            app.build_correlation_statisticTab.Title = 'build_correlation_statistic';
            app.RefreshButton = uibutton(app.build_correlation_statisticTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.RefreshButton.Text = 'Refresh';
            app.Correlations_x_axisDropDownLabel = uilabel(app.build_correlation_statisticTab);
            app.Correlations_x_axisDropDownLabel.Text = 'Correlations_x_axis';
            app.Correlations_x_axisDropDown = uidropdown(app.build_correlation_statisticTab);
            app.Correlations_x_axisDropDown.Items = {'Height', 'Position', 'FWHM', 'Distance_to_position', 'Gaussian_cont', 'rmse'};
            app.Correlations_x_axisDropDown.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.Correlations_x_axisDropDown.Value = 'Position';
            app.Correlations_y_axisDropDownLabel = uilabel(app.build_correlation_statisticTab);
            app.Correlations_y_axisDropDownLabel.Text = 'Correlations_y_axis';
            app.Correlations_y_axisDropDown = uidropdown(app.build_correlation_statisticTab);
            app.Correlations_y_axisDropDown.Items = {'Height', 'Position', 'FWHM', 'Distance_to_position', 'Gaussian_cont', 'rmse'};
            app.Correlations_y_axisDropDown.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.Correlations_y_axisDropDown.Value = 'FWHM';
            app.Correlations_colorDropDown_Label = uilabel(app.build_correlation_statisticTab);
            app.Correlations_colorDropDown_Label.Text = 'Correlations_color';
            app.Correlations_colorDropDown = uidropdown(app.build_correlation_statisticTab);
            app.Correlations_colorDropDown.Items = {'Height', 'Position', 'FWHM', 'Distance_to_position', 'Gaussian_cont', 'rmse', 'none'};
            app.Correlations_colorDropDown.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.Correlations_colorDropDown.Tooltip = {'The positions x is changingvalue 1 and y is changingvalue 2.'};
            app.Correlations_colorDropDown.Value = 'Height';
            app.Correlations_z_axisDropDownLabel = uilabel(app.build_correlation_statisticTab);
            app.Correlations_z_axisDropDownLabel.Text = 'Correlations_z_axis';
            app.Correlations_z_axisDropDown = uidropdown(app.build_correlation_statisticTab);
            app.Correlations_z_axisDropDown.Items = {'Height', 'Position', 'FWHM', 'Distance_to_position', 'Gaussian_cont', 'rmse', 'none'};
            app.Correlations_z_axisDropDown.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.Correlations_z_axisDropDown.Value = 'none';
            app.xSpinnerLabel = uilabel(app.build_correlation_statisticTab);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.build_correlation_statisticTab);
            app.xSpinner.Step = 0.1;
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.xSpinner.Value = 37.5;
            app.ySpinnerLabel = uilabel(app.build_correlation_statisticTab);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.build_correlation_statisticTab);
            app.ySpinner.Step = 0.1;
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.ySpinner.Value = 37;
            app.d1SpinnerLabel = uilabel(app.build_correlation_statisticTab);
            app.d1SpinnerLabel.Text = 'd1';
            app.d1Spinner = uispinner(app.build_correlation_statisticTab);
            app.d1Spinner.Limits = [0 Inf];
            app.d1Spinner.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.only_points_in_rangeCheckBox = uicheckbox(app.build_correlation_statisticTab);
            app.only_points_in_rangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.only_points_in_rangeCheckBox.Text = 'only_points_in_range';
            app.d2SpinnerLabel = uilabel(app.build_correlation_statisticTab);
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.build_correlation_statisticTab);
            app.d2Spinner.Limits = [0 Inf];
            app.d2Spinner.ValueChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.d2Spinner.Value = 38;
            app.HistogramButtonGroup = uibuttongroup(app.build_correlation_statisticTab);
            app.HistogramButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Correlations_x_axisDropDownValueChanged, true);
            app.HistogramButtonGroup.Title = 'Histogram';
            app.PositionButton = uiradiobutton(app.HistogramButtonGroup);
            app.PositionButton.Text = 'Position';
            app.FWHMButton = uiradiobutton(app.HistogramButtonGroup);
            app.FWHMButton.Text = 'FWHM';
            app.HeightButton = uiradiobutton(app.HistogramButtonGroup);
            app.HeightButton.Text = 'Height';
            app.Distance_to_positionButton = uiradiobutton(app.HistogramButtonGroup);
            app.Distance_to_positionButton.Text = 'Distance_to_position';
            app.Gaussian_contButton = uiradiobutton(app.HistogramButtonGroup);
            app.Gaussian_contButton.Text = 'Gaussian_cont';
            app.rmseButton = uiradiobutton(app.HistogramButtonGroup);
            app.rmseButton.Text = 'rmse';
            app.noneButton = uiradiobutton(app.HistogramButtonGroup);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.display_peak_as_wavelengthCheckBox_2 = uicheckbox(app.build_correlation_statisticTab);
            app.display_peak_as_wavelengthCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @display_peak_as_wavelengthCheckBox_2ValueChanged, true);
            app.display_peak_as_wavelengthCheckBox_2.Text = 'display_peak_as_wavelength';
            app.display_peak_as_wavelengthCheckBox_2.Value = true;
            app.DisplayedPeakDropDown_4Label = uilabel(app.build_correlation_statisticTab);
            app.DisplayedPeakDropDown_4Label.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown_4 = uidropdown(app.build_correlation_statisticTab);
            app.DisplayedPeakDropDown_4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown_4.ValueChangedFcn = createCallbackFcn(app, @DisplayedPeakDropDown_4ValueChanged, true);
            app.DisplayedPeakDropDown_4.Value = '1';
            app.DisplayedValueDropDown_4Label = uilabel(app.build_correlation_statisticTab);
            app.DisplayedValueDropDown_4Label.Text = 'Displayed Value';
            app.DisplayedValueDropDown_4 = uidropdown(app.build_correlation_statisticTab);
            app.DisplayedValueDropDown_4.Items = {'rmse', 'Height', 'Position', 'FWHM', 'Eta'};
            app.DisplayedValueDropDown_4.ValueChangedFcn = createCallbackFcn(app, @DisplayedValueDropDown_4ValueChanged, true);
            app.DisplayedValueDropDown_4.Value = 'Position';
            app.fitDropDown_4Label = uilabel(app.build_correlation_statisticTab);
            app.fitDropDown_4Label.Text = 'fit';
            app.fitDropDown_4 = uidropdown(app.build_correlation_statisticTab);
            app.fitDropDown_4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown_4.ValueChangedFcn = createCallbackFcn(app, @fitDropDown_4ValueChanged, true);
            app.fitDropDown_4.Value = '1';
            app.SaveDataButton_5 = uibutton(app.build_correlation_statisticTab, 'push');
            app.SaveDataButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_1Pushed, true);
            app.SaveDataButton_5.Text = 'Save Data';
            app.Comparison_with_simulationTab = uitab(app.TabGroup2);
            app.Comparison_with_simulationTab.Title = 'Comparison_with_simulation';
            app.Load_sim_dataButton = uibutton(app.Comparison_with_simulationTab, 'push');
            app.Load_sim_dataButton.ButtonPushedFcn = createCallbackFcn(app, @Load_sim_dataButtonPushed, true);
            app.Load_sim_dataButton.Text = 'Load_sim_data';
            app.display_modeSwitch = uiswitch(app.Comparison_with_simulationTab, 'slider');
            app.display_modeSwitch.Items = {'nm', 'ML'};
            app.display_modeSwitch.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.display_modeSwitch.Enable = 'off';
            app.display_modeSwitch.Value = 'nm';
            app.display_typeSwitch = uiswitch(app.Comparison_with_simulationTab, 'slider');
            app.display_typeSwitch.Items = {'thickness', 'composition'};
            app.display_typeSwitch.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.display_typeSwitch.Enable = 'off';
            app.display_typeSwitch.Value = 'thickness';
            app.auto_MLCheckBox = uicheckbox(app.Comparison_with_simulationTab);
            app.auto_MLCheckBox.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.auto_MLCheckBox.Enable = 'off';
            app.auto_MLCheckBox.Text = 'auto_ML';
            app.auto_MLCheckBox.Value = true;
            app.n_contour_linesSpinnerLabel = uilabel(app.Comparison_with_simulationTab);
            app.n_contour_linesSpinnerLabel.Enable = 'off';
            app.n_contour_linesSpinnerLabel.Text = 'n_contour_lines';
            app.n_contour_linesSpinner = uispinner(app.Comparison_with_simulationTab);
            app.n_contour_linesSpinner.Limits = [1 Inf];
            app.n_contour_linesSpinner.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.n_contour_linesSpinner.Enable = 'off';
            app.n_contour_linesSpinner.Value = 5;
            app.DisplayedmaterialDropDownLabel = uilabel(app.Comparison_with_simulationTab);
            app.DisplayedmaterialDropDownLabel.Enable = 'off';
            app.DisplayedmaterialDropDownLabel.Text = 'Displayed material';
            app.DisplayedmaterialDropDown = uidropdown(app.Comparison_with_simulationTab);
            app.DisplayedmaterialDropDown.Items = {'GaAs', 'AlAs', 'AlGaAs', 'InAs', 'InGaAs'};
            app.DisplayedmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.DisplayedmaterialDropDown.Enable = 'off';
            app.DisplayedmaterialDropDown.Value = 'GaAs';
            app.DisplayedLayerDropDownLabel = uilabel(app.Comparison_with_simulationTab);
            app.DisplayedLayerDropDownLabel.Enable = 'off';
            app.DisplayedLayerDropDownLabel.Text = 'Displayed Layer';
            app.DisplayedLayerDropDown = uidropdown(app.Comparison_with_simulationTab);
            app.DisplayedLayerDropDown.Items = {'1'};
            app.DisplayedLayerDropDown.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.DisplayedLayerDropDown.Enable = 'off';
            app.DisplayedLayerDropDown.Value = '1';
            app.ML_contour_stepsEditFieldLabel = uilabel(app.Comparison_with_simulationTab);
            app.ML_contour_stepsEditFieldLabel.Enable = 'off';
            app.ML_contour_stepsEditFieldLabel.Text = 'ML_contour_steps';
            app.ML_contour_stepsEditField = uieditfield(app.Comparison_with_simulationTab, 'numeric');
            app.ML_contour_stepsEditField.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.ML_contour_stepsEditField.Enable = 'off';
            app.ML_contour_stepsEditField.Tooltip = {'Rounds the smallest and the highest value to the next integer (down/up) and finds levels in spacing of steps.'};
            app.ML_minSpinnerLabel = uilabel(app.Comparison_with_simulationTab);
            app.ML_minSpinnerLabel.Enable = 'off';
            app.ML_minSpinnerLabel.Text = 'ML_min';
            app.ML_minSpinner = uispinner(app.Comparison_with_simulationTab);
            app.ML_minSpinner.Step = 0.1;
            app.ML_minSpinner.Limits = [0 Inf];
            app.ML_minSpinner.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.ML_minSpinner.Enable = 'off';
            app.ML_minSpinner.Tooltip = {'In automatic mode, the smallest layer thickness rounded to the first decimal is displayed here. In manual mode, a layer thickness can be entered here up to which contour lines are shown. If this value is outside the simulated values, the smallest simulated value is used instead.'};
            app.ML_maxSpinnerLabel = uilabel(app.Comparison_with_simulationTab);
            app.ML_maxSpinnerLabel.Enable = 'off';
            app.ML_maxSpinnerLabel.Text = 'ML_max';
            app.ML_maxSpinner = uispinner(app.Comparison_with_simulationTab);
            app.ML_maxSpinner.Step = 0.1;
            app.ML_maxSpinner.Limits = [0 Inf];
            app.ML_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.ML_maxSpinner.Enable = 'off';
            app.ML_maxSpinner.Tooltip = {'In automatic mode, the largest layer thickness rounded to the first decimal is displayed here. In manual mode, a layer thickness can be entered here up to which contour lines are shown. If this value is outside the simulated values, the largest simulated value is used instead.'};
            app.overlay_contourButton = uibutton(app.Comparison_with_simulationTab, 'push');
            app.overlay_contourButton.ButtonPushedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.overlay_contourButton.Enable = 'off';
            app.overlay_contourButton.Text = 'overlay_contour';
            app.MapRefreshButton_7 = uibutton(app.Comparison_with_simulationTab, 'push');
            app.MapRefreshButton_7.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButton_6Pushed, true);
            app.MapRefreshButton_7.Enable = 'off';
            app.MapRefreshButton_7.Text = 'Refresh';
            app.sumCheckBox = uicheckbox(app.Comparison_with_simulationTab);
            app.sumCheckBox.Enable = 'off';
            app.sumCheckBox.Text = 'sum';
            app.lower_boundEditFieldLabel = uilabel(app.Comparison_with_simulationTab);
            app.lower_boundEditFieldLabel.Text = 'lower_bound';
            app.lower_boundEditField = uieditfield(app.Comparison_with_simulationTab, 'numeric');
            app.upper_boundEditFieldLabel = uilabel(app.Comparison_with_simulationTab);
            app.upper_boundEditFieldLabel.Text = 'upper_bound';
            app.upper_boundEditField = uieditfield(app.Comparison_with_simulationTab, 'numeric');
            app.excludeCheckBox = uicheckbox(app.Comparison_with_simulationTab);
            app.excludeCheckBox.Text = 'exclude';
            app.Map6 = uiaxes(app.AnalyzeFitDataTab);
            title(app.Map6, 'Map')
            xlabel(app.Map6, 'X (mm)')
            ylabel(app.Map6, 'Y (mm)')
            app.Spectrum8 = uiaxes(app.AnalyzeFitDataTab);
            title(app.Spectrum8, 'Spectrum')
            xlabel(app.Spectrum8, 'Wavelength (nm)')
            ylabel(app.Spectrum8, 'Intensity (counts/s)')
            app.Spectrum8_2 = uiaxes(app.AnalyzeFitDataTab);
            title(app.Spectrum8_2, 'Spectral range')
            xlabel(app.Spectrum8_2, 'Intensity (Counts/s)')
            ylabel(app.Spectrum8_2, 'Counts (#)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_fit_graph(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end