classdef app_gradient_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        menuMenu                        matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        Export_gifMenu                  matlab.ui.container.Menu
        graph_along_xMenu               matlab.ui.container.Menu
        graph_along_yMenu               matlab.ui.container.Menu
        CopytoclipboardMenu             matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        copy_mapMenu                    matlab.ui.container.Menu
        copy_graphMenu                  matlab.ui.container.Menu
        sand_data_toMenu                matlab.ui.container.Menu
        single_graphMenu                matlab.ui.container.Menu
        multi_graph_1Menu               matlab.ui.container.Menu
        multi_graph_2Menu               matlab.ui.container.Menu
        multi_graph_bothMenu            matlab.ui.container.Menu
        gradient_graphMenu              matlab.ui.container.Menu
        fit_graphMenu                   matlab.ui.container.Menu
        map_settingsMenu                matlab.ui.container.Menu
        map_colorMenu                   matlab.ui.container.Menu
        histogram_settingsMenu          matlab.ui.container.Menu
        show_statisticsMenu             matlab.ui.container.Menu
        enableMenu                      matlab.ui.container.Menu
        disableMenu                     matlab.ui.container.Menu
        binsMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GradientTab                     matlab.ui.container.Tab
        VertikalScanPanel               matlab.ui.container.Panel
        XSpinner_4Label                 matlab.ui.control.Label
        XSpinner_4                      matlab.ui.control.Spinner
        HorizontalScanPanel             matlab.ui.container.Panel
        YSpinner_4Label                 matlab.ui.control.Label
        YSpinner_4                      matlab.ui.control.Spinner
        FreeScanPanel                   matlab.ui.container.Panel
        X1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        Y1EditField                     matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        Y2EditField                     matlab.ui.control.NumericEditField
        Switch_7                        matlab.ui.control.Switch
        steps_widthSpinnerLabel         matlab.ui.control.Label
        steps_widthSpinner              matlab.ui.control.Spinner
        startingSwitch                  matlab.ui.control.Switch
        startingSwitchLabel             matlab.ui.control.Label
        get_line_distanceCheckBox       matlab.ui.control.CheckBox
        set_lineButton                  matlab.ui.control.StateButton
        set_line_from_centerButton      matlab.ui.control.StateButton
        snapp_to_gridCheckBox           matlab.ui.control.CheckBox
        MapPanel                        matlab.ui.container.Panel
        LowerWavelengthSpinner_4Label   matlab.ui.control.Label
        MapLowerWavelengthSpinner_4     matlab.ui.control.Spinner
        UpperWavelengthSpinner_4Label_2  matlab.ui.control.Label
        MapUpperWavelengthSpinner_4     matlab.ui.control.Spinner
        MapRefreshButton_4              matlab.ui.control.Button
        MinCountsEditField_4Label       matlab.ui.control.Label
        MapMinCountsEditField_4         matlab.ui.control.NumericEditField
        ColorstepsEditField_4Label      matlab.ui.control.Label
        MapColorstepsEditField_4        matlab.ui.control.NumericEditField
        getrangefromspectraButton       matlab.ui.control.Button
        Map_switch_4                    matlab.ui.control.Switch
        MaxCountsEditField_4Label       matlab.ui.control.Label
        MapMaxCountsEditField_4         matlab.ui.control.NumericEditField
        PowerSpinner_4Label             matlab.ui.control.Label
        PowerSpinner_4                  matlab.ui.control.Spinner
        TemperatureIndexSpinner_4Label  matlab.ui.control.Label
        TemperatureIndexSpinner_4       matlab.ui.control.Spinner
        MapappearanceDropDown_4         matlab.ui.control.DropDown
        ContourCheckBox_4               matlab.ui.control.CheckBox
        ncontourlinesSpinner_2Label     matlab.ui.control.Label
        ncontourlinesSpinner_2          matlab.ui.control.Spinner
        locktoareaCheckBox_4            matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        LowerWavelengthSpinner_5Label   matlab.ui.control.Label
        Spectrum_LowerWavelengthSpinner_1  matlab.ui.control.Spinner
        UpperWavelengthSpinner_5Label   matlab.ui.control.Label
        Spectrum_UpperWavelengthSpinner_1  matlab.ui.control.Spinner
        Switch_4                        matlab.ui.control.Switch
        ColorstepsEditField_5Label      matlab.ui.control.Label
        ColorstepsEditField_5           matlab.ui.control.NumericEditField
        getrangefrommapButton           matlab.ui.control.Button
        color_mapDropDownLabel          matlab.ui.control.Label
        color_mapDropDown               matlab.ui.control.DropDown
        OffsetSpinnerLabel              matlab.ui.control.Label
        OffsetSpinner_1                 matlab.ui.control.Spinner
        endSpinnerLabel                 matlab.ui.control.Label
        endSpinner                      matlab.ui.control.Spinner
        stepsSpinnerLabel               matlab.ui.control.Label
        stepsSpinner                    matlab.ui.control.Spinner
        plot_type                       matlab.ui.control.Switch
        RefreshButton_8                 matlab.ui.control.Button
        startSpinnerLabel               matlab.ui.control.Label
        startSpinner                    matlab.ui.control.Spinner
        sum_wavelengthCheckBox          matlab.ui.control.CheckBox
        Line_StyleDropDownLabel         matlab.ui.control.Label
        Line_StyleDropDown              matlab.ui.control.DropDown
        SampleInformationTextAreaLabel_2  matlab.ui.control.Label
        Sample_Information_4_TextArea   matlab.ui.control.TextArea
        Map4                            matlab.ui.control.UIAxes
        Spectrum4                       matlab.ui.control.UIAxes
        Spectrum4_2                     matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        DialogImportApp;
        DialogExportApp;
        DialogPreferencesApp;
        line_handle;
        starting_x;
        starting_y;
        starting_power;
        starting_tamperature;
        starting_color_steps;
        map_typ;
        idx_list;
        last_map;
        c_map;
        last_graph;
        c_graph;
        %coordinats for free gradients
        x_gradient;
        y_gradient;
        x_free_line;
        y_free_line;
    end
    
    properties (Access = public)
        gradient_dimantion;
        PLMap_Data;
        data;
        data_path;
        Data_Fit;
        programm_path;
        min_file_size;
        contourtext;
        contour_lines;
        bin;
        show_histogram_statistics;
        selectedTab;
        data_map;
        data_position;
        data_label_columns;
        area_dim_4;
    end
    
    methods (Access = private)
        function import_data(app,Data_Map,Data_Fit)
            start_values = cell(1,5);
            start_values{1,1} = app.starting_power;
            start_values{1,2} = app.starting_tamperature;
            start_values{1,3} = app.starting_x;
            start_values{1,4} = app.starting_y;
            start_values{1,5} = app.starting_color_steps;
            start_values{1,6} = app.map_typ;
            
            app.PLMap_Data = [];
            app.PLMap_Data = Data_Map;
            app.Data_Fit = [];
            app.Data_Fit = Data_Fit;
            
            %creat coordinat list
            app.x_gradient = nan(numel(app.PLMap_Data.X_vector) * numel(app.PLMap_Data.Y_vector),1);
            app.y_gradient = nan(numel(app.PLMap_Data.X_vector) * numel(app.PLMap_Data.Y_vector),1);
            j = 0;
            for ix = 1 : numel(app.PLMap_Data.X_vector)
                for iy = 1 : numel(app.PLMap_Data.Y_vector)
                    j = j+1;
                    app.x_gradient(j,1) = app.PLMap_Data.X_vector(ix);
                    app.y_gradient(j,1) = app.PLMap_Data.Y_vector(iy);
                end
            end
            
            %set the width spinner to good start values
            app.steps_widthSpinner.Value = app.PLMap_Data.X_Steps;
            
            [app.MapLowerWavelengthSpinner_4.Value,app.MapUpperWavelengthSpinner_4.Value,...
                app.TemperatureIndexSpinner_4.Value,app.PowerSpinner_4.Value] = start_Map(app,...
                app.Map4,app.Spectrum4,start_values,app.XSpinner_4,app.YSpinner_4,...
                app.PowerSpinner_4,app.TemperatureIndexSpinner_4,app.ContourCheckBox_4.Value);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            temperature = meta_data_text_average_tamperature(app.PLMap_Data.Temperature_array,1,power_index);
            app.Sample_Information_4_TextArea.Value = {app.PLMap_Data.Sample;app.PLMap_Data.Date;app.PLMap_Data.Measurment_time;temperature};
        end
        % ----------------------------------------------------------------------------------------------------------------
        % Display Map
        % ----------------------------------------------------------------------------------------------------------------
        function [lower_wavelength,upper_wavelength,temperature,power] = start_Map(app,Defind_Map_Axis,spectrum,...
                start_values,x_spinner,y_spinner,power_spiner,tamperature_spinner,contour_state)
            %define indeces of start power and tamperature
            power_index = define_Spinner(power_spiner,app.PLMap_Data.Power,start_values{1,1});
            tamperature_index = define_Spinner(tamperature_spinner,app.PLMap_Data.Temperature_index,...
                start_values{1,2});
            x = define_Spinner(x_spinner,app.PLMap_Data.X_vector,start_values{1,3});
            y = define_Spinner(y_spinner,app.PLMap_Data.Y_vector,start_values{1,4});
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [lower_wavelength,upper_wavelength,temperature,power,app.data_map,...
                app.c_map] = update_map(app.PLMap_Data,tamperature_spinner.Value,...
                power_spiner.Value,0,2000,Defind_Map_Axis,start_values{1,5},...
                start_values{1,6},-inf,inf,label,'integrated',contour_state,...
                app.ncontourlinesSpinner_2.Value,app.contourtext,[],0);
            
            app.last_map = Defind_Map_Axis;
            
            %display histogram
            update_histogram(app.Spectrum4_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            %display gradient
            start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.Y_vector,'low');
            end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.Y_vector,'upper');
            app.stepsSpinner.Value = 1;
                %creat Spectrum array
                j = 0;
                for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                    j = j+1;
                    PL_Intensity(j,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,:);
                end
                
                % define gradient diraction case
                app.gradient_dimantion = -1;
                
                %Define Wavelength vektor
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(1:numel(app.PLMap_Data.Wavelength));
                
                %Displays the Spectra
                [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(spectrum,app.Switch_4.Value,Spectral_Wavelength_Vector,...
                    app.PLMap_Data.Y_vector,PL_Intensity,start_values{1,5},app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
                
                %draws the waferadges in the Graph
                app.line_handle = line(Defind_Map_Axis,[x_spinner.Value x_spinner.Value],...
                    [min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle',...
                    '--', 'LineWidth', 2);
        end
        
        function show_map(app)
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [app.MapLowerWavelengthSpinner_4.Value,app.MapUpperWavelengthSpinner_4.Value,...
                app.TemperatureIndexSpinner_4.Value,app.PowerSpinner_4.Value,app.data_map,...
                app.c_map] = update_map(app.PLMap_Data,app.TemperatureIndexSpinner_4.Value,...
                app.PowerSpinner_4.Value,app.MapLowerWavelengthSpinner_4.Value,...
                app.MapUpperWavelengthSpinner_4.Value,app.Map4,...
                app.MapColorstepsEditField_4.Value,app.map_typ,app.MapMinCountsEditField_4.Value,...
                app.MapMaxCountsEditField_4.Value,label,app.MapappearanceDropDown_4.Value,...
                app.ContourCheckBox_4.Value,app.ncontourlinesSpinner_2.Value,app.contourtext,...
                app.area_dim_4,app.locktoareaCheckBox_4.Value);
            
            app.last_map = app.Map4;
        end
    end
    
    methods (Access = public)
        function import_data_public(app,Data_Map,Fit_Data_Map,rmse_Map,rsquare_Map,Data_fit_parameter,~)
            start_values = cell(1,5);
            start_values{1,1} = app.starting_power;
            start_values{1,2} = app.starting_tamperature;
            start_values{1,3} = app.starting_x;
            start_values{1,4} = app.starting_y;
            start_values{1,5} = app.starting_color_steps;
            start_values{1,6} = app.map_typ;
            
            app.PLMap_Data = [];
            app.PLMap_Data = Data_Map;
            app.Fit_Data_Map = {};
            app.rmse_Map = [];
            app.rsquare_Map = [];
            app.fit_data_parameter = [];
            
            %sets fit data
            if ~isempty(Fit_Data_Map)
                app.Fit_Data_Map = Fit_Data_Map;
            else
                app.Fit_Data_Map = cell(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets fit data_low
            if ~isempty(rmse_Map)
                app.rmse_Map = rmse_Map;
            else
                app.rmse_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets fit data_up
            if ~isempty(rsquare_Map)
                app.rsquare_Map = rsquare_Map;
            else
                app.rsquare_Map = nan(1,numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power), ...
                    numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            end
            
            %sets fit data
            if ~isempty(Data_fit_parameter)
                app.fit_data_parameter = Data_fit_parameter;
            else
                app.fit_data_parameter = cell(numel(app.PLMap_Data.Temperature_index),numel(app.PLMap_Data.Power),8);
            end
            
            %creat coordinat list
            app.x_gradient = nan(numel(app.PLMap_Data.X_vector) * numel(app.PLMap_Data.Y_vector),1);
            app.y_gradient = nan(numel(app.PLMap_Data.X_vector) * numel(app.PLMap_Data.Y_vector),1);
            j = 0;
            for ix = 1 : numel(app.PLMap_Data.X_vector)
                for iy = 1 : numel(app.PLMap_Data.Y_vector)
                    j = j+1;
                    app.x_gradient(j,1) = app.PLMap_Data.X_vector(ix);
                    app.y_gradient(j,1) = app.PLMap_Data.Y_vector(iy);
                end
            end
            
            %set the width spinner to good start values
            app.steps_widthSpinner.Value = app.PLMap_Data.X_Steps;
            
            [app.MapLowerWavelengthSpinner_4.Value,app.MapUpperWavelengthSpinner_4.Value,...
                app.TemperatureIndexSpinner_4.Value,app.PowerSpinner_4.Value] = start_Map(app,...
                app.Map4,app.Spectrum4,start_values,app.XSpinner_4,app.YSpinner_4,...
                app.PowerSpinner_4,app.TemperatureIndexSpinner_4,app.ContourCheckBox_4.Value);
            
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            temperature = meta_data_text_average_tamperature(app.PLMap_Data.Temperature_array,1,power_index);
            app.Sample_Information_4_TextArea.Value = {app.PLMap_Data.Sample;app.PLMap_Data.Date;app.PLMap_Data.Measurment_time;temperature};
        end 
        
        function update_contour_lines(app,contour_lines,contourtext)
            app.contour_lines = contour_lines;
            app.ncontourlinesSpinner_2.Value = contour_lines;
            app.contourtext = contourtext;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data_Map, Data_Fit)
        %Imports the Data to a Variable
            app.PLMap_Data = [];
            app.Data_Fit = [];
            
            str = pwd; %path containing the data
            str = [str,'/'];
            app.programm_path = join(str,"");
            temp_import_Structure = load([app.programm_path, 'config']);
            config_data = temp_import_Structure.config;
            
            app.starting_x = config_data.x;
            app.starting_y = config_data.y;
            app.starting_power = 'low';
            app.starting_tamperature = 'low';
            app.starting_color_steps = config_data.color_steps;
            
            app.map_typ =  config_data.map_typ;
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black'};
            app.idx_list = find(contains(list,config_data.map_typ));
            
            app.MapColorstepsEditField_4.Value = config_data.color_steps;
            app.ColorstepsEditField_5.Value = config_data.color_steps;
            app.data_path = config_data.path;
            app.min_file_size = config_data.min_file_size;
            app.contour_lines = config_data.contour_lines;
            app.contourtext = config_data.contourtext;
            app.bin = config_data.bin;
            app.show_histogram_statistics = config_data.show_histogram_statistics;
            app.area_dim_4 = [];
            
            app.selectedTab = app.TabGroup.SelectedTab;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            if ~isempty(Data_Map)
                import_data(app,Data_Map,Data_Fit);
            end
        end

        % Value changed function: MapColorstepsEditField_4, 
        % MapLowerWavelengthSpinner_4, MapMaxCountsEditField_4, 
        % MapMinCountsEditField_4, MapUpperWavelengthSpinner_4, 
        % MapappearanceDropDown_4, ncontourlinesSpinner_2
        function MapLowerWavelengthSpinner_4ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum4_2,app.data_map,app.bin,app.show_histogram_statistics);

            %redraw line
            switch app.gradient_dimantion
                case -2
                    app.line_handle = drawpolyline(app.Map4,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
                case -1
                    app.line_handle = line(app.Map4,[app.XSpinner_4.Value app.XSpinner_4.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);
                case 0
                    app.line_handle = line(app.Map4,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.YSpinner_4.Value app.YSpinner_4.Value],'color','g','LineStyle','--', 'LineWidth', 2);
            end
        end

        % Button pushed function: MapRefreshButton_4
        function MapRefreshButton_4Pushed(app, event)
            %define colormap dimentions
            app.MapColorstepsEditField_4.Value =100;
            app.MapMinCountsEditField_4.Value = -inf;
            app.MapMaxCountsEditField_4.Value = inf;
            
            MapLowerWavelengthSpinner_4ValueChanged(app, event);
        end

        % Value changed function: ColorstepsEditField_5, 
        % Line_StyleDropDown, OffsetSpinner_1, 
        % Spectrum_LowerWavelengthSpinner_1, 
        % Spectrum_UpperWavelengthSpinner_1, color_mapDropDown, 
        % endSpinner, plot_type, startSpinner, stepsSpinner
        function GradientUpperWavelengthSpinnerValueChanged(app, event)
            %define start and end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            app.Spectrum_UpperWavelengthSpinner_1.Value = app.PLMap_Data.Wavelength(end_wavelength_index);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %define indeces of x and y position
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            
            %creat Spectrum array
            switch app.gradient_dimantion
                case -2
                    %defines the start positions for the line
                    start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
                    
                    [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                        app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                        1,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                        app.get_line_distanceCheckBox.Value);
                    
                    for i = 1 : length(app.x_free_line)
                        ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                        iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
                    end
                    
                    %declares the data for clipboard
                    app.data_position = Position';
                    
                    %changes the y label ecordingly
                    ylabel(app.Spectrum4, y_label);
                    
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = drawpolyline(app.Map4,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
                case -1
                    %creat Spectrum array
                    j = 0;
                    start_index_rang = find_index(app.PLMap_Data.Y_vector,app.startSpinner.Value,1);
                    end_index_rang = find_index(app.PLMap_Data.Y_vector,app.endSpinner.Value,1);
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        j = j+1;
                        PL_Intensity(j,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                        Position(j) = app.PLMap_Data.Y_vector(i);
                    end
                    
                    app.data_position = Position;
                    ylabel(app.Spectrum4, 'Y [mm]');
                    
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = line(app.Map4,[app.XSpinner_4.Value app.XSpinner_4.Value],[app.PLMap_Data.Y_vector(start_index_rang) app.PLMap_Data.Y_vector(end_index_rang)],'color','g','LineStyle','--', 'LineWidth', 2);
                case 0
                    %creat Spectrum array
                    j = 0;
                    start_index_rang = find_index(app.PLMap_Data.X_vector,app.startSpinner.Value,1);
                    end_index_rang = find_index(app.PLMap_Data.X_vector,app.endSpinner.Value,1);
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        j = j+1;
                        PL_Intensity(j,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                        Position(j) = app.PLMap_Data.X_vector(i);
                    end
                    
                    app.data_position = Position;
                    ylabel(app.Spectrum4, 'X [mm]');
                    
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = line(app.Map4,[app.PLMap_Data.X_vector(start_index_rang) app.PLMap_Data.X_vector(end_index_rang)],[app.YSpinner_4.Value app.YSpinner_4.Value],'color','g','LineStyle','--', 'LineWidth', 2);
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
        end

        % Value changed function: XSpinner_4
        function XSpinner_4ValueChanged(app, event)
            app.gradient_dimantion = -1;
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %define closest x value
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            app.XSpinner_4.Value = app.PLMap_Data.X_vector(x);
            
            %creat Spectrum array
            start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.Y_vector,'low');
            end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.Y_vector,'upper');
            j = 0;
            for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                j = j+1;
                PL_Intensity(j,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                Position(j) = app.PLMap_Data.Y_vector(i);
            end
            
            ylabel(app.Spectrum4, 'Y [mm]');
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
            
            %draws the waferadges in the Graph
            delete(app.line_handle);
            app.line_handle = line(app.Map4,[app.XSpinner_4.Value app.XSpinner_4.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %define indeces of x and y position
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            
            %creat Spectrum array
            switch app.gradient_dimantion
                case -2
                    %defines the start positions for the line
                    start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
                    
                    [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                        app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                        0,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                        app.get_line_distanceCheckBox.Value);
                    
                    for i = 1 : length(app.x_free_line)
                        ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                        iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
                    end
                    
                    %declares the data for clipboard
                    app.data_position = Position';
                    
                    %changes the y label ecordingly
                    ylabel(app.Spectrum4, y_label);
                    
                case -1
                    %creat Spectrum array
                    for i = 1 : numel(app.PLMap_Data.Y_vector)
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'Y [mm]');
                    Position = app.PLMap_Data.Y_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                case 0
                    %creat Spectrum array
                    for i = 1 : numel(app.PLMap_Data.X_vector)
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                    end
                    
                    ylabel(app.Spectrum4, 'X [mm]');
                    Position = app.PLMap_Data.X_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
        end

        % Value changed function: YSpinner_4
        function YSpinner_4ValueChanged(app, event)
            app.gradient_dimantion = 0;
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %define closest y value
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            app.YSpinner_4.Value = app.PLMap_Data.Y_vector(y);
            
            %creat Spectrum array
            start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.X_vector,'low');
            end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.X_vector,'upper');
            j = 0;
            for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                j = j+1;
                PL_Intensity(j,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                Position(j) = app.PLMap_Data.X_vector(i);
            end
            
            %declares the data for clipboard
            app.data_position = Position;
            
            ylabel(app.Spectrum4, 'X [mm]');
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
            
            %draws the waferadges in the Graph
            delete(app.line_handle);
            app.line_handle = line(app.Map4,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.YSpinner_4.Value app.YSpinner_4.Value],'color','g','LineStyle','--', 'LineWidth', 2);
        end

        % Button pushed function: RefreshButton_8
        function GradientRefreshButtonPushed(app, event)
            %define start end end Wavelength index
            start_wavelength_index = 1;
            end_wavelength_index = numel(app.PLMap_Data.Wavelength);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %define indeces of x and y position
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            
            %creat Spectrum array
            switch app.gradient_dimantion
                case -2
                    %defines the start positions for the line
                    start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
                    
                    [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                        app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                        0,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                        app.get_line_distanceCheckBox.Value);
                    
                    for i = 1 : length(app.x_free_line)
                        ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                        iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
                    end
                    
                    %declares the data for clipboard
                    app.data_position = Position';
                    
                    %changes the y label ecordingly
                    ylabel(app.Spectrum4, y_label);
                    
                case -1
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.Y_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.Y_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'Y [mm]');
                    Position = app.PLMap_Data.Y_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                case 0
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.X_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.X_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'X [mm]');
                    Position = app.PLMap_Data.X_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
            xlim(app.Spectrum4,[-inf inf]);
            ylim(app.Spectrum4,[-inf inf]);
            
            %reset Ui values
            app.Spectrum_LowerWavelengthSpinner_1.Value = min(app.PLMap_Data.Wavelength);
            app.Spectrum_UpperWavelengthSpinner_1.Value = max(app.PLMap_Data.Wavelength);
        end

        % Button pushed function: getrangefromspectraButton
        function getrangefromspectraButtonPushed(app, event)
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [app.MapLowerWavelengthSpinner_4.Value,app.MapUpperWavelengthSpinner_4.Value,...
                app.TemperatureIndexSpinner_4.Value,app.PowerSpinner_4.Value,app.data_map,...
                app.c_map] = update_map(app.PLMap_Data,app.TemperatureIndexSpinner_4.Value,...
                app.PowerSpinner_4.Value,app.Spectrum_LowerWavelengthSpinner_1.Value,...
                app.Spectrum_UpperWavelengthSpinner_1.Value,app.Map4,...
                app.MapColorstepsEditField_4.Value,app.map_typ,app.MapMinCountsEditField_4.Value,...
                app.MapMaxCountsEditField_4.Value,label,app.MapappearanceDropDown_4.Value,...
                app.ContourCheckBox_4.Value,app.ncontourlinesSpinner_2.Value,app.contourtext,...
                app.area_dim_4,app.locktoareaCheckBox_4.Value);
            
            app.last_map = app.Map4;
            
            %display histogram
            update_histogram(app.Spectrum4_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            %redraw line
            switch app.gradient_dimantion
                case -2
                    app.line_handle = drawpolyline(app.Map4,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
                case -1
                    app.line_handle = line(app.Map4,[app.XSpinner_4.Value app.XSpinner_4.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);
                case 0
                    app.line_handle = line(app.Map4,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.YSpinner_4.Value app.YSpinner_4.Value],'color','g','LineStyle','--', 'LineWidth', 2);
            end
        end

        % Button pushed function: getrangefrommapButton
        function getrangefrommapButtonPushed(app, event)
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.MapLowerWavelengthSpinner_4.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.MapUpperWavelengthSpinner_4.Value,1);
            app.Spectrum_LowerWavelengthSpinner_1.Value = app.PLMap_Data.Wavelength(start_wavelength_index);
            app.Spectrum_UpperWavelengthSpinner_1.Value = app.PLMap_Data.Wavelength(end_wavelength_index);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %define indeces of x and y position
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            
            %creat Spectrum array
            switch app.gradient_dimantion
                case -2
                    %defines the start positions for the line
                    start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
                    
                    [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                        app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                        0,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                        app.get_line_distanceCheckBox.Value);
                    
                    for i = 1 : length(app.x_free_line)
                        ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                        iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
                    end
                    
                    %declares the data for clipboard
                    app.data_position = Position';
                    
                    %changes the y label ecordingly
                    ylabel(app.Spectrum4, y_label);
                    
                case -1
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.Y_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.Y_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'Y [mm]');
                    Position = app.PLMap_Data.Y_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                case 0
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.X_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.X_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'X [mm]');
                    Position = app.PLMap_Data.X_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogImportApp)
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.DialogExportApp = DialogExportApp(app,app.map_typ);
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            app.DialogPreferencesApp = DialogPreferencesApp(app);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            app.DialogImportApp = DialogImportApp(app);
        end

        % Value changed function: PowerSpinner_4
        function PowerSpinner_4ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum4_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            %define indeces of x/y position and power
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            app.XSpinner_4.Value = app.PLMap_Data.X_vector(x);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            app.YSpinner_4.Value = app.PLMap_Data.Y_vector(y);
            
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %creat Spectrum array
            switch app.gradient_dimantion
                case -2
                    %defines the start positions for the line
                    start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
                    
                    [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                        app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                        0,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                        app.get_line_distanceCheckBox.Value);
                    
                    for i = 1 : length(app.x_free_line)
                        ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                        iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
                    end
                    
                    %declares the data for clipboard
                    app.data_position = Position';
                    
                    %changes the y label ecordingly
                    ylabel(app.Spectrum4, y_label);
                    
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = drawpolyline(app.Map4,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
                case -1
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.Y_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.Y_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                        
                        app.line_handle = line(app.Map4,[app.XSpinner_4.Value app.XSpinner_4.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);
                    end
                    ylabel(app.Spectrum4, 'Y [mm]');
                    Position = app.PLMap_Data.Y_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                case 0
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.X_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.X_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'X [mm]');
                    Position = app.PLMap_Data.X_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                    
                    app.line_handle = line(app.Map4,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.YSpinner_4.Value app.YSpinner_4.Value],'color','g','LineStyle','--', 'LineWidth', 2);
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
        end

        % Value changed function: TemperatureIndexSpinner_4
        function TemperatureIndexSpinner_4ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum4_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            %define indeces of x/y position and power
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_4.Value,1);
            app.XSpinner_4.Value = app.PLMap_Data.X_vector(x);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_4.Value,1);
            app.YSpinner_4.Value = app.PLMap_Data.Y_vector(y);
            
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %change temparature values
            temperature = meta_data_text_average_tamperature(app.PLMap_Data.Temperature_array,tamperature_index,power_index);
            app.Sample_Information_4_TextArea.Value = {app.PLMap_Data.Sample;app.PLMap_Data.Date;app.PLMap_Data.Measurment_time;temperature};
            
            %creat Spectrum array
            switch app.gradient_dimantion
                case -2
                    %defines the start positions for the line
                    start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
                    
                    [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                        app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                        0,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                        app.get_line_distanceCheckBox.Value);
                    
                    for i = 1 : length(app.x_free_line)
                        ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                        iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
                    end
                    
                    %declares the data for clipboard
                    app.data_position = Position';
                    
                    %changes the y label ecordingly
                    ylabel(app.Spectrum4, y_label);
                    
                    %draws the waferadges in the Graph
                    delete(app.line_handle);
                    app.line_handle = drawpolyline(app.Map4,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
                case -1
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.Y_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.Y_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,i,x,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'Y [mm]');
                    Position = app.PLMap_Data.Y_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                   
                    app.line_handle = line(app.Map4,[app.XSpinner_4.Value app.XSpinner_4.Value],[min(app.PLMap_Data.Y_vector) max(app.PLMap_Data.Y_vector)],'color','g','LineStyle','--', 'LineWidth', 2);          
                case 0
                    %creat Spectrum array
                    start_index_rang = define_Spinner(app.startSpinner,app.PLMap_Data.X_vector,'low');
                    end_index_rang = define_Spinner(app.endSpinner,app.PLMap_Data.X_vector,'upper');
                    for i = start_index_rang : app.stepsSpinner.Value : end_index_rang
                        PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,y,i,start_wavelength_index:end_wavelength_index);
                    end
                    ylabel(app.Spectrum4, 'X [mm]');
                    Position = app.PLMap_Data.X_vector;
                    
                    %declares the data for clipboard
                    app.data_position = Position;
                    
                    app.line_handle = line(app.Map4,[min(app.PLMap_Data.X_vector) max(app.PLMap_Data.X_vector)],[app.YSpinner_4.Value app.YSpinner_4.Value],'color','g','LineStyle','--', 'LineWidth', 2);
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,app.plot_type.Value,app.OffsetSpinner_1);
        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            clipboard_data = [app.data_position' app.data_map];
            clipboard_data = num2cell(clipboard_data);
            clipboard_data = [app.data_label_columns; clipboard_data];
            
            copyed_array = mat2clip(clipboard_data, '\t');
        end

        % Menu selected function: copy_mapMenu
        function copy_mapMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map,ax, 'colorbar', app.c_map);
        end

        % Value changed function: X1EditField, X2EditField, 
        % Y1EditField, Y2EditField, get_line_distanceCheckBox, 
        % startingSwitch, steps_widthSpinner
        function X1EditFieldValueChanged(app, event)
            app.gradient_dimantion = -2;
            %define start end end Wavelength index
            start_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_LowerWavelengthSpinner_1.Value,1);
            end_wavelength_index = find_index(app.PLMap_Data.Wavelength,app.Spectrum_UpperWavelengthSpinner_1.Value,1);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_4.Value,1);
            tamperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_4.Value,1);
            
            %defines the start positions for the line
            start_position = [app.X1EditField.Value,app.Y1EditField.Value;app.X2EditField.Value,app.Y2EditField.Value];
            
            [Position,app.x_free_line,app.y_free_line,y_label] = points_on_line(start_position,...
                app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.Switch_7.Value,app.steps_widthSpinner.Value,...
                0,app.startSpinner,app.endSpinner,app.stepsSpinner.Value,app.startingSwitch.Value,...
                app.get_line_distanceCheckBox.Value);
            
            for i = 1 : length(app.x_free_line)
                ix = find_index(app.PLMap_Data.X_vector,app.x_free_line(i),1);
                iy = find_index(app.PLMap_Data.Y_vector,app.y_free_line(i),1);
                PL_Intensity(i,:) = app.PLMap_Data.data_array(tamperature_index,power_index,iy,ix,start_wavelength_index:end_wavelength_index);
            end
            
            %declares the data for clipboard
            app.data_position = Position';
            
            %changes the y label ecordingly
            ylabel(app.Spectrum4, y_label);
                    
            if isempty(PL_Intensity)
                return
            end
            
            app.data_label_columns = [];
            formatSpec = '%.1f';
            %Define Wavelength vektor
            if app.sum_wavelengthCheckBox.Value
                Spectral_Wavelength_Vector = [];
                app.data_label_columns{1,1} = 'Position';
                str1 = 'wavelength_';
                str2 = num2str(app.PLMap_Data.Wavelength(start_wavelength_index),formatSpec);
                str3 = num2str(app.PLMap_Data.Wavelength(end_wavelength_index),formatSpec);
                newStr = join([str1,str2,'_to_',str3],'');
                app.data_label_columns{1,2} = newStr;
            else
                Spectral_Wavelength_Vector = app.PLMap_Data.Wavelength(start_wavelength_index:end_wavelength_index);
                size_Spectral_Wavelength_Vector = size(Spectral_Wavelength_Vector);
                app.data_label_columns{1,1} = 'Position';
                for i = 1 : size_Spectral_Wavelength_Vector(1)
                    str1 = 'wavelength_';
                    str2 = num2str(Spectral_Wavelength_Vector(i),formatSpec);
                    newStr = join([str1,str2],'');
                    app.data_label_columns{1,i+1} = newStr;
                end
            end
            
            %Displays the Spectra
            [app.last_graph,app.data_map,app.c_graph] = Display_Gradient(app.Spectrum4,app.Switch_4.Value,Spectral_Wavelength_Vector,...
                Position,PL_Intensity,app.ColorstepsEditField_5.Value,app.color_mapDropDown.Value,app.Line_StyleDropDown.Value,...
                app.plot_type.Value,app.OffsetSpinner_1);
        
            %draws the waferadges in the Graph
            delete(app.line_handle);
            app.line_handle = drawpolyline(app.Map4,'Position',[app.x_free_line,app.y_free_line],'Color','g', 'LineWidth', 2,'Deletable',false);
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            switch app.Switch_7.Value
                case 'exactly'
                    app.steps_widthSpinnerLabel.Enable = 'off';
                    app.steps_widthSpinner.Enable = 'off';
                case 'close_to'
                    app.steps_widthSpinnerLabel.Enable = 'on';
                    app.steps_widthSpinner.Enable = 'on';
            end
            X1EditFieldValueChanged(app, event);
        end

        % Value changed function: ContourCheckBox_4
        function ContourCheckBox_4ValueChanged(app, event)
            if app.ContourCheckBox_4.Value
                app.ncontourlinesSpinner_2Label.Enable = 'on';
                app.ncontourlinesSpinner_2.Enable = 'on';
            else
                app.ncontourlinesSpinner_2Label.Enable = 'off';
                app.ncontourlinesSpinner_2.Enable = 'off';
            end
            MapLowerWavelengthSpinner_4ValueChanged(app, event);
        end

        % Value changed function: set_lineButton
        function set_lineButtonValueChanged(app, event)
            %gets the second point
            [roi, x, y] = select_data.selectDataLine(app.Map4,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,app.snapp_to_gridCheckBox.Value);
            
            %delets the points von the map an sets the value
            delete(roi);
            app.X1EditField.Value = x(1,1);
            app.X2EditField.Value = x(2,1);
            app.Y1EditField.Value = y(1,1);
            app.Y2EditField.Value = y(2,1);
            
            %get the points on the line an drwas the spectra
            X1EditFieldValueChanged(app, event);
            
            %reset the button
            app.set_lineButton.Value = 0;
        end

        % Value changed function: set_line_from_centerButton
        function set_line_from_centerButtonValueChanged(app, event)
            %sets the start value
            start_point = images.roi.Point(app.Map4,'Position',[37.5 37]);
            
            %gets the second point
            [roi, x, y] = select_data.selectDataPoint(app.Map4,app.PLMap_Data.X_Steps,app.PLMap_Data.Y_Steps,app.snapp_to_gridCheckBox.Value);
            
            %delets the points von the map an sets the value
            delete(roi);
            delete(start_point);
            app.X1EditField.Value = 37.5;
            app.X2EditField.Value = x(1,1);
            app.Y1EditField.Value = 37;
            app.Y2EditField.Value = y(1,1);
            
            %get the points on the line an drwas the spectra
            X1EditFieldValueChanged(app, event);
            
            %reset the button
            app.set_line_from_centerButton.Value = 0;
        end

        % Value changed function: sum_wavelengthCheckBox
        function sum_wavelengthCheckBoxValueChanged(app, event)
            if app.sum_wavelengthCheckBox.Value
                app.Line_StyleDropDownLabel.Enable = 'on';
                app.Line_StyleDropDown.Enable = 'on';
            else
                app.Line_StyleDropDownLabel.Enable = 'off';
                app.Line_StyleDropDown.Enable = 'off';
            end
            GradientUpperWavelengthSpinnerValueChanged(app, event);
        end

        % Menu selected function: single_graphMenu
        function single_graphMenuSelected(app, event)
            app_single_graph(app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: multi_graph_1Menu
        function multi_graph_1MenuSelected(app, event)
            app_multi_graph(app.PLMap_Data,app.Data_Fit,[],[]);
        end

        % Menu selected function: multi_graph_2Menu
        function multi_graph_2MenuSelected(app, event)
            app_multi_graph([],[],app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: multi_graph_bothMenu
        function multi_graph_bothMenuSelected(app, event)
            app_multi_graph(app.PLMap_Data,app.Data_Fit,app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: fit_graphMenu
        function fit_graphMenuSelected(app, event)
            app_fit_graph(app.PLMap_Data,app.Data_Fit,'PL_Map');
        end

        % Value changed function: locktoareaCheckBox_4
        function locktoareaCheckBox_4ValueChanged(app, event)
            if app.locktoareaCheckBox_4.Value
                prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                dlgtitle = 'Input area';
                dims = [1 35];
                definput = {'38','38','10','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                % Handle response
                if isempty(answer)
                    app.locktoareaCheckBox_4.Value = 0;
                    return
                else
                    app.area_dim_4(1) = str2num(answer{1});
                    app.area_dim_4(2) = str2num(answer{2});
                    app.area_dim_4(3) = str2num(answer{3});
                    app.area_dim_4(4) = str2num(answer{4});
                end
            else
                app.area_dim_4 = [];
            end
            MapLowerWavelengthSpinner_4ValueChanged(app, event);
        end

        % Menu selected function: map_colorMenu
        function map_colorMenuSelected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list = idx;
                app.map_typ = list{idx};
            end
            show_map(app);
        end

        % Menu selected function: copy_graphMenu
        function copy_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_graph,ax, 'colorbar', app.c_graph);
        end

        % Menu selected function: gradient_graphMenu
        function gradient_graphMenuSelected(app, event)
            app_gradient_graph(app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: enableMenu
        function enableMenuSelected(app, event)
            app.show_histogram_statistics = 1;
        end

        % Menu selected function: disableMenu
        function disableMenuSelected(app, event)
            app.show_histogram_statistics = 0;
        end

        % Menu selected function: binsMenu
        function binsMenuSelected(app, event)
            prompt = {'Enter number of bins:'};
            dlgtitle = 'define the number of bins';
            dims = [1 25];
            s = num2str(app.bin,'%u');
            definput = {s};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.bin = ceil(str2num(answer{1}));
        end

        % Menu selected function: graph_along_xMenu
        function graph_along_xMenuSelected2(app, event)
            %save data into .mat file
            save_data = uigetdir(app.data_path,'choose the path to save your graphs');
            
            for x = min(app.PLMap_Data.X_vector) : app.PLMap_Data.X_Steps : max(app.PLMap_Data.X_vector)
                app.XSpinner_4.Value = x;
                XSpinner_4ValueChanged(app, event)
                filename = join(['y_gradient_at_x_',strrep(num2str(x),'.','_'),'_mm']);
                title_name = join(['y-gradient at x = ',num2str(x),' mm']);
                title(app.Spectrum4, title_name);
                f = figure;
                f.Visible = 'off';
                ax = axes(f);
                copyUIGraphics.copyUIAxes(app.last_graph,ax, 'colorbar', app.c_graph);
                saveas(f, fullfile(save_data, filename), 'png');
                delete(f);
            end
        end

        % Menu selected function: graph_along_yMenu
        function graph_along_yMenuSelected(app, event)
            %save data into .mat file
            save_data = uigetdir(app.data_path,'choose the path to save your graphs');
            
            for y = min(app.PLMap_Data.Y_vector) : app.PLMap_Data.Y_Steps : max(app.PLMap_Data.Y_vector)
                app.YSpinner_4.Value = y;
                YSpinner_4ValueChanged(app, event)
                filename = join(['x_gradient_at_y_',strrep(num2str(y),'.','_'),'_mm']);
                title_name = join(['x-gradient at y = ',num2str(y),' mm']);
                title(app.Spectrum4, title_name);
                f = figure;
                f.Visible = 'off';
                ax = axes(f);
                copyUIGraphics.copyUIAxes(app.last_graph,ax, 'colorbar', app.c_graph);
                saveas(f, fullfile(save_data, filename), 'png');
                delete(f);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'hyperspectral analyzer by Marcel Schmidt at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.menuMenu = uimenu(app.UIFigure);
            app.menuMenu.Text = 'menu';
            app.LoadDataMenu = uimenu(app.menuMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Accelerator = 'l';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExportMenu = uimenu(app.menuMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.Export_gifMenu = uimenu(app.menuMenu);
            app.Export_gifMenu.Text = 'Export_gif';
            app.graph_along_xMenu = uimenu(app.Export_gifMenu);
            app.graph_along_xMenu.MenuSelectedFcn = createCallbackFcn(app, @graph_along_xMenuSelected2, true);
            app.graph_along_xMenu.Text = 'graph_along_x';
            app.graph_along_yMenu = uimenu(app.Export_gifMenu);
            app.graph_along_yMenu.MenuSelectedFcn = createCallbackFcn(app, @graph_along_yMenuSelected, true);
            app.graph_along_yMenu.Text = 'graph_along_y';
            app.CopytoclipboardMenu = uimenu(app.menuMenu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Accelerator = 'c';
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.PreferencesMenu = uimenu(app.menuMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Accelerator = 'p';
            app.PreferencesMenu.Text = 'Preferences';
            app.copy_mapMenu = uimenu(app.menuMenu);
            app.copy_mapMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_mapMenuSelected, true);
            app.copy_mapMenu.Text = 'copy_map';
            app.copy_graphMenu = uimenu(app.menuMenu);
            app.copy_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_graphMenuSelected, true);
            app.copy_graphMenu.Text = 'copy_graph';
            app.sand_data_toMenu = uimenu(app.UIFigure);
            app.sand_data_toMenu.Text = 'sand_data_to';
            app.single_graphMenu = uimenu(app.sand_data_toMenu);
            app.single_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @single_graphMenuSelected, true);
            app.single_graphMenu.Text = 'single_graph';
            app.multi_graph_1Menu = uimenu(app.sand_data_toMenu);
            app.multi_graph_1Menu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_1MenuSelected, true);
            app.multi_graph_1Menu.Text = 'multi_graph_1';
            app.multi_graph_2Menu = uimenu(app.sand_data_toMenu);
            app.multi_graph_2Menu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_2MenuSelected, true);
            app.multi_graph_2Menu.Text = 'multi_graph_2';
            app.multi_graph_bothMenu = uimenu(app.sand_data_toMenu);
            app.multi_graph_bothMenu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_bothMenuSelected, true);
            app.multi_graph_bothMenu.Text = 'multi_graph_both';
            app.gradient_graphMenu = uimenu(app.sand_data_toMenu);
            app.gradient_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @gradient_graphMenuSelected, true);
            app.gradient_graphMenu.Text = 'gradient_graph';
            app.fit_graphMenu = uimenu(app.sand_data_toMenu);
            app.fit_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @fit_graphMenuSelected, true);
            app.fit_graphMenu.Text = 'fit_graph';
            app.map_settingsMenu = uimenu(app.UIFigure);
            app.map_settingsMenu.Text = 'map_settings';
            app.map_colorMenu = uimenu(app.map_settingsMenu);
            app.map_colorMenu.MenuSelectedFcn = createCallbackFcn(app, @map_colorMenuSelected, true);
            app.map_colorMenu.Text = 'map_color';
            app.histogram_settingsMenu = uimenu(app.UIFigure);
            app.histogram_settingsMenu.Text = 'histogram_settings';
            app.show_statisticsMenu = uimenu(app.histogram_settingsMenu);
            app.show_statisticsMenu.Text = 'show_statistics';
            app.enableMenu = uimenu(app.show_statisticsMenu);
            app.enableMenu.MenuSelectedFcn = createCallbackFcn(app, @enableMenuSelected, true);
            app.enableMenu.Text = 'enable';
            app.disableMenu = uimenu(app.show_statisticsMenu);
            app.disableMenu.MenuSelectedFcn = createCallbackFcn(app, @disableMenuSelected, true);
            app.disableMenu.Text = 'disable';
            app.binsMenu = uimenu(app.histogram_settingsMenu);
            app.binsMenu.MenuSelectedFcn = createCallbackFcn(app, @binsMenuSelected, true);
            app.binsMenu.Text = '#bins';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GradientTab = uitab(app.TabGroup);
            app.GradientTab.Title = 'Gradient';
            app.VertikalScanPanel = uipanel(app.GradientTab);
            app.VertikalScanPanel.Title = 'Vertikal Scan';
            app.XSpinner_4Label = uilabel(app.VertikalScanPanel);
            app.XSpinner_4Label.Text = 'X';
            app.XSpinner_4 = uispinner(app.VertikalScanPanel);
            app.XSpinner_4.ValueChangedFcn = createCallbackFcn(app, @XSpinner_4ValueChanged, true);
            app.HorizontalScanPanel = uipanel(app.GradientTab);
            app.HorizontalScanPanel.Title = 'Horizontal Scan';
            app.YSpinner_4Label = uilabel(app.HorizontalScanPanel);
            app.YSpinner_4Label.Text = 'Y';
            app.YSpinner_4 = uispinner(app.HorizontalScanPanel);
            app.YSpinner_4.ValueChangedFcn = createCallbackFcn(app, @YSpinner_4ValueChanged, true);
            app.FreeScanPanel = uipanel(app.GradientTab);
            app.FreeScanPanel.Title = 'Free Scan';
            app.X1EditFieldLabel = uilabel(app.FreeScanPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.X1EditField.Limits = [0 76];
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X1EditField.Value = 37.5;
            app.X2EditFieldLabel = uilabel(app.FreeScanPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.X2EditField.Limits = [0 76];
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditField.Value = 38.5;
            app.Y1EditFieldLabel = uilabel(app.FreeScanPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.Y1EditField.Limits = [0 76];
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.Y1EditField.Value = 37;
            app.Y2EditFieldLabel = uilabel(app.FreeScanPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.FreeScanPanel, 'numeric');
            app.Y2EditField.Limits = [0 76];
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.Y2EditField.Value = 38;
            app.Switch_7 = uiswitch(app.FreeScanPanel, 'slider');
            app.Switch_7.Items = {'exactly', 'close_to'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Value = 'close_to';
            app.steps_widthSpinnerLabel = uilabel(app.FreeScanPanel);
            app.steps_widthSpinnerLabel.Text = 'steps_width';
            app.steps_widthSpinner = uispinner(app.FreeScanPanel);
            app.steps_widthSpinner.Step = 0.1;
            app.steps_widthSpinner.LowerLimitInclusive = 'off';
            app.steps_widthSpinner.Limits = [0 Inf];
            app.steps_widthSpinner.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.steps_widthSpinner.Value = 0.5;
            app.startingSwitch = uiswitch(app.FreeScanPanel, 'slider');
            app.startingSwitch.Items = {'left', 'right'};
            app.startingSwitch.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.startingSwitch.Value = 'left';
            app.startingSwitchLabel = uilabel(app.FreeScanPanel);
            app.startingSwitchLabel.Text = 'starting';
            app.get_line_distanceCheckBox = uicheckbox(app.FreeScanPanel);
            app.get_line_distanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.get_line_distanceCheckBox.Text = 'get_line_distance';
            app.set_lineButton = uibutton(app.FreeScanPanel, 'state');
            app.set_lineButton.ValueChangedFcn = createCallbackFcn(app, @set_lineButtonValueChanged, true);
            app.set_lineButton.Text = 'set_line';
            app.set_line_from_centerButton = uibutton(app.FreeScanPanel, 'state');
            app.set_line_from_centerButton.ValueChangedFcn = createCallbackFcn(app, @set_line_from_centerButtonValueChanged, true);
            app.set_line_from_centerButton.Text = 'set_line_from_center';
            app.snapp_to_gridCheckBox = uicheckbox(app.FreeScanPanel);
            app.snapp_to_gridCheckBox.Text = 'snapp_to_grid';
            app.snapp_to_gridCheckBox.Value = true;
            app.MapPanel = uipanel(app.GradientTab);
            app.MapPanel.Title = 'Map';
            app.LowerWavelengthSpinner_4Label = uilabel(app.MapPanel);
            app.LowerWavelengthSpinner_4Label.Text = 'Lower Wavelength';
            app.MapLowerWavelengthSpinner_4 = uispinner(app.MapPanel);
            app.MapLowerWavelengthSpinner_4.ValueDisplayFormat = '%.3f';
            app.MapLowerWavelengthSpinner_4.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.MapLowerWavelengthSpinner_4.Value = 340.2334;
            app.UpperWavelengthSpinner_4Label_2 = uilabel(app.MapPanel);
            app.UpperWavelengthSpinner_4Label_2.Text = 'Upper Wavelength';
            app.MapUpperWavelengthSpinner_4 = uispinner(app.MapPanel);
            app.MapUpperWavelengthSpinner_4.ValueDisplayFormat = '%.3f';
            app.MapUpperWavelengthSpinner_4.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.MapUpperWavelengthSpinner_4.Value = 1715.109591;
            app.MapRefreshButton_4 = uibutton(app.MapPanel, 'push');
            app.MapRefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButton_4Pushed, true);
            app.MapRefreshButton_4.Text = 'Refresh';
            app.MinCountsEditField_4Label = uilabel(app.MapPanel);
            app.MinCountsEditField_4Label.Text = 'Min Counts';
            app.MapMinCountsEditField_4 = uieditfield(app.MapPanel, 'numeric');
            app.MapMinCountsEditField_4.ValueDisplayFormat = '%1.2e';
            app.MapMinCountsEditField_4.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.MapMinCountsEditField_4.Value = -Inf;
            app.ColorstepsEditField_4Label = uilabel(app.MapPanel);
            app.ColorstepsEditField_4Label.Text = 'Color steps';
            app.MapColorstepsEditField_4 = uieditfield(app.MapPanel, 'numeric');
            app.MapColorstepsEditField_4.Limits = [1 Inf];
            app.MapColorstepsEditField_4.RoundFractionalValues = 'on';
            app.MapColorstepsEditField_4.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.MapColorstepsEditField_4.Value = 100;
            app.getrangefromspectraButton = uibutton(app.MapPanel, 'push');
            app.getrangefromspectraButton.ButtonPushedFcn = createCallbackFcn(app, @getrangefromspectraButtonPushed, true);
            app.getrangefromspectraButton.Text = 'get range from spectra';
            app.Map_switch_4 = uiswitch(app.MapPanel, 'slider');
            app.Map_switch_4.Items = {'log', 'linear'};
            app.Map_switch_4.Value = 'linear';
            app.MaxCountsEditField_4Label = uilabel(app.MapPanel);
            app.MaxCountsEditField_4Label.Text = 'Max Counts';
            app.MapMaxCountsEditField_4 = uieditfield(app.MapPanel, 'numeric');
            app.MapMaxCountsEditField_4.ValueDisplayFormat = '%1.2e';
            app.MapMaxCountsEditField_4.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.MapMaxCountsEditField_4.Value = Inf;
            app.PowerSpinner_4Label = uilabel(app.MapPanel);
            app.PowerSpinner_4Label.Text = 'Power';
            app.PowerSpinner_4 = uispinner(app.MapPanel);
            app.PowerSpinner_4.Step = 0.5;
            app.PowerSpinner_4.Limits = [0 76];
            app.PowerSpinner_4.ValueChangedFcn = createCallbackFcn(app, @PowerSpinner_4ValueChanged, true);
            app.PowerSpinner_4.Value = 1;
            app.TemperatureIndexSpinner_4Label = uilabel(app.MapPanel);
            app.TemperatureIndexSpinner_4Label.Text = 'Temperature Index';
            app.TemperatureIndexSpinner_4 = uispinner(app.MapPanel);
            app.TemperatureIndexSpinner_4.Step = 0.5;
            app.TemperatureIndexSpinner_4.Limits = [0 76];
            app.TemperatureIndexSpinner_4.ValueChangedFcn = createCallbackFcn(app, @TemperatureIndexSpinner_4ValueChanged, true);
            app.TemperatureIndexSpinner_4.Value = 1;
            app.MapappearanceDropDown_4 = uidropdown(app.MapPanel);
            app.MapappearanceDropDown_4.Items = {'integrated', 'averaged'};
            app.MapappearanceDropDown_4.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.MapappearanceDropDown_4.Value = 'integrated';
            app.ContourCheckBox_4 = uicheckbox(app.MapPanel);
            app.ContourCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ContourCheckBox_4ValueChanged, true);
            app.ContourCheckBox_4.Text = 'Contour';
            app.ncontourlinesSpinner_2Label = uilabel(app.MapPanel);
            app.ncontourlinesSpinner_2Label.Enable = 'off';
            app.ncontourlinesSpinner_2Label.Text = 'n contour lines';
            app.ncontourlinesSpinner_2 = uispinner(app.MapPanel);
            app.ncontourlinesSpinner_2.Limits = [1 Inf];
            app.ncontourlinesSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_4ValueChanged, true);
            app.ncontourlinesSpinner_2.Enable = 'off';
            app.ncontourlinesSpinner_2.Value = 5;
            app.locktoareaCheckBox_4 = uicheckbox(app.MapPanel);
            app.locktoareaCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBox_4ValueChanged, true);
            app.locktoareaCheckBox_4.Text = 'lock to area';
            app.Panel = uipanel(app.GradientTab);
            app.Panel.Title = 'Panel';
            app.LowerWavelengthSpinner_5Label = uilabel(app.Panel);
            app.LowerWavelengthSpinner_5Label.Text = 'Lower Wavelength';
            app.Spectrum_LowerWavelengthSpinner_1 = uispinner(app.Panel);
            app.Spectrum_LowerWavelengthSpinner_1.ValueDisplayFormat = '%.3f';
            app.Spectrum_LowerWavelengthSpinner_1.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.Spectrum_LowerWavelengthSpinner_1.Value = 340.2334;
            app.UpperWavelengthSpinner_5Label = uilabel(app.Panel);
            app.UpperWavelengthSpinner_5Label.Text = 'Upper Wavelength';
            app.Spectrum_UpperWavelengthSpinner_1 = uispinner(app.Panel);
            app.Spectrum_UpperWavelengthSpinner_1.ValueDisplayFormat = '%.3f';
            app.Spectrum_UpperWavelengthSpinner_1.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.Spectrum_UpperWavelengthSpinner_1.Value = 1715.109591;
            app.Switch_4 = uiswitch(app.Panel, 'slider');
            app.Switch_4.Items = {'linear', 'log'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'linear';
            app.ColorstepsEditField_5Label = uilabel(app.Panel);
            app.ColorstepsEditField_5Label.Text = 'Color steps';
            app.ColorstepsEditField_5 = uieditfield(app.Panel, 'numeric');
            app.ColorstepsEditField_5.Limits = [1 Inf];
            app.ColorstepsEditField_5.RoundFractionalValues = 'on';
            app.ColorstepsEditField_5.ValueDisplayFormat = '%.0f';
            app.ColorstepsEditField_5.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.ColorstepsEditField_5.Value = 50;
            app.getrangefrommapButton = uibutton(app.Panel, 'push');
            app.getrangefrommapButton.ButtonPushedFcn = createCallbackFcn(app, @getrangefrommapButtonPushed, true);
            app.getrangefrommapButton.Text = 'get range from map';
            app.color_mapDropDownLabel = uilabel(app.Panel);
            app.color_mapDropDownLabel.Text = 'color_map';
            app.color_mapDropDown = uidropdown(app.Panel);
            app.color_mapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black', 'wbry'};
            app.color_mapDropDown.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.color_mapDropDown.Value = 'jet';
            app.OffsetSpinnerLabel = uilabel(app.Panel);
            app.OffsetSpinnerLabel.Enable = 'off';
            app.OffsetSpinnerLabel.Text = 'Off-set';
            app.OffsetSpinner_1 = uispinner(app.Panel);
            app.OffsetSpinner_1.Limits = [0 Inf];
            app.OffsetSpinner_1.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.OffsetSpinner_1.Enable = 'off';
            app.OffsetSpinner_1.Value = 10;
            app.endSpinnerLabel = uilabel(app.Panel);
            app.endSpinnerLabel.Text = 'end';
            app.endSpinner = uispinner(app.Panel);
            app.endSpinner.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.stepsSpinnerLabel = uilabel(app.Panel);
            app.stepsSpinnerLabel.Text = 'steps';
            app.stepsSpinner = uispinner(app.Panel);
            app.stepsSpinner.Limits = [1 Inf];
            app.stepsSpinner.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.stepsSpinner.Value = 1;
            app.plot_type = uiswitch(app.Panel, 'slider');
            app.plot_type.Items = {'color', 'waterfall'};
            app.plot_type.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.plot_type.Value = 'color';
            app.RefreshButton_8 = uibutton(app.Panel, 'push');
            app.RefreshButton_8.ButtonPushedFcn = createCallbackFcn(app, @GradientRefreshButtonPushed, true);
            app.RefreshButton_8.Text = 'Refresh';
            app.startSpinnerLabel = uilabel(app.Panel);
            app.startSpinnerLabel.Text = 'start';
            app.startSpinner = uispinner(app.Panel);
            app.startSpinner.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.sum_wavelengthCheckBox = uicheckbox(app.Panel);
            app.sum_wavelengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @sum_wavelengthCheckBoxValueChanged, true);
            app.sum_wavelengthCheckBox.Text = 'sum_wavelength';
            app.Line_StyleDropDownLabel = uilabel(app.Panel);
            app.Line_StyleDropDownLabel.Enable = 'off';
            app.Line_StyleDropDownLabel.Text = 'Line_Style';
            app.Line_StyleDropDown = uidropdown(app.Panel);
            app.Line_StyleDropDown.Items = {'*', '-*', '--*', ':*', '-.*', 'o', '-o', '--o', ':o', '-.o', '-'};
            app.Line_StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @GradientUpperWavelengthSpinnerValueChanged, true);
            app.Line_StyleDropDown.Enable = 'off';
            app.Line_StyleDropDown.Value = '*';
            app.SampleInformationTextAreaLabel_2 = uilabel(app.GradientTab);
            app.SampleInformationTextAreaLabel_2.Text = 'Sample Information';
            app.Sample_Information_4_TextArea = uitextarea(app.GradientTab);
            app.Map4 = uiaxes(app.GradientTab);
            title(app.Map4, 'Map')
            xlabel(app.Map4, 'X [mm]')
            ylabel(app.Map4, 'Y [mm]')
            app.Spectrum4 = uiaxes(app.GradientTab);
            title(app.Spectrum4, 'Spectrum')
            xlabel(app.Spectrum4, 'Wavelength [nm]')
            ylabel(app.Spectrum4, 'Position [mm]')
            app.Spectrum4_2 = uiaxes(app.GradientTab);
            title(app.Spectrum4_2, 'Spectral range')
            xlabel(app.Spectrum4_2, 'Intensity [Counts/s]')
            ylabel(app.Spectrum4_2, 'Counts [#]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_gradient_graph(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end