classdef app_growth_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        growthsimulationbyMarcelSchmidtUIFigure  matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        load_sim_dataMenu               matlab.ui.container.Menu
        save_sim_dataMenu               matlab.ui.container.Menu
        CopytoclipboardMenu             matlab.ui.container.Menu
        copy_axisMenu                   matlab.ui.container.Menu
        copy_sim_axisMenu               matlab.ui.container.Menu
        copy_rate_leftMenu              matlab.ui.container.Menu
        copy_rate_rightMenu             matlab.ui.container.Menu
        create_new_rates_from_PLMenu    matlab.ui.container.Menu
        base_QWsMenu                    matlab.ui.container.Menu
        loadMenu                        matlab.ui.container.Menu
        extrasMenu                      matlab.ui.container.Menu
        display_critical_layer_thicknessMenu  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        simulationTab                   matlab.ui.container.Tab
        SimulateButton                  matlab.ui.control.Button
        QDgrowthLabel                   matlab.ui.control.Label
        display_modeSwitch              matlab.ui.control.Switch
        GridLabel                       matlab.ui.control.Label
        rot_dir                         matlab.ui.control.Switch
        contour_linesCheckBox           matlab.ui.control.CheckBox
        display_typeSwitch              matlab.ui.control.Switch
        FitDataLabel                    matlab.ui.control.Label
        Load_fit_dataButton             matlab.ui.control.Button
        compareButton                   matlab.ui.control.Button
        dimension_of_plotSwitch         matlab.ui.control.Switch
        resetButton                     matlab.ui.control.Button
        sumCheckBox                     matlab.ui.control.CheckBox
        show_sim_stepsCheckBox          matlab.ui.control.CheckBox
        Growth_modeDropDownLabel        matlab.ui.control.Label
        Growth_modeDropDown             matlab.ui.control.DropDown
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        Simmulation_sizeSpinnerLabel    matlab.ui.control.Label
        Simmulation_sizeSpinner         matlab.ui.control.Spinner
        time_per_rotationSpinnerLabel   matlab.ui.control.Label
        time_per_rotationSpinner        matlab.ui.control.Spinner
        step_resolutionSpinnerLabel     matlab.ui.control.Label
        step_resolutionSpinner          matlab.ui.control.Spinner
        rotSpinnerLabel                 matlab.ui.control.Label
        rotSpinner                      matlab.ui.control.Spinner
        stopSpinnerLabel                matlab.ui.control.Label
        stopSpinner                     matlab.ui.control.Spinner
        t_waitSpinnerLabel              matlab.ui.control.Label
        t_waitSpinner                   matlab.ui.control.Spinner
        start_angleSpinnerLabel         matlab.ui.control.Label
        start_angleSpinner              matlab.ui.control.Spinner
        x_contentSpinnerLabel           matlab.ui.control.Label
        x_contentSpinner                matlab.ui.control.Spinner
        x_startSpinnerLabel             matlab.ui.control.Label
        x_startSpinner                  matlab.ui.control.Spinner
        x_endSpinnerLabel               matlab.ui.control.Label
        x_endSpinner                    matlab.ui.control.Spinner
        x_stepsSpinnerLabel             matlab.ui.control.Label
        x_stepsSpinner                  matlab.ui.control.Spinner
        y_startSpinnerLabel             matlab.ui.control.Label
        y_startSpinner                  matlab.ui.control.Spinner
        y_endSpinnerLabel               matlab.ui.control.Label
        y_endSpinner                    matlab.ui.control.Spinner
        y_stepsSpinnerLabel             matlab.ui.control.Label
        y_stepsSpinner                  matlab.ui.control.Spinner
        growth_timeSpinnerLabel         matlab.ui.control.Label
        growth_timeSpinner              matlab.ui.control.Spinner
        n_contour_linesSpinnerLabel     matlab.ui.control.Label
        n_contour_linesSpinner          matlab.ui.control.Spinner
        growth_rat_1_set_valueEditFieldLabel  matlab.ui.control.Label
        growth_rat_1_set_valueEditField  matlab.ui.control.NumericEditField
        t_growthSpinnerLabel            matlab.ui.control.Label
        t_growthSpinner                 matlab.ui.control.Spinner
        growth_rat_2_set_valueEditFieldLabel  matlab.ui.control.Label
        growth_rat_2_set_valueEditField  matlab.ui.control.NumericEditField
        changing_rateDropDownLabel      matlab.ui.control.Label
        changing_rateDropDown           matlab.ui.control.DropDown
        DisplayedPeakDropDownLabel      matlab.ui.control.Label
        DisplayedPeakDropDown           matlab.ui.control.DropDown
        DisplayedValueDropDownLabel     matlab.ui.control.Label
        DisplayedValueDropDown          matlab.ui.control.DropDown
        PowerSpinnerLabel               matlab.ui.control.Label
        PowerSpinner                    matlab.ui.control.Spinner
        TemperatureIndexSpinnerLabel    matlab.ui.control.Label
        TemperatureIndexSpinner         matlab.ui.control.Spinner
        DisplayedmaterialDropDownLabel  matlab.ui.control.Label
        DisplayedmaterialDropDown       matlab.ui.control.DropDown
        DisplayedLayerDropDownLabel     matlab.ui.control.Label
        DisplayedLayerDropDown          matlab.ui.control.DropDown
        steps_per_rotSpinnerLabel       matlab.ui.control.Label
        steps_per_rotSpinner            matlab.ui.control.Spinner
        ML_contour_stepsEditFieldLabel  matlab.ui.control.Label
        ML_contour_stepsEditField       matlab.ui.control.NumericEditField
        effective_growth_rateEditFieldLabel  matlab.ui.control.Label
        effective_growth_rateEditField  matlab.ui.control.NumericEditField
        T_growthEditFieldLabel          matlab.ui.control.Label
        T_growthEditField               matlab.ui.control.NumericEditField
        ML_ratesButton                  matlab.ui.control.Button
        desorption_rateEditFieldLabel   matlab.ui.control.Label
        desorption_rateEditField        matlab.ui.control.NumericEditField
        break_timeSpinnerLabel          matlab.ui.control.Label
        break_timeSpinner               matlab.ui.control.Spinner
        fitDropDownLabel                matlab.ui.control.Label
        fitDropDown                     matlab.ui.control.DropDown
        gammaLabel                      matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.NumericEditField
        RoughnessLabel                  matlab.ui.control.Label
        showroughnessButton             matlab.ui.control.Button
        QDdensityLabel                  matlab.ui.control.Label
        alphaEditFieldLabel             matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        critical_thicknessEditFieldLabel  matlab.ui.control.Label
        critical_thicknessEditField     matlab.ui.control.NumericEditField
        maximum_coverageEditFieldLabel  matlab.ui.control.Label
        maximum_coverageEditField       matlab.ui.control.NumericEditField
        showdensityButton               matlab.ui.control.Button
        R_0EditFieldLabel               matlab.ui.control.Label
        R_0EditField                    matlab.ui.control.NumericEditField
        Delta_REditFieldLabel           matlab.ui.control.Label
        Delta_REditField                matlab.ui.control.NumericEditField
        conversion_factorEditFieldLabel  matlab.ui.control.Label
        conversion_factorEditField      matlab.ui.control.NumericEditField
        roughness_version               matlab.ui.control.Switch
        incloudroughnessCheckBox        matlab.ui.control.CheckBox
        roughness_loadedLampLabel       matlab.ui.control.Label
        roughness_loadedLamp            matlab.ui.control.Lamp
        critical_layer_thicknessEditFieldLabel  matlab.ui.control.Label
        critical_layer_thicknessEditField  matlab.ui.control.NumericEditField
        display_peak_as_wavelengthCheckBox  matlab.ui.control.CheckBox
        refreshButton                   matlab.ui.control.Button
        overlay_contourButton           matlab.ui.control.Button
        ML_minSpinnerLabel              matlab.ui.control.Label
        ML_minSpinner                   matlab.ui.control.Spinner
        ML_maxSpinnerLabel              matlab.ui.control.Label
        ML_maxSpinner                   matlab.ui.control.Spinner
        auto_MLCheckBox                 matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        recipeTab                       matlab.ui.container.Tab
        recipe                          matlab.ui.control.Table
        add_layerButton                 matlab.ui.control.Button
        SimulateButton_2                matlab.ui.control.Button
        simulated_layersTab             matlab.ui.container.Tab
        simulated_layers                matlab.ui.control.Table
        display_summed_layerButton      matlab.ui.control.StateButton
        computeratesfromPLTab           matlab.ui.container.Tab
        calculateratesButton            matlab.ui.control.Button
        qwtypDropDownLabel              matlab.ui.control.Label
        qwtypDropDown                   matlab.ui.control.DropDown
        BrowseButton                    matlab.ui.control.Button
        sampleIDEditFieldLabel          matlab.ui.control.Label
        sampleIDEditField               matlab.ui.control.NumericEditField
        temperature_indexSpinnerLabel   matlab.ui.control.Label
        temperature_indexSpinner        matlab.ui.control.Spinner
        power_indexSpinnerLabel         matlab.ui.control.Label
        power_indexSpinner              matlab.ui.control.Spinner
        GaAs_indexSpinnerLabel          matlab.ui.control.Label
        GaAs_indexSpinner               matlab.ui.control.Spinner
        QW_indexSpinnerLabel            matlab.ui.control.Label
        QW_indexSpinner                 matlab.ui.control.Spinner
        GaAs_growth_timeSpinnerLabel    matlab.ui.control.Label
        GaAs_growth_timeSpinner         matlab.ui.control.Spinner
        degre_1SpinnerLabel             matlab.ui.control.Label
        degre_1Spinner                  matlab.ui.control.Spinner
        QW_growth_timeSpinnerLabel      matlab.ui.control.Label
        QW_growth_timeSpinner           matlab.ui.control.Spinner
        degre_2SpinnerLabel             matlab.ui.control.Label
        degre_2Spinner                  matlab.ui.control.Spinner
        degre_stepsSpinnerLabel         matlab.ui.control.Label
        degre_stepsSpinner              matlab.ui.control.Spinner
        cell_angleSpinnerLabel          matlab.ui.control.Label
        cell_angleSpinner               matlab.ui.control.Spinner
        cellDropDownLabel               matlab.ui.control.Label
        cellDropDown                    matlab.ui.control.DropDown
        Ga_cell_angleSpinnerLabel       matlab.ui.control.Label
        Ga_cell_angleSpinner            matlab.ui.control.Spinner
        show_rotationButton             matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        GaAs_peakSpinnerLabel           matlab.ui.control.Label
        GaAs_peakSpinner                matlab.ui.control.Spinner
        QW_peakSpinnerLabel             matlab.ui.control.Label
        QW_peakSpinner                  matlab.ui.control.Spinner
        rate_gaasEditFieldLabel         matlab.ui.control.Label
        rate_gaasEditField              matlab.ui.control.NumericEditField
        rate_qwEditFieldLabel           matlab.ui.control.Label
        rate_qwEditField                matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    
    properties (Access = private)
        f_ga_rate %growth rate function
        f_al_low_rate %growth rate function
        f_al_high_rate %growth rate function
        f_in_rate %growth rate function
        start_angle_AL %correction vor different QW stops during growth
        start_angle_IN %correction vor different QW stops during growth
        growth_rate_ga %computed grid growth rates
        growth_rate_al127 %computed grid growth rates
        growth_rate_al33 %computed grid growth rates
        growth_rate_in %computed grid growth rates
        Ga_thickness_matrix %thickness creates by growth
        Al_thickness_matrix %thickness creates by growth
        In_thickness_matrix %thickness creates by growth
        thickness_matrix %current matrix
        x_matrix %current matrix
        dim
        v_creation_indicator %indicates that v was created
        v %grid fector to be simulated
        mesh_size_v
        v_rot90 %grid fector to be simulated
        size_v
        X_vector %x simulation range
        Y_vector %y simulation range
        x_center %center of rotation
        y_center %center of rotation
        lable %graph labels
        fit_data %fit data to be compared
        Fit_Data_Map;
        Fit_Data_function_parameter;
        Fit_Data_function_grade;
        rmse_Map;
        rsquare_Map;
        displayed_fit;
        n_fitparameter;
        grade;
        displayed_peak;
        displayed_fit_parameter;
        PLMap_Data %PL map data to be compared
        last_map %sets the last changed map vor export
        c % sets the colormal for export
        programm_path %the current programm path
        data_path %start path from the config
        map_typ
        copy_map %map vo strg+c
        R %roughness map
        d_PDL %thickness of the Petter definition layer
        data %data for the fit of the growthrates
        rate_right
        rate_right_c
        rate_left
        rate_left_c
    end
    
    methods (Access = private)
        
        function thickness = grow(app,f_growth_rate,set_value_growth_rate,angle_correction,d)
            %compute corrigation factor for growthrate
            if any(set_value_growth_rate)
                j = 0;
                for x = -1 : 0.5 : 1
                    for y = -1 : 0.5 : 1
                        j = j+1;
                        middle_rat = f_growth_rate([x,y]);
                        factor(j) = set_value_growth_rate/middle_rat;
                    end
                end
                factor = mean(factor);
            else
                factor = 1;
            end
            
            %rotation direction
            switch app.rot_dir.Value
                case 'clockwise'
                    rotation_dir = 1;
                case 'counterclockwise'
                    rotation_dir = 0;
            end
            
            %create the center of rotation
            center = make_center(app.x_center,app.y_center,app.size_v);
            
            
            %angle to theta
            theta = converter.degr2rad(app.start_angleSpinner.Value + angle_correction);
            
            %define what to be grown
            switch app.Growth_modeDropDown.Value
                case 'Rotation'
                    %get rotations
                    rotations = app.growth_timeSpinner.Value/app.time_per_rotationSpinner.Value;
                    [full_rotations,partial_rotations,~] = rotation.parameters(rotations);
                    
                    %time per step
                    time_per_step = rotation.time_per_step(app.time_per_rotationSpinner.Value,app.steps_per_rotSpinner.Value);
                    
                    %theta change per step
                    theta_step_size = converter.theta_steps(app.steps_per_rotSpinner.Value);
                    
                    %grow rotation layer
                    [thickness,thickness_steps,~] = grow_rotation_layer(full_rotations,partial_rotations,rotation_dir,app.steps_per_rotSpinner.Value,theta,app.v,app.size_v,center,f_growth_rate,factor,time_per_step,theta_step_size);
                    
                    if app.show_sim_stepsCheckBox.Value
                        size_thickness_steps = size(thickness_steps);
                        for i = 1 : size_thickness_steps(1)
                            thickness_metrix = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,thickness_steps(i,:));
                            display_sim(app,thickness_metrix,[]);
                            pause(0.1)
                        end
                    end
                case 'Gradient'
                    thickness = grow_stop_layer(rotation_dir,theta,app.v,center,f_growth_rate,factor,app.growth_timeSpinner.Value);
                case 'QD'
                    %stop
                    t_stop_growth = app.stopSpinner.Value * app.t_growthSpinner.Value;
                    thickness(:,1) = grow_stop_layer(rotation_dir,theta,app.v,center,f_growth_rate,factor,t_stop_growth);
                    
                    %rot
                    %get rotations
                    [full_rotations,partial_rotations,~] = rotation.parameters(app.rotSpinner.Value);
                    
                    %time per step
                    time_per_step = rotation.time_per_step(app.time_per_rotationSpinner.Value,app.steps_per_rotSpinner.Value);
                    
                    %theta change per step
                    theta_step_size = converter.theta_steps(app.steps_per_rotSpinner.Value);
                    
                    %steps during wait time
                    steps_per_time = rotation.during_time(app.t_waitSpinner.Value,time_per_step);
                    
                    %rotation in one growth phase
                    qd_rotations = app.t_growthSpinner.Value/app.time_per_rotationSpinner.Value;
                    
                    if t_stop_growth ~= 0
                        %first rot
                        theta = theta + steps_per_time * theta_step_size;
                    end
                    
                    %calculate uniform desorption for stop layers
                    t_stop_growth = t_stop_growth + app.stopSpinner.Value * app.t_waitSpinner.Value;
                    thickness(:,1) = thickness(:,1) - t_stop_growth * app.desorption_rateEditField.Value;
                    
                    %iterate growth
                    j = 1;
                    elapsedTime = nan(full_rotations);
                    elapsedTime(1) = 1;
                    for i = 1 : full_rotations
                        tic
                        s = seconds(mean(elapsedTime,'all','omitnan')*(full_rotations-(i-1)));
                        s.Format = 'hh:mm:ss';
                        estimate_time = char(s);
                        d.Message = sprintf([' grow rotation %1.0u ... estimate time = ',estimate_time],i);
                        drawnow
                        
                        %grow rotation layer
                        j = j+1;
                        [thickness(:,j),~,end_angle] = grow_rotation_layer(0,qd_rotations,rotation_dir,app.steps_per_rotSpinner.Value,...
                            theta,app.v,app.size_v,center,f_growth_rate,factor,time_per_step,theta_step_size);
                        theta = end_angle + steps_per_time * theta_step_size;
                        
                        %calculate uniform desorption for stop layers
                        t_desorption = app.t_growthSpinner.Value + app.t_waitSpinner.Value;
                        thickness(:,j) = thickness(:,j) - t_desorption * app.desorption_rateEditField.Value;
                        
                        %get the time for the rotation iteration
                        elapsedTime(i) = toc;
                    end
                    
                    if partial_rotations > 0
                        d.Message = 'grow partial rotation ...';
                        drawnow
                        %rotation partial growth phase
                        qd_rotations = partial_rotations * app.t_growthSpinner.Value/app.time_per_rotationSpinner.Value;
                        
                        %grow rotation layer
                        j = j+1;
                        [thickness(:,j),~,end_angle] = grow_rotation_layer(0,qd_rotations,rotation_dir,app.steps_per_rotSpinner.Value,...
                            theta,app.v,app.size_v,center,f_growth_rate,factor,time_per_step,theta_step_size);
                        
                        %calculate uniform desorption for stop layers
                        t_desorption = partial_rotations * (app.t_growthSpinner.Value + app.t_waitSpinner.Value);
                        thickness(:,j) = thickness(:,j) - t_desorption * app.desorption_rateEditField.Value;
                    end
                    
                    if app.show_sim_stepsCheckBox.Value
                        size_thickness = size(thickness);
                        for i = 1 : size_thickness(2)
                            thickness_step = thickness(:,i);
                            thickness_metrix = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,thickness_step');
                            display_sim(app,thickness_metrix,[]);
                            pause(2)
                        end
                    end
                    thickness = sum(thickness,2);
            end
        end
        
        function end_angle = growth_pause(app,start_angle)
            %displays a statusBar indicationg the progras of importing the data into an matlab structure
            figure_ui = uifigure;
            d = uiprogressdlg(figure_ui,'Title','simulate growth ...','Message','create vector ...','Indeterminate','on');
            drawnow;
            pause(0.5);
            
            %time per step
            time_per_step = rotation.time_per_step(app.time_per_rotationSpinner.Value,app.steps_per_rotSpinner.Value);
            
            %theta change per step
            theta_step_size = converter.theta_steps(app.steps_per_rotSpinner.Value);
            
            %calculate end angle
            end_angle = start_angle + app.break_timeSpinner.Value * time_per_step * theta_step_size;
            
            %get dimentions
            if ~isempty(app.Ga_thickness_matrix)
                size_of_thickness = size(app.Ga_thickness_matrix);
                j = size_of_thickness(1)+1;
                app.dim{end+1} = num2str(j);
            else
                j = 1;
                app.dim{1} =  num2str(j);
            end
            
            %sets the layer number
            app.DisplayedLayerDropDown.Items = app.dim;
            app.DisplayedLayerDropDown.Value = num2str(j);
            
            switch app.Growth_modeDropDown.Value
                case 'QD_break'
                    In_thickness = zeros(numel(app.Y_vector)*numel(app.X_vector),1);
                    desorption = app.break_timeSpinner.Value * app.desorption_rateEditField.Value;
                    In_thickness = In_thickness - desorption;
                    app.In_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,In_thickness);
                case 'break'
                    app.In_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
            end
            
            %sets the other layers to nan
            app.Al_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
            app.Ga_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
            
            %close the statusbar
            close(d);
            close(figure_ui);
        end
        
        function [thickness_matrix,x_matrix] = create_sim_data(app)
            temp_x = [];
            x_matrix = [];
            
            %gets witch material to be displayed
            switch app.DisplayedmaterialDropDown.Value
                case 'GaAs'
                    temp_thickness_matrix = app.Ga_thickness_matrix;
                case 'AlAs'
                    temp_thickness_matrix = app.Al_thickness_matrix;
                case 'InAs'
                    temp_thickness_matrix = app.In_thickness_matrix;
                case 'AlGaAs'
                    temp_ga_thickness_matrix = app.Ga_thickness_matrix;
                    temp_al_thickness_matrix = app.Al_thickness_matrix;
                    size_tensor = size(temp_ga_thickness_matrix);
                    temp_thickness_matrix = reshape((temp_ga_thickness_matrix(:)+temp_al_thickness_matrix(:)),size_tensor);
                    
                    temp_x = reshape(temp_al_thickness_matrix./(temp_al_thickness_matrix+temp_ga_thickness_matrix),size_tensor);
                case 'InGaAs'
                    temp_ga_thickness_matrix = app.Ga_thickness_matrix;
                    temp_in_thickness_matrix = app.In_thickness_matrix;
                    size_tensor = size(temp_ga_thickness_matrix);
                    temp_thickness_matrix = reshape((temp_ga_thickness_matrix(:)+temp_in_thickness_matrix(:)),size_tensor);
                    
                    temp_x = reshape(temp_in_thickness_matrix./(temp_in_thickness_matrix+temp_ga_thickness_matrix),size_tensor);
            end
            
            %gets value to be displayed
            if app.sumCheckBox.Value
                thickness_matrix(:,:) = sum(temp_thickness_matrix,1);
                if ~isempty(temp_x)
                    x_matrix(:,:) = sum(temp_x,1);
                end
            else
                j = str2num(app.DisplayedLayerDropDown.Value);
                thickness_matrix(:,:) = temp_thickness_matrix(j,:,:);
                if ~isempty(temp_x)
                    x_matrix(:,:) = temp_x(j,:,:);
                end
            end
        end
        
        function display_sim(app,thickness_matrix,x_matrix)
            switch app.display_typeSwitch.Value
                %dasplaying the composition map
                case 'composition'
                    if ~isempty(x_matrix)
                        cla(app.UIAxes,'reset')
                        
                        %change the label for the graph
                        app.lable{4} = '%';
                        
                        %dispaly the grown layer
                        [app.last_map,app.c] = Display_Map(app.UIAxes,app.X_vector,app.Y_vector,x_matrix,500,app.map_typ,...
                            app.lable,-inf,inf,app.contour_linesCheckBox.Value,app.n_contour_linesSpinner.Value,'on',0);
                        
                        %declares current map vor copy
                        app.copy_map = x_matrix;
                    end
                case 'thickness'
                    if ~isempty(thickness_matrix)
                        cla(app.UIAxes,'reset')
                        
                        %dasplaying the thickness map
                        switch app.display_modeSwitch.Value
                            case 'nm'
                                %change the label for the graph
                                app.lable{4} = 'nm';
                            case 'ML'
                                %change the label for the graph
                                app.lable{4} = 'ML';
                                
                                %convert nm to ML
                                thickness_matrix = converter.nm_to_ML(app.DisplayedmaterialDropDown.Value,thickness_matrix,x_matrix,app.T_growthEditField.Value);
                        end
                       
                        ML_min = min(thickness_matrix,[],'all');
                        ML_min_round = round(ML_min,1);
                        if ML_min_round > ML_min
                            ML_min_round = ML_min_round - 0.1;
                        end
                        
                        ML_max = max(thickness_matrix,[],'all');
                        ML_max_round = round(ML_max,1);
                        if ML_max_round < ML_max
                            ML_max_round = ML_max_round + 0.1;
                        end
                        
                        if app.auto_MLCheckBox.Value
                            app.ML_minSpinner.Value = ML_min_round;
                            app.ML_maxSpinner.Value = ML_max_round;
                        else
                            if app.ML_minSpinner.Value < ML_min_round
                                app.ML_minSpinner.Value = ML_min_round;
                            end
                            if app.ML_maxSpinner.Value > ML_max_round
                                app.ML_maxSpinner.Value = ML_max_round;
                            end
                        end
                        % ML_min = round(min(thickness_matrix,[],'all'),2);
%                         ML_max = round(max(thickness_matrix,[],'all'),2);
                        if app.ML_contour_stepsEditField.Value > 0
                            contour_lines = [app.ML_minSpinner.Value : app.ML_contour_stepsEditField.Value : app.ML_maxSpinner.Value];
                        else
                            contour_lines = app.n_contour_linesSpinner.Value;
                        end
                        
                        %dispaly the grown layer
                        [app.last_map,app.c] = Display_Map(app.UIAxes,app.X_vector,app.Y_vector,thickness_matrix,500,...
                            app.map_typ,app.lable,-inf,inf,app.contour_linesCheckBox.Value,contour_lines,'on',0);
                    end
                    %declares current map vor copy
                    app.copy_map = thickness_matrix;
            end
        end
        
        function R = calculate_standard_roughness(app,thickness_metrix,x_matrix)
            app.d_PDL = converter.nm_to_ML(app.MaterialDropDown.Value,thickness_metrix,x_matrix,app.T_growthEditField.Value);
            R = converter.thickness_to_roughness(app.d_PDL,app.gammaEditField.Value);
        end
        
        function R = calculate_expand_roughness(app,thickness_metrix,x_matrix)
            app.d_PDL = converter.nm_to_ML(app.MaterialDropDown.Value,thickness_metrix,x_matrix,app.T_growthEditField.Value);
            R = app.R_0EditField.Value + app.Delta_REditField.Value * sin(pi * app.d_PDL).^2;
        end
    
        
        function calculate_growthrates_from_PL_data(app,center,degree)
            figure_ui = uifigure;
            d = uiprogressdlg(figure_ui,'Title','calculate growth rates ...','Message','calculate growth rates ...','Indeterminate','on');
            drawnow;
            pause(0.5);
            
            elapsedTime = nan(length(degree));
            elapsedTime(1) = 35;
            tic
            for i = 1 : length(degree)
                s = seconds(mean(elapsedTime,'all','omitnan')*length(degree));
                s.Format = 'hh:mm:ss';
                estimate_time = char(s);
                d.Message = sprintf(['compute growthrate with rotation = %2.1f ... estimate time = ',estimate_time],degree(i));
                drawnow
                
                %(data_pl_map,fit_data,file_qw,t,p,peak_gaas,peak_qw,t_growth_gaas,t_growth_qw,center,degr)
                [gaas_growth_rate_raw,gaas_growth_rate,gaas_growth_rate_rot,qw_growth_rate,sf_gaas,x_qw,sf_qw,...
                    a_gaas,a_qw] = create_rates_from_pl_data(app.PLMap_Data,app.Fit_Data_Map,app.Fit_Data_function_parameter,...
                    app.qwtypDropDown.Value,app.temperature_indexSpinner.Value,app.power_indexSpinner.Value,...
                    app.GaAs_peakSpinner.Value,app.QW_peakSpinner.Value,app.GaAs_indexSpinner.Value,...
                    app.QW_indexSpinner.Value,app.GaAs_growth_timeSpinner.Value,app.QW_growth_timeSpinner.Value,center,degree(i));
                app.data{i}.sample_id  = app.sampleIDEditField.Value;
                app.data{i}.gaas_growth_rate_rot  = gaas_growth_rate_rot;
                app.data{i}.gaas_growth_rate_raw  = gaas_growth_rate_raw;
                app.data{i}.gaas_growth_rate  = gaas_growth_rate;
                app.data{i}.t_growth_gaas = app.GaAs_growth_timeSpinner.Value;
                app.data{i}.t_growth_qw = app.QW_growth_timeSpinner.Value;
                app.data{i}.sf_gaas = sf_gaas;
                app.data{i}.sf_qw = sf_qw;
                app.data{i}.qw_growth_rate  = qw_growth_rate;
                app.data{i}.x_qw = x_qw;
                app.data{i}.x_degr = degree(i);
                app.data{i}.a_gaas = a_gaas;
                app.data{i}.a_qw = a_qw;
                
                %% get timestemp
                format longG
                time = now;
                time_stamp = datetime(time,'ConvertFrom','datenum');
                app.data{i}.time_stamp = time_stamp;
                
                %get the time for the rotation iteration
                elapsedTime(i) = toc;
            end
            
            %close the statusbar
            close(d);
            close(figure_ui);
        end
        
        function pre_processed_data_map = update_fit_map(app)
            [pre_processed_data_map,~,~] = get_fit_data(app.PLMap_Data,app.Fit_Data_Map,app.rmse_Map,app.PowerSpinner.Value,app.TemperatureIndexSpinner.Value,app.displayed_fit,app.displayed_peak,app.displayed_fit_parameter,app.n_fitparameter);
            
            if app.displayed_fit_parameter == 2
                if app.display_peak_as_wavelengthCheckBox.Value
                    pre_processed_data_map = converter.energy_wavelength(pre_processed_data_map);
                end
            elseif app.displayed_fit_parameter == 3
                if app.n_fitparameter == 4
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'pseudo-voigt');
                else
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'gauss');
                end
                pre_processed_data_map = pre_processed_data_map * 1000;
            end
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X (mm)';
            label{3} = 'Y (mm)';
            
            %gets the correct c label
            if app.displayed_fit_parameter == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter == 3
                label{4} = 'FWHM (meV)';
            elseif app.displayed_fit_parameter == 4
                label{4} = 'Eta';
            elseif app.displayed_fit_parameter == 5
                label{4} = 'rmse';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %Display map
            [pre_processed_data_map,app.last_map,app.c] = Display_Fit_Map(app.PLMap_Data,[],pre_processed_data_map,0,...
                app.UIAxes,100,app.map_typ,label,-inf,inf,0,10,'off',0);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_map, fit_data)
            str = pwd; %path containing the data
            str = [str,'/'];
            app.programm_path = join(str,"");
            temp_import_Structure = load([app.programm_path, 'config']);
            config_data = temp_import_Structure.config;
            app.data_path = config_data.path;
            app.map_typ = config_data.map_typ;
            
            %start up v creation
            app.v_creation_indicator = 1; %indicates that v was not created
            app.v = []; %grid fector to be simulated
            app.mesh_size_v = [];
            app.v_rot90 = []; %grid fector to be simulated
            app.size_v = [];
            
            app.Ga_thickness_matrix  = [];%thickness creates by growth
            app.Al_thickness_matrix  = [];%thickness creates by growth
            app.In_thickness_matrix  = [];%thickness creates by growth
            app.thickness_matrix  = [];%current matrix
            app.x_matrix  = [];%current matrix
            
            %simulation parameter
            app.x_center = 37.5;
            app.y_center = 37;
            
            app.start_angle_AL = -11;
            app.start_angle_IN = 3;
            
            
            if isempty(data_map)
                app.X_vector = app.x_startSpinner.Value : app.x_stepsSpinner.Value : app.x_endSpinner.Value;
                app.Y_vector = app.y_startSpinner.Value : app.y_stepsSpinner.Value : app.y_endSpinner.Value;
            else
                app.PLMap_Data = data_map;
                app.X_vector = data_map.X_vector;
                app.Y_vector = data_map.Y_vector;
                x_min_index = define_Spinner(app.x_startSpinner,data_map.X_vector,'low');
                x_max_index = define_Spinner(app.x_endSpinner,data_map.X_vector,'upper');
                y_min_index = define_Spinner(app.y_startSpinner,data_map.X_vector,'low');
                y_max_index = define_Spinner(app.y_endSpinner,data_map.X_vector,'upper');
                app.x_stepsSpinner.Value = data_map.X_Steps;
                app.y_stepsSpinner.Value = data_map.Y_Steps;
            end
            
            if ~isempty(fit_data)
                app.PowerSpinnerLabel.Enable = 'on';
                app.PowerSpinner.Enable = 'on';
                app.TemperatureIndexSpinnerLabel.Enable = 'on';
                app.TemperatureIndexSpinner.Enable = 'on';
                app.DisplayedPeakDropDownLabel.Enable = 'on';
                app.DisplayedPeakDropDown.Enable = 'on';
                app.DisplayedValueDropDownLabel.Enable = 'on';
                app.DisplayedValueDropDown.Enable = 'on';
                app.fitDropDown.Enable  = 'on';
                app.fitDropDownLabel.Enable = 'on';
                
                app.fit_data = fit_data;
                
                %changes the fit spinners
                size_fit_data = size(app.fit_data.Fit_Data_Map);
                
                %tamperature number
                if size_fit_data(1) ~=1
                    app.TemperatureIndexSpinner.Limits = [1 size_fit_data(2)];
                end
                
                %power number
                if size_fit_data(2) ~=1
                    app.PowerSpinner.Limits = [1 size_fit_data(3)];
                end
                
                %fit number
                number_of_different_peaks = size_fit_data(1);
                app.fitDropDown.Items = split(num2str(1:number_of_different_peaks))';
                
                p = app.PowerSpinner.Value;
                t = app.TemperatureIndexSpinner.Value;
                displayed_fit = num2str(app.fitDropDown.Value);
                
                parameter_array(:,:) = app.fit_data.Fit_Data_function_parameter(displayed_fit,t,p,:,:);
                grade_array(:,:) = app.fit_data.Fit_Data_function_grade(displayed_fit,t,p,:,:);
                
                parameter = max(parameter_array,[],'all');
                grade = max(grade_array,[],'all');
                
                if str2num(app.DisplayedPeakDropDown_1.Value)>grade
                    app.DisplayedPeakDropDown.Value = '1';
                end
                
                app.DisplayedPeakDropDown.Items = split(num2str(1:grade))';
                
                switch parameter
                    case 3
                        app.DisplayedValueDropDown.Items = {'Height', 'Position', 'FWHM'};
                    case 4
                        app.DisplayedValueDropDown.Items = {'Height', 'Position', 'FWHM', 'Eta'};
                end
            end
            
            %label for graph
            app.lable{1} = 'Thickness of the grown layer';
            app.lable{2} = 'X [mm]';
            app.lable{3} = 'Y [mm]';
            app.lable{4} = 'nm';
            
            %load growth rates
%             sim_data = load('al_low_rate.mat');
%             app.f_ga_rate = sim_data.data.sf_gaas;
%             app.f_al_low_rate = sim_data.data.sf_qw;
%             sim_data = load('al_high_rate.mat');
%             app.f_al_high_rate = sim_data.data.sf_qw;
%             sim_data = load('in_rate.mat');
%             app.f_in_rate = sim_data.data.sf_qw;
            
            %create intial recipe table
            Material =  convertCharsToStrings(app.MaterialDropDown.Value);
            growth_rat_1_set_value = app.growth_rat_1_set_valueEditField.Value;
            growth_rat_2_set_value = app.growth_rat_2_set_valueEditField.Value;
            x_content = app.x_contentSpinner.Value;
            Growth_mode = convertCharsToStrings(app.Growth_modeDropDown.Value);
            start_angle = app.start_angleSpinner.Value;
            t_growth = app.growth_timeSpinner.Value;
            t_wait = app.t_waitSpinner.Value;
            qd_stop = app.stopSpinner.Value;
            qd_rot = app.rotSpinner.Value;
            desorption_rate = app.desorption_rateEditField.Value;
            
            t = table(Material,growth_rat_1_set_value,growth_rat_2_set_value,x_content,Growth_mode,start_angle,t_growth,t_wait,qd_stop,qd_rot,desorption_rate);
        
            % Convert Material to categorical
            cats = categorical(t.Material,{'GaAs','AlAs','AlGaAs','InAs','InGaAs'});
            t.Material = cats;
            
            % Convert Material to categorical
            cats = categorical(t.Growth_mode,{'Rotation','Gradient','QD','break','QD_break'});
            t.Growth_mode = cats;
            
            % Add data to the Table UI Component
            app.recipe.Data = t;
            
            app.roughness_loadedLamp.Color = [1,0,0];
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = app.growthsimulationbyMarcelSchmidtUIFigure.Position(3);
            window_height = app.growthsimulationbyMarcelSchmidtUIFigure.Position(4);
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.growthsimulationbyMarcelSchmidtUIFigure.Position = [x,y,window_width,window_height];
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %displays a statusBar indicationg the progras of importing the data into an matlab structure
            figure_ui = uifigure;
            d = uiprogressdlg(figure_ui,'Title','simulate growth ...','Message','create vector ...','Indeterminate','on');
            drawnow;
            pause(0.5);
            
            %create v if nessesery
            if app.v_creation_indicator
                %create the grid parameters
                [app.mesh_size_v,app.v,app.size_v] = create_v(app.X_vector,app.Y_vector,app.x_stepsSpinner.Value,app.y_stepsSpinner.Value);
                app.v_creation_indicator = 0;
            end
            
            %get dimentions
            if ~isempty(app.Ga_thickness_matrix)
                size_of_thickness = size(app.Ga_thickness_matrix);
                j = size_of_thickness(1)+1;
                app.dim{end+1} = num2str(j);
            else
                j = 1;
                app.dim{1} =  num2str(j);
            end
            
            %sets the layer number
            app.DisplayedLayerDropDown.Items = app.dim;
            app.DisplayedLayerDropDown.Value = num2str(j);
            
            d.Message = 'grow layer ...';
            %define material to be grown
            switch app.MaterialDropDown.Value
                case 'GaAs'
                    f_growth_rate = app.f_ga_rate;
                    Ga_thickness = grow(app,f_growth_rate,app.growth_rat_1_set_valueEditField.Value,0,d);
                    %creating the matrix from the vector
                    app.Ga_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,Ga_thickness);
                    app.Al_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
                    app.In_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
                case 'AlAs'
                    f_growth_rate = app.f_al_low_rate;
                    Al_thickness = grow(app,f_growth_rate,app.growth_rat_1_set_valueEditField.Value,app.start_angle_AL,d);
                    %creating the matrix from the vector
                    app.Ga_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
                    app.Al_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,Al_thickness);
                    app.In_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
                case 'InAs'
                    f_growth_rate = app.f_in_rate;
                    In_thickness = grow(app,f_growth_rate,app.growth_rat_1_set_valueEditField.Value,app.start_angle_IN,d);
                    %creating the matrix from the vector
                    app.Ga_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
                    app.Al_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
                    app.In_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,In_thickness);
                case 'AlGaAs'
                    d.Message = 'grow al layer ...';
                    f_growth_rate = app.f_al_low_rate;
                    Al_thickness = grow(app,f_growth_rate,app.growth_rat_1_set_valueEditField.Value,app.start_angle_AL,d);
                    %creating the matrix from the vector
                    app.Al_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,Al_thickness);                    
                    
                    d.Message = 'grow ga layer ...';
                    f_growth_rate = app.f_ga_rate;
                    Ga_thickness = grow(app,f_growth_rate,app.growth_rat_2_set_valueEditField.Value,0,d);                                    
                    %creating the matrix from the vector
                    app.Ga_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,Ga_thickness);     
                    
                    app.In_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));    
                case 'InGaAs'
                    d.Message = 'grow in layer ...';
                    f_growth_rate = app.f_in_rate;
                    In_thickness = grow(app,f_growth_rate,app.growth_rat_1_set_valueEditField.Value,app.start_angle_IN,d);
                    %creating the matrix from the vector
                    app.In_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,In_thickness);

                    d.Message = 'grow ga layer ...';
                    f_growth_rate = app.f_ga_rate;
                    Ga_thickness = grow(app,f_growth_rate,app.growth_rat_2_set_valueEditField.Value,0,d);
                    %creating the matrix from the vector
                    app.Ga_thickness_matrix(j,:,:) = creat_thicknass_matrix(app.v,app.X_vector,app.Y_vector,app.x_center,app.y_center,app.Simmulation_sizeSpinner.Value,Ga_thickness);
                    
                    app.Al_thickness_matrix(j,:,:) = nan(numel(app.Y_vector),numel(app.X_vector));
            end
            
            d.Message = 'display simulation ...';
            %displays the simulation
            [app.thickness_matrix,app.x_matrix] = create_sim_data(app);
            display_sim(app,app.thickness_matrix,app.x_matrix);

            app.display_modeSwitch.Enable = 'on';
            app.contour_linesCheckBox.Enable = 'on';
            app.display_typeSwitch.Enable = 'on';
            app.DisplayedmaterialDropDownLabel.Enable = 'on';
            app.DisplayedmaterialDropDown.Enable = 'on';
            app.DisplayedLayerDropDownLabel.Enable = 'on';
            app.DisplayedLayerDropDown.Enable = 'on';
            app.sumCheckBox.Enable = 'on';
            app.n_contour_linesSpinnerLabel.Enable = 'on';
            app.n_contour_linesSpinner.Enable = 'on';
            app.ML_contour_stepsEditFieldLabel.Enable = 'on';
            app.ML_contour_stepsEditField.Enable = 'on';
            app.ML_maxSpinnerLabel.Enable = 'on';
            app.ML_maxSpinner.Enable = 'on';
            app.ML_minSpinnerLabel.Enable = 'on';
            app.ML_minSpinner.Enable = 'on';
            app.auto_MLCheckBox.Enable = 'on';
                
            if ~isempty(app.fit_data)
                app.compareButton.Enable = 'on';
                app.dimension_of_plotSwitch.Enable = 'on';
            end
            
            %close the statusbar
            close(d);
            close(figure_ui);
        end

        % Value changed function: Growth_modeDropDown
        function Growth_modeDropDownValueChanged(app, event)
            %changes the displayed buttons
            switch app.Growth_modeDropDown.Value
                case 'Rotation'
                    app.rotSpinnerLabel.Enable = 'off';
                    app.rotSpinner.Enable = 'off';
                    app.stopSpinnerLabel.Enable = 'off';
                    app.stopSpinner.Enable = 'off';
                    app.t_growthSpinnerLabel.Enable = 'off';
                    app.t_growthSpinner.Enable = 'off';
                    app.t_waitSpinnerLabel.Enable = 'off';
                    app.t_waitSpinner.Enable = 'off';
                    app.growth_timeSpinnerLabel.Enable = 'on';
                    app.growth_timeSpinner.Enable = 'on';
                    app.effective_growth_rateEditFieldLabel.Enable = 'off';
                    app.effective_growth_rateEditField.Enable = 'off';
                    app.desorption_rateEditFieldLabel.Enable = 'off';
                    app.desorption_rateEditField.Enable = 'off';
                    app.break_timeSpinnerLabel.Enable = 'off';
                    app.break_timeSpinner.Enable = 'off';
                case 'Gradient'
                    app.rotSpinnerLabel.Enable = 'off';
                    app.rotSpinner.Enable = 'off';
                    app.stopSpinnerLabel.Enable = 'off';
                    app.stopSpinner.Enable = 'off';
                    app.t_growthSpinnerLabel.Enable = 'off';
                    app.t_growthSpinner.Enable = 'off';
                    app.t_waitSpinnerLabel.Enable = 'off';
                    app.t_waitSpinner.Enable = 'off';
                    app.growth_timeSpinnerLabel.Enable = 'on';
                    app.growth_timeSpinner.Enable = 'on';
                    app.effective_growth_rateEditFieldLabel.Enable = 'off';
                    app.effective_growth_rateEditField.Enable = 'off';
                    app.desorption_rateEditFieldLabel.Enable = 'off';
                    app.desorption_rateEditField.Enable = 'off';
                    app.break_timeSpinnerLabel.Enable = 'off';
                    app.break_timeSpinner.Enable = 'off';
                case 'QD'
                    app.rotSpinnerLabel.Enable = 'on';
                    app.rotSpinner.Enable = 'on';
                    app.stopSpinnerLabel.Enable = 'on';
                    app.stopSpinner.Enable = 'on';
                    app.t_growthSpinnerLabel.Enable = 'on';
                    app.t_growthSpinner.Enable = 'on';
                    app.t_waitSpinnerLabel.Enable = 'on';
                    app.t_waitSpinner.Enable = 'on';
                    app.MaterialDropDown.Value = 'InAs';
                    app.DisplayedmaterialDropDown.Value = 'InAs';
                    app.growth_timeSpinnerLabel.Enable = 'off';
                    app.growth_timeSpinner.Enable = 'off';
                    app.effective_growth_rateEditFieldLabel.Enable = 'on';
                    app.effective_growth_rateEditField.Enable = 'on';
                    app.desorption_rateEditFieldLabel.Enable = 'on';
                    app.desorption_rateEditField.Enable = 'on';
                    app.break_timeSpinnerLabel.Enable = 'off';
                    app.break_timeSpinner.Enable = 'off';
                case {'break','QD_break'}
                    app.rotSpinnerLabel.Enable = 'off';
                    app.rotSpinner.Enable = 'off';
                    app.stopSpinnerLabel.Enable = 'off';
                    app.stopSpinner.Enable = 'off';
                    app.t_growthSpinnerLabel.Enable = 'off';
                    app.t_growthSpinner.Enable = 'off';
                    app.t_waitSpinnerLabel.Enable = 'off';
                    app.t_waitSpinner.Enable = 'off';
                    app.growth_timeSpinnerLabel.Enable = 'on';
                    app.growth_timeSpinner.Enable = 'on';
                    app.effective_growth_rateEditFieldLabel.Enable = 'off';
                    app.effective_growth_rateEditField.Enable = 'off';
                    app.desorption_rateEditFieldLabel.Enable = 'off';
                    app.desorption_rateEditField.Enable = 'off';
                    app.break_timeSpinnerLabel.Enable = 'on';
                    app.break_timeSpinner.Enable = 'on';
            end
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            %changes the displayed buttons
            switch app.MaterialDropDown.Value
                case {'GaAs', 'AlAs', 'InAs'}
                    app.x_contentSpinnerLabel.Enable = 'off';
                    app.x_contentSpinner.Enable = 'off';
                    app.growth_rat_2_set_valueEditFieldLabel.Enable = 'off';
                    app.growth_rat_2_set_valueEditField.Enable = 'off';
                    app.display_typeSwitch.Enable = 'off';
                case {'AlGaAs','InGaAs'}
                    app.x_contentSpinnerLabel.Enable = 'on';
                    app.x_contentSpinner.Enable = 'on';
                    app.growth_rat_2_set_valueEditFieldLabel.Enable = 'on';
                    app.growth_rat_2_set_valueEditField.Enable = 'on';
                    app.display_typeSwitch.Enable = 'on';
            end
            switch app.MaterialDropDown.Value
                case 'GaAs'
                    app.DisplayedmaterialDropDown.Value = 'GaAs';
                    app.critical_layer_thicknessEditFieldLabel.Enable = 'off';
                    app.critical_layer_thicknessEditField.Enable = 'off';
                case 'AlAs'
                    app.DisplayedmaterialDropDown.Value = 'AlAs';
                    app.critical_layer_thicknessEditFieldLabel.Enable = 'off';
                    app.critical_layer_thicknessEditField.Enable = 'off';
                case 'InAs'
                    app.DisplayedmaterialDropDown.Value = 'InAs';
                    app.critical_layer_thicknessEditFieldLabel.Enable = 'off';
                    app.critical_layer_thicknessEditField.Enable = 'off';
                case 'AlGaAs'
                    app.DisplayedmaterialDropDown.Value = 'AlGaAs';
                    app.critical_layer_thicknessEditFieldLabel.Enable = 'off';
                    app.critical_layer_thicknessEditField.Enable = 'off';
                case 'InGaAs'
                    app.DisplayedmaterialDropDown.Value = 'InGaAs';
                    app.critical_layer_thicknessEditFieldLabel.Enable = 'on';
                    app.critical_layer_thicknessEditField.Enable = 'on';
            end
        end

        % Value changed function: x_endSpinner, x_startSpinner, 
        % x_stepsSpinner, y_endSpinner, y_startSpinner, 
        % y_stepsSpinner
        function x_startSpinnerValueChanged(app, event)
            %sets the indicator so v will be created new with the new parameters
            app.v_creation_indicator = 1;
            
            %create fectors
            app.X_vector = app.x_startSpinner.Value : app.x_stepsSpinner.Value : app.x_endSpinner.Value;
            app.Y_vector = app.y_startSpinner.Value : app.y_stepsSpinner.Value : app.y_endSpinner.Value;
            
            resetButtonPushed(app, event);
        end

        % Value changed function: DisplayedLayerDropDown, 
        % DisplayedmaterialDropDown, sumCheckBox
        function display_modeSwitchValueChanged(app, event)
            app.MaterialDropDown.Value = app.DisplayedmaterialDropDown.Value;
            [app.thickness_matrix,app.x_matrix] = create_sim_data(app);
            display_sim(app,app.thickness_matrix,app.x_matrix);
        end

        % Value changed function: T_growthEditField, 
        % growth_rat_1_set_valueEditField, 
        % growth_rat_2_set_valueEditField, x_contentSpinner
        function growth_rat_1_set_valueEditFieldValueChanged(app, event)
            switch app.changing_rateDropDown.Value
                case 'rate_1'
                    app.growth_rat_1_set_valueEditField.Value = app.x_contentSpinner.Value * app.growth_rat_2_set_valueEditField.Value/(1-app.x_contentSpinner.Value);
                case 'rate_2'
                    app.growth_rat_2_set_valueEditField.Value = (app.growth_rat_1_set_valueEditField.Value/app.x_contentSpinner.Value) - app.growth_rat_1_set_valueEditField.Value;
                case 'x_content'
                    app.x_contentSpinner.Value = app.growth_rat_1_set_valueEditField.Value/(app.growth_rat_1_set_valueEditField.Value+app.growth_rat_2_set_valueEditField.Value);
            end
            switch app.MaterialDropDown.Value
                case 'InAs'
                    app.effective_growth_rateEditField.Value = app.growth_rat_1_set_valueEditField.Value * app.t_growthSpinner.Value / (app.t_growthSpinner.Value + app.t_waitSpinner.Value) - app.desorption_rateEditField.Value;
                case 'InGaAs'
                    app.critical_layer_thicknessEditField.Value = critical_thickness.critical_thickness_ingaas_on_gaas(app.x_contentSpinner.Value,app.T_growthEditField.Value,10^(-9),100)/10^(-9);
            end
        end

        % Value changed function: changing_rateDropDown
        function changing_rateDropDownValueChanged(app, event)
            switch app.changing_rateDropDown.Value
                case 'none'
                    app.x_contentSpinnerLabel.Enable = 'off';
                    app.x_contentSpinner.Enable = 'off';
                otherwise
                    app.x_contentSpinnerLabel.Enable = 'on';
                    app.x_contentSpinner.Enable = 'on';
            end
        end

        % Button pushed function: BrowseButton, Load_fit_dataButton
        function Load_fit_dataButtonPushed(app, event)
            app.PowerSpinnerLabel.Enable = 'on';
            app.PowerSpinner.Enable = 'on';
            app.TemperatureIndexSpinnerLabel.Enable = 'on';
            app.TemperatureIndexSpinner.Enable = 'on';
            app.DisplayedPeakDropDownLabel.Enable = 'on';
            app.DisplayedPeakDropDown.Enable = 'on';
            app.DisplayedValueDropDownLabel.Enable = 'on';
            app.DisplayedValueDropDown.Enable = 'on';
            app.fitDropDown.Enable = 'on';
            app.fitDropDownLabel.Enable = 'on';
            app.display_peak_as_wavelengthCheckBox.Enable = 'on';
            
            if ~isempty(app.thickness_matrix)
                app.compareButton.Enable = 'on';
                app.dimension_of_plotSwitch.Enable = 'on';
            end
            
            %gets the path of the data to be imported
            path_fit_data = uigetdir;
            path_fit_data = convertCharsToStrings(path_fit_data);
            path_fit_data = join([path_fit_data,"/"],'');
            path_fit_data = convertStringsToChars(path_fit_data);
            
            %change folder to surch for fit data
            oldFolder = cd(path_fit_data);
            file = ls('*Data_Fit*');
            
            %imports the data
            if ~isempty(file)
                app.fit_data = load([path_fit_data, file]);
            end
            
            file = ls('*Data_PL_Map*');
            
            %imports the data
            if ~isempty(file)
                temp_import_Structure = load([path_fit_data, file]);
                app.PLMap_Data = temp_import_Structure.PL_Map;
            end
            cd(oldFolder);
            
            %define the spinner
            app.X_vector = app.PLMap_Data.X_vector;
            app.Y_vector = app.PLMap_Data.Y_vector;
            x_min_index = define_Spinner(app.x_startSpinner,app.PLMap_Data.X_vector,'low');
            x_max_index = define_Spinner(app.x_endSpinner,app.PLMap_Data.X_vector,'upper');
            y_min_index = define_Spinner(app.y_startSpinner,app.PLMap_Data.X_vector,'low');
            y_max_index = define_Spinner(app.y_endSpinner,app.PLMap_Data.X_vector,'upper');
            app.x_stepsSpinner.Value = app.PLMap_Data.X_Steps;
            app.y_stepsSpinner.Value = app.PLMap_Data.Y_Steps;
            p = define_Spinner(app.PowerSpinner,app.PLMap_Data.Power,'low');
            
            %sets Fit_Data_Map
            app.Fit_Data_Map = app.fit_data.Fit_Data_Map;
            app.Fit_Data_function_parameter = app.fit_data.Fit_Data_function_parameter;
            app.Fit_Data_function_grade = app.fit_data.Fit_Data_function_grade;
            
            %get the fit size
            size_fit_data = size(app.Fit_Data_Map);
            
            %tamperature number
            if size_fit_data(2) == 1
                app.TemperatureIndexSpinner.Enable = "off";
            else
                app.TemperatureIndexSpinner.Limits = [1 size_fit_data(2)];
            end
            app.TemperatureIndexSpinner.Value = 1;
            t = 1;
            
            app.displayed_fit = 1;
            
            
            parameter_array(:,:) = app.Fit_Data_function_parameter(app.displayed_fit,t,p,:,:);
            grade_array(:,:) = app.Fit_Data_function_grade(app.displayed_fit,t,p,:,:);
            
            app.n_fitparameter = [];
            app.grade = [];
            app.n_fitparameter = max(parameter_array,[],'all');
            app.grade = max(grade_array,[],'all');
            
            if str2num(app.DisplayedPeakDropDown.Value)>app.grade
                app.DisplayedPeakDropDown.Value = '1';
            end
      
            app.DisplayedPeakDropDown.Items = split(num2str(1:app.grade))';
            
            switch app.n_fitparameter
                case 3
                    app.DisplayedValueDropDown.Items = {'rmse','Height', 'Position', 'FWHM'};
                case 4
                    app.DisplayedValueDropDown.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
            end
            
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown.Value);
            
            switch app.DisplayedValueDropDown.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
            end
            
            sample_id_temp = app.PLMap_Data.Sample;
            sample_id_temp = split(sample_id_temp,' ');
            app.sampleIDEditField.Value = str2num(sample_id_temp{2});
        end

        % Button pushed function: compareButton
        function compareButtonPushed(app, event)
           cla(app.UIAxes,'reset')
            %get the fit data
            switch app.DisplayedValueDropDown.Value
                case 'Height'
                    typ = 1;
                    app.lable{3} = 'height [1/counts]';
                case 'Position'
                    typ = 2;
                    app.lable{3} = 'position [nm]';
                case 'FWHM'
                    typ = 3;
                    app.lable{3} = 'FWHM [eV]';
                case 'Eta'
                    typ = 4;
                    app.lable{3} = 'eta';
            end
            
            p = app.PowerSpinner.Value;
            t = app.TemperatureIndexSpinner.Value;
            displayed_fit = num2str(app.fitDropDown.Value);
            
            parameter_array(:,:) = app.fit_data.Fit_Data_function_parameter(displayed_fit,t,p,:,:);
            grade_array(:,:) = app.fit_data.Fit_Data_function_grade(displayed_fit,t,p,:,:);
            
            parameter = max(parameter_array,[],'all');
            grade = max(grade_array,[],'all');
            
            pre_processed_data_map = nan(numel(app.PLMap_Data.Y_vector),numel(app.PLMap_Data.X_vector));
            
            for y = 1 : numel(app.PLMap_Data.Y_vector)
                for x = 1 : numel(app.PLMap_Data.X_vector)
                    coeffvals(:) = app.fit_data.Fit_Data_Map(displayed_fit,t,p,y,x,:);
                    if ~all(isnan(coeffvals),'all')
                        pre_processed_data_map(y,x) = coeffvals(typ+(peak-1)*parameter);
                    end
                end
            end
            
            compare_fit = pre_processed_data_map(:);
            
            %get simulated data
            switch app.display_typeSwitch.Value
                case 'composition'
                    compare_matrix = app.x_matrix(:);
                    app.lable{2} = 'composition';
                case 'thickness'
                    compare_matrix = app.thickness_matrix(:);
                    app.lable{2} = 'thickness';
            end

            app.lable{1} = '';
            %display comparison
            switch app.dimension_of_plotSwitch.Value
                case '2d'
                    scatter(app.UIAxes,compare_matrix,compare_fit,'filled');
                    title(app.UIAxes, app.lable{1});
                    xlabel(app.UIAxes, app.lable{2});
                    ylabel(app.UIAxes, app.lable{3});
%                     xlim(app.UIAxes,[min(compare_matrix) max(compare_matrix)]);
%                     ylim(app.UIAxes,[min(compare_fit) max(compare_fit)]);
                case '3d'
                    scatter3(app.UIAxes,app.x_matrix(:),app.thickness_matrix(:),compare_fit,'filled');
                    title(app.UIAxes, app.lable{1});
                    xlabel(app.UIAxes, 'composition');
                    ylabel(app.UIAxes, 'thickness');
                    zlabel(app.UIAxes, app.lable{3});
                    xlim(app.UIAxes,'auto');
                    ylim(app.UIAxes,'auto');
                    zlim(app.UIAxes,'auto');
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            app.Ga_thickness_matrix = [];
            app.Al_thickness_matrix = [];
            app.In_thickness_matrix = [];
            app.thickness_matrix = [];
            app.x_matrix = [];
            app.DisplayedLayerDropDown.Items = {'1'};
            app.dim = [];
            
            app.display_modeSwitch.Enable = 'off';
            app.contour_linesCheckBox.Enable = 'off';
            app.display_typeSwitch.Enable = 'off';
            app.DisplayedmaterialDropDownLabel.Enable = 'off';
            app.DisplayedmaterialDropDown.Enable = 'off';
            app.DisplayedLayerDropDownLabel.Enable = 'off';
            app.DisplayedLayerDropDown.Enable = 'off';
            app.sumCheckBox.Enable = 'off';
            app.n_contour_linesSpinnerLabel.Enable = 'off';
            app.n_contour_linesSpinner.Enable = 'off';
            app.ML_contour_stepsEditFieldLabel.Enable = 'off';
            app.ML_contour_stepsEditField.Enable = 'off';
            app.ML_maxSpinnerLabel.Enable = 'off';
            app.ML_maxSpinner.Enable = 'off';
            app.ML_minSpinnerLabel.Enable = 'off';
            app.ML_minSpinner.Enable = 'off';
            app.auto_MLCheckBox.Enable = 'off';
        end

        % Value changed function: ML_contour_stepsEditField, 
        % ML_maxSpinner, ML_minSpinner, contour_linesCheckBox, 
        % display_modeSwitch, display_typeSwitch, 
        % n_contour_linesSpinner
        function contour_linesCheckBoxValueChanged(app, event)
            display_sim(app,app.thickness_matrix,app.x_matrix);
        end

        % Menu selected function: save_sim_dataMenu
        function save_sim_dataMenuSelected(app, event)
            %get save path
            save_data_path = uigetdir(app.data_path,'choose the same Path as the PL-Data');
            
            %put data in save structure
            sim_data.Al_thickness_matrix = app.Al_thickness_matrix;
            sim_data.Ga_thickness_matrix = app.Ga_thickness_matrix;
            sim_data.In_thickness_matrix = app.In_thickness_matrix;
            
            %saves the data to location
            save(fullfile(save_data_path, 'sim_data'), 'sim_data');
        end

        % Menu selected function: load_sim_dataMenu
        function load_sim_dataMenuSelected(app, event)
            app.Al_thickness_matrix = [];
            app.Ga_thickness_matrix = [];
            app.In_thickness_matrix = [];
            
            %get the load path
            load_data_path = uigetdir(app.data_path);
            
            %Imports the Data to a Variable
            temp_import_Structure = load([load_data_path, '/sim_data']);
            data = temp_import_Structure.sim_data;
            
            app.Al_thickness_matrix = data.Al_thickness_matrix;
            app.Ga_thickness_matrix = data.Ga_thickness_matrix;
            app.In_thickness_matrix = data.In_thickness_matrix;
            
            %get the dimansion of the loaded data and sets the layer display
            %correctly
            size_of_sim = size(app.Al_thickness_matrix);
            
            for i = 1 : size_of_sim(1)
                if i == 1
                    app.dim{1} =  num2str(i);
                else 
                    app.dim{end+1} = num2str(i);
                end
            end
            
            app.DisplayedLayerDropDown.Items = app.dim;
            
            %enable buttons
            app.display_modeSwitch.Enable = 'on';
            app.contour_linesCheckBox.Enable = 'on';
            app.display_typeSwitch.Enable = 'on';
            app.DisplayedmaterialDropDownLabel.Enable = 'on';
            app.DisplayedmaterialDropDown.Enable = 'on';
            app.DisplayedLayerDropDownLabel.Enable = 'on';
            app.DisplayedLayerDropDown.Enable = 'on';
            app.sumCheckBox.Enable = 'on';
            app.n_contour_linesSpinnerLabel.Enable = 'on';
            app.n_contour_linesSpinner.Enable = 'on';
            app.ML_contour_stepsEditFieldLabel.Enable = 'on';
            app.ML_contour_stepsEditField.Enable = 'on';
                
            if ~isempty(app.fit_data)
                app.compareButton.Enable = 'on';
                app.dimension_of_plotSwitch.Enable = 'on';
            end
        end

        % Value changed function: desorption_rateEditField, 
        % t_growthSpinner, t_waitSpinner
        function t_growthSpinnerValueChanged(app, event)
            app.effective_growth_rateEditField.Value = app.growth_rat_1_set_valueEditField.Value * app.t_growthSpinner.Value / (app.t_growthSpinner.Value + app.t_waitSpinner.Value) - app.desorption_rateEditField.Value;
        end

        % Button pushed function: ML_ratesButton
        function ML_ratesButtonPushed(app, event)
            app_growthrate_calculator(app);
        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            copyed_array = mat2clip(app.copy_map, '\t');
        end

        % Button pushed function: add_layerButton
        function add_layerButtonPushed(app, event)
            % Get Table UI component data
            t = app.recipe.DisplayData; 
            
            %create intial recipe table
            Material =  convertCharsToStrings(app.MaterialDropDown.Value);
            growth_rat_1_set_value = app.growth_rat_1_set_valueEditField.Value;
            growth_rat_2_set_value = app.growth_rat_2_set_valueEditField.Value;
            x_content = app.x_contentSpinner.Value;
            Growth_mode = convertCharsToStrings(app.Growth_modeDropDown.Value);
            start_angle = app.start_angleSpinner.Value;
            t_growth = app.growth_timeSpinner.Value;
            t_wait = app.t_waitSpinner.Value;
            qd_stop = app.stopSpinner.Value;
            qd_rot = app.rotSpinner.Value;
            desorption_rate = app.desorption_rateEditField.Value;
            
            t{end+1,:} = [Material,growth_rat_1_set_value,growth_rat_2_set_value,x_content,Growth_mode,start_angle,t_growth,t_wait,qd_stop,qd_rot,desorption_rate];
            
            % Add data to the Table UI Component
            app.recipe.Data = t;
        end

        % Button pushed function: SimulateButton_2
        function SimulateButton_2Pushed(app, event)
            % Get Table UI component data
            t = app.recipe.DisplayData;
            
            % get the number of layers
            size_of_t = size(t);
            
            %goes through all layers
            for i = 1 : size_of_t(1)
                app.MaterialDropDown.Value =  char(t{i,1});
                app.growth_rat_1_set_valueEditField.Value = t{i,2};
                app.growth_rat_2_set_valueEditField.Value = t{i,3};
                app.x_contentSpinner.Value = t{i,4};
                app.Growth_modeDropDown.Value = char(t{i,5});
                app.start_angleSpinner.Value = t{i,6};
                app.t_waitSpinner.Value = t{i,8};
                app.stopSpinner.Value = t{i,9};
                app.rotSpinner.Value = t{i,10};
                app.desorption_rateEditField.Value = t{i,11};
                
                switch char(t{i,5})
                    case {'Rotation','Gradient'}
                        app.growth_timeSpinner.Value = t{i,7};
                        
                        %dose the single layer simmulation step
                        SimulateButtonPushed(app, event)
                    case 'QD'
                        app.t_growthSpinner.Value = t{i,7};
                        
                        %dose the single layer simmulation step
                        SimulateButtonPushed(app, event)
                    case {'brak','QD_break'}
                        end_angle = growth_pause(app,app.start_angleSpinner.Value);
                end
                
            end
        end

        % Value changed function: DisplayedPeakDropDown, 
        % DisplayedValueDropDown, PowerSpinner, 
        % TemperatureIndexSpinner, fitDropDown
        function fitDropDownValueChanged(app, event)
            app.displayed_fit = str2num(app.fitDropDown.Value);
            app.displayed_peak  = str2num(app.DisplayedPeakDropDown.Value);
            
            switch app.DisplayedValueDropDown.Value
                case 'Height'
                    app.displayed_fit_parameter = 1;
                case 'Position'
                    app.displayed_fit_parameter = 2;
                case 'FWHM'
                    app.displayed_fit_parameter = 3;
                case 'Eta'
                    app.displayed_fit_parameter = 4;
                case 'rmse'
                    app.displayed_fit_parameter = 5;
            end
            
            pre_processed_data_map = update_fit_map(app);
        end

        % Button pushed function: showroughnessButton
        function showroughnessButtonPushed(app, event)
            switch app.roughness_version.Value
                case 'standard'
                    app.R = calculate_standard_roughness(app,app.thickness_matrix,app.x_matrix);
                case 'expand'
                    app.R = calculate_expand_roughness(app,app.thickness_matrix,app.x_matrix);
            end
            
            %dispaly the grown layer
            [app.last_map,app.c] = Display_Map(app.UIAxes,app.X_vector,app.Y_vector,app.R,500,...
                app.map_typ,app.lable,-inf,inf,app.contour_linesCheckBox.Value,app.n_contour_linesSpinner.Value,'on',0);
            
            app.roughness_loadedLamp.Color = [0,1,0];
        end

        % Button pushed function: showdensityButton
        function showdensityButtonPushed(app, event)
            %convert nm to ML
            coverage = converter.nm_to_ML(app.MaterialDropDown.Value,app.thickness_matrix,app.x_matrix,app.T_growthEditField.Value);
            
            if app.incloudroughnessCheckBox.Value
                ct = QD_critical_thickness_mod(app.critical_thicknessEditField.Value,app.Delta_REditField.Value,...
                    app.conversion_factorEditField.Value,app.R_0EditField.Value,app.d_PDL,app.R,app.roughness_version.Value);
            else
                ct = app.critical_thicknessEditField.Value;
            end
            
            effectiv_coverage = coverage-ct;
            effectiv_coverage(effectiv_coverage<0) = nan;
            QD_density = app.maximum_coverageEditField.Value.*effectiv_coverage.^app.alphaEditField.Value;
            
            %dispaly the grown layer
            [app.last_map,app.c] = Display_Map(app.UIAxes,app.X_vector,app.Y_vector,QD_density,500,...
                app.map_typ,app.lable,-inf,inf,app.contour_linesCheckBox.Value,app.n_contour_linesSpinner.Value,'on',0);
        end

        % Menu selected function: 
        % display_critical_layer_thicknessMenu
        function display_critical_layer_thicknessMenuSelected(app, event)
            cla(app.UIAxes,'reset')
            x = [0.001 : 0.001 : 0.999];
            n_x = numel(x);
            hc = nan(1,n_x);
            
            j = 0;
            for x = 0.001 : 0.001 : 0.999
                j = j+1;
                hc(j) = critical_thickness.critical_thickness_ingaas_on_gaas(x,app.T_growthEditField.Value,10^(-9),100);
            end
            x = [0.001 : 0.001 : 0.999];
            plot(app.UIAxes,x,hc/10^(-9));
            ax = app.UIAxes;
            ax.YScale = 'log';
            ax.XLabel.String = 'InAs content';
            ax.YLabel.String = 'critical thickness (nm)';
            ax.Title.String = 'Critical thickness of In_{x}Ga_{1-x}As on GaAs';
            ax.XGrid = "on";
            ax.YGrid = "on";
        end

        % Menu selected function: create_new_rates_from_PLMenu
        function create_new_rates_from_PLMenuSelected(app, event)
            %gets the Path of the Data - Note that one has just to give the folder where the Data are
            Path_of_Data = uigetdir;
            
            list = {'algaas','ingaas'};
            [indx,~] = listdlg('ListString',list,'SelectionMode','single','Name','Select QW');
            
            prompt = {'Temperature index:','Power index:','Index of Ga peak:','Ga growthtime:','Index of QW peak:','QW growthtime:','rotation of ga compared with qw:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'1','1','3','35','4','35','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            
            %load tge data
            pl_data = fullfile(Path_of_Data,'Data_PL_Map.mat');
            fited_data = fullfile(Path_of_Data,'Data_Fit.mat');
            pl_data = load(pl_data);
            pl_data = pl_data.PL_Map;
            fited_data = load(fited_data);
            
            sample_id_temp = pl_data.Sample;
            sample_id_temp = split(sample_id_temp,' ');
            sample_id = str2num(sample_id_temp{2});
            
            %define the center of the wafer for rotation symmetrie
            center = [37.5,37];
            
%             %Import Sim Data AlGaAs
%             %read values from the file
%             Data_temp = load('AlGaAs');
%             data_AlGaAs = Data_temp.data;
%             clear Data_temp
%             
%             %Import Sim Data InGaAs
%             %read values from the file
%             Data_temp = load('InGaAs');
%             data_InGaAs = Data_temp.data;
%             clear Data_temp
%             
%             sf_algaas = surface_fit_3d_data(data_AlGaAs.x_QW,data_AlGaAs.d_QW,data_AlGaAs.hhole_transistion_wavelength(:,:,2));
            
            %(data_pl_map,fit_data,file_qw,t,p,peak_gaas,peak_qw,t_growth_gaas,t_growth_qw,center,degr)
            [gaas_growth_rate_raw,gaas_growth_rate,gaas_growth_rate_rot,qw_growth_rate,sf_gaas,x_qw,sf_qw] = create_rates_from_pl_data(pl_data,fited_data,list{indx},str2num(answer{1}),str2num(answer{2}),str2num(answer{3}),str2num(answer{5}),str2num(answer{4}),str2num(answer{6}),center,str2num(answer{7}));
            data.sample_id  = sample_id;
            data.gaas_growth_rate_rot  = gaas_growth_rate_rot;
            data.gaas_growth_rate_raw  = gaas_growth_rate_raw;
            data.gaas_growth_rate  = gaas_growth_rate;
            data.t_growth_gaas = str2num(answer{5});
            data.t_growth_qw = str2num(answer{6});
            data.sf_gaas = sf_gaas;
            data.sf_qw = sf_qw;
            data.qw_growth_rate  = qw_growth_rate;
            data.x_qw = x_qw;
            data.x_degr = str2num(answer{7});

            %% get timestemp
            format longG
            time = now;
            time_stamp = datetime(time,'ConvertFrom','datenum');
            data.time_stamp = time_stamp;
            
            %save the data
            currentFolder = pwd;
            save_folder = join([currentFolder,'\growth_rates']);
            full_filename = join([num2str(sample_id),'_',list{indx}]);
            save(fullfile(save_folder, full_filename),'data');
        end

        % Button pushed function: calculateratesButton
        function calculateratesButtonPushed(app, event)
            %clear old data
            app.data = [];
            
            % define the center of the wafer for rotation symmetrie
            center = [37.5,37];
            
            if app.degre_stepsSpinner.Value == 0
                degree = 0;
            else
                degree = app.degre_1Spinner.Value : app.degre_stepsSpinner.Value : app.degre_2Spinner.Value;
            end
            
            calculate_growthrates_from_PL_data(app,center,degree)
        end

        % Value changed function: cellDropDown
        function cellDropDownValueChanged(app, event)
            switch app.cellDropDown.Value
                case 'AL'
                    app.cell_angleSpinner.Value = -38;
                case 'AL-B'
                    app.cell_angleSpinner.Value = -110;
                case 'In'
                    app.cell_angleSpinner.Value = 137;
            end
            show_rotationButtonPushed(app, event)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            size_data = size(app.data);
            for i = 1 : size_data(2)
                degr = app.data{1,i}.x_degr;
                if degr == app.degre_1Spinner.Value
                    data = app.data{1,i};
                end
            end
            data.cell_angle = app.cell_angleSpinner.Value;
            data.cell = app.cellDropDown.Value;
            data.ga_cell_angle = app.Ga_cell_angleSpinner.Value;
            
            %save the data
            currentFolder = pwd;
            save_folder = join([currentFolder,'\growth_rates']);
            full_filename = join([num2str(app.sampleIDEditField.Value),'_',app.qwtypDropDown.Value,'_cell_',app.cellDropDown.Value,...
                '_ga-cell-angle_',strrep(num2str(app.Ga_cell_angleSpinner.Value),'.','_'),'_correction-angle_',strrep(num2str(app.degre_1Spinner.Value),'.','_')]);
            
            prompt = {'Enter savename:'};
            dlgtitle = 'savename';
            dims = [1 80];
            definput = {full_filename};
            full_filename = inputdlg(prompt,dlgtitle,dims,definput);
            
            if ~isempty(full_filename)
                full_filename = full_filename{1,1};
                save(fullfile(save_folder, full_filename),'data');
            end
        end

        % Callback function: Ga_cell_angleSpinner, cell_angleSpinner, 
        % show_rotationButton
        function show_rotationButtonPushed(app, event)
            size_data = size(app.data);
            if app.degre_stepsSpinner.Value == 0
                for i = 1 : size_data(2)
                    degr = app.data{1,i}.x_degr;
                    if degr == app.degre_1Spinner.Value
                        degr_index = i;
                    end
                end
                degr = app.data{1,degr_index}.x_degr;
                
                %center of rotation
                center_x  = 37.5;
                center_y  = 37;
                
                %star position of the Ga cell
                ga_cell_x = 37.5;
                ga_cell_y = -5;
                ga_cell_x = ga_cell_x - center_x;
                ga_cell_y = ga_cell_y - center_y;
                
                %start position vor orthogonal lines
                xoa1 = -20;
                xob1 = 20;
                yo1 = (center_y/4)-center_y;
                yo2 = (center_y/2)-center_y;
                yo3 = (3*center_y/4)-center_y;
                
                coords = [ga_cell_x,xoa1,xob1,xoa1,xob1,xoa1,xob1;ga_cell_y,yo1,yo1,yo2,yo2,yo3,yo3];
                
                %rotation of the Ga cell
                rad = app.Ga_cell_angleSpinner.Value*pi/180;
                Rot = [cos(-rad) -sin(-rad); sin(-rad) cos(-rad)];
                vr_ga =  Rot*coords;
                
                coords = [ga_cell_x,xoa1,xob1,xoa1,xob1,xoa1,xob1,xoa1,xob1,xoa1,xob1,xoa1,xob1;ga_cell_y,yo1,yo1,yo2,yo2,yo3,yo3,-yo1,-yo1,-yo2,-yo2,-yo3,-yo3];
                
                %rotation of the qw cell
                rad = (app.Ga_cell_angleSpinner.Value+app.cell_angleSpinner.Value)*pi/180;
                Rot = [cos(-rad) -sin(-rad); sin(-rad) cos(-rad)];
                vr_qw =  Rot*coords;
                
                center = [0;0];
                coords = [vr_ga,center,vr_qw];
                
                %rotation both
                rad = degr*pi/180;
                Rot = [cos(-rad) -sin(-rad); sin(-rad) cos(-rad)];
                vr =  Rot*coords;
                
                x = vr(1,:) + center_x;
                y = vr(2,:) + center_y;
                
                xl1 = [x(1),x(8),x(9)];
                yl1 = [y(1),y(8),y(9)]; 
                xl2 = [x(2),x(3)];
                yl2 = [y(2),y(3)]; 
                xl3 = [x(4),x(5)];
                yl3 = [y(4),y(5)];
                xl4 = [x(6),x(7)];
                yl4 = [y(6),y(7)];
                xl5 = [x(10),x(11)];
                yl5 = [y(10),y(11)];
                xl6 = [x(12),x(13)];
                yl6 = [y(12),y(13)];
                xl7 = [x(14),x(15)];
                yl7 = [y(14),y(15)];
                xl8 = [x(16),x(17)];
                yl8 = [y(16),y(17)];
                xl9 = [x(18),x(19)];
                yl9 = [y(18),y(19)];
                xl10 = [x(20),x(21)];
                yl10 = [y(20),y(21)];
                
                label{1} = join(['GaAs growth rate at rotation angle = ',num2str(degr),'°']);
                label{2} = 'x (mm)';
                label{3} = 'y (mm)';
                label{4} = 'growth rate (nm/s)';
                
                %[Defind_Map_Axis,c] = Display_Map(Defind_Map_Axis,X_Position,Y_Position,map_data,color_steps,map_typ,...
                %label,lower_count_limit,upper_count_limit,contour_state,contour_lines,contour_text,lock_to_area_check)
                [app.rate_right,app.rate_right_c] = Display_Map(app.UIAxes2,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.data{1,degr_index}.gaas_growth_rate_rot,500,...
                    'hot',label,-inf,inf,1,20,0,0);
                line(app.UIAxes2,xl1, yl1,'LineWidth',1.5);
                line(app.UIAxes2,xl2, yl2,'LineWidth',1.5);
                line(app.UIAxes2,xl3, yl3,'LineWidth',1.5);
                line(app.UIAxes2,xl4, yl4,'LineWidth',1.5);
                line(app.UIAxes2,xl5, yl5,'LineWidth',1.5);
                line(app.UIAxes2,xl6, yl6,'LineWidth',1.5);
                line(app.UIAxes2,xl7, yl7,'LineWidth',1.5);
                
                label{1} = join([app.cellDropDown.Value,' growth rate at rotation angle = ',num2str(degr),'°']);
                label{2} = 'x (mm)';
                label{3} = 'y (mm)';
                label{4} = 'growth rate (nm/s)';
                
                [app.rate_left,app.rate_left_c] = Display_Map(app.UIAxes3,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.data{1,degr_index}.qw_growth_rate,500,...
                    'hot',label,-inf,inf,1,20,0,0);
                line(app.UIAxes3,xl1, yl1,'LineWidth',1.5);
                line(app.UIAxes3,xl2, yl2,'LineWidth',1.5);
                line(app.UIAxes3,xl3, yl3,'LineWidth',1.5);
                line(app.UIAxes3,xl4, yl4,'LineWidth',1.5);
                line(app.UIAxes3,xl5, yl5,'LineWidth',1.5);
                line(app.UIAxes3,xl6, yl6,'LineWidth',1.5);
                line(app.UIAxes3,xl7, yl7,'LineWidth',1.5);
                line(app.UIAxes3,xl8, yl8,'LineWidth',1.5);
                line(app.UIAxes3,xl9, yl9,'LineWidth',1.5);
                line(app.UIAxes3,xl10, yl10,'LineWidth',1.5);
                
                %show center rate
                app.rate_gaasEditField.Value = app.data{1,degr_index}.sf_gaas(0,0);
                app.rate_qwEditField.Value = app.data{1,degr_index}.sf_qw(0,0);
            else
                for i = 1 : size_data(2)
                    degr = app.data{1,i}.x_degr;
                    %center of rotation
                    center_x  = 37.5;
                    center_y  = 37;
                    
                    %star position of the Ga cell
                    ga_cell_x = 37.5;
                    ga_cell_y = -5;
                    ga_cell_x = ga_cell_x - center_x;
                    ga_cell_y = ga_cell_y - center_y;
                    
                    %start position vor orthogonal lines
                    xoa1 = -20;
                    xob1 = 20;
                    yo1 = (center_y/4)-center_y;
                    yo2 = (center_y/2)-center_y;
                    yo3 = (3*center_y/4)-center_y;
                    
                    coords = [ga_cell_x,xoa1,xob1,xoa1,xob1,xoa1,xob1;ga_cell_y,yo1,yo1,yo2,yo2,yo3,yo3];
                    
                    %rotation of the Ga cell
                    rad = app.Ga_cell_angleSpinner.Value*pi/180;
                    Rot = [cos(-rad) -sin(-rad); sin(-rad) cos(-rad)];
                    vr_ga =  Rot*coords;
                    
                    coords = [ga_cell_x,xoa1,xob1,xoa1,xob1,xoa1,xob1,xoa1,xob1,xoa1,xob1,xoa1,xob1;...
                              ga_cell_y,yo1,yo1,yo2,yo2,yo3,yo3,-yo1,-yo1,-yo2,-yo2,-yo3,-yo3];
                    
                    %rotation of the qw cell
                    rad = (app.Ga_cell_angleSpinner.Value+app.cell_angleSpinner.Value)*pi/180;
                    Rot = [cos(-rad) -sin(-rad); sin(-rad) cos(-rad)];
                    vr_qw =  Rot*coords;
                    
                    center = [0;0];
                    coords = [vr_ga,center,vr_qw];
                    
                    %rotation both
                    rad = degr*pi/180;
                    Rot = [cos(-rad) -sin(-rad); sin(-rad) cos(-rad)];
                    vr =  Rot*coords;
                    
                    x = vr(1,:) + center_x;
                    y = vr(2,:) + center_y;
                    
                    xl1 = [x(1),x(8),x(9)];
                    yl1 = [y(1),y(8),y(9)];
                    xl2 = [x(2),x(3)];
                    yl2 = [y(2),y(3)];
                    xl3 = [x(4),x(5)];
                    yl3 = [y(4),y(5)];
                    xl4 = [x(6),x(7)];
                    yl4 = [y(6),y(7)];
                    xl5 = [x(10),x(11)];
                    yl5 = [y(10),y(11)];
                    xl6 = [x(12),x(13)];
                    yl6 = [y(12),y(13)];
                    xl7 = [x(14),x(15)];
                    yl7 = [y(14),y(15)];
                    xl8 = [x(16),x(17)];
                    yl8 = [y(16),y(17)];
                    xl9 = [x(18),x(19)];
                    yl9 = [y(18),y(19)];
                    xl10 = [x(20),x(21)];
                    yl10 = [y(20),y(21)];
                    
                    label{1} = join(['GaAs growth rate at rotation angle = ',num2str(degr),'°']);
                    label{2} = 'x (mm)';
                    label{3} = 'y (mm)';
                    label{4} = 'growth rate (nm/s)';
                    
                    %[Defind_Map_Axis,c] = Display_Map(Defind_Map_Axis,X_Position,Y_Position,map_data,color_steps,map_typ,...
                    %label,lower_count_limit,upper_count_limit,contour_state,contour_lines,contour_text,lock_to_area_check)
                    [app.rate_right,app.rate_right_c] = Display_Map(app.UIAxes2,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.data{1,i}.gaas_growth_rate_rot,500,...
                        'hot',label,-inf,inf,1,20,0,0);
                    line(app.UIAxes2,xl1, yl1,'LineWidth',1.5);
                    line(app.UIAxes2,xl2, yl2,'LineWidth',1.5);
                    line(app.UIAxes2,xl3, yl3,'LineWidth',1.5);
                    line(app.UIAxes2,xl4, yl4,'LineWidth',1.5);
                    line(app.UIAxes2,xl5, yl5,'LineWidth',1.5);
                    line(app.UIAxes2,xl6, yl6,'LineWidth',1.5);
                    line(app.UIAxes2,xl7, yl7,'LineWidth',1.5);
                    
                    
                    label{1} = join([app.cellDropDown.Value,' growth rate at rotation angle = ',num2str(degr),'°']);
                    label{2} = 'x (mm)';
                    label{3} = 'y (mm)';
                    label{4} = 'growth rate (nm/s)';
                    
                    [app.rate_left,app.rate_left_c] = Display_Map(app.UIAxes3,app.PLMap_Data.X_vector,app.PLMap_Data.Y_vector,app.data{1,i}.qw_growth_rate,500,...
                        'hot',label,-inf,inf,1,20,0,0);
                    line(app.UIAxes3,xl1, yl1,'LineWidth',1.5);
                    line(app.UIAxes3,xl2, yl2,'LineWidth',1.5);
                    line(app.UIAxes3,xl3, yl3,'LineWidth',1.5);
                    line(app.UIAxes3,xl4, yl4,'LineWidth',1.5);
                    line(app.UIAxes3,xl5, yl5,'LineWidth',1.5);
                    line(app.UIAxes3,xl6, yl6,'LineWidth',1.5);
                    line(app.UIAxes3,xl7, yl7,'LineWidth',1.5);
                    line(app.UIAxes3,xl8, yl8,'LineWidth',1.5);
                    line(app.UIAxes3,xl9, yl9,'LineWidth',1.5);
                    line(app.UIAxes3,xl10, yl10,'LineWidth',1.5);
                    
                    %show center rate
                    app.rate_gaasEditField.Value = app.data{1,i}.sf_gaas(0,0);
                    app.rate_qwEditField.Value = app.data{1,i}.sf_qw(0,0);
                    pause(1);
                end
            end
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            pre_processed_data_map = update_fit_map(app);
        end

        % Button pushed function: overlay_contourButton
        function overlay_contourButtonPushed(app, event)
            ML_min = floor(min(app.copy_map,[],'all'));
            ML_max = ceil(max(app.copy_map,[],'all'));
            if app.ML_contour_stepsEditField.Value > 0
                contour_lines = [ML_min : app.ML_contour_stepsEditField.Value : ML_max];
            else
                contour_lines = app.n_contour_linesSpinner.Value;
            end
            pre_processed_data_map = update_fit_map(app);
            hold(app.UIAxes,"on");
            contour(app.UIAxes,app.X_vector,app.Y_vector,app.copy_map,contour_lines,'k-','ShowText','on');
            hold(app.UIAxes,"off");
            caxis(app.UIAxes,([min(pre_processed_data_map,[],'all') max(pre_processed_data_map,[],'all')]));
        end

        % Menu selected function: copy_sim_axisMenu
        function copy_sim_axisMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map,ax, 'colorbar', app.c);
        end

        % Menu selected function: copy_rate_leftMenu
        function copy_rate_leftMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.rate_left,ax, 'colorbar', app.rate_left_c);
        end

        % Menu selected function: copy_rate_rightMenu
        function copy_rate_rightMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.rate_right,ax, 'colorbar', app.rate_right_c);
        end

        % Menu selected function: loadMenu
        function loadMenuSelected(app, event)
            %load growth rates
            [file,path] = uigetfile('*.mat','Select AlGaAs with lowest Al content');
            sim_data = load(fullfile(path,file));
            app.f_al_low_rate = [];
            app.f_al_low_rate = sim_data.data.sf_qw;
            [file,path] = uigetfile('*.mat','Select AlGaAs with highest Al content');
            sim_data = load(fullfile(path,file));
            app.f_al_high_rate = [];
            app.f_al_high_rate = sim_data.data.sf_qw;
            [file,path] = uigetfile('*.mat','Select InGaAs');
            sim_data = load(fullfile(path,file));
            app.f_ga_rate = [];
            app.f_ga_rate = sim_data.data.sf_gaas;
            app.f_in_rate = [];
            app.f_in_rate = sim_data.data.sf_qw;
            
            %reset all
            resetButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.growthsimulationbyMarcelSchmidtUIFigure = uifigure('Visible', 'off');
            app.growthsimulationbyMarcelSchmidtUIFigure.Name = 'growth simulation  by Marcel Schmidt at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.Menu = uimenu(app.growthsimulationbyMarcelSchmidtUIFigure);
            app.Menu.Text = 'Menu';
            app.load_sim_dataMenu = uimenu(app.Menu);
            app.load_sim_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @load_sim_dataMenuSelected, true);
            app.load_sim_dataMenu.Text = 'load_sim_data';
            app.save_sim_dataMenu = uimenu(app.Menu);
            app.save_sim_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @save_sim_dataMenuSelected, true);
            app.save_sim_dataMenu.Text = 'save_sim_data';
            app.CopytoclipboardMenu = uimenu(app.Menu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Accelerator = 'c';
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.copy_axisMenu = uimenu(app.Menu);
            app.copy_axisMenu.Text = 'copy_axis';
            app.copy_sim_axisMenu = uimenu(app.copy_axisMenu);
            app.copy_sim_axisMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_sim_axisMenuSelected, true);
            app.copy_sim_axisMenu.Text = 'copy_sim_axis';
            app.copy_rate_leftMenu = uimenu(app.copy_axisMenu);
            app.copy_rate_leftMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_rate_leftMenuSelected, true);
            app.copy_rate_leftMenu.Text = 'copy_rate_left';
            app.copy_rate_rightMenu = uimenu(app.copy_axisMenu);
            app.copy_rate_rightMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_rate_rightMenuSelected, true);
            app.copy_rate_rightMenu.Text = 'copy_rate_right';
            app.create_new_rates_from_PLMenu = uimenu(app.Menu);
            app.create_new_rates_from_PLMenu.MenuSelectedFcn = createCallbackFcn(app, @create_new_rates_from_PLMenuSelected, true);
            app.create_new_rates_from_PLMenu.Text = 'create_new_rates_from_PL';
            app.base_QWsMenu = uimenu(app.growthsimulationbyMarcelSchmidtUIFigure);
            app.base_QWsMenu.Text = 'base_QWs';
            app.loadMenu = uimenu(app.base_QWsMenu);
            app.loadMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMenuSelected, true);
            app.loadMenu.Text = 'load';
            app.extrasMenu = uimenu(app.growthsimulationbyMarcelSchmidtUIFigure);
            app.extrasMenu.Text = 'extras';
            app.display_critical_layer_thicknessMenu = uimenu(app.extrasMenu);
            app.display_critical_layer_thicknessMenu.MenuSelectedFcn = createCallbackFcn(app, @display_critical_layer_thicknessMenuSelected, true);
            app.display_critical_layer_thicknessMenu.Text = 'display_critical_layer_thickness';
            app.TabGroup = uitabgroup(app.growthsimulationbyMarcelSchmidtUIFigure);
            app.simulationTab = uitab(app.TabGroup);
            app.simulationTab.Title = 'simulation';
            app.SimulateButton = uibutton(app.simulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.QDgrowthLabel = uilabel(app.simulationTab);
            app.QDgrowthLabel.Text = 'QD growth';
            app.display_modeSwitch = uiswitch(app.simulationTab, 'slider');
            app.display_modeSwitch.Items = {'nm', 'ML'};
            app.display_modeSwitch.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.display_modeSwitch.Enable = 'off';
            app.display_modeSwitch.Value = 'nm';
            app.GridLabel = uilabel(app.simulationTab);
            app.GridLabel.Text = 'Grid';
            app.rot_dir = uiswitch(app.simulationTab, 'slider');
            app.rot_dir.Items = {'clockwise', 'counterclockwise'};
            app.rot_dir.Value = 'clockwise';
            app.contour_linesCheckBox = uicheckbox(app.simulationTab);
            app.contour_linesCheckBox.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.contour_linesCheckBox.Enable = 'off';
            app.contour_linesCheckBox.Text = 'contour_lines';
            app.contour_linesCheckBox.Value = true;
            app.display_typeSwitch = uiswitch(app.simulationTab, 'slider');
            app.display_typeSwitch.Items = {'thickness', 'composition'};
            app.display_typeSwitch.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.display_typeSwitch.Enable = 'off';
            app.display_typeSwitch.Value = 'thickness';
            app.FitDataLabel = uilabel(app.simulationTab);
            app.FitDataLabel.Text = 'Fit Data';
            app.Load_fit_dataButton = uibutton(app.simulationTab, 'push');
            app.Load_fit_dataButton.ButtonPushedFcn = createCallbackFcn(app, @Load_fit_dataButtonPushed, true);
            app.Load_fit_dataButton.Text = 'Load_fit_data';
            app.compareButton = uibutton(app.simulationTab, 'push');
            app.compareButton.ButtonPushedFcn = createCallbackFcn(app, @compareButtonPushed, true);
            app.compareButton.Enable = 'off';
            app.compareButton.Text = 'compare';
            app.dimension_of_plotSwitch = uiswitch(app.simulationTab, 'slider');
            app.dimension_of_plotSwitch.Items = {'2d', '3d'};
            app.dimension_of_plotSwitch.Enable = 'off';
            app.dimension_of_plotSwitch.Value = '2d';
            app.resetButton = uibutton(app.simulationTab, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.sumCheckBox = uicheckbox(app.simulationTab);
            app.sumCheckBox.ValueChangedFcn = createCallbackFcn(app, @display_modeSwitchValueChanged, true);
            app.sumCheckBox.Enable = 'off';
            app.sumCheckBox.Text = 'sum';
            app.show_sim_stepsCheckBox = uicheckbox(app.simulationTab);
            app.show_sim_stepsCheckBox.Text = 'show_sim_steps';
            app.Growth_modeDropDownLabel = uilabel(app.simulationTab);
            app.Growth_modeDropDownLabel.Text = 'Growth_mode';
            app.Growth_modeDropDown = uidropdown(app.simulationTab);
            app.Growth_modeDropDown.Items = {'Rotation', 'Gradient', 'QD', 'break', 'QD_break'};
            app.Growth_modeDropDown.ValueChangedFcn = createCallbackFcn(app, @Growth_modeDropDownValueChanged, true);
            app.Growth_modeDropDown.Value = 'Rotation';
            app.MaterialDropDownLabel = uilabel(app.simulationTab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.simulationTab);
            app.MaterialDropDown.Items = {'GaAs', 'AlAs', 'AlGaAs', 'InAs', 'InGaAs'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'GaAs';
            app.Simmulation_sizeSpinnerLabel = uilabel(app.simulationTab);
            app.Simmulation_sizeSpinnerLabel.Text = 'Simmulation_size';
            app.Simmulation_sizeSpinner = uispinner(app.simulationTab);
            app.Simmulation_sizeSpinner.Limits = [1 Inf];
            app.Simmulation_sizeSpinner.Tooltip = {'radius on the sample you want to simulate'};
            app.Simmulation_sizeSpinner.Value = 38;
            app.time_per_rotationSpinnerLabel = uilabel(app.simulationTab);
            app.time_per_rotationSpinnerLabel.Text = 'time_per_rotation';
            app.time_per_rotationSpinner = uispinner(app.simulationTab);
            app.time_per_rotationSpinner.Step = 0.1;
            app.time_per_rotationSpinner.Limits = [0 Inf];
            app.time_per_rotationSpinner.Tooltip = {'Time in seconds needed for one complete rotation'};
            app.time_per_rotationSpinner.Value = 6.5;
            app.step_resolutionSpinnerLabel = uilabel(app.simulationTab);
            app.step_resolutionSpinnerLabel.Text = 'step_resolution';
            app.step_resolutionSpinner = uispinner(app.simulationTab);
            app.step_resolutionSpinner.Limits = [1 Inf];
            app.step_resolutionSpinner.Tooltip = {'The resolution of the growth rate is 2^(step_resolution) more detailed than the step size.'; ''; 'An increase of the step resolution increases the simulation time dramatically. Too low step resolution reduces the accuracy and can cause artifacts. '};
            app.step_resolutionSpinner.Value = 2;
            app.rotSpinnerLabel = uilabel(app.simulationTab);
            app.rotSpinnerLabel.Enable = 'off';
            app.rotSpinnerLabel.Text = 'rot';
            app.rotSpinner = uispinner(app.simulationTab);
            app.rotSpinner.Limits = [0 Inf];
            app.rotSpinner.Enable = 'off';
            app.rotSpinner.Value = 6.5;
            app.stopSpinnerLabel = uilabel(app.simulationTab);
            app.stopSpinnerLabel.Enable = 'off';
            app.stopSpinnerLabel.Text = 'stop';
            app.stopSpinner = uispinner(app.simulationTab);
            app.stopSpinner.Limits = [0 Inf];
            app.stopSpinner.Enable = 'off';
            app.stopSpinner.Value = 6;
            app.t_waitSpinnerLabel = uilabel(app.simulationTab);
            app.t_waitSpinnerLabel.Enable = 'off';
            app.t_waitSpinnerLabel.Text = 't_wait';
            app.t_waitSpinner = uispinner(app.simulationTab);
            app.t_waitSpinner.Limits = [0 Inf];
            app.t_waitSpinner.ValueChangedFcn = createCallbackFcn(app, @t_growthSpinnerValueChanged, true);
            app.t_waitSpinner.Enable = 'off';
            app.t_waitSpinner.Value = 4;
            app.start_angleSpinnerLabel = uilabel(app.simulationTab);
            app.start_angleSpinnerLabel.Text = 'start_angle';
            app.start_angleSpinner = uispinner(app.simulationTab);
            app.start_angleSpinner.Tooltip = {'in degrees'};
            app.x_contentSpinnerLabel = uilabel(app.simulationTab);
            app.x_contentSpinnerLabel.Enable = 'off';
            app.x_contentSpinnerLabel.Text = 'x_content';
            app.x_contentSpinner = uispinner(app.simulationTab);
            app.x_contentSpinner.Step = 0.01;
            app.x_contentSpinner.Limits = [0 1];
            app.x_contentSpinner.ValueChangedFcn = createCallbackFcn(app, @growth_rat_1_set_valueEditFieldValueChanged, true);
            app.x_contentSpinner.Enable = 'off';
            app.x_contentSpinner.Value = 0.33;
            app.x_startSpinnerLabel = uilabel(app.simulationTab);
            app.x_startSpinnerLabel.Text = 'x_start';
            app.x_startSpinner = uispinner(app.simulationTab);
            app.x_startSpinner.Limits = [0 Inf];
            app.x_startSpinner.ValueChangedFcn = createCallbackFcn(app, @x_startSpinnerValueChanged, true);
            app.x_endSpinnerLabel = uilabel(app.simulationTab);
            app.x_endSpinnerLabel.Text = 'x_end';
            app.x_endSpinner = uispinner(app.simulationTab);
            app.x_endSpinner.Limits = [0 Inf];
            app.x_endSpinner.ValueChangedFcn = createCallbackFcn(app, @x_startSpinnerValueChanged, true);
            app.x_endSpinner.Value = 76;
            app.x_stepsSpinnerLabel = uilabel(app.simulationTab);
            app.x_stepsSpinnerLabel.Text = 'x_steps';
            app.x_stepsSpinner = uispinner(app.simulationTab);
            app.x_stepsSpinner.Step = 0.25;
            app.x_stepsSpinner.Limits = [0 Inf];
            app.x_stepsSpinner.ValueChangedFcn = createCallbackFcn(app, @x_startSpinnerValueChanged, true);
            app.x_stepsSpinner.Value = 0.5;
            app.y_startSpinnerLabel = uilabel(app.simulationTab);
            app.y_startSpinnerLabel.Text = 'y_start';
            app.y_startSpinner = uispinner(app.simulationTab);
            app.y_startSpinner.Limits = [0 Inf];
            app.y_startSpinner.ValueChangedFcn = createCallbackFcn(app, @x_startSpinnerValueChanged, true);
            app.y_endSpinnerLabel = uilabel(app.simulationTab);
            app.y_endSpinnerLabel.Text = 'y_end';
            app.y_endSpinner = uispinner(app.simulationTab);
            app.y_endSpinner.Limits = [0 Inf];
            app.y_endSpinner.ValueChangedFcn = createCallbackFcn(app, @x_startSpinnerValueChanged, true);
            app.y_endSpinner.Value = 76;
            app.y_stepsSpinnerLabel = uilabel(app.simulationTab);
            app.y_stepsSpinnerLabel.Text = 'y_steps';
            app.y_stepsSpinner = uispinner(app.simulationTab);
            app.y_stepsSpinner.Step = 0.25;
            app.y_stepsSpinner.Limits = [0 Inf];
            app.y_stepsSpinner.ValueChangedFcn = createCallbackFcn(app, @x_startSpinnerValueChanged, true);
            app.y_stepsSpinner.Value = 0.5;
            app.growth_timeSpinnerLabel = uilabel(app.simulationTab);
            app.growth_timeSpinnerLabel.Text = 'growth_time';
            app.growth_timeSpinner = uispinner(app.simulationTab);
            app.growth_timeSpinner.Step = 0.1;
            app.growth_timeSpinner.LowerLimitInclusive = 'off';
            app.growth_timeSpinner.Limits = [0 Inf];
            app.growth_timeSpinner.Value = 6.5;
            app.n_contour_linesSpinnerLabel = uilabel(app.simulationTab);
            app.n_contour_linesSpinnerLabel.Enable = 'off';
            app.n_contour_linesSpinnerLabel.Text = 'n_contour_lines';
            app.n_contour_linesSpinner = uispinner(app.simulationTab);
            app.n_contour_linesSpinner.Limits = [1 Inf];
            app.n_contour_linesSpinner.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.n_contour_linesSpinner.Enable = 'off';
            app.n_contour_linesSpinner.Value = 5;
            app.growth_rat_1_set_valueEditFieldLabel = uilabel(app.simulationTab);
            app.growth_rat_1_set_valueEditFieldLabel.Text = 'growth_rat_1_set_value';
            app.growth_rat_1_set_valueEditField = uieditfield(app.simulationTab, 'numeric');
            app.growth_rat_1_set_valueEditField.ValueChangedFcn = createCallbackFcn(app, @growth_rat_1_set_valueEditFieldValueChanged, true);
            app.growth_rat_1_set_valueEditField.Tooltip = {'zero means no changes.'; 'give the growth rate in nm/s.'};
            app.growth_rat_1_set_valueEditField.Value = 0.2;
            app.t_growthSpinnerLabel = uilabel(app.simulationTab);
            app.t_growthSpinnerLabel.Enable = 'off';
            app.t_growthSpinnerLabel.Text = 't_growth';
            app.t_growthSpinner = uispinner(app.simulationTab);
            app.t_growthSpinner.Limits = [0 Inf];
            app.t_growthSpinner.ValueChangedFcn = createCallbackFcn(app, @t_growthSpinnerValueChanged, true);
            app.t_growthSpinner.Enable = 'off';
            app.t_growthSpinner.Value = 4;
            app.growth_rat_2_set_valueEditFieldLabel = uilabel(app.simulationTab);
            app.growth_rat_2_set_valueEditFieldLabel.Enable = 'off';
            app.growth_rat_2_set_valueEditFieldLabel.Text = 'growth_rat_2_set_value';
            app.growth_rat_2_set_valueEditField = uieditfield(app.simulationTab, 'numeric');
            app.growth_rat_2_set_valueEditField.ValueChangedFcn = createCallbackFcn(app, @growth_rat_1_set_valueEditFieldValueChanged, true);
            app.growth_rat_2_set_valueEditField.Enable = 'off';
            app.growth_rat_2_set_valueEditField.Tooltip = {'zero means no changes.'; 'give the growth rate in nm/s.'};
            app.growth_rat_2_set_valueEditField.Value = 0.2;
            app.changing_rateDropDownLabel = uilabel(app.simulationTab);
            app.changing_rateDropDownLabel.Text = 'changing_rate';
            app.changing_rateDropDown = uidropdown(app.simulationTab);
            app.changing_rateDropDown.Items = {'rate_1', 'rate_2', 'x_content', 'none'};
            app.changing_rateDropDown.ValueChangedFcn = createCallbackFcn(app, @changing_rateDropDownValueChanged, true);
            app.changing_rateDropDown.Value = 'rate_2';
            app.DisplayedPeakDropDownLabel = uilabel(app.simulationTab);
            app.DisplayedPeakDropDownLabel.Enable = 'off';
            app.DisplayedPeakDropDownLabel.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown = uidropdown(app.simulationTab);
            app.DisplayedPeakDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown.ValueChangedFcn = createCallbackFcn(app, @fitDropDownValueChanged, true);
            app.DisplayedPeakDropDown.Enable = 'off';
            app.DisplayedPeakDropDown.Value = '1';
            app.DisplayedValueDropDownLabel = uilabel(app.simulationTab);
            app.DisplayedValueDropDownLabel.Enable = 'off';
            app.DisplayedValueDropDownLabel.Text = 'Displayed Value';
            app.DisplayedValueDropDown = uidropdown(app.simulationTab);
            app.DisplayedValueDropDown.Items = {'Height', 'Position', 'FWHM'};
            app.DisplayedValueDropDown.ValueChangedFcn = createCallbackFcn(app, @fitDropDownValueChanged, true);
            app.DisplayedValueDropDown.Enable = 'off';
            app.DisplayedValueDropDown.Value = 'Position';
            app.PowerSpinnerLabel = uilabel(app.simulationTab);
            app.PowerSpinnerLabel.Enable = 'off';
            app.PowerSpinnerLabel.Text = 'Power';
            app.PowerSpinner = uispinner(app.simulationTab);
            app.PowerSpinner.Limits = [0 76];
            app.PowerSpinner.ValueChangedFcn = createCallbackFcn(app, @fitDropDownValueChanged, true);
            app.PowerSpinner.Enable = 'off';
            app.PowerSpinner.Value = 1;
            app.TemperatureIndexSpinnerLabel = uilabel(app.simulationTab);
            app.TemperatureIndexSpinnerLabel.Enable = 'off';
            app.TemperatureIndexSpinnerLabel.Text = 'Temperature Index';
            app.TemperatureIndexSpinner = uispinner(app.simulationTab);
            app.TemperatureIndexSpinner.Limits = [0 76];
            app.TemperatureIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @fitDropDownValueChanged, true);
            app.TemperatureIndexSpinner.Enable = 'off';
            app.TemperatureIndexSpinner.Value = 1;
            app.DisplayedmaterialDropDownLabel = uilabel(app.simulationTab);
            app.DisplayedmaterialDropDownLabel.Enable = 'off';
            app.DisplayedmaterialDropDownLabel.Text = 'Displayed material';
            app.DisplayedmaterialDropDown = uidropdown(app.simulationTab);
            app.DisplayedmaterialDropDown.Items = {'GaAs', 'AlAs', 'AlGaAs', 'InAs', 'InGaAs'};
            app.DisplayedmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @display_modeSwitchValueChanged, true);
            app.DisplayedmaterialDropDown.Enable = 'off';
            app.DisplayedmaterialDropDown.Value = 'GaAs';
            app.DisplayedLayerDropDownLabel = uilabel(app.simulationTab);
            app.DisplayedLayerDropDownLabel.Enable = 'off';
            app.DisplayedLayerDropDownLabel.Text = 'Displayed Layer';
            app.DisplayedLayerDropDown = uidropdown(app.simulationTab);
            app.DisplayedLayerDropDown.Items = {'1'};
            app.DisplayedLayerDropDown.ValueChangedFcn = createCallbackFcn(app, @display_modeSwitchValueChanged, true);
            app.DisplayedLayerDropDown.Enable = 'off';
            app.DisplayedLayerDropDown.Value = '1';
            app.steps_per_rotSpinnerLabel = uilabel(app.simulationTab);
            app.steps_per_rotSpinnerLabel.Text = 'steps_per_rot';
            app.steps_per_rotSpinner = uispinner(app.simulationTab);
            app.steps_per_rotSpinner.Limits = [10 Inf];
            app.steps_per_rotSpinner.Tooltip = {'The motor on the MBE needs 400 steps for one complete rotation.'; ''; 'By reducing the step size the simulation is faster but the accuracy is reduced.'; ''};
            app.steps_per_rotSpinner.Value = 800;
            app.ML_contour_stepsEditFieldLabel = uilabel(app.simulationTab);
            app.ML_contour_stepsEditFieldLabel.Enable = 'off';
            app.ML_contour_stepsEditFieldLabel.Text = 'ML_contour_steps';
            app.ML_contour_stepsEditField = uieditfield(app.simulationTab, 'numeric');
            app.ML_contour_stepsEditField.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.ML_contour_stepsEditField.Enable = 'off';
            app.ML_contour_stepsEditField.Tooltip = {'Rounds the smallest and the highest value to the next integer (down/up) and finds levels in spacing of steps.'};
            app.effective_growth_rateEditFieldLabel = uilabel(app.simulationTab);
            app.effective_growth_rateEditFieldLabel.Enable = 'off';
            app.effective_growth_rateEditFieldLabel.Text = 'effective_growth_rate';
            app.effective_growth_rateEditField = uieditfield(app.simulationTab, 'numeric');
            app.effective_growth_rateEditField.Editable = 'off';
            app.effective_growth_rateEditField.Enable = 'off';
            app.effective_growth_rateEditField.Tooltip = {'effective growth rate in nm/s. It is assumed that the growth rate is distributed over the total time - growth and waiting. Therefore a shorter t_wait could give a better result.'; 'with effectiv_growth_rate = growth_rate_1 * t_growth / (t_growth + t_wait)'};
            app.T_growthEditFieldLabel = uilabel(app.simulationTab);
            app.T_growthEditFieldLabel.Text = 'T_growth';
            app.T_growthEditField = uieditfield(app.simulationTab, 'numeric');
            app.T_growthEditField.ValueChangedFcn = createCallbackFcn(app, @growth_rat_1_set_valueEditFieldValueChanged, true);
            app.T_growthEditField.Value = 300;
            app.ML_ratesButton = uibutton(app.simulationTab, 'push');
            app.ML_ratesButton.ButtonPushedFcn = createCallbackFcn(app, @ML_ratesButtonPushed, true);
            app.ML_ratesButton.Text = 'ML_rates';
            app.desorption_rateEditFieldLabel = uilabel(app.simulationTab);
            app.desorption_rateEditFieldLabel.Enable = 'off';
            app.desorption_rateEditFieldLabel.Text = 'desorption_rate';
            app.desorption_rateEditField = uieditfield(app.simulationTab, 'numeric');
            app.desorption_rateEditField.ValueChangedFcn = createCallbackFcn(app, @t_growthSpinnerValueChanged, true);
            app.desorption_rateEditField.Enable = 'off';
            app.desorption_rateEditField.Tooltip = {'in nm/s'};
            app.break_timeSpinnerLabel = uilabel(app.simulationTab);
            app.break_timeSpinnerLabel.Enable = 'off';
            app.break_timeSpinnerLabel.Text = 'break_time';
            app.break_timeSpinner = uispinner(app.simulationTab);
            app.break_timeSpinner.Step = 0.1;
            app.break_timeSpinner.Limits = [0 Inf];
            app.break_timeSpinner.Enable = 'off';
            app.break_timeSpinner.Value = 30;
            app.fitDropDownLabel = uilabel(app.simulationTab);
            app.fitDropDownLabel.Enable = 'off';
            app.fitDropDownLabel.Text = 'fit';
            app.fitDropDown = uidropdown(app.simulationTab);
            app.fitDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown.ValueChangedFcn = createCallbackFcn(app, @fitDropDownValueChanged, true);
            app.fitDropDown.Enable = 'off';
            app.fitDropDown.Value = '1';
            app.gammaLabel = uilabel(app.simulationTab);
            app.gammaLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.simulationTab, 'numeric');
            app.gammaEditField.Value = 0.015;
            app.RoughnessLabel = uilabel(app.simulationTab);
            app.RoughnessLabel.Text = 'Roughness';
            app.showroughnessButton = uibutton(app.simulationTab, 'push');
            app.showroughnessButton.ButtonPushedFcn = createCallbackFcn(app, @showroughnessButtonPushed, true);
            app.showroughnessButton.Text = 'show roughness';
            app.QDdensityLabel = uilabel(app.simulationTab);
            app.QDdensityLabel.Text = 'QD density';
            app.alphaEditFieldLabel = uilabel(app.simulationTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.simulationTab, 'numeric');
            app.alphaEditField.Value = 1.76;
            app.critical_thicknessEditFieldLabel = uilabel(app.simulationTab);
            app.critical_thicknessEditFieldLabel.Text = 'critical_thickness';
            app.critical_thicknessEditField = uieditfield(app.simulationTab, 'numeric');
            app.critical_thicknessEditField.Value = 1.5;
            app.maximum_coverageEditFieldLabel = uilabel(app.simulationTab);
            app.maximum_coverageEditFieldLabel.Text = 'maximum_coverage';
            app.maximum_coverageEditField = uieditfield(app.simulationTab, 'numeric');
            app.maximum_coverageEditField.Value = 200000000000;
            app.showdensityButton = uibutton(app.simulationTab, 'push');
            app.showdensityButton.ButtonPushedFcn = createCallbackFcn(app, @showdensityButtonPushed, true);
            app.showdensityButton.Text = 'show density';
            app.R_0EditFieldLabel = uilabel(app.simulationTab);
            app.R_0EditFieldLabel.Text = 'R_0';
            app.R_0EditField = uieditfield(app.simulationTab, 'numeric');
            app.R_0EditField.Value = 5;
            app.Delta_REditFieldLabel = uilabel(app.simulationTab);
            app.Delta_REditFieldLabel.Text = 'Delta_R';
            app.Delta_REditField = uieditfield(app.simulationTab, 'numeric');
            app.Delta_REditField.Value = 2;
            app.conversion_factorEditFieldLabel = uilabel(app.simulationTab);
            app.conversion_factorEditFieldLabel.Text = 'conversion_factor';
            app.conversion_factorEditField = uieditfield(app.simulationTab, 'numeric');
            app.conversion_factorEditField.Value = 5.1;
            app.roughness_version = uiswitch(app.simulationTab, 'slider');
            app.roughness_version.Items = {'standard', 'expand'};
            app.roughness_version.Value = 'standard';
            app.incloudroughnessCheckBox = uicheckbox(app.simulationTab);
            app.incloudroughnessCheckBox.Text = 'incloud roughness';
            app.roughness_loadedLampLabel = uilabel(app.simulationTab);
            app.roughness_loadedLampLabel.Text = 'roughness_loaded';
            app.roughness_loadedLamp = uilamp(app.simulationTab);
            app.critical_layer_thicknessEditFieldLabel = uilabel(app.simulationTab);
            app.critical_layer_thicknessEditFieldLabel.Enable = 'off';
            app.critical_layer_thicknessEditFieldLabel.Text = 'critical_layer_thickness';
            app.critical_layer_thicknessEditField = uieditfield(app.simulationTab, 'numeric');
            app.critical_layer_thicknessEditField.Editable = 'off';
            app.critical_layer_thicknessEditField.Enable = 'off';
            app.critical_layer_thicknessEditField.Tooltip = {'critical thicknes of InGaAs on GaAs in [nm] before Dislocations'};
            app.display_peak_as_wavelengthCheckBox = uicheckbox(app.simulationTab);
            app.display_peak_as_wavelengthCheckBox.Enable = 'off';
            app.display_peak_as_wavelengthCheckBox.Text = 'display_peak_as_wavelength';
            app.display_peak_as_wavelengthCheckBox.Value = true;
            app.refreshButton = uibutton(app.simulationTab, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.overlay_contourButton = uibutton(app.simulationTab, 'push');
            app.overlay_contourButton.ButtonPushedFcn = createCallbackFcn(app, @overlay_contourButtonPushed, true);
            app.overlay_contourButton.Text = 'overlay_contour';
            app.ML_minSpinnerLabel = uilabel(app.simulationTab);
            app.ML_minSpinnerLabel.Enable = 'off';
            app.ML_minSpinnerLabel.Text = 'ML_min';
            app.ML_minSpinner = uispinner(app.simulationTab);
            app.ML_minSpinner.Step = 0.1;
            app.ML_minSpinner.Limits = [0 Inf];
            app.ML_minSpinner.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.ML_minSpinner.Enable = 'off';
            app.ML_minSpinner.Tooltip = {'In automatic mode, the smallest layer thickness rounded to the first decimal is displayed here. In manual mode, a layer thickness can be entered here up to which contour lines are shown. If this value is outside the simulated values, the smallest simulated value is used instead.'};
            app.ML_maxSpinnerLabel = uilabel(app.simulationTab);
            app.ML_maxSpinnerLabel.Enable = 'off';
            app.ML_maxSpinnerLabel.Text = 'ML_max';
            app.ML_maxSpinner = uispinner(app.simulationTab);
            app.ML_maxSpinner.Step = 0.1;
            app.ML_maxSpinner.Limits = [0 Inf];
            app.ML_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @contour_linesCheckBoxValueChanged, true);
            app.ML_maxSpinner.Enable = 'off';
            app.ML_maxSpinner.Tooltip = {'In automatic mode, the largest layer thickness rounded to the first decimal is displayed here. In manual mode, a layer thickness can be entered here up to which contour lines are shown. If this value is outside the simulated values, the largest simulated value is used instead.'};
            app.auto_MLCheckBox = uicheckbox(app.simulationTab);
            app.auto_MLCheckBox.Enable = 'off';
            app.auto_MLCheckBox.Text = 'auto_ML';
            app.auto_MLCheckBox.Value = true;
            app.UIAxes = uiaxes(app.simulationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X [mm]')
            ylabel(app.UIAxes, 'Y [mm]')
            app.recipeTab = uitab(app.TabGroup);
            app.recipeTab.Title = 'recipe';
            app.recipe = uitable(app.recipeTab);
            app.recipe.ColumnName = {'Material'; 'growth_rat_1_set_value'; 'growth_rat_2_set_value'; 'x_content'; 'Growth_mode'; 'start_angle'; 't_growth'; 't_wait'; 'qd_stop'; 'qd_rot'; 'desorption_rate'};
            app.recipe.RowName = {};
            app.recipe.ColumnEditable = true;
            app.add_layerButton = uibutton(app.recipeTab, 'push');
            app.add_layerButton.ButtonPushedFcn = createCallbackFcn(app, @add_layerButtonPushed, true);
            app.add_layerButton.Text = 'add_layer';
            app.SimulateButton_2 = uibutton(app.recipeTab, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_2Pushed, true);
            app.SimulateButton_2.Text = 'Simulate';
            app.simulated_layersTab = uitab(app.TabGroup);
            app.simulated_layersTab.Title = 'simulated_layers';
            app.simulated_layers = uitable(app.simulated_layersTab);
            app.simulated_layers.ColumnName = {'Layer'; 'Sum'; 'Material'; 'center_thickness_nm'; 'center_thickness_ML'};
            app.simulated_layers.RowName = {};
            app.display_summed_layerButton = uibutton(app.simulated_layersTab, 'state');
            app.display_summed_layerButton.Text = 'display_summed_layer';
            app.computeratesfromPLTab = uitab(app.TabGroup);
            app.computeratesfromPLTab.Title = 'compute rates from PL';
            app.calculateratesButton = uibutton(app.computeratesfromPLTab, 'push');
            app.calculateratesButton.ButtonPushedFcn = createCallbackFcn(app, @calculateratesButtonPushed, true);
            app.calculateratesButton.Text = 'calculate rates';
            app.qwtypDropDownLabel = uilabel(app.computeratesfromPLTab);
            app.qwtypDropDownLabel.Text = 'qw-typ';
            app.qwtypDropDown = uidropdown(app.computeratesfromPLTab);
            app.qwtypDropDown.Items = {'algaas', 'ingaas'};
            app.qwtypDropDown.Value = 'algaas';
            app.BrowseButton = uibutton(app.computeratesfromPLTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Load_fit_dataButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.sampleIDEditFieldLabel = uilabel(app.computeratesfromPLTab);
            app.sampleIDEditFieldLabel.Text = 'sample ID';
            app.sampleIDEditField = uieditfield(app.computeratesfromPLTab, 'numeric');
            app.sampleIDEditField.ValueDisplayFormat = '%.0f';
            app.sampleIDEditField.Editable = 'off';
            app.temperature_indexSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.temperature_indexSpinnerLabel.Text = 'temperature_index';
            app.temperature_indexSpinner = uispinner(app.computeratesfromPLTab);
            app.temperature_indexSpinner.Limits = [1 Inf];
            app.temperature_indexSpinner.Value = 1;
            app.power_indexSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.power_indexSpinnerLabel.Text = 'power_index';
            app.power_indexSpinner = uispinner(app.computeratesfromPLTab);
            app.power_indexSpinner.Limits = [1 Inf];
            app.power_indexSpinner.Value = 1;
            app.GaAs_indexSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.GaAs_indexSpinnerLabel.Text = 'GaAs_index';
            app.GaAs_indexSpinner = uispinner(app.computeratesfromPLTab);
            app.GaAs_indexSpinner.Limits = [1 Inf];
            app.GaAs_indexSpinner.Value = 1;
            app.QW_indexSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.QW_indexSpinnerLabel.Text = 'QW_index';
            app.QW_indexSpinner = uispinner(app.computeratesfromPLTab);
            app.QW_indexSpinner.Value = 1;
            app.GaAs_growth_timeSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.GaAs_growth_timeSpinnerLabel.Text = 'GaAs_growth_time';
            app.GaAs_growth_timeSpinner = uispinner(app.computeratesfromPLTab);
            app.GaAs_growth_timeSpinner.Step = 0.1;
            app.GaAs_growth_timeSpinner.Limits = [0.1 Inf];
            app.GaAs_growth_timeSpinner.Value = 35;
            app.degre_1SpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.degre_1SpinnerLabel.Text = 'degre_1';
            app.degre_1Spinner = uispinner(app.computeratesfromPLTab);
            app.degre_1Spinner.Step = 0.1;
            app.QW_growth_timeSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.QW_growth_timeSpinnerLabel.Text = 'QW_growth_time';
            app.QW_growth_timeSpinner = uispinner(app.computeratesfromPLTab);
            app.QW_growth_timeSpinner.Step = 0.1;
            app.QW_growth_timeSpinner.Limits = [0.1 Inf];
            app.QW_growth_timeSpinner.Value = 35;
            app.degre_2SpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.degre_2SpinnerLabel.Text = 'degre_2';
            app.degre_2Spinner = uispinner(app.computeratesfromPLTab);
            app.degre_2Spinner.Step = 0.1;
            app.degre_2Spinner.Value = 5;
            app.degre_stepsSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.degre_stepsSpinnerLabel.Text = 'degre_steps';
            app.degre_stepsSpinner = uispinner(app.computeratesfromPLTab);
            app.degre_stepsSpinner.Step = 0.1;
            app.degre_stepsSpinner.Limits = [0 Inf];
            app.degre_stepsSpinner.Value = 1;
            app.cell_angleSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.cell_angleSpinnerLabel.Text = 'cell_angle';
            app.cell_angleSpinner = uispinner(app.computeratesfromPLTab);
            app.cell_angleSpinner.Step = 0.1;
            app.cell_angleSpinner.ValueChangedFcn = createCallbackFcn(app, @show_rotationButtonPushed, true);
            app.cell_angleSpinner.Value = -110;
            app.cellDropDownLabel = uilabel(app.computeratesfromPLTab);
            app.cellDropDownLabel.Text = 'cell';
            app.cellDropDown = uidropdown(app.computeratesfromPLTab);
            app.cellDropDown.Items = {'AL', 'AL-B', 'In'};
            app.cellDropDown.ValueChangedFcn = createCallbackFcn(app, @cellDropDownValueChanged, true);
            app.cellDropDown.Value = 'AL-B';
            app.Ga_cell_angleSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.Ga_cell_angleSpinnerLabel.Text = 'Ga_cell_angle';
            app.Ga_cell_angleSpinner = uispinner(app.computeratesfromPLTab);
            app.Ga_cell_angleSpinner.Step = 0.1;
            app.Ga_cell_angleSpinner.ValueChangedFcn = createCallbackFcn(app, @show_rotationButtonPushed, true);
            app.show_rotationButton = uibutton(app.computeratesfromPLTab, 'push');
            app.show_rotationButton.ButtonPushedFcn = createCallbackFcn(app, @show_rotationButtonPushed, true);
            app.show_rotationButton.Text = 'show_rotation';
            app.saveButton = uibutton(app.computeratesfromPLTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.GaAs_peakSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.GaAs_peakSpinnerLabel.Text = 'GaAs_peak';
            app.GaAs_peakSpinner = uispinner(app.computeratesfromPLTab);
            app.GaAs_peakSpinner.Limits = [1 Inf];
            app.GaAs_peakSpinner.Value = 1;
            app.QW_peakSpinnerLabel = uilabel(app.computeratesfromPLTab);
            app.QW_peakSpinnerLabel.Text = 'QW_peak';
            app.QW_peakSpinner = uispinner(app.computeratesfromPLTab);
            app.QW_peakSpinner.Limits = [1 Inf];
            app.QW_peakSpinner.Value = 1;
            app.rate_gaasEditFieldLabel = uilabel(app.computeratesfromPLTab);
            app.rate_gaasEditFieldLabel.Text = 'rate_gaas';
            app.rate_gaasEditField = uieditfield(app.computeratesfromPLTab, 'numeric');
            app.rate_gaasEditField.ValueDisplayFormat = '%5.4f';
            app.rate_gaasEditField.Editable = 'off';
            app.rate_qwEditFieldLabel = uilabel(app.computeratesfromPLTab);
            app.rate_qwEditFieldLabel.Text = 'rate_qw';
            app.rate_qwEditField = uieditfield(app.computeratesfromPLTab, 'numeric');
            app.rate_qwEditField.ValueDisplayFormat = '%5.4f';
            app.rate_qwEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.computeratesfromPLTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.computeratesfromPLTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.growthsimulationbyMarcelSchmidtUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_growth_sim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.growthsimulationbyMarcelSchmidtUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.growthsimulationbyMarcelSchmidtUIFigure)
        end
    end
end