classdef app_growthrate_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hyperspectralanalyzerbyMarcelSchmidtUIFigure  matlab.ui.Figure
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        x_contentSpinnerLabel           matlab.ui.control.Label
        x_contentSpinner                matlab.ui.control.Spinner
        growth_rat_1_set_valueEditFieldLabel  matlab.ui.control.Label
        growth_rat_1_set_valueEditField  matlab.ui.control.NumericEditField
        growth_rat_2_set_valueEditFieldLabel  matlab.ui.control.Label
        growth_rat_2_set_valueEditField  matlab.ui.control.NumericEditField
        changing_rateDropDownLabel      matlab.ui.control.Label
        changing_rateDropDown           matlab.ui.control.DropDown
        GrowthratesinnmsLabel           matlab.ui.control.Label
        TpSpinnerLabel                  matlab.ui.control.Label
        TpSpinner                       matlab.ui.control.Spinner
        total_rateEditFieldLabel        matlab.ui.control.Label
        total_rateEditField             matlab.ui.control.NumericEditField
        total_rat_MLEditFieldLabel      matlab.ui.control.Label
        total_rat_MLEditField           matlab.ui.control.NumericEditField
        growth_rat_2_MLEditFieldLabel   matlab.ui.control.Label
        growth_rat_2_MLEditField        matlab.ui.control.NumericEditField
        growth_rat_1_MLEditFieldLabel   matlab.ui.control.Label
        growth_rat_1_MLEditField        matlab.ui.control.NumericEditField
        GrowthratesinMLsLabel           matlab.ui.control.Label
        t_waitSpinnerLabel              matlab.ui.control.Label
        t_waitSpinner                   matlab.ui.control.Spinner
        t_growthSpinnerLabel            matlab.ui.control.Label
        t_growthSpinner                 matlab.ui.control.Spinner
        effective_growth_rateEditFieldLabel  matlab.ui.control.Label
        effective_growth_rateEditField  matlab.ui.control.NumericEditField
        effective_growth_rate_MLEditFieldLabel  matlab.ui.control.Label
        effective_growth_rate_MLEditField  matlab.ui.control.NumericEditField
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    
    properties (Access = private)
        CallingApp; % Description
    end
    
    methods (Access = private)
        function calculate_nm(app)
            %changes the displayed buttons
            switch app.MaterialDropDown.Value
                case 'GaAs'
                    app.growth_rat_1_set_valueEditField.Value = converter.ML_to_nm('GaAs',app.growth_rat_1_MLEditField.Value,...
                        [],app.TpSpinner.Value);
                case'AlAs'
                    app.growth_rat_1_set_valueEditField.Value = converter.ML_to_nm('AlAs',app.growth_rat_1_MLEditField.Value,...
                        [],app.TpSpinner.Value);
                case'InAs'
                    app.growth_rat_1_set_valueEditField.Value = converter.ML_to_nm('InAs',app.growth_rat_1_MLEditField.Value,...
                        [],app.TpSpinner.Value);
                case 'AlGaAs'
                    app.growth_rat_1_set_valueEditField.Value = converter.ML_to_nm('AlAs',app.growth_rat_1_MLEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.growth_rat_2_set_valueEditField.Value = converter.ML_to_nm('GaAs',app.growth_rat_2_MLEditField.Value,...
                        [],app.TpSpinner.Value);
                case'InGaAs'
                    app.growth_rat_1_set_valueEditField.Value = converter.ML_to_nm('InAs',app.growth_rat_1_MLEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.growth_rat_2_set_valueEditField.Value = converter.ML_to_nm('GaAs',app.growth_rat_2_MLEditField.Value,...
                        [],app.TpSpinner.Value);
            end
            
            switch app.changing_rateDropDown.Value
                case 'rate_1'
                    app.growth_rat_1_set_valueEditField.Value = app.x_contentSpinner.Value * app.growth_rat_2_set_valueEditField.Value/(1-app.x_contentSpinner.Value);
                case 'rate_2'
                    app.growth_rat_2_set_valueEditField.Value = (app.growth_rat_1_set_valueEditField.Value/app.x_contentSpinner.Value) - app.growth_rat_1_set_valueEditField.Value;
                case 'x_content'
                    app.x_contentSpinner.Value = app.growth_rat_1_set_valueEditField.Value/(app.growth_rat_1_set_valueEditField.Value+app.growth_rat_2_set_valueEditField.Value);
            end
            
            %add the total thicknrss
            app.total_rateEditField.Value = app.growth_rat_1_set_valueEditField.Value + app.growth_rat_2_set_valueEditField.Value;
            
            switch app.MaterialDropDown.Value
                case 'InAs'
                    app.effective_growth_rateEditField.Value = app.growth_rat_1_set_valueEditField.Value * app.t_growthSpinner.Value / (app.t_growthSpinner.Value + app.t_waitSpinner.Value);
            end
            
            calculate_ML(app);
        end
        
        function calculate_ML(app)
            %changes the displayed buttons
            switch app.MaterialDropDown.Value
                case 'GaAs'
                    app.growth_rat_1_MLEditField.Value = converter.nm_to_ML('GaAs',app.growth_rat_1_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                case'AlAs'
                    app.growth_rat_1_MLEditField.Value = converter.nm_to_ML('AlAs',app.growth_rat_1_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                case'InAs'
                    app.growth_rat_1_MLEditField.Value = converter.nm_to_ML('InAs',app.growth_rat_1_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.effective_growth_rate_MLEditField.Value = converter.nm_to_ML('InAs',app.effective_growth_rateEditField.Value,...
                        [],app.TpSpinner.Value);
                case 'AlGaAs'
                    app.growth_rat_1_MLEditField.Value = converter.nm_to_ML('AlAs',app.growth_rat_1_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.growth_rat_2_MLEditField.Value = converter.nm_to_ML('GaAs',app.growth_rat_2_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.total_rat_MLEditField.Value = app.growth_rat_1_MLEditField.Value*app.x_contentSpinner.Value + (1-app.x_contentSpinner.Value) * app.growth_rat_2_MLEditField.Value;
                case'InGaAs'
                    app.growth_rat_1_MLEditField.Value = converter.nm_to_ML('InAs',app.growth_rat_1_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.growth_rat_2_MLEditField.Value = converter.nm_to_ML('GaAs',app.growth_rat_2_set_valueEditField.Value,...
                        [],app.TpSpinner.Value);
                    app.total_rat_MLEditField.Value = app.growth_rat_1_MLEditField.Value*app.x_contentSpinner.Value + (1-app.x_contentSpinner.Value) * app.growth_rat_2_MLEditField.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %store main app opject
            app.CallingApp = mainapp;
            app.MaterialDropDown.Value = app.CallingApp.MaterialDropDown.Value;
            app.x_contentSpinner.Value = app.CallingApp.x_contentSpinner.Value;
            app.growth_rat_1_set_valueEditField.Value = app.CallingApp.growth_rat_1_set_valueEditField.Value;
            app.growth_rat_2_set_valueEditField.Value = app.CallingApp.growth_rat_2_set_valueEditField.Value;
            app.TpSpinner.Value = app.CallingApp.T_growthEditField.Value;
            app.changing_rateDropDown.Value = app.CallingApp.changing_rateDropDown.Value;
            app.t_growthSpinner.Value = app.CallingApp.t_growthSpinner.Value;
            app.t_waitSpinner.Value = app.CallingApp.t_waitSpinner.Value;
            app.effective_growth_rateEditField.Value = app.CallingApp.effective_growth_rateEditField.Value;
            
            %sets the visibility for all fields according to the material
            %state
            switch app.MaterialDropDown.Value
                case {'GaAs', 'AlAs', 'InAs'}
                    app.x_contentSpinnerLabel.Enable = 'off';
                    app.x_contentSpinner.Enable = 'off';
                    app.growth_rat_2_set_valueEditFieldLabel.Enable = 'off';
                    app.growth_rat_2_set_valueEditField.Enable = 'off';
                    app.total_rateEditField.Enable = 'off';
                    app.total_rateEditFieldLabel.Enable = 'off';
                    app.growth_rat_2_MLEditField.Enable = 'off';
                    app.growth_rat_2_MLEditFieldLabel.Enable = 'off';
                    app.total_rat_MLEditField.Enable = 'off';
                    app.total_rat_MLEditFieldLabel.Enable = 'off';
                case {'AlGaAs','InGaAs'}
                    app.x_contentSpinnerLabel.Enable = 'on';
                    app.x_contentSpinner.Enable = 'on';
                    app.growth_rat_2_set_valueEditFieldLabel.Enable = 'on';
                    app.growth_rat_2_set_valueEditField.Enable = 'on';
                    app.total_rateEditField.Enable = 'on';
                    app.total_rateEditFieldLabel.Enable = 'on';
                    app.growth_rat_2_MLEditField.Enable = 'on';
                    app.growth_rat_2_MLEditFieldLabel.Enable = 'on';
                    app.total_rat_MLEditField.Enable = 'on';
                    app.total_rat_MLEditFieldLabel.Enable = 'on';
            end
            
            %calcuats the rates in ML/s
            calculate_ML(app)
            
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Position(3);
            window_height = app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Position(4);
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Position = [x,y,window_width,window_height];
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            %changes the displayed buttons
            switch app.MaterialDropDown.Value
                case {'GaAs', 'AlAs', 'InAs'}
                    app.x_contentSpinnerLabel.Enable = 'off';
                    app.x_contentSpinner.Enable = 'off';
                    app.growth_rat_2_set_valueEditFieldLabel.Enable = 'off';
                    app.growth_rat_2_set_valueEditField.Enable = 'off';
                    app.total_rateEditField.Enable = 'off';
                    app.total_rateEditFieldLabel.Enable = 'off';
                    app.growth_rat_2_MLEditField.Enable = 'off';
                    app.growth_rat_2_MLEditFieldLabel.Enable = 'off';
                    app.total_rat_MLEditField.Enable = 'off';
                    app.total_rat_MLEditFieldLabel.Enable = 'off';
                case {'AlGaAs','InGaAs'}
                    app.x_contentSpinnerLabel.Enable = 'on';
                    app.x_contentSpinner.Enable = 'on';
                    app.growth_rat_2_set_valueEditFieldLabel.Enable = 'on';
                    app.growth_rat_2_set_valueEditField.Enable = 'on';
                    app.total_rateEditField.Enable = 'on';
                    app.total_rateEditFieldLabel.Enable = 'on';
                    app.growth_rat_2_MLEditField.Enable = 'on';
                    app.growth_rat_2_MLEditFieldLabel.Enable = 'on';
                    app.total_rat_MLEditField.Enable = 'on';
                    app.total_rat_MLEditFieldLabel.Enable = 'on';
            end
        end

        % Value changed function: TpSpinner, 
        % growth_rat_1_set_valueEditField, 
        % growth_rat_2_set_valueEditField, x_contentSpinner
        function x_contentSpinnerValueChanged(app, event)
            switch app.changing_rateDropDown.Value
                case 'rate_1'
                    app.growth_rat_1_set_valueEditField.Value = app.x_contentSpinner.Value * app.growth_rat_2_set_valueEditField.Value/(1-app.x_contentSpinner.Value);
                case 'rate_2'
                    app.growth_rat_2_set_valueEditField.Value = (app.growth_rat_1_set_valueEditField.Value/app.x_contentSpinner.Value) - app.growth_rat_1_set_valueEditField.Value;
                case 'x_content'
                    app.x_contentSpinner.Value = app.growth_rat_1_set_valueEditField.Value/(app.growth_rat_1_set_valueEditField.Value+app.growth_rat_2_set_valueEditField.Value);
            end
            
            %add the total thicknrss
            app.total_rateEditField.Value = app.growth_rat_1_set_valueEditField.Value + app.growth_rat_2_set_valueEditField.Value;
            
            switch app.MaterialDropDown.Value
                case 'InAs'
                    app.effective_growth_rateEditField.Value = app.growth_rat_1_set_valueEditField.Value * app.t_growthSpinner.Value / (app.t_growthSpinner.Value + app.t_waitSpinner.Value);
            end
            
            calculate_ML(app);
        end

        % Value changed function: growth_rat_1_MLEditField, 
        % growth_rat_2_MLEditField
        function growth_rat_1_MLEditFieldValueChanged(app, event)
            calculate_nm(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure = uifigure('Visible', 'off');
            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Name = 'hyperspectral analyzer by Marcel Schmidt  at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.MaterialDropDownLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.MaterialDropDown.Items = {'GaAs', 'AlAs', 'AlGaAs', 'InAs', 'InGaAs'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'GaAs';
            app.x_contentSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_contentSpinnerLabel.Enable = 'off';
            app.x_contentSpinnerLabel.Text = 'x_content';
            app.x_contentSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_contentSpinner.Step = 0.01;
            app.x_contentSpinner.Limits = [0 1];
            app.x_contentSpinner.ValueChangedFcn = createCallbackFcn(app, @x_contentSpinnerValueChanged, true);
            app.x_contentSpinner.Enable = 'off';
            app.x_contentSpinner.Value = 0.33;
            app.growth_rat_1_set_valueEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.growth_rat_1_set_valueEditFieldLabel.Text = 'growth_rat_1_set_value';
            app.growth_rat_1_set_valueEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.growth_rat_1_set_valueEditField.ValueChangedFcn = createCallbackFcn(app, @x_contentSpinnerValueChanged, true);
            app.growth_rat_1_set_valueEditField.Tooltip = {'zero means no changes.'; 'give the growth rate in nm/s.'};
            app.growth_rat_1_set_valueEditField.Value = 0.2;
            app.growth_rat_2_set_valueEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.growth_rat_2_set_valueEditFieldLabel.Enable = 'off';
            app.growth_rat_2_set_valueEditFieldLabel.Text = 'growth_rat_2_set_value';
            app.growth_rat_2_set_valueEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.growth_rat_2_set_valueEditField.ValueChangedFcn = createCallbackFcn(app, @x_contentSpinnerValueChanged, true);
            app.growth_rat_2_set_valueEditField.Enable = 'off';
            app.growth_rat_2_set_valueEditField.Tooltip = {'zero means no changes.'; 'give the growth rate in nm/s.'};
            app.growth_rat_2_set_valueEditField.Value = 0.2;
            app.changing_rateDropDownLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.changing_rateDropDownLabel.Text = 'changing_rate';
            app.changing_rateDropDown = uidropdown(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.changing_rateDropDown.Items = {'rate_1', 'rate_2', 'x_content', 'none'};
            app.changing_rateDropDown.Value = 'rate_2';
            app.GrowthratesinnmsLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.GrowthratesinnmsLabel.Text = 'Growthrates in nm/s';
            app.TpSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.TpSpinnerLabel.Text = 'Tp';
            app.TpSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.TpSpinner.LowerLimitInclusive = 'off';
            app.TpSpinner.Limits = [-273.15 Inf];
            app.TpSpinner.ValueChangedFcn = createCallbackFcn(app, @x_contentSpinnerValueChanged, true);
            app.TpSpinner.Value = 300;
            app.total_rateEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.total_rateEditFieldLabel.Enable = 'off';
            app.total_rateEditFieldLabel.Text = 'total_rate';
            app.total_rateEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.total_rateEditField.Enable = 'off';
            app.total_rat_MLEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.total_rat_MLEditFieldLabel.Enable = 'off';
            app.total_rat_MLEditFieldLabel.Text = 'total_rat_ML';
            app.total_rat_MLEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.total_rat_MLEditField.Editable = 'off';
            app.total_rat_MLEditField.Enable = 'off';
            app.growth_rat_2_MLEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.growth_rat_2_MLEditFieldLabel.Enable = 'off';
            app.growth_rat_2_MLEditFieldLabel.Text = 'growth_rat_2_ML';
            app.growth_rat_2_MLEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.growth_rat_2_MLEditField.ValueChangedFcn = createCallbackFcn(app, @growth_rat_1_MLEditFieldValueChanged, true);
            app.growth_rat_2_MLEditField.Enable = 'off';
            app.growth_rat_1_MLEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.growth_rat_1_MLEditFieldLabel.Text = 'growth_rat_1_ML';
            app.growth_rat_1_MLEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.growth_rat_1_MLEditField.ValueChangedFcn = createCallbackFcn(app, @growth_rat_1_MLEditFieldValueChanged, true);
            app.GrowthratesinMLsLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.GrowthratesinMLsLabel.Text = 'Growthrates in ML/s';
            app.t_waitSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.t_waitSpinnerLabel.Enable = 'off';
            app.t_waitSpinnerLabel.Text = 't_wait';
            app.t_waitSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.t_waitSpinner.Limits = [0 Inf];
            app.t_waitSpinner.Enable = 'off';
            app.t_waitSpinner.Value = 4;
            app.t_growthSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.t_growthSpinnerLabel.Enable = 'off';
            app.t_growthSpinnerLabel.Text = 't_growth';
            app.t_growthSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.t_growthSpinner.Limits = [0 Inf];
            app.t_growthSpinner.Enable = 'off';
            app.t_growthSpinner.Value = 4;
            app.effective_growth_rateEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.effective_growth_rateEditFieldLabel.Enable = 'off';
            app.effective_growth_rateEditFieldLabel.Text = 'effective_growth_rate';
            app.effective_growth_rateEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.effective_growth_rateEditField.Editable = 'off';
            app.effective_growth_rateEditField.Enable = 'off';
            app.effective_growth_rateEditField.Tooltip = {'effective growth rate in nm/s. It is assumed that the growth rate is distributed over the total time - growth and waiting. Therefore a shorter t_wait could give a better result.'; 'with effectiv_growth_rate = growth_rate_1 * t_growth / (t_growth + t_wait)'};
            app.effective_growth_rate_MLEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.effective_growth_rate_MLEditFieldLabel.Enable = 'off';
            app.effective_growth_rate_MLEditFieldLabel.Text = 'effective_growth_rate_ML';
            app.effective_growth_rate_MLEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'numeric');
            app.effective_growth_rate_MLEditField.Editable = 'off';
            app.effective_growth_rate_MLEditField.Enable = 'off';
            app.effective_growth_rate_MLEditField.Tooltip = {'effective growth rate in nm/s. It is assumed that the growth rate is distributed over the total time - growth and waiting. Therefore a shorter t_wait could give a better result.'; 'with effectiv_growth_rate = growth_rate_1 * t_growth / (t_growth + t_wait)'};
            % Show the figure after all components are created
            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_growthrate_calculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hyperspectralanalyzerbyMarcelSchmidtUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure)
        end
    end
end