classdef app_multi_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        menuMenu                        matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        CopytoclipboardMenu             matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        copy_mapMenu                    matlab.ui.container.Menu
        copy_map_1Menu                  matlab.ui.container.Menu
        copy_map_2Menu                  matlab.ui.container.Menu
        copy_bothMenu                   matlab.ui.container.Menu
        copy_spectrumMenu               matlab.ui.container.Menu
        copy_spectrum_1_graphMenu       matlab.ui.container.Menu
        copy_spectrum_1_dataMenu        matlab.ui.container.Menu
        copy_spectrum_2_graphMenu       matlab.ui.container.Menu
        copy_spectrum_2_dataMenu        matlab.ui.container.Menu
        send_data_toMenu                matlab.ui.container.Menu
        map_1_to_single_graphMenu       matlab.ui.container.Menu
        map_2_to_single_graphMenu       matlab.ui.container.Menu
        map_1_to_map_2Menu              matlab.ui.container.Menu
        map_2_to_map_1Menu              matlab.ui.container.Menu
        map_1_gradient_graphMenu        matlab.ui.container.Menu
        map_2_gradient_graphMenu        matlab.ui.container.Menu
        map_1_fit_graphMenu             matlab.ui.container.Menu
        map_2_fit_graphMenu             matlab.ui.container.Menu
        map_settingsMenu                matlab.ui.container.Menu
        map_1Menu                       matlab.ui.container.Menu
        map_colorMenu_1                 matlab.ui.container.Menu
        map_2Menu                       matlab.ui.container.Menu
        map_colorMenu_2                 matlab.ui.container.Menu
        operationsMenu                  matlab.ui.container.Menu
        FitMenu                         matlab.ui.container.Menu
        differenceMap1Map2Menu_2        matlab.ui.container.Menu
        differenceMap2Map1Menu          matlab.ui.container.Menu
        MapMenu                         matlab.ui.container.Menu
        differenceMap1Map2Menu_3        matlab.ui.container.Menu
        differenceMap2Map1Menu_2        matlab.ui.container.Menu
        displayed_dataMenu              matlab.ui.container.Menu
        Fit_1Menu                       matlab.ui.container.Menu
        Map1Menu                        matlab.ui.container.Menu
        Fit_2Menu                       matlab.ui.container.Menu
        Map2Menu                        matlab.ui.container.Menu
        graph_settingsMenu              matlab.ui.container.Menu
        graph_1Menu                     matlab.ui.container.Menu
        lock_zoomMenu_1                 matlab.ui.container.Menu
        graph_2Menu                     matlab.ui.container.Menu
        lock_zoomMenu_2                 matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        MultiGraphTab                   matlab.ui.container.Tab
        Map_1Panel                      matlab.ui.container.Panel
        LowerWavelengthSpinner_3Label   matlab.ui.control.Label
        MapLowerWavelengthSpinner_2     matlab.ui.control.Spinner
        UpperWavelengthSpinner_2Label   matlab.ui.control.Label
        MapUpperWavelengthSpinner_2     matlab.ui.control.Spinner
        MapRefreshButton_2              matlab.ui.control.Button
        MaxCountsEditFieldLabel         matlab.ui.control.Label
        MapMaxCountsEditField_2         matlab.ui.control.NumericEditField
        MinCountsEditFieldLabel         matlab.ui.control.Label
        MapMinCountsEditField_2         matlab.ui.control.NumericEditField
        ColorstepsEditFieldLabel        matlab.ui.control.Label
        MapColorstepsEditField_2        matlab.ui.control.NumericEditField
        Map_switch_2                    matlab.ui.control.Switch
        PowerSpinner_2Label             matlab.ui.control.Label
        PowerSpinner_2                  matlab.ui.control.Spinner
        TemperatureIndexSpinner_2Label  matlab.ui.control.Label
        TemperatureIndexSpinner_2       matlab.ui.control.Spinner
        MapappearanceDropDown_2         matlab.ui.control.DropDown
        ContourCheckBox_2               matlab.ui.control.CheckBox
        showfullrangeCheckBox_2         matlab.ui.control.CheckBox
        locktoareaCheckBox_2            matlab.ui.control.CheckBox
        fitDropDown_1Label              matlab.ui.control.Label
        fitDropDown_1                   matlab.ui.control.DropDown
        DisplayedPeakDropDown_1Label    matlab.ui.control.Label
        DisplayedPeakDropDown_1         matlab.ui.control.DropDown
        DisplayedValueDropDown_1Label   matlab.ui.control.Label
        DisplayedValueDropDown_1        matlab.ui.control.DropDown
        energy_to_waveCheckBox_1        matlab.ui.control.CheckBox
        Map_2Panel                      matlab.ui.container.Panel
        LowerWavelengthSpinner_2Label   matlab.ui.control.Label
        MapLowerWavelengthSpinner_3     matlab.ui.control.Spinner
        UpperWavelengthSpinner_3Label   matlab.ui.control.Label
        MapUpperWavelengthSpinner_3     matlab.ui.control.Spinner
        MapRefreshButton_3              matlab.ui.control.Button
        MaxCountsEditField_2Label       matlab.ui.control.Label
        MapMaxCountsEditField_3         matlab.ui.control.NumericEditField
        MinCountsEditField_2Label       matlab.ui.control.Label
        MapMinCountsEditField_3         matlab.ui.control.NumericEditField
        ColorstepsEditField_2Label      matlab.ui.control.Label
        MapColorstepsEditField_3        matlab.ui.control.NumericEditField
        Map_switch_3                    matlab.ui.control.Switch
        PowerSpinner_3Label             matlab.ui.control.Label
        PowerSpinner_3                  matlab.ui.control.Spinner
        TemperatureIndexSpinner_3Label  matlab.ui.control.Label
        TemperatureIndexSpinner_3       matlab.ui.control.Spinner
        MapappearanceDropDown_3         matlab.ui.control.DropDown
        ContourCheckBox_3               matlab.ui.control.CheckBox
        showfullrangeCheckBox_3         matlab.ui.control.CheckBox
        locktoareaCheckBox_3            matlab.ui.control.CheckBox
        fitDropDown_2Label              matlab.ui.control.Label
        fitDropDown_2                   matlab.ui.control.DropDown
        DisplayedPeakDropDown_2Label    matlab.ui.control.Label
        DisplayedPeakDropDown_2         matlab.ui.control.DropDown
        DisplayedValueDropDown_2Label   matlab.ui.control.Label
        DisplayedValueDropDown_2        matlab.ui.control.DropDown
        energy_to_waveCheckBox_2        matlab.ui.control.CheckBox
        Spectrum1ParameterPanel         matlab.ui.container.Panel
        XSpinner_2Label                 matlab.ui.control.Label
        XSpinner_2                      matlab.ui.control.Spinner
        YSpinner_2Label                 matlab.ui.control.Label
        YSpinner_2                      matlab.ui.control.Spinner
        RefreshButton_5                 matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        Spectrum2ParameterPanel         matlab.ui.container.Panel
        XSpinner_3Label                 matlab.ui.control.Label
        XSpinner_3                      matlab.ui.control.Spinner
        YSpinner_3Label                 matlab.ui.control.Label
        YSpinner_3                      matlab.ui.control.Spinner
        RefreshButton_6                 matlab.ui.control.Button
        Switch_3                        matlab.ui.control.Switch
        SampleInformationMap1Label      matlab.ui.control.Label
        Sample_Information_2_TextArea   matlab.ui.control.TextArea
        SampleInformationMap2Label      matlab.ui.control.Label
        Sample_Information_3_TextArea   matlab.ui.control.TextArea
        Map3                            matlab.ui.control.UIAxes
        Spectrum2                       matlab.ui.control.UIAxes
        Spectrum3                       matlab.ui.control.UIAxes
        Map2                            matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    
    properties (Access = private)
        DialogImportApp;
        DialogExportApp;
        DialogPreferencesApp;
        cross_handle_2;
        cross_handle_3;
        area_spectral_range_handle2;
        area_spectral_range_handle3;
        starting_x;
        starting_y;
        starting_power;
        starting_tamperature;
        starting_color_steps;
        spectrum;
        spectrum_a;
        range_vector_a;
        spectrum_b;
        range_vector_b;
        last_map_2;
        last_map_3;
        c_2;
        c_3;
        map_2_data;
        map_3_data;
        last_spectrum_2;
        last_spectrum_3;
        map_1_typ;
        idx_list_1;
        map_2_typ;
        idx_list_2;
        rio2;
        rio3;
        lock_zoom_graph_1;
        lock_zoom_graph_2;
    end
    
    properties (Access = public)
        gradient_dimantion;
        PLMap_Data;
        data;
        data_path;
        Data_Fit1;
        Data_Fit2;
        programm_path;
        min_file_size;
        contourtext;
        contour_lines;
        selectedTab;
        data_map;
        data_position;
        area_dim_2;
        area_dim_3;
        displayed_fit1;
        displayed_peak1;
        displayed_fit_parameter1;
        displayed_fit2;
        displayed_peak2;
        displayed_fit_parameter2;
        n_fitparameter1;
        n_fitparameter2;
        displayed_typ1;
        displayed_typ2;
    end
    
    methods (Access = private)
        function import_data(app,Data_Map1,Data_Fit1,Data_Map2,Data_Fit2)
            start_values = cell(1,5);
            start_values{1,1} = app.starting_power;
            start_values{1,2} = app.starting_tamperature;
            start_values{1,3} = app.starting_x;
            start_values{1,4} = app.starting_y;
            start_values{1,5} = app.starting_color_steps;
            start_values{1,6} = app.map_1_typ;
            
            if ~isempty(Data_Map1)
                app.PLMap_Data{1} = [];
                app.PLMap_Data{1} = Data_Map1;
                app.Data_Fit1 = [];
                app.Data_Fit1 = Data_Fit1;
            
                [app.MapLowerWavelengthSpinner_2.Value,app.MapUpperWavelengthSpinner_2.Value,app.TemperatureIndexSpinner_2.Value,...
                    app.PowerSpinner_2.Value,app.c_2] = start_Map(app,1,app.Map2,app.Spectrum2,start_values,app.XSpinner_2,app.YSpinner_2,...
                    app.PowerSpinner_2,app.TemperatureIndexSpinner_2,app.ContourCheckBox_2.Value);
                app.last_map_2 = app.Map2;
                app.map_2_data = app.data_map;
                power_index = find_index(app.PLMap_Data{1}.Power,app.PowerSpinner_2.Value,1);
                temperature = meta_data_text_average_tamperature(app.PLMap_Data{1}.Temperature_array,1,power_index);
                app.Sample_Information_2_TextArea.Value = {app.PLMap_Data{1}.Sample;app.PLMap_Data{1}.Date;app.PLMap_Data{1}.Measurment_time;temperature};
            end
            
            if ~isempty(Data_Map2)
                app.PLMap_Data{2} = [];
                app.PLMap_Data{2} = Data_Map2;
                app.Data_Fit2 = [];
                app.Data_Fit2 = Data_Fit2;
                
                [app.MapLowerWavelengthSpinner_3.Value,app.MapUpperWavelengthSpinner_3.Value,app.TemperatureIndexSpinner_3.Value,...
                    app.PowerSpinner_3.Value,app.c_3] = start_Map(app,2,app.Map3,app.Spectrum3,start_values,app.XSpinner_3,app.YSpinner_3,...
                    app.PowerSpinner_3,app.TemperatureIndexSpinner_3,app.ContourCheckBox_3.Value);
                app.last_map_3 = app.Map3;
                app.map_3_data = app.data_map;
                power_index = find_index(app.PLMap_Data{2}.Power,app.PowerSpinner_3.Value,1);
                temperature = meta_data_text_average_tamperature(app.PLMap_Data{2}.Temperature_array,1,power_index);
                app.Sample_Information_3_TextArea.Value = {app.PLMap_Data{2}.Sample;app.PLMap_Data{2}.Date;app.PLMap_Data{2}.Measurment_time;temperature};
            end
        end
        % ----------------------------------------------------------------------------------------------------------------
        % Display Map
        % ----------------------------------------------------------------------------------------------------------------
        function [lower_wavelength,upper_wavelength,temperature,power,c] = start_Map(app,index,Defind_Map_Axis,spectrum,start_values,x_spinner,...
                y_spinner,power_spiner,tamperature_spinner,contour_state)
        %define indeces of start power and tamperature
        power_index = define_Spinner(power_spiner,app.PLMap_Data{index}.Power,start_values{1,1});
        tamperature_index = define_Spinner(tamperature_spinner,app.PLMap_Data{index}.Temperature_index,...
            start_values{1,2});
        x = define_Spinner(x_spinner,app.PLMap_Data{index}.X_vector,start_values{1,3});
        y = define_Spinner(y_spinner,app.PLMap_Data{index}.Y_vector,start_values{1,4});
        
        
        %get info for map
        label{1} = 'Map';
        label{2} = 'X [mm]';
        label{3} = 'Y [mm]';
        label{4} = 'Intensity (Counts/s)';
        
        %displays Map
        [lower_wavelength,upper_wavelength,temperature,power,app.data_map,c] = update_map(app.PLMap_Data{index},tamperature_spinner.Value,...
            power_spiner.Value,0,2000,Defind_Map_Axis,start_values{1,5},start_values{1,6},-inf,inf,label,'integrated',contour_state,...
            app.contour_lines,app.contourtext,[],0);
        
        %Display first Spectrum at start point
        [app.spectrum,~,~,~] = Display_Spectrum(spectrum,app.PLMap_Data{index}.Wavelength,app.PLMap_Data{index}.data_array,...
            tamperature_index,power_index,y,x,lower_wavelength,upper_wavelength,'All_points','movmean',1,1,1000,'PL_Map',0);
        end
        
        function show_map2(app)
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [app.MapLowerWavelengthSpinner_2.Value,app.MapUpperWavelengthSpinner_2.Value,app.TemperatureIndexSpinner_2.Value,...
                app.PowerSpinner_2.Value,app.data_map,app.c_2] = update_map(app.PLMap_Data{1},app.TemperatureIndexSpinner_2.Value,...
                app.PowerSpinner_2.Value,app.MapLowerWavelengthSpinner_2.Value,app.MapUpperWavelengthSpinner_2.Value,app.Map2,...
                app.MapColorstepsEditField_2.Value,app.map_1_typ,app.MapMinCountsEditField_2.Value,app.MapMaxCountsEditField_2.Value,...
                label,app.MapappearanceDropDown_2.Value,app.ContourCheckBox_2.Value,app.contour_lines,app.contourtext,app.area_dim_2,...
                app.locktoareaCheckBox_2.Value);
            
            app.last_map_2 = app.Map2;
            app.map_2_data = app.data_map;
        end
        
        function show_map3(app)
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [app.MapLowerWavelengthSpinner_3.Value,app.MapUpperWavelengthSpinner_3.Value,app.TemperatureIndexSpinner_3.Value,...
                app.PowerSpinner_3.Value,app.data_map,app.c_3] = update_map(app.PLMap_Data{2},app.TemperatureIndexSpinner_3.Value,...
                app.PowerSpinner_3.Value,app.MapLowerWavelengthSpinner_3.Value,app.MapUpperWavelengthSpinner_3.Value,app.Map3,...
                app.MapColorstepsEditField_3.Value,app.map_2_typ,app.MapMinCountsEditField_3.Value,app.MapMaxCountsEditField_3.Value,...
                label,app.MapappearanceDropDown_3.Value,app.ContourCheckBox_3.Value,app.contour_lines,app.contourtext,app.area_dim_3,...
                app.locktoareaCheckBox_3.Value);
            
            app.last_map_3 = app.Map3;
        end
        
        function [pre_processed_data_map,t,p] = get_fit_data1(app,displayed_fit,peak,typ)
            %define indeces of power and tamperature
            [~, p] = min(abs(app.PLMap_Data{1}.Power-app.PowerSpinner_2.Value));
            app.PowerSpinner_2.Value = app.PLMap_Data{1}.Power(p);
            [~, t] = min(abs(app.PLMap_Data{1}.Temperature_index-app.TemperatureIndexSpinner_2.Value));
            app.TemperatureIndexSpinner_2.Value = app.PLMap_Data{1}.Temperature_index(t);
            
            parameter_array(:,:) = app.Data_Fit1.Fit_Data_function_parameter(displayed_fit,t,p,:,:);
            grade_array(:,:) = app.Data_Fit1.Fit_Data_function_grade(displayed_fit,t,p,:,:);
            
            app.n_fitparameter1 = [];
            grade = [];
            app.n_fitparameter1 = max(parameter_array,[],'all');
            grade = max(grade_array,[],'all');
            
            if str2num(app.DisplayedPeakDropDown_1.Value)>grade
                app.DisplayedPeakDropDown_1.Value = '1';
            end
            
            app.DisplayedPeakDropDown_1.Items = split(num2str(1:grade))';
            
            switch app.n_fitparameter1
                case 3
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM'};
                case 4
                    app.DisplayedValueDropDown_1.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
            end
            
            pre_processed_data_map = nan(numel(app.PLMap_Data{1}.Y_vector),numel(app.PLMap_Data{1}.X_vector));
            
            
            if typ == 5
                for y = 1 : numel(app.PLMap_Data{1}.Y_vector)
                    for x = 1 : numel(app.PLMap_Data{1}.X_vector)
                        if ~isnan(app.Data_Fit1.rmse_Map(displayed_fit,t,p,y,x))
                            pre_processed_data_map(y,x) = app.Data_Fit1.rmse_Map(displayed_fit,t,p,y,x);
                        end
                    end
                end
            else
                for y = 1 : numel(app.PLMap_Data{1}.Y_vector)
                    for x = 1 : numel(app.PLMap_Data{1}.X_vector)
                        coeffvals(:) = app.Data_Fit1.Fit_Data_Map(displayed_fit,t,p,y,x,:);
                        if ~all(isnan(coeffvals),'all')
                            size_coeff = size(coeffvals);
                            if peak <= size_coeff(2)/app.n_fitparameter1
                                pre_processed_data_map(y,x) = coeffvals(typ+(peak-1)*app.n_fitparameter1);
                            end
                        end
                    end
                end
            end
        end
        
        function update_fit_map1(app)
            [pre_processed_data_map,~,~] = get_fit_data1(app,app.displayed_fit1,app.displayed_peak1,app.displayed_fit_parameter1);
            
            if app.displayed_fit_parameter1 == 2
                if app.energy_to_waveCheckBox_1.Value
                    pre_processed_data_map = converter.energy_wavelength(pre_processed_data_map);
                end
            elseif app.displayed_fit_parameter1 == 3
                if app.n_fitparameter1 == 4
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'pseudo-voigt');
                else
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'gauss');
                end
                 pre_processed_data_map = pre_processed_data_map * 1000;
            end
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            
            %gets the correct c label
            if app.displayed_fit_parameter1 == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter1 == 3
                label{4} = 'FWHM (eV)';
            elseif app.displayed_fit_parameter1 == 4
                label{4} = 'Eta';
            elseif app.displayed_fit_parameter1 == 5
                label{4} = 'rmse';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %Display map
            [pre_processed_data_map,app.last_map_2,app.c_2] = Display_Fit_Map(app.PLMap_Data{1},app.area_dim_2,pre_processed_data_map,0,...
                app.Map2,app.MapColorstepsEditField_2.Value,app.map_1_typ,label,app.MapMinCountsEditField_2.Value,...
                app.MapMaxCountsEditField_2.Value,app.ContourCheckBox_2.Value,app.contour_lines,app.contourtext,...
                app.locktoareaCheckBox_2.Value);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
        end
        
        function [pre_processed_data_map,t,p] = get_fit_data2(app,displayed_fit,peak,typ)
            %define indeces of power and tamperature
            [~, p] = min(abs(app.PLMap_Data{2}.Power-app.PowerSpinner_3.Value));
            app.PowerSpinner_3.Value = app.PLMap_Data{2}.Power(p);
            [~, t] = min(abs(app.PLMap_Data{2}.Temperature_index-app.TemperatureIndexSpinner_3.Value));
            app.TemperatureIndexSpinner_3.Value = app.PLMap_Data{2}.Temperature_index(t);
            
            parameter_array(:,:) = app.Data_Fit2.Fit_Data_function_parameter(displayed_fit,t,p,:,:);
            grade_array(:,:) = app.Data_Fit2.Fit_Data_function_grade(displayed_fit,t,p,:,:);
            
            app.n_fitparameter2 = [];
            grade = [];
            app.n_fitparameter2 = max(parameter_array,[],'all');
            grade = max(grade_array,[],'all');
            
            if str2num(app.DisplayedPeakDropDown_2.Value)>grade
                app.DisplayedPeakDropDown_2.Value = '1';
            end
            
            app.DisplayedPeakDropDown_2.Items = split(num2str(1:grade))';
            
            switch app.n_fitparameter2
                case 3
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM'};
                case 4
                    app.DisplayedValueDropDown_2.Items = {'rmse','Height', 'Position', 'FWHM', 'Eta'};
            end
            
            pre_processed_data_map = nan(numel(app.PLMap_Data{2}.Y_vector),numel(app.PLMap_Data{2}.X_vector));
            
            if typ == 5
                for y = 1 : numel(app.PLMap_Data{2}.Y_vector)
                    for x = 1 : numel(app.PLMap_Data{2}.X_vector)
                        if ~isnan(app.Data_Fit2.rmse_Map(displayed_fit,t,p,y,x))
                            pre_processed_data_map(y,x) = app.Data_Fit2.rmse_Map(displayed_fit,t,p,y,x);
                        end
                    end
                end
            else
                for y = 1 : numel(app.PLMap_Data{2}.Y_vector)
                    for x = 1 : numel(app.PLMap_Data{2}.X_vector)
                        coeffvals(:) = app.Data_Fit2.Fit_Data_Map(displayed_fit,t,p,y,x,:);
                        if ~all(isnan(coeffvals),'all')
                            size_coeff = size(coeffvals);
                            if peak <= size_coeff(2)/app.n_fitparameter2
                                pre_processed_data_map(y,x) = coeffvals(typ+(peak-1)*app.n_fitparameter2);
                            end
                        end
                    end
                end
            end
        end
        
        function update_fit_map2(app)
            [pre_processed_data_map,~,~] = get_fit_data2(app,app.displayed_fit2,app.displayed_peak2,app.displayed_fit_parameter2);
            
            if app.displayed_fit_parameter2 == 2
                if app.energy_to_waveCheckBox_2.Value
                    pre_processed_data_map = converter.energy_wavelength(pre_processed_data_map);
                end
            elseif app.displayed_fit_parameter2 == 3
                if app.n_fitparameter2 == 4
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'pseudo-voigt');
                else
                    pre_processed_data_map = converter.FWHM_sigma(pre_processed_data_map,'gauss');
                end
                 pre_processed_data_map = pre_processed_data_map * 1000;
            end
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            
            %gets the correct c label
            if app.displayed_fit_parameter2 == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter2 == 3
                label{4} = 'FWHM (eV)';
            elseif app.displayed_fit_parameter2 == 4
                label{4} = 'Eta';
            elseif app.displayed_fit_parameter2 == 5
                label{4} = 'rmse';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %Display map
            [pre_processed_data_map,app.last_map_3,app.c_3] = Display_Fit_Map(app.PLMap_Data{2},app.area_dim_3,pre_processed_data_map,0,...
                app.Map3,app.MapColorstepsEditField_3.Value,app.map_2_typ,label,app.MapMinCountsEditField_3.Value,...
                app.MapMaxCountsEditField_3.Value,app.ContourCheckBox_3.Value,app.contour_lines,app.contourtext,...
                app.locktoareaCheckBox_3.Value);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
        end
        
        function moverio(app,src,data,roi,x_spinner,y_spinner,PLMap_Data)
            pos = ceil(data.CurrentPosition)
            %snaps to grid
            x_spinner.Value = pos(1) - mod(pos(1),PLMap_Data.X_Steps);
            roi.Position(1) = x_spinner.Value;
            
            y_spinner.Value = pos(2) - mod(pos(2),PLMap_Data.Y_Steps);
            roi.Position(2) = y_spinner.Value;
            XSpinner_2ValueChanged(app, event);
        end
        
        function [x1,y1,map1,x2,y2,map2] = correct_size_of_maps(app,x1_old,y1_old,map1_old,x2_old,y2_old,map2_old)
            if numel(x1_old) > numel(x2_old) || numel(y1_old) > numel(y2_old)
                map1 = nan(size(map2_old));
                map2 = map2_old;
                for x = 1 : numel(x2_old)
                    idex = find_index(x1_old,x2_old(x),1);
                    for y = 1 : numel(y2_old)
                        idey = find_index(y1_old,y2_old(y),1);
                        map1(y,x) = map1_old(idey,idex);
                    end
                end
                x1 = x2_old;
                x2 = x2_old;
                y1 = y2_old;
                y2 = y2_old;
                
            elseif numel(x1_old) < numel(x2_old) || numel(y1_old) < numel(y2_old)
                map2 = nan(size(map1_old));
                map1 = map1_old;
                for x = 1 : numel(x1_old)
                    idex = find_index(x2_old,x1_old(x),1);
                    for y = 1 : numel(y1_old)
                        idey = find_index(y2_old,y1_old(y),1);
                        map2(y,x) = map1_old(idey,idex);
                    end
                end
                x1 = x2_old;
                x2 = x2_old;
                y1 = y2_old;
                y2 = y2_old;
                
            elseif numel(x1_old) == numel(x2_old) && numel(y1_old) == numel(y2_old)
                map1 = map1_old;
                map2 = map2_old;
                x1 = x1_old;
                x2 = x2_old;
                y1 = y1_old;
                y2 = y2_old;
            end
        end
        
        function diff_maps(app,pre_processed_data_map1_full,pre_processed_data_map2_full,mode)
            if ~isempty(app.area_dim_2) && ~isempty(app.area_dim_3)
                if app.area_dim_2(3) ~= app.area_dim_3(3) || app.area_dim_2(4) ~= app.area_dim_3(4)
                    prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                    dlgtitle = 'Input area';
                    dims = [1 35];
                    definput = {'38','38','10','10'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    
                    % Handle response
                    if isempty(answer)
                        app.locktoareaCheckBox_2.Value = 0;
                        return
                    else
                        app.area_dim_2(1) = str2num(answer{1});
                        app.area_dim_2(2) = str2num(answer{2});
                        app.area_dim_2(3) = str2num(answer{3});
                        app.area_dim_2(4) = str2num(answer{4});
                        
                        %position spectrum correctly
                        app.XSpinner_2.Value = str2num(answer{1});
                        app.YSpinner_2.Value = str2num(answer{2});
                        XSpinner_2ValueChanged(app, event);
                    end
                    
                    prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                    dlgtitle = 'Input area';
                    dims = [1 35];
                    definput = {'38','38',answer{3},answer{4}};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    
                    % Handle response
                    if isempty(answer)
                        app.locktoareaCheckBox_2.Value = 0;
                        return
                    else
                        app.area_dim_3(1) = str2num(answer{1});
                        app.area_dim_3(2) = str2num(answer{2});
                        app.area_dim_3(3) = str2num(answer{3});
                        app.area_dim_3(4) = str2num(answer{4});
                        
                        %position spectrum correctly
                        app.XSpinner_3.Value = str2num(answer{1});
                        app.YSpinner_3.Value = str2num(answer{2});
                        XSpinner_3ValueChanged(app, event);
                    end
                end
            end
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            
            prompt = {'Rotate Map1 k times by 90°:'};
            dlgtitle = 'Input k rotation';
            dims = [1 35];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Handle response
            if isempty(answer)
                return
            else
                k = str2num(answer{1});
            end
            
            [x1,y1,pre_processed_data_map1_full,label] = create_map_sector(app.PLMap_Data{1},app.area_dim_2,pre_processed_data_map1_full,k,label);
            
            prompt = {'Rotate Map2 k times by 90°:'};
            dlgtitle = 'Input k rotation';
            dims = [1 35];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Handle response
            if isempty(answer)
                return
            else
                k = str2num(answer{1});
            end
            
            [x2,y2,pre_processed_data_map2_full,label] = create_map_sector(app.PLMap_Data{2},app.area_dim_3,pre_processed_data_map2_full,k,label);
            
            %correct size if necessary
            [x,y,pre_processed_data_map1,~,~,pre_processed_data_map2] = correct_size_of_maps(app,x1,y1,pre_processed_data_map1_full,x2,y2,pre_processed_data_map2_full);
            
            %constructs the arrays without single Data in one array
            pre_processed_data_map2 = transfer_nan_between_arrayes(pre_processed_data_map1,pre_processed_data_map2);
            pre_processed_data_map1 = transfer_nan_between_arrayes(pre_processed_data_map2,pre_processed_data_map1);
            
            %difference between arrays
            switch mode
                case '1-2'
                    pre_processed_data_map = pre_processed_data_map1 - pre_processed_data_map2;
                case '2-1'
                    pre_processed_data_map = pre_processed_data_map2 - pre_processed_data_map1;
            end
            
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            
            %gets the correct c label
            if app.displayed_fit_parameter1 == 2
                label{4} = 'Wavelength (nm)';
            elseif app.displayed_fit_parameter1 == 3
                label{4} = 'FWHM (eV)';
            elseif app.displayed_fit_parameter1 == 4
                label{4} = 'Eta';
            elseif app.displayed_fit_parameter1 == 5
                label{4} = 'rmse';
            else
                label{4} = 'Intensity (Counts/s)';
            end
            
            %Display map
            [app.last_map_2,app.c_2] = Display_Map(app.Map2,x,y,...
                pre_processed_data_map,app.MapColorstepsEditField_2.Value,app.map_1_typ,label, ...
                app.MapMinCountsEditField_2.Value,app.MapMaxCountsEditField_2.Value,app.ContourCheckBox_2.Value,app.contour_lines,...
                app.contourtext,1);
            
            %lokates the data in the temporary variable for clipboard access
            app.data_map = pre_processed_data_map;
            
            %create box plots
            boxplot(app.Spectrum2,[pre_processed_data_map1(:),pre_processed_data_map2(:)],'Notch','on','Labels',{'Map1','Map2'},'Colors',[79/255,129/255,189/255]);
            app.last_spectrum_2 = app.Spectrum2;
            app.Spectrum2.Title.String = 'Statistics for difference positions';
            
            pre_processed_data_map1 = pre_processed_data_map1_full(:);
            pre_processed_data_map2 = pre_processed_data_map2_full(:);
            if numel(pre_processed_data_map1) < numel(pre_processed_data_map2)
                pre_processed_data_map1_temp = pre_processed_data_map1;
                pre_processed_data_map1 = nan(size(pre_processed_data_map2));
                for i = 1 : numel(pre_processed_data_map1_temp)
                    pre_processed_data_map1(i) = pre_processed_data_map1_temp(i);
                end
            elseif numel(pre_processed_data_map1) > numel(pre_processed_data_map2)
                pre_processed_data_map2_temp = pre_processed_data_map2;
                pre_processed_data_map2 = nan(size(pre_processed_data_map1));
                for i = 1 : numel(pre_processed_data_map2_temp)
                    pre_processed_data_map2(i) = pre_processed_data_map2_temp(i);
                end
            end
            boxplot(app.Spectrum3,[pre_processed_data_map1,pre_processed_data_map2],'Notch','on','Labels',{'Map1','Map2'},'Colors',[79/255,129/255,189/255]);
            app.last_spectrum_3 = app.Spectrum3;
            app.Spectrum3.Title.String = 'Statistics for each map individually';
        end
    end
    
    methods (Access = public)
        function import_data_public(app,Data_Map,Data_Fit,data_to_import)
            start_values = cell(1,5);
            start_values{1,1} = app.starting_power;
            start_values{1,2} = app.starting_tamperature;
            start_values{1,3} = app.starting_x;
            start_values{1,4} = app.starting_y;
            start_values{1,5} = app.starting_color_steps;
            start_values{1,6} = app.map_1_typ;
            
            switch data_to_import
                case 'Multi Graph 1'
                    if ~isempty(Data_Map)
                        app.PLMap_Data{1} = [];
                        app.PLMap_Data{1} = Data_Map;
                        
                        app.Data_Fit1 = Data_Fit;
                        
                        [app.MapLowerWavelengthSpinner_2.Value,app.MapUpperWavelengthSpinner_2.Value,app.TemperatureIndexSpinner_2.Value,...
                            app.PowerSpinner_2.Value,app.c_2] = start_Map(app,1,app.Map2,app.Spectrum2,start_values,app.XSpinner_2,app.YSpinner_2,...
                            app.PowerSpinner_2,app.TemperatureIndexSpinner_2,app.ContourCheckBox_2.Value);
                        app.last_map_2 = app.Map2;
                        app.map_2_data = app.data_map;
                        power_index = find_index(app.PLMap_Data{1}.Power,app.PowerSpinner_2.Value,1);
                        temperature = meta_data_text_average_tamperature(app.PLMap_Data{1}.Temperature_array,1,power_index);
                        app.Sample_Information_2_TextArea.Value = {app.PLMap_Data{1}.Sample;app.PLMap_Data{1}.Date;...
                            app.PLMap_Data{1}.Measurment_time;temperature};
                    end
                case 'Multi Graph 2'
                    if ~isempty(Data_Map)
                        app.PLMap_Data{2} = [];
                        app.PLMap_Data{2} = Data_Map;
                        
                        app.Data_Fit2 = Data_Fit;
                        
                        [app.MapLowerWavelengthSpinner_3.Value,app.MapUpperWavelengthSpinner_3.Value,app.TemperatureIndexSpinner_3.Value,...
                            app.PowerSpinner_3.Value,app.c_3] = start_Map(app,2,app.Map3,app.Spectrum3,start_values,app.XSpinner_3,app.YSpinner_3,...
                            app.PowerSpinner_3,app.TemperatureIndexSpinner_3,app.ContourCheckBox_3.Value);
                        app.last_map_3 = app.Map3;
                        app.map_3_data = app.data_map;
                        power_index = find_index(app.PLMap_Data{2}.Power,app.PowerSpinner_3.Value,1);
                        temperature = meta_data_text_average_tamperature(app.PLMap_Data{2}.Temperature_array,1,power_index);
                        app.Sample_Information_3_TextArea.Value = {app.PLMap_Data{2}.Sample;app.PLMap_Data{2}.Date;...
                            app.PLMap_Data{2}.Measurment_time;temperature};
                    end
                case 'Multi Graph both'
                    if ~isempty(Data_Map)
                        app.PLMap_Data{1} = [];
                        app.PLMap_Data{1} = Data_Map;
                        
                        app.Data_Fit1 = Data_Fit;
                        
                        [app.MapLowerWavelengthSpinner_2.Value,app.MapUpperWavelengthSpinner_2.Value,app.TemperatureIndexSpinner_2.Value,...
                            app.PowerSpinner_2.Value,app.c_2] = start_Map(app,1,app.Map2,app.Spectrum2,start_values,app.XSpinner_2,app.YSpinner_2,...
                            app.PowerSpinner_2,app.TemperatureIndexSpinner_2,app.ContourCheckBox_2.Value);
                        app.last_map_2 = app.Map2;
                        app.map_2_data = app.data_map;
                        power_index = find_index(app.PLMap_Data{1}.Power,app.PowerSpinner_2.Value,1);
                        temperature = meta_data_text_average_tamperature(app.PLMap_Data{1}.Temperature_array,1,power_index);
                        app.Sample_Information_2_TextArea.Value = {app.PLMap_Data{1}.Sample;app.PLMap_Data{1}.Date;...
                            app.PLMap_Data{1}.Measurment_time;temperature};
                    end
                    
                    if ~isempty(Data_Map)
                        app.PLMap_Data{2} = [];
                        app.PLMap_Data{2} = Data_Map;
                        
                        app.Data_Fit2 = Data_Fit;
                        
                        [app.MapLowerWavelengthSpinner_3.Value,app.MapUpperWavelengthSpinner_3.Value,app.TemperatureIndexSpinner_3.Value,...
                            app.PowerSpinner_3.Value,app.c_3] = start_Map(app,2,app.Map3,app.Spectrum3,start_values,app.XSpinner_3,app.YSpinner_3,...
                            app.PowerSpinner_3,app.TemperatureIndexSpinner_3,app.ContourCheckBox_3.Value);
                        app.last_map_3 = app.Map3;
                        app.map_3_data = app.data_map;
                        power_index = find_index(app.PLMap_Data{2}.Power,app.PowerSpinner_3.Value,1);
                        temperature = meta_data_text_average_tamperature(app.PLMap_Data{2}.Temperature_array,1,power_index);
                        app.Sample_Information_3_TextArea.Value = {app.PLMap_Data{2}.Sample;app.PLMap_Data{2}.Date;...
                            app.PLMap_Data{2}.Measurment_time;temperature};
                    end
            end
        end
        
        function update_contour_lines(app,contour_lines,contourtext)
            app.contour_lines = contour_lines;
            app.contourtext = contourtext;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data_Map1, Data_Fit1, Data_Map2, Data_Fit2)
            %Imports the Data to a Variable
            app.PLMap_Data{1} = [];
            app.PLMap_Data{2} = [];
            app.Data_Fit1 = [];
            app.Data_Fit2 = [];
            
            str = pwd; %path containing the data
            str = [str,'/'];
            app.programm_path = join(str,"");
            temp_import_Structure = load([app.programm_path, 'config']);
            config_data = temp_import_Structure.config;
            
            app.starting_x = config_data.x;
            app.starting_y = config_data.y;
            app.starting_power = 'low';
            app.starting_tamperature = 'low';
            app.starting_color_steps = config_data.color_steps;
            
            app.map_1_typ = config_data.map_typ;
            app.map_2_typ = config_data.map_typ;
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black'};
            app.idx_list_1 = find(contains(list,config_data.map_typ));
            app.idx_list_2 = app.idx_list_1;
            
            app.MapColorstepsEditField_2.Value = config_data.color_steps;
            app.MapColorstepsEditField_3.Value = config_data.color_steps;
            app.data_path = config_data.path;
            app.min_file_size = config_data.min_file_size;
            app.contour_lines = config_data.contour_lines;
            app.contourtext = config_data.contourtext;
            
            app.area_dim_2 = [];
            app.area_dim_3 = [];
            app.displayed_fit_parameter1 = 2;
            app.displayed_peak1 = 1;
            app.displayed_fit1 = 1;
            app.displayed_typ1 = 0;
        
            app.displayed_fit_parameter2 = 2;
            app.displayed_peak2 = 1;
            app.displayed_fit2 = 1;
            app.displayed_typ2 = 0;
            
            app.lock_zoom_graph_1 = 0;
            app.lock_zoom_graph_2 = 0;
            
            app.selectedTab = app.TabGroup.SelectedTab;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            import_data(app,Data_Map1,Data_Fit1,Data_Map2,Data_Fit2);
        end

        % Button pushed function: MapRefreshButton_2
        function MapRefreshButton_2Pushed(app, event)
            %define colormap dimentions
            app.MapColorstepsEditField_2.Value =100;
            app.MapMinCountsEditField_2.Value = -inf;
            app.MapMaxCountsEditField_2.Value = inf;
            
            if app.displayed_typ1
                update_fit_map1(app)
            else
            show_map2(app);
            end
        end

        % Button pushed function: MapRefreshButton_3
        function MapRefreshButton_3Pushed(app, event)
            %define colormap dimentions
            app.MapColorstepsEditField_3.Value =100;
            app.MapMinCountsEditField_3.Value = -inf;
            app.MapMaxCountsEditField_3.Value = inf;
            
            if app.displayed_typ2
                update_fit_map2(app)
            else
            show_map3(app);
            end
        end

        % Value changed function: ContourCheckBox_2, 
        % MapLowerWavelengthSpinner_2, MapUpperWavelengthSpinner_2, 
        % MapappearanceDropDown_2
        function MapLowerWavelengthSpinner_2ValueChanged(app, event)
            show_map2(app);
            
            %draw range in the Graph
            XSpinner_2ValueChanged(app, event)
        end

        % Value changed function: ContourCheckBox_3, 
        % MapLowerWavelengthSpinner_3, MapUpperWavelengthSpinner_3, 
        % MapappearanceDropDown_3
        function MapLowerWavelengthSpinner_3ValueChanged(app, event)
            show_map3(app)
            
            %draw range in Graph
            XSpinner_3ValueChanged(app, event)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            change_spectrum_y_scale(app.Switch_2.Value,app.Spectrum2)
            app.Spectrum2.XTickLabelMode = "auto";
            app.Spectrum2.XTickMode = "auto";
            app.Spectrum2.Title.String = 'Spectrum 1';
            app.Spectrum2.XLabel.String = 'Wavelength [nm]';
            app.Spectrum2.YLabel.String = 'Counts [1/s]';
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            change_spectrum_y_scale(app.Switch_3.Value,app.Spectrum3)
            app.Spectrum3.XTickLabelMode = "auto";
            app.Spectrum3.XTickMode = "auto";
            app.Spectrum3.Title.String = 'Spectrum 1';
            app.Spectrum3.XLabel.String = 'Wavelength [nm]';
            app.Spectrum3.YLabel.String = 'Counts [1/s]';
        end

        % Value changed function: XSpinner_2, YSpinner_2, 
        % showfullrangeCheckBox_2
        function XSpinner_2ValueChanged(app, event)
            %--------------------------------------------------------------------------------------------------------
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %--------------------------------------------------------------------------------------------------------
            x = find_index(app.PLMap_Data{1}.X_vector,app.XSpinner_2.Value,1);
            app.XSpinner_2.Value = app.PLMap_Data{1}.X_vector(x);
            y = find_index(app.PLMap_Data{1}.Y_vector,app.YSpinner_2.Value,1);
            app.YSpinner_2.Value = app.PLMap_Data{1}.Y_vector(y);
            power_index = find_index(app.PLMap_Data{1}.Power,app.PowerSpinner_2.Value,1);
            app.PowerSpinner_2.Value = app.PLMap_Data{1}.Power(power_index);
            
            app.Spectrum2.XTickLabelMode = "auto";
            app.Spectrum2.XTickMode = "auto";
           
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %Display first Spectrum at starting Point
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            if app.showfullrangeCheckBox_2.Value
                %--------------------------------------------------------------------------------------------------------
                %Display first Spectrum at starting Point
                %--------------------------------------------------------------------------------------------------------
                [app.spectrum_a,app.range_vector_a,app.last_spectrum_2,~] = Display_Spectrum(app.Spectrum2,app.PLMap_Data{1}.Wavelength,app.PLMap_Data{1}.data_array, ...
                    app.TemperatureIndexSpinner_2.Value,power_index,y,x,min(app.PLMap_Data{1}.Wavelength), ...
                    max(app.PLMap_Data{1}.Wavelength),'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_1);
                
                %--------------------------------------------------------------------------------------------------------
                %marks the spectral region used for the heatmap
                %--------------------------------------------------------------------------------------------------------
                if(app.MapLowerWavelengthSpinner_2.Value~=min(app.PLMap_Data{1}.Wavelength))...
                        ||(app.MapUpperWavelengthSpinner_2.Value~=max(app.PLMap_Data{1}.Wavelength))
                mark_area(app.Spectrum2,app.area_spectral_range_handle2,app.data_map,app.spectrum_a,...
                    app.MapLowerWavelengthSpinner_2,app.MapUpperWavelengthSpinner_2,0)
                end
            else
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %displays only the region used in the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum_a,app.range_vector_a,app.last_spectrum_2,~] = Display_Spectrum(app.Spectrum2,app.PLMap_Data{1}.Wavelength,app.PLMap_Data{1}.data_array, ...
                    app.TemperatureIndexSpinner_2.Value,power_index,y,x,app.MapLowerWavelengthSpinner_2.Value, ...
                    app.MapUpperWavelengthSpinner_2.Value,'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_1);
            end
            
            %--------------------------------------------------------------------------------------------------------
            %draws a cross in the the heatmap at the position of the spectrum
            %--------------------------------------------------------------------------------------------------------
            delete(app.cross_handle_2);
            hold(app.Map2,'on');
            app.cross_handle_2 = plot(app.Map2,app.XSpinner_2.Value,app.YSpinner_2.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map2,'off');
            app.Spectrum2.Title.String = 'Spectrum 1';
            app.Spectrum2.XLabel.String = 'Wavelength [nm]';
            app.Spectrum2.YLabel.String = 'Counts [1/s]';
        end

        % Value changed function: XSpinner_3, YSpinner_3, 
        % showfullrangeCheckBox_3
        function XSpinner_3ValueChanged(app, event)
            %--------------------------------------------------------------------------------------------------------
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %--------------------------------------------------------------------------------------------------------
            x = find_index(app.PLMap_Data{2}.X_vector,app.XSpinner_3.Value,1);
            app.XSpinner_3.Value = app.PLMap_Data{2}.X_vector(x);
            y = find_index(app.PLMap_Data{2}.Y_vector,app.YSpinner_3.Value,1);
            app.YSpinner_3.Value = app.PLMap_Data{2}.Y_vector(y);
            power_index = find_index(app.PLMap_Data{2}.Power,app.PowerSpinner_3.Value,1);
            app.PowerSpinner_3.Value = app.PLMap_Data{2}.Power(power_index);
            
            app.Spectrum3.XTickLabelMode = "auto";
            app.Spectrum3.XTickMode = "auto";
            
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %Display first Spectrum at starting Point
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            if app.showfullrangeCheckBox_3.Value
                %--------------------------------------------------------------------------------------------------------
                %Display first Spectrum at starting Point
                %--------------------------------------------------------------------------------------------------------
                [app.spectrum_b,app.range_vector_b,app.last_spectrum_3,~] = Display_Spectrum(app.Spectrum3,app.PLMap_Data{2}.Wavelength,app.PLMap_Data{2}.data_array, ...
                    app.TemperatureIndexSpinner_3.Value,power_index,y,x,min(app.PLMap_Data{2}.Wavelength), ...
                    max(app.PLMap_Data{2}.Wavelength),'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_2);
                
                %--------------------------------------------------------------------------------------------------------
                %marks the spectral region used for the heatmap
                %--------------------------------------------------------------------------------------------------------
                if(app.MapLowerWavelengthSpinner_3.Value~=min(app.PLMap_Data{2}.Wavelength))...
                        ||(app.MapUpperWavelengthSpinner_3.Value~=max(app.PLMap_Data{2}.Wavelength))
                mark_area(app.Spectrum3,app.area_spectral_range_handle3,app.data_map,app.spectrum_b,...
                    app.MapLowerWavelengthSpinner_3,app.MapUpperWavelengthSpinner_3,0)
                end
            else
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %displays only the region used in the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum_b,app.range_vector_b,app.last_spectrum_3,~] = Display_Spectrum(app.Spectrum3,app.PLMap_Data{2}.Wavelength,app.PLMap_Data{2}.data_array, ...
                    app.TemperatureIndexSpinner_3.Value,power_index,y,x,app.MapLowerWavelengthSpinner_3.Value, ...
                    app.MapUpperWavelengthSpinner_3.Value,'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_2);
            end
            
            %--------------------------------------------------------------------------------------------------------
            %draws a cross in the the heatmap at the position of the spectrum
            %--------------------------------------------------------------------------------------------------------
            delete(app.cross_handle_3);
            hold(app.Map3,'on');
            app.cross_handle_3 = plot(app.Map3,app.XSpinner_3.Value,app.YSpinner_3.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map3,'off');
            
            app.Spectrum3.Title.String = 'Spectrum 2';
            app.Spectrum3.XLabel.String = 'Wavelength [nm]';
            app.Spectrum3.YLabel.String = 'Counts [1/s]';
        end

        % Value changed function: MapColorstepsEditField_2, 
        % MapMaxCountsEditField_2, MapMinCountsEditField_2
        function MapMaxCountsEditField_2ValueChanged(app, event)
            if app.displayed_typ1
                update_fit_map1(app);
            else
            show_map2(app);
            end
        end

        % Value changed function: MapColorstepsEditField_3, 
        % MapMaxCountsEditField_3, MapMinCountsEditField_3
        function MapMaxCountsEditField_3ValueChanged(app, event)
            if app.displayed_typ2
                update_fit_map2(app);
            else
            show_map3(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogImportApp)
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.DialogExportApp = DialogExportApp(app,app.map_1_typ);
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            app.DialogPreferencesApp = DialogPreferencesApp(app);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            app.DialogImportApp = DialogImportApp(app);
        end

        % Value changed function: PowerSpinner_2
        function PowerSpinner_2ValueChanged(app, event)
            show_map2(app)
            
            %--------------------------------------------------------------------------------------------------------
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %--------------------------------------------------------------------------------------------------------
            x = find_index(app.PLMap_Data{1}.X_vector,app.XSpinner_2.Value,1);
            app.XSpinner_2.Value = app.PLMap_Data{1}.X_vector(x);
            y = find_index(app.PLMap_Data{1}.Y_vector,app.YSpinner_2.Value,1);
            app.YSpinner_2.Value = app.PLMap_Data{1}.Y_vector(y);
            power_index = find_index(app.PLMap_Data{1}.Power,app.PowerSpinner_2.Value,1);
            app.PowerSpinner_2.Value = app.PLMap_Data{1}.Power(power_index);
            
            %--------------------------------------------------------------------------------------------------------
            %Display first Spectrum at starting Point
            %--------------------------------------------------------------------------------------------------------
            [app.spectrum_a,app.range_vector_a,app.last_spectrum_2,~] = Display_Spectrum(app.Spectrum2,app.PLMap_Data{1}.Wavelength,app.PLMap_Data{1}.data_array, ...
                app.TemperatureIndexSpinner_2.Value,power_index,y,x,min(app.PLMap_Data{1}.Wavelength), ...
                max(app.PLMap_Data{1}.Wavelength),'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_1);
            
            %--------------------------------------------------------------------------------------------------------
            %marks the spectral region used for the heatmap
            %--------------------------------------------------------------------------------------------------------
            if(app.MapLowerWavelengthSpinner_2.Value~=min(app.PLMap_Data{1}.Wavelength))...
                    ||(app.MapUpperWavelengthSpinner_2.Value~=max(app.PLMap_Data{1}.Wavelength))
            mark_area(app.Spectrum2,app.area_spectral_range_handle2,app.data_map,app.spectrum_a,...
                app.MapLowerWavelengthSpinner_2,app.MapUpperWavelengthSpinner_2,0)
            end
            
            %--------------------------------------------------------------------------------------------------------
            %draws a cross in the the heatmap at the position of the spectrum
            %--------------------------------------------------------------------------------------------------------
            delete(app.cross_handle_2);
            hold(app.Map2,'on');
            app.cross_handle_2 = plot(app.Map2,app.XSpinner_2.Value,app.YSpinner_2.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map2,'off');
        end

        % Value changed function: TemperatureIndexSpinner_2
        function TemperatureIndexSpinner_2ValueChanged(app, event)
            show_map2(app)
            %--------------------------------------------------------------------------------------------------------
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %--------------------------------------------------------------------------------------------------------
            x = find_index(app.PLMap_Data{1}.X_vector,app.XSpinner_2.Value,1);
            app.XSpinner_2.Value = app.PLMap_Data{1}.X_vector(x);
            y = find_index(app.PLMap_Data{1}.Y_vector,app.YSpinner_2.Value,1);
            app.YSpinner_2.Value = app.PLMap_Data{1}.Y_vector(y);
            power_index = find_index(app.PLMap_Data{1}.Power,app.PowerSpinner_2.Value,1);
            app.PowerSpinner_2.Value = app.PLMap_Data{1}.Power(power_index);
            
            %--------------------------------------------------------------------------------------------------------
            %Display first Spectrum at starting Point
            %--------------------------------------------------------------------------------------------------------
            [app.spectrum_a,app.range_vector_a,app.last_spectrum_2,~] = Display_Spectrum(app.Spectrum2,app.PLMap_Data{1}.Wavelength,app.PLMap_Data{1}.data_array, ...
                app.TemperatureIndexSpinner_2.Value,power_index,y,x,min(app.PLMap_Data{1}.Wavelength), ...
                max(app.PLMap_Data{1}.Wavelength),'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_1);
            
            %--------------------------------------------------------------------------------------------------------
            %marks the spectral region used for the heatmap
            %--------------------------------------------------------------------------------------------------------
            if(app.MapLowerWavelengthSpinner_2.Value~=min(app.PLMap_Data{1}.Wavelength))...
                    ||(app.MapUpperWavelengthSpinner_2.Value~=max(app.PLMap_Data{1}.Wavelength))
            mark_area(app.Spectrum2,app.area_spectral_range_handle2,app.data_map,app.spectrum_a,...
                app.MapLowerWavelengthSpinner_2,app.MapUpperWavelengthSpinner_2,0)
            end
            
            %--------------------------------------------------------------------------------------------------------
            %draws a cross in the the heatmap at the position of the spectrum
            %--------------------------------------------------------------------------------------------------------
            delete(app.cross_handle_2);
            hold(app.Map2,'on');
            app.cross_handle_2 = plot(app.Map2,app.XSpinner_2.Value,app.YSpinner_2.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map2,'off');
        end

        % Value changed function: PowerSpinner_3
        function PowerSpinner_3ValueChanged(app, event)
            show_map3(app)
            %--------------------------------------------------------------------------------------------------------
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %--------------------------------------------------------------------------------------------------------
            x = find_index(app.PLMap_Data{2}.X_vector,app.XSpinner_3.Value,1);
            app.XSpinner_3.Value = app.PLMap_Data{2}.X_vector(x);
            y = find_index(app.PLMap_Data{2}.Y_vector,app.YSpinner_3.Value,1);
            app.YSpinner_3.Value = app.PLMap_Data{2}.Y_vector(y);
            power_index = find_index(app.PLMap_Data{2}.Power,app.PowerSpinner_3.Value,1);
            app.PowerSpinner_3.Value = app.PLMap_Data{2}.Power(power_index);
            
            %--------------------------------------------------------------------------------------------------------
            %Display first Spectrum at starting Point
            %--------------------------------------------------------------------------------------------------------
            [app.spectrum_b,app.range_vector_b,app.last_spectrum_3,~] = Display_Spectrum(app.Spectrum3,app.PLMap_Data{2}.Wavelength,app.PLMap_Data{2}.data_array, ...
                app.TemperatureIndexSpinner_3.Value,power_index,y,x,min(app.PLMap_Data{2}.Wavelength), ...
                max(app.PLMap_Data{2}.Wavelength),'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_2);
            
            %--------------------------------------------------------------------------------------------------------
            %marks the spectral region used for the heatmap
            %--------------------------------------------------------------------------------------------------------
            if(app.MapLowerWavelengthSpinner_3.Value~=min(app.PLMap_Data{2}.Wavelength))...
                    ||(app.MapUpperWavelengthSpinner_3.Value~=max(app.PLMap_Data{2}.Wavelength))
            mark_area(app.Spectrum3,app.area_spectral_range_handle3,app.data_map,app.spectrum_b,...
                app.MapLowerWavelengthSpinner_3,app.MapUpperWavelengthSpinner_3,0)
            end
            
            %--------------------------------------------------------------------------------------------------------
            %draws a cross in the the heatmap at the position of the spectrum
            %--------------------------------------------------------------------------------------------------------
            delete(app.cross_handle_3);
            hold(app.Map3,'on');
            app.cross_handle_3 = plot(app.Map3,app.XSpinner_3.Value,app.YSpinner_3.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map3,'off');
        end

        % Value changed function: TemperatureIndexSpinner_3
        function TemperatureIndexSpinner_3ValueChanged(app, event)
            show_map3(app)
            %--------------------------------------------------------------------------------------------------------
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %--------------------------------------------------------------------------------------------------------
            x = find_index(app.PLMap_Data{2}.X_vector,app.XSpinner_3.Value,1);
            app.XSpinner_3.Value = app.PLMap_Data{2}.X_vector(x);
            y = find_index(app.PLMap_Data{2}.Y_vector,app.YSpinner_3.Value,1);
            app.YSpinner_3.Value = app.PLMap_Data{2}.Y_vector(y);
            power_index = find_index(app.PLMap_Data{2}.Power,app.PowerSpinner_3.Value,1);
            app.PowerSpinner_3.Value = app.PLMap_Data{2}.Power(power_index);
            
            %--------------------------------------------------------------------------------------------------------
            %Display first Spectrum at starting Point
            %--------------------------------------------------------------------------------------------------------
            [app.spectrum_b,app.range_vector_b,app.last_spectrum_3,~] = Display_Spectrum(app.Spectrum3,app.PLMap_Data{2}.Wavelength,app.PLMap_Data{2}.data_array, ...
                app.TemperatureIndexSpinner_3.Value,power_index,y,x,min(app.PLMap_Data{2}.Wavelength), ...
                max(app.PLMap_Data{2}.Wavelength),'All_points','movmean',1,1,1000,'PL_Map',app.lock_zoom_graph_2);
            
            %--------------------------------------------------------------------------------------------------------
            %marks the spectral region used for the heatmap
            %--------------------------------------------------------------------------------------------------------
            if(app.MapLowerWavelengthSpinner_3.Value~=min(app.PLMap_Data{2}.Wavelength))...
                    ||(app.MapUpperWavelengthSpinner_3.Value~=max(app.PLMap_Data{2}.Wavelength))
            mark_area(app.Spectrum3,app.area_spectral_range_handle3,app.data_map,app.spectrum_b,...
                app.MapLowerWavelengthSpinner_3,app.MapUpperWavelengthSpinner_3,0)
            end
            
            %--------------------------------------------------------------------------------------------------------
            %draws a cross in the the heatmap at the position of the spectrum
            %--------------------------------------------------------------------------------------------------------
            delete(app.cross_handle_3);
            hold(app.Map3,'on');
            app.cross_handle_3 = plot(app.Map3,app.XSpinner_3.Value,app.YSpinner_3.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map3,'off');
        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            copyed_array = mat2clip(app.data_map, '\t');
        end

        % Menu selected function: map_1_to_single_graphMenu
        function map_1_to_single_graphMenuSelected(app, event)
            app_single_graph(app.PLMap_Data{1},app.Data_Fit1);
        end

        % Menu selected function: map_1_gradient_graphMenu
        function map_1_gradient_graphMenuSelected(app, event)
            app_gradient_graph(app.PLMap_Data{1},app.Data_Fit1);
        end

        % Menu selected function: map_1_fit_graphMenu
        function map_1_fit_graphMenuSelected(app, event)
            app_fit_graph(app.PLMap_Data{1},app.Data_Fit1,'PL_Map');
        end

        % Menu selected function: map_2_to_single_graphMenu
        function map_2_to_single_graphMenuSelected(app, event)
            app_single_graph(app.PLMap_Data{2},app.Data_Fit2);
        end

        % Menu selected function: map_2_gradient_graphMenu
        function map_2_gradient_graphMenuSelected(app, event)
            app_gradient_graph(app.PLMap_Data{2},app.Data_Fit2);
        end

        % Menu selected function: map_2_fit_graphMenu
        function map_2_fit_graphMenuSelected(app, event)
            app_fit_graph(app.PLMap_Data{2},app.Data_Fit2,'PL_Map');
        end

        % Value changed function: locktoareaCheckBox_2
        function locktoareaCheckBox_2ValueChanged(app, event)
            if app.locktoareaCheckBox_2.Value
                prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                dlgtitle = 'Input area';
                dims = [1 35];
                definput = {'38','38','10','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                % Handle response
                if isempty(answer)
                    app.locktoareaCheckBox_2.Value = 0;
                    return
                else
                    app.area_dim_2(1) = str2num(answer{1});
                    app.area_dim_2(2) = str2num(answer{2});
                    app.area_dim_2(3) = str2num(answer{3});
                    app.area_dim_2(4) = str2num(answer{4});
                    
                    %position spectrum correctly
                    app.XSpinner_2.Value = str2num(answer{1});
                    app.YSpinner_2.Value = str2num(answer{2});
                    XSpinner_2ValueChanged(app, event);
                end
            else
                app.area_dim_2 = [];
            end
            MapRefreshButton_2Pushed(app, event);
        end

        % Value changed function: locktoareaCheckBox_3
        function locktoareaCheckBox_3ValueChanged(app, event)
            if app.locktoareaCheckBox_3.Value
                prompt = {'Enter start x:','Enter start y:','Enter width:','Enter height:'};
                dlgtitle = 'Input area';
                dims = [1 35];
                definput = {'38','38','10','10'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                % Handle response
                if isempty(answer)
                    app.locktoareaCheckBox_3.Value = 0;
                    return
                else
                    app.area_dim_3(1) = str2num(answer{1});
                    app.area_dim_3(2) = str2num(answer{2});
                    app.area_dim_3(3) = str2num(answer{3});
                    app.area_dim_3(4) = str2num(answer{4});
                    
                    %position spectrum correctly
                    app.XSpinner_3.Value = str2num(answer{1});
                    app.YSpinner_3.Value = str2num(answer{2});
                    XSpinner_3ValueChanged(app, event);
                end
            else
                app.area_dim_3 = [];
            end
            MapRefreshButton_3Pushed(app, event);
        end

        % Callback function
        function get_pointMenuSelected(app, event)
            [app.rio2, app.XSpinner_2.Value, app.YSpinner_2.Value] = select_data.selectPointCrosshair(app.Map2,app.PLMap_Data{1}.X_Steps,app.PLMap_Data{1}.Y_Steps);
            addlistener(app.rio2,'ROIMoved',@(src,data)moverio(app,src,data,app.rio2,app.XSpinner_2,app.YSpinner_2,app.PLMap_Data{1}));
            XSpinner_2ValueChanged(app, event);
        end

        % Menu selected function: map_colorMenu_1
        function map_colorMenu_1Selected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list_1,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list_1 = idx;
                app.map_1_typ = list{idx};
            end
            show_map2(app);
        end

        % Menu selected function: map_colorMenu_2
        function map_colorMenu_2Selected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list_2,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list_2 = idx;
                app.map_2_typ = list{idx};
            end
            show_map3(app);
        end

        % Menu selected function: lock_zoomMenu_1
        function lock_zoomMenu_1Selected(app, event)
            if app.lock_zoom_graph_1
                app.lock_zoom_graph_1 = 0;
            else
                app.lock_zoom_graph_1 = 1;
            end
        end

        % Menu selected function: lock_zoomMenu_2
        function lock_zoomMenu_2Selected(app, event)
            if app.lock_zoom_graph_2
                app.lock_zoom_graph_2 = 0;
            else
                app.lock_zoom_graph_2 = 1;
            end
        end

        % Button pushed function: RefreshButton_5
        function RefreshButton_5Pushed(app, event)
            app.lock_zoom_graph_1 = 0;
            XSpinner_2ValueChanged(app, event);
        end

        % Button pushed function: RefreshButton_6
        function RefreshButton_6Pushed(app, event)
            app.lock_zoom_graph_2 = 0;
            XSpinner_3ValueChanged(app, event);
        end

        % Menu selected function: copy_map_1Menu
        function copy_map_1MenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map_2,ax, 'colorbar', app.c_2);
        end

        % Menu selected function: copy_map_2Menu
        function copy_map_2MenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map_3,ax, 'colorbar', app.c_3);
        end

        % Menu selected function: copy_spectrum_1_graphMenu
        function copy_spectrum_1_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_spectrum_2,ax);
        end

        % Menu selected function: copy_spectrum_2_graphMenu
        function copy_spectrum_2_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_spectrum_3,ax);
        end

        % Menu selected function: map_1_to_map_2Menu
        function map_1_to_map_2MenuSelected(app, event)
            import_data(app,[],app.PLMap_Data{1});
        end

        % Menu selected function: map_2_to_map_1Menu
        function map_2_to_map_1MenuSelected(app, event)
            import_data(app,app.PLMap_Data{2},[]);
        end

        % Menu selected function: Fit_1Menu
        function Fit_1MenuSelected(app, event)
            app.MapUpperWavelengthSpinner_2.Visible = 'off';
            app.UpperWavelengthSpinner_2Label.Visible = 'off';
            app.MapLowerWavelengthSpinner_2.Visible = 'off';
            app.LowerWavelengthSpinner_3Label.Visible = 'off';
            app.showfullrangeCheckBox_2.Visible = 'off';
            app.fitDropDown_1.Visible = 'on';
            app.fitDropDown_1Label.Visible = 'on';
            app.DisplayedPeakDropDown_1.Visible = 'on';
            app.DisplayedPeakDropDown_1Label.Visible = 'on';
            app.DisplayedValueDropDown_1.Visible = 'on';
            app.DisplayedValueDropDown_1Label.Visible = 'on';
            app.energy_to_waveCheckBox_1.Visible = 'on';
            
            app.displayed_typ1 = 1;
            
            MapRefreshButton_2Pushed(app, event)
        end

        % Menu selected function: Fit_2Menu
        function Fit_2MenuSelected(app, event)
            app.MapUpperWavelengthSpinner_3.Visible = 'off';
            app.UpperWavelengthSpinner_3Label.Visible = 'off';
            app.MapLowerWavelengthSpinner_3.Visible = 'off';
            app.LowerWavelengthSpinner_2Label.Visible = 'off';
            app.showfullrangeCheckBox_3.Visible = 'off';
            app.fitDropDown_2.Visible = 'on';
            app.fitDropDown_2Label.Visible = 'on';
            app.DisplayedPeakDropDown_2.Visible = 'on';
            app.DisplayedPeakDropDown_2Label.Visible = 'on';
            app.DisplayedValueDropDown_2.Visible = 'on';
            app.DisplayedValueDropDown_2Label.Visible = 'on';
            app.energy_to_waveCheckBox_2.Visible = 'on';
            
            app.displayed_typ2 = 1;
            
            MapRefreshButton_3Pushed(app, event)
        end

        % Menu selected function: Map1Menu
        function Map1MenuSelected(app, event)
            app.MapUpperWavelengthSpinner_2.Visible = 'on';
            app.UpperWavelengthSpinner_2Label.Visible = 'on';
            app.MapLowerWavelengthSpinner_2.Visible = 'on';
            app.LowerWavelengthSpinner_3Label.Visible = 'on';
            app.showfullrangeCheckBox_2.Visible = 'on';
            app.fitDropDown_1.Visible = 'off';
            app.fitDropDown_1Label.Visible = 'off';
            app.DisplayedPeakDropDown_1.Visible = 'off';
            app.DisplayedPeakDropDown_1Label.Visible = 'off';
            app.DisplayedValueDropDown_1.Visible = 'off';
            app.DisplayedValueDropDown_1Label.Visible = 'off';
            app.energy_to_waveCheckBox_1.Visible = 'off';
            
            app.displayed_typ1 = 0;
            
            MapRefreshButton_2Pushed(app, event)
        end

        % Menu selected function: Map2Menu
        function Map2MenuSelected(app, event)
            app.MapUpperWavelengthSpinner_3.Visible = 'on';
            app.UpperWavelengthSpinner_3Label.Visible = 'on';
            app.MapLowerWavelengthSpinner_3.Visible = 'on';
            app.LowerWavelengthSpinner_2Label.Visible = 'on';
            app.showfullrangeCheckBox_3.Visible = 'on';
            app.fitDropDown_2.Visible = 'off';
            app.fitDropDown_2Label.Visible = 'off';
            app.DisplayedPeakDropDown_2.Visible = 'off';
            app.DisplayedPeakDropDown_2Label.Visible = 'off';
            app.DisplayedValueDropDown_2.Visible = 'off';
            app.DisplayedValueDropDown_2Label.Visible = 'off';
            app.energy_to_waveCheckBox_2.Visible = 'off';
            
            app.displayed_typ2 = 0;
            
            MapRefreshButton_3Pushed(app, event)
        end

        % Value changed function: DisplayedPeakDropDown_1
        function DisplayedPeakDropDown_1ValueChanged(app, event)
            app.displayed_peak1  = str2num(app.DisplayedPeakDropDown_1.Value);
            MapRefreshButton_2Pushed(app, event)
        end

        % Value changed function: DisplayedPeakDropDown_2
        function DisplayedPeakDropDown_2ValueChanged(app, event)
            app.displayed_peak2  = str2num(app.DisplayedPeakDropDown_2.Value);
            MapRefreshButton_3Pushed(app, event)
        end

        % Value changed function: DisplayedValueDropDown_2
        function DisplayedValueDropDown_2ValueChanged(app, event)
            switch app.DisplayedValueDropDown_2.Value
                case 'Height'
                    app.displayed_fit_parameter2 = 1;
                case 'Position'
                    app.displayed_fit_parameter2 = 2;
                case 'FWHM'
                    app.displayed_fit_parameter2 = 3;
                case 'Eta'
                    app.displayed_fit_parameter2 = 4;
                case 'rmse'
                    app.displayed_fit_parameter2 = 5;
            end
            
            MapRefreshButton_3Pushed(app, event)
        end

        % Value changed function: DisplayedValueDropDown_1
        function DisplayedValueDropDown_1ValueChanged(app, event)
            switch app.DisplayedValueDropDown_1.Value
                case 'Height'
                    app.displayed_fit_parameter1 = 1;
                case 'Position'
                    app.displayed_fit_parameter1 = 2;
                case 'FWHM'
                    app.displayed_fit_parameter1 = 3;
                case 'Eta'
                    app.displayed_fit_parameter1 = 4;
                case 'rmse'
                    app.displayed_fit_parameter1 = 5;
            end
            
            MapRefreshButton_2Pushed(app, event)
        end

        % Value changed function: fitDropDown_1
        function fitDropDown_1ValueChanged(app, event)
            app.displayed_fit1 = str2num(app.fitDropDown_1.Value);
            MapRefreshButton_2Pushed(app, event)
        end

        % Value changed function: fitDropDown_2
        function fitDropDown_2ValueChanged(app, event)
            app.displayed_fit2 = str2num(app.fitDropDown_2.Value);
            MapRefreshButton_3Pushed(app, event)
        end

        % Menu selected function: differenceMap1Map2Menu_2
        function differenceMap1Map2MenuSelected(app, event)
            [pre_processed_data_map1_full,~,~] = get_fit_data1(app,app.displayed_fit1,app.displayed_peak1,app.displayed_fit_parameter1);
            [pre_processed_data_map2_full,~,~] = get_fit_data2(app,app.displayed_fit2,app.displayed_peak2,app.displayed_fit_parameter2);
            
            if app.displayed_fit_parameter1 == 2
                if app.energy_to_waveCheckBox_1.Value
                    pre_processed_data_map1_full = converter.energy_wavelength(pre_processed_data_map1_full);
                    pre_processed_data_map2_full = converter.energy_wavelength(pre_processed_data_map2_full);
                end
            end
            
            diff_maps(app,pre_processed_data_map1_full,pre_processed_data_map2_full,'1-2');
        end

        % Menu selected function: differenceMap1Map2Menu_3
        function differenceMap1Map2Menu_3Selected(app, event)
            pre_processed_data_map1_full = app.map_2_data;
            pre_processed_data_map2_full = app.map_3_data;
            
            diff_maps(app,pre_processed_data_map1_full,pre_processed_data_map2_full,'1-2');
        end

        % Menu selected function: differenceMap2Map1Menu
        function differenceMap2Map1MenuSelected(app, event)
            [pre_processed_data_map1_full,~,~] = get_fit_data1(app,app.displayed_fit1,app.displayed_peak1,app.displayed_fit_parameter1);
            [pre_processed_data_map2_full,~,~] = get_fit_data2(app,app.displayed_fit2,app.displayed_peak2,app.displayed_fit_parameter2);
            
            if app.displayed_fit_parameter1 == 2
                if app.energy_to_waveCheckBox_1.Value
                    pre_processed_data_map1_full = converter.energy_wavelength(pre_processed_data_map1_full);
                    pre_processed_data_map2_full = converter.energy_wavelength(pre_processed_data_map2_full);
                end
            end
            
            diff_maps(app,pre_processed_data_map1_full,pre_processed_data_map2_full,'2-1');
        end

        % Menu selected function: differenceMap2Map1Menu_2
        function differenceMap2Map1Menu_2Selected(app, event)
            pre_processed_data_map1_full = app.map_2_data;
            pre_processed_data_map2_full = app.map_3_data;
            
            diff_maps(app,pre_processed_data_map1_full,pre_processed_data_map2_full,'2-1');
        end

        % Menu selected function: copy_bothMenu
        function copy_bothMenuSelected(app, event)
            f = figure;
            f.Units = 'centimeters';
            f.Position = [10,10,15.5,7.75];
            
            ax2 = subplot(1,2,1);
            copyUIGraphics.copyUIAxes(app.last_map_2,ax2, 'colorbar', app.c_2);
            ax2.OuterPosition = [0.01,0.01,0.485,0.98];
            
            ax3 = subplot(1,2,2);
            copyUIGraphics.copyUIAxes(app.last_map_3,ax3, 'colorbar', app.c_3);
            ax3.OuterPosition = [0.505,0.01,0.485,0.98];
        end

        % Menu selected function: copy_spectrum_1_dataMenu
        function copy_spectrum_1_dataMenuSelected(app, event)
            coppy_array = [app.range_vector_a,double(app.spectrum_a')];
            copyed_array = mat2clip(coppy_array, '\t');
        end

        % Menu selected function: copy_spectrum_2_dataMenu
        function copy_spectrum_2_dataMenuSelected(app, event)
            coppy_array = [app.range_vector_b,double(app.spectrum_b')];
            copyed_array = mat2clip(coppy_array, '\t');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'hyperspectral analyzer by Marcel Schmidt at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.menuMenu = uimenu(app.UIFigure);
            app.menuMenu.Text = 'menu';
            app.LoadDataMenu = uimenu(app.menuMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Accelerator = 'l';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExportMenu = uimenu(app.menuMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.CopytoclipboardMenu = uimenu(app.menuMenu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Accelerator = 'c';
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.PreferencesMenu = uimenu(app.menuMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Accelerator = 'p';
            app.PreferencesMenu.Text = 'Preferences';
            app.copy_mapMenu = uimenu(app.menuMenu);
            app.copy_mapMenu.Text = 'copy_map';
            app.copy_map_1Menu = uimenu(app.copy_mapMenu);
            app.copy_map_1Menu.MenuSelectedFcn = createCallbackFcn(app, @copy_map_1MenuSelected, true);
            app.copy_map_1Menu.Text = 'copy_map_1';
            app.copy_map_2Menu = uimenu(app.copy_mapMenu);
            app.copy_map_2Menu.MenuSelectedFcn = createCallbackFcn(app, @copy_map_2MenuSelected, true);
            app.copy_map_2Menu.Text = 'copy_map_2';
            app.copy_bothMenu = uimenu(app.copy_mapMenu);
            app.copy_bothMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_bothMenuSelected, true);
            app.copy_bothMenu.Text = 'copy_both';
            app.copy_spectrumMenu = uimenu(app.menuMenu);
            app.copy_spectrumMenu.Text = 'copy_spectrum';
            app.copy_spectrum_1_graphMenu = uimenu(app.copy_spectrumMenu);
            app.copy_spectrum_1_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_spectrum_1_graphMenuSelected, true);
            app.copy_spectrum_1_graphMenu.Text = 'copy_spectrum_1_graph';
            app.copy_spectrum_1_dataMenu = uimenu(app.copy_spectrumMenu);
            app.copy_spectrum_1_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_spectrum_1_dataMenuSelected, true);
            app.copy_spectrum_1_dataMenu.Text = 'copy_spectrum_1_data';
            app.copy_spectrum_2_graphMenu = uimenu(app.copy_spectrumMenu);
            app.copy_spectrum_2_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_spectrum_2_graphMenuSelected, true);
            app.copy_spectrum_2_graphMenu.Text = 'copy_spectrum_2_graph';
            app.copy_spectrum_2_dataMenu = uimenu(app.copy_spectrumMenu);
            app.copy_spectrum_2_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @copy_spectrum_2_dataMenuSelected, true);
            app.copy_spectrum_2_dataMenu.Text = 'copy_spectrum_2_data';
            app.send_data_toMenu = uimenu(app.UIFigure);
            app.send_data_toMenu.Text = 'send_data_to';
            app.map_1_to_single_graphMenu = uimenu(app.send_data_toMenu);
            app.map_1_to_single_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @map_1_to_single_graphMenuSelected, true);
            app.map_1_to_single_graphMenu.Text = 'map_1_to_single_graph';
            app.map_2_to_single_graphMenu = uimenu(app.send_data_toMenu);
            app.map_2_to_single_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @map_2_to_single_graphMenuSelected, true);
            app.map_2_to_single_graphMenu.Text = 'map_2_to_single_graph';
            app.map_1_to_map_2Menu = uimenu(app.send_data_toMenu);
            app.map_1_to_map_2Menu.MenuSelectedFcn = createCallbackFcn(app, @map_1_to_map_2MenuSelected, true);
            app.map_1_to_map_2Menu.Text = 'map_1_to_map_2';
            app.map_2_to_map_1Menu = uimenu(app.send_data_toMenu);
            app.map_2_to_map_1Menu.MenuSelectedFcn = createCallbackFcn(app, @map_2_to_map_1MenuSelected, true);
            app.map_2_to_map_1Menu.Text = 'map_2_to_map_1';
            app.map_1_gradient_graphMenu = uimenu(app.send_data_toMenu);
            app.map_1_gradient_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @map_1_gradient_graphMenuSelected, true);
            app.map_1_gradient_graphMenu.Text = 'map_1_gradient_graph';
            app.map_2_gradient_graphMenu = uimenu(app.send_data_toMenu);
            app.map_2_gradient_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @map_2_gradient_graphMenuSelected, true);
            app.map_2_gradient_graphMenu.Text = 'map_2_gradient_graph';
            app.map_1_fit_graphMenu = uimenu(app.send_data_toMenu);
            app.map_1_fit_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @map_1_fit_graphMenuSelected, true);
            app.map_1_fit_graphMenu.Text = 'map_1_fit_graph';
            app.map_2_fit_graphMenu = uimenu(app.send_data_toMenu);
            app.map_2_fit_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @map_2_fit_graphMenuSelected, true);
            app.map_2_fit_graphMenu.Text = 'map_2_fit_graph';
            app.map_settingsMenu = uimenu(app.UIFigure);
            app.map_settingsMenu.Text = 'map_settings';
            app.map_1Menu = uimenu(app.map_settingsMenu);
            app.map_1Menu.Text = 'map_1';
            app.map_colorMenu_1 = uimenu(app.map_1Menu);
            app.map_colorMenu_1.MenuSelectedFcn = createCallbackFcn(app, @map_colorMenu_1Selected, true);
            app.map_colorMenu_1.Text = 'map_color';
            app.map_2Menu = uimenu(app.map_settingsMenu);
            app.map_2Menu.Text = 'map_2';
            app.map_colorMenu_2 = uimenu(app.map_2Menu);
            app.map_colorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @map_colorMenu_2Selected, true);
            app.map_colorMenu_2.Text = 'map_color';
            app.operationsMenu = uimenu(app.UIFigure);
            app.operationsMenu.Text = 'operations';
            app.FitMenu = uimenu(app.operationsMenu);
            app.FitMenu.Text = 'Fit';
            app.differenceMap1Map2Menu_2 = uimenu(app.FitMenu);
            app.differenceMap1Map2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @differenceMap1Map2MenuSelected, true);
            app.differenceMap1Map2Menu_2.Text = 'difference (Map1-Map2)';
            app.differenceMap2Map1Menu = uimenu(app.FitMenu);
            app.differenceMap2Map1Menu.MenuSelectedFcn = createCallbackFcn(app, @differenceMap2Map1MenuSelected, true);
            app.differenceMap2Map1Menu.Text = 'difference (Map2-Map1)';
            app.MapMenu = uimenu(app.operationsMenu);
            app.MapMenu.Text = 'Map';
            app.differenceMap1Map2Menu_3 = uimenu(app.MapMenu);
            app.differenceMap1Map2Menu_3.MenuSelectedFcn = createCallbackFcn(app, @differenceMap1Map2Menu_3Selected, true);
            app.differenceMap1Map2Menu_3.Text = 'difference (Map1-Map2)';
            app.differenceMap2Map1Menu_2 = uimenu(app.MapMenu);
            app.differenceMap2Map1Menu_2.MenuSelectedFcn = createCallbackFcn(app, @differenceMap2Map1Menu_2Selected, true);
            app.differenceMap2Map1Menu_2.Text = 'difference (Map2-Map1)';
            app.displayed_dataMenu = uimenu(app.UIFigure);
            app.displayed_dataMenu.Text = 'displayed_data';
            app.Fit_1Menu = uimenu(app.displayed_dataMenu);
            app.Fit_1Menu.MenuSelectedFcn = createCallbackFcn(app, @Fit_1MenuSelected, true);
            app.Fit_1Menu.Text = 'Fit_1';
            app.Map1Menu = uimenu(app.displayed_dataMenu);
            app.Map1Menu.MenuSelectedFcn = createCallbackFcn(app, @Map1MenuSelected, true);
            app.Map1Menu.Text = 'Map1';
            app.Fit_2Menu = uimenu(app.displayed_dataMenu);
            app.Fit_2Menu.MenuSelectedFcn = createCallbackFcn(app, @Fit_2MenuSelected, true);
            app.Fit_2Menu.Text = 'Fit_2';
            app.Map2Menu = uimenu(app.displayed_dataMenu);
            app.Map2Menu.MenuSelectedFcn = createCallbackFcn(app, @Map2MenuSelected, true);
            app.Map2Menu.Text = 'Map2';
            app.graph_settingsMenu = uimenu(app.UIFigure);
            app.graph_settingsMenu.Text = 'graph_settings';
            app.graph_1Menu = uimenu(app.graph_settingsMenu);
            app.graph_1Menu.Text = 'graph_1';
            app.lock_zoomMenu_1 = uimenu(app.graph_1Menu);
            app.lock_zoomMenu_1.MenuSelectedFcn = createCallbackFcn(app, @lock_zoomMenu_1Selected, true);
            app.lock_zoomMenu_1.Text = 'lock_zoom';
            app.graph_2Menu = uimenu(app.graph_settingsMenu);
            app.graph_2Menu.Text = 'graph_2';
            app.lock_zoomMenu_2 = uimenu(app.graph_2Menu);
            app.lock_zoomMenu_2.MenuSelectedFcn = createCallbackFcn(app, @lock_zoomMenu_2Selected, true);
            app.lock_zoomMenu_2.Text = 'lock_zoom';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MultiGraphTab = uitab(app.TabGroup);
            app.MultiGraphTab.Title = 'Multi Graph';
            app.Map_1Panel = uipanel(app.MultiGraphTab);
            app.Map_1Panel.Title = 'Map_1';
            app.LowerWavelengthSpinner_3Label = uilabel(app.Map_1Panel);
            app.LowerWavelengthSpinner_3Label.Text = 'Lower Wavelength';
            app.MapLowerWavelengthSpinner_2 = uispinner(app.Map_1Panel);
            app.MapLowerWavelengthSpinner_2.ValueDisplayFormat = '%.3f';
            app.MapLowerWavelengthSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_2ValueChanged, true);
            app.MapLowerWavelengthSpinner_2.Value = 340.2334;
            app.UpperWavelengthSpinner_2Label = uilabel(app.Map_1Panel);
            app.UpperWavelengthSpinner_2Label.Text = 'Upper Wavelength';
            app.MapUpperWavelengthSpinner_2 = uispinner(app.Map_1Panel);
            app.MapUpperWavelengthSpinner_2.ValueDisplayFormat = '%.3f';
            app.MapUpperWavelengthSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_2ValueChanged, true);
            app.MapUpperWavelengthSpinner_2.Value = 1715.109591;
            app.MapRefreshButton_2 = uibutton(app.Map_1Panel, 'push');
            app.MapRefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButton_2Pushed, true);
            app.MapRefreshButton_2.Text = 'Refresh';
            app.MaxCountsEditFieldLabel = uilabel(app.Map_1Panel);
            app.MaxCountsEditFieldLabel.Text = 'Max Counts';
            app.MapMaxCountsEditField_2 = uieditfield(app.Map_1Panel, 'numeric');
            app.MapMaxCountsEditField_2.ValueDisplayFormat = '%1.2e';
            app.MapMaxCountsEditField_2.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_2ValueChanged, true);
            app.MapMaxCountsEditField_2.Value = Inf;
            app.MinCountsEditFieldLabel = uilabel(app.Map_1Panel);
            app.MinCountsEditFieldLabel.Text = 'Min Counts';
            app.MapMinCountsEditField_2 = uieditfield(app.Map_1Panel, 'numeric');
            app.MapMinCountsEditField_2.ValueDisplayFormat = '%1.2e';
            app.MapMinCountsEditField_2.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_2ValueChanged, true);
            app.MapMinCountsEditField_2.Value = -Inf;
            app.ColorstepsEditFieldLabel = uilabel(app.Map_1Panel);
            app.ColorstepsEditFieldLabel.Text = 'Color steps';
            app.MapColorstepsEditField_2 = uieditfield(app.Map_1Panel, 'numeric');
            app.MapColorstepsEditField_2.Limits = [1 Inf];
            app.MapColorstepsEditField_2.RoundFractionalValues = 'on';
            app.MapColorstepsEditField_2.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_2ValueChanged, true);
            app.MapColorstepsEditField_2.Value = 100;
            app.Map_switch_2 = uiswitch(app.Map_1Panel, 'slider');
            app.Map_switch_2.Items = {'log', 'linear'};
            app.Map_switch_2.Value = 'linear';
            app.PowerSpinner_2Label = uilabel(app.Map_1Panel);
            app.PowerSpinner_2Label.Text = 'Power';
            app.PowerSpinner_2 = uispinner(app.Map_1Panel);
            app.PowerSpinner_2.Step = 0.5;
            app.PowerSpinner_2.Limits = [0 76];
            app.PowerSpinner_2.ValueChangedFcn = createCallbackFcn(app, @PowerSpinner_2ValueChanged, true);
            app.PowerSpinner_2.Value = 1;
            app.TemperatureIndexSpinner_2Label = uilabel(app.Map_1Panel);
            app.TemperatureIndexSpinner_2Label.Text = 'Temperature Index';
            app.TemperatureIndexSpinner_2 = uispinner(app.Map_1Panel);
            app.TemperatureIndexSpinner_2.Step = 0.5;
            app.TemperatureIndexSpinner_2.Limits = [0 76];
            app.TemperatureIndexSpinner_2.ValueChangedFcn = createCallbackFcn(app, @TemperatureIndexSpinner_2ValueChanged, true);
            app.TemperatureIndexSpinner_2.Value = 1;
            app.MapappearanceDropDown_2 = uidropdown(app.Map_1Panel);
            app.MapappearanceDropDown_2.Items = {'integrated', 'averaged'};
            app.MapappearanceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_2ValueChanged, true);
            app.MapappearanceDropDown_2.Value = 'integrated';
            app.ContourCheckBox_2 = uicheckbox(app.Map_1Panel);
            app.ContourCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_2ValueChanged, true);
            app.ContourCheckBox_2.Text = 'Contour';
            app.showfullrangeCheckBox_2 = uicheckbox(app.Map_1Panel);
            app.showfullrangeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @XSpinner_2ValueChanged, true);
            app.showfullrangeCheckBox_2.Text = 'show full range';
            app.showfullrangeCheckBox_2.Value = true;
            app.locktoareaCheckBox_2 = uicheckbox(app.Map_1Panel);
            app.locktoareaCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBox_2ValueChanged, true);
            app.locktoareaCheckBox_2.Text = 'lock to area';
            app.fitDropDown_1Label = uilabel(app.Map_1Panel);
            app.fitDropDown_1Label.Visible = 'off';
            app.fitDropDown_1Label.Text = 'fit';
            app.fitDropDown_1 = uidropdown(app.Map_1Panel);
            app.fitDropDown_1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown_1.ValueChangedFcn = createCallbackFcn(app, @fitDropDown_1ValueChanged, true);
            app.fitDropDown_1.Visible = 'off';
            app.fitDropDown_1.Value = '1';
            app.DisplayedPeakDropDown_1Label = uilabel(app.Map_1Panel);
            app.DisplayedPeakDropDown_1Label.Visible = 'off';
            app.DisplayedPeakDropDown_1Label.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown_1 = uidropdown(app.Map_1Panel);
            app.DisplayedPeakDropDown_1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown_1.ValueChangedFcn = createCallbackFcn(app, @DisplayedPeakDropDown_1ValueChanged, true);
            app.DisplayedPeakDropDown_1.Visible = 'off';
            app.DisplayedPeakDropDown_1.Value = '1';
            app.DisplayedValueDropDown_1Label = uilabel(app.Map_1Panel);
            app.DisplayedValueDropDown_1Label.Visible = 'off';
            app.DisplayedValueDropDown_1Label.Text = 'Displayed Value';
            app.DisplayedValueDropDown_1 = uidropdown(app.Map_1Panel);
            app.DisplayedValueDropDown_1.Items = {'rmse', 'Height', 'Position', 'FWHM', 'Eta'};
            app.DisplayedValueDropDown_1.ValueChangedFcn = createCallbackFcn(app, @DisplayedValueDropDown_1ValueChanged, true);
            app.DisplayedValueDropDown_1.Visible = 'off';
            app.DisplayedValueDropDown_1.Value = 'Position';
            app.energy_to_waveCheckBox_1 = uicheckbox(app.Map_1Panel);
            app.energy_to_waveCheckBox_1.Visible = 'off';
            app.energy_to_waveCheckBox_1.Text = 'energy_to_wave';
            app.energy_to_waveCheckBox_1.Value = true;
            app.Map_2Panel = uipanel(app.MultiGraphTab);
            app.Map_2Panel.Title = 'Map_2';
            app.LowerWavelengthSpinner_2Label = uilabel(app.Map_2Panel);
            app.LowerWavelengthSpinner_2Label.Text = 'Lower Wavelength';
            app.MapLowerWavelengthSpinner_3 = uispinner(app.Map_2Panel);
            app.MapLowerWavelengthSpinner_3.ValueDisplayFormat = '%.3f';
            app.MapLowerWavelengthSpinner_3.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_3ValueChanged, true);
            app.MapLowerWavelengthSpinner_3.Value = 340.2334;
            app.UpperWavelengthSpinner_3Label = uilabel(app.Map_2Panel);
            app.UpperWavelengthSpinner_3Label.Text = 'Upper Wavelength';
            app.MapUpperWavelengthSpinner_3 = uispinner(app.Map_2Panel);
            app.MapUpperWavelengthSpinner_3.ValueDisplayFormat = '%.3f';
            app.MapUpperWavelengthSpinner_3.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_3ValueChanged, true);
            app.MapUpperWavelengthSpinner_3.Value = 1715.109591;
            app.MapRefreshButton_3 = uibutton(app.Map_2Panel, 'push');
            app.MapRefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButton_3Pushed, true);
            app.MapRefreshButton_3.Text = 'Refresh';
            app.MaxCountsEditField_2Label = uilabel(app.Map_2Panel);
            app.MaxCountsEditField_2Label.Text = 'Max Counts';
            app.MapMaxCountsEditField_3 = uieditfield(app.Map_2Panel, 'numeric');
            app.MapMaxCountsEditField_3.ValueDisplayFormat = '%1.2e';
            app.MapMaxCountsEditField_3.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_3ValueChanged, true);
            app.MapMaxCountsEditField_3.Value = Inf;
            app.MinCountsEditField_2Label = uilabel(app.Map_2Panel);
            app.MinCountsEditField_2Label.Text = 'Min Counts';
            app.MapMinCountsEditField_3 = uieditfield(app.Map_2Panel, 'numeric');
            app.MapMinCountsEditField_3.ValueDisplayFormat = '%1.2e';
            app.MapMinCountsEditField_3.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_3ValueChanged, true);
            app.MapMinCountsEditField_3.Value = -Inf;
            app.ColorstepsEditField_2Label = uilabel(app.Map_2Panel);
            app.ColorstepsEditField_2Label.Text = 'Color steps';
            app.MapColorstepsEditField_3 = uieditfield(app.Map_2Panel, 'numeric');
            app.MapColorstepsEditField_3.Limits = [1 Inf];
            app.MapColorstepsEditField_3.RoundFractionalValues = 'on';
            app.MapColorstepsEditField_3.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_3ValueChanged, true);
            app.MapColorstepsEditField_3.Value = 100;
            app.Map_switch_3 = uiswitch(app.Map_2Panel, 'slider');
            app.Map_switch_3.Items = {'log', 'linear'};
            app.Map_switch_3.Value = 'linear';
            app.PowerSpinner_3Label = uilabel(app.Map_2Panel);
            app.PowerSpinner_3Label.Text = 'Power';
            app.PowerSpinner_3 = uispinner(app.Map_2Panel);
            app.PowerSpinner_3.Step = 0.5;
            app.PowerSpinner_3.Limits = [0 76];
            app.PowerSpinner_3.ValueChangedFcn = createCallbackFcn(app, @PowerSpinner_3ValueChanged, true);
            app.PowerSpinner_3.Value = 1;
            app.TemperatureIndexSpinner_3Label = uilabel(app.Map_2Panel);
            app.TemperatureIndexSpinner_3Label.Text = 'Temperature Index';
            app.TemperatureIndexSpinner_3 = uispinner(app.Map_2Panel);
            app.TemperatureIndexSpinner_3.Step = 0.5;
            app.TemperatureIndexSpinner_3.Limits = [0 76];
            app.TemperatureIndexSpinner_3.ValueChangedFcn = createCallbackFcn(app, @TemperatureIndexSpinner_3ValueChanged, true);
            app.TemperatureIndexSpinner_3.Value = 1;
            app.MapappearanceDropDown_3 = uidropdown(app.Map_2Panel);
            app.MapappearanceDropDown_3.Items = {'integrated', 'averaged'};
            app.MapappearanceDropDown_3.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_3ValueChanged, true);
            app.MapappearanceDropDown_3.Value = 'integrated';
            app.ContourCheckBox_3 = uicheckbox(app.Map_2Panel);
            app.ContourCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_3ValueChanged, true);
            app.ContourCheckBox_3.Text = 'Contour';
            app.showfullrangeCheckBox_3 = uicheckbox(app.Map_2Panel);
            app.showfullrangeCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @XSpinner_3ValueChanged, true);
            app.showfullrangeCheckBox_3.Text = 'show full range';
            app.showfullrangeCheckBox_3.Value = true;
            app.locktoareaCheckBox_3 = uicheckbox(app.Map_2Panel);
            app.locktoareaCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBox_3ValueChanged, true);
            app.locktoareaCheckBox_3.Text = 'lock to area';
            app.fitDropDown_2Label = uilabel(app.Map_2Panel);
            app.fitDropDown_2Label.Visible = 'off';
            app.fitDropDown_2Label.Text = 'fit';
            app.fitDropDown_2 = uidropdown(app.Map_2Panel);
            app.fitDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.fitDropDown_2.ValueChangedFcn = createCallbackFcn(app, @fitDropDown_2ValueChanged, true);
            app.fitDropDown_2.Visible = 'off';
            app.fitDropDown_2.Value = '1';
            app.DisplayedPeakDropDown_2Label = uilabel(app.Map_2Panel);
            app.DisplayedPeakDropDown_2Label.Visible = 'off';
            app.DisplayedPeakDropDown_2Label.Text = 'Displayed Peak';
            app.DisplayedPeakDropDown_2 = uidropdown(app.Map_2Panel);
            app.DisplayedPeakDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.DisplayedPeakDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayedPeakDropDown_2ValueChanged, true);
            app.DisplayedPeakDropDown_2.Visible = 'off';
            app.DisplayedPeakDropDown_2.Value = '1';
            app.DisplayedValueDropDown_2Label = uilabel(app.Map_2Panel);
            app.DisplayedValueDropDown_2Label.Visible = 'off';
            app.DisplayedValueDropDown_2Label.Text = 'Displayed Value';
            app.DisplayedValueDropDown_2 = uidropdown(app.Map_2Panel);
            app.DisplayedValueDropDown_2.Items = {'rmse', 'Height', 'Position', 'FWHM', 'Eta'};
            app.DisplayedValueDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayedValueDropDown_2ValueChanged, true);
            app.DisplayedValueDropDown_2.Visible = 'off';
            app.DisplayedValueDropDown_2.Value = 'Position';
            app.energy_to_waveCheckBox_2 = uicheckbox(app.Map_2Panel);
            app.energy_to_waveCheckBox_2.Visible = 'off';
            app.energy_to_waveCheckBox_2.Text = 'energy_to_wave';
            app.energy_to_waveCheckBox_2.Value = true;
            app.Spectrum1ParameterPanel = uipanel(app.MultiGraphTab);
            app.Spectrum1ParameterPanel.Title = 'Spectrum1 Parameter';
            app.XSpinner_2Label = uilabel(app.Spectrum1ParameterPanel);
            app.XSpinner_2Label.Text = 'X';
            app.XSpinner_2 = uispinner(app.Spectrum1ParameterPanel);
            app.XSpinner_2.Step = 0.5;
            app.XSpinner_2.Limits = [0 76];
            app.XSpinner_2.ValueChangedFcn = createCallbackFcn(app, @XSpinner_2ValueChanged, true);
            app.XSpinner_2.Value = 1;
            app.YSpinner_2Label = uilabel(app.Spectrum1ParameterPanel);
            app.YSpinner_2Label.Text = 'Y';
            app.YSpinner_2 = uispinner(app.Spectrum1ParameterPanel);
            app.YSpinner_2.Step = 0.5;
            app.YSpinner_2.Limits = [0 76];
            app.YSpinner_2.ValueChangedFcn = createCallbackFcn(app, @XSpinner_2ValueChanged, true);
            app.YSpinner_2.Value = 1;
            app.RefreshButton_5 = uibutton(app.Spectrum1ParameterPanel, 'push');
            app.RefreshButton_5.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_5Pushed, true);
            app.RefreshButton_5.Text = 'Refresh';
            app.Switch_2 = uiswitch(app.Spectrum1ParameterPanel, 'slider');
            app.Switch_2.Items = {'log', 'linear'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'linear';
            app.Spectrum2ParameterPanel = uipanel(app.MultiGraphTab);
            app.Spectrum2ParameterPanel.Title = 'Spectrum2 Parameter';
            app.XSpinner_3Label = uilabel(app.Spectrum2ParameterPanel);
            app.XSpinner_3Label.Text = 'X';
            app.XSpinner_3 = uispinner(app.Spectrum2ParameterPanel);
            app.XSpinner_3.Step = 0.5;
            app.XSpinner_3.Limits = [0 76];
            app.XSpinner_3.ValueChangedFcn = createCallbackFcn(app, @XSpinner_3ValueChanged, true);
            app.XSpinner_3.Value = 1;
            app.YSpinner_3Label = uilabel(app.Spectrum2ParameterPanel);
            app.YSpinner_3Label.Text = 'Y';
            app.YSpinner_3 = uispinner(app.Spectrum2ParameterPanel);
            app.YSpinner_3.Step = 0.5;
            app.YSpinner_3.Limits = [0 76];
            app.YSpinner_3.ValueChangedFcn = createCallbackFcn(app, @XSpinner_3ValueChanged, true);
            app.YSpinner_3.Value = 1;
            app.RefreshButton_6 = uibutton(app.Spectrum2ParameterPanel, 'push');
            app.RefreshButton_6.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_6Pushed, true);
            app.RefreshButton_6.Text = 'Refresh';
            app.Switch_3 = uiswitch(app.Spectrum2ParameterPanel, 'slider');
            app.Switch_3.Items = {'log', 'linear'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'linear';
            app.SampleInformationMap1Label = uilabel(app.MultiGraphTab);
            app.SampleInformationMap1Label.Text = 'Sample Information Map 1';
            app.Sample_Information_2_TextArea = uitextarea(app.MultiGraphTab);
            app.SampleInformationMap2Label = uilabel(app.MultiGraphTab);
            app.SampleInformationMap2Label.Text = 'Sample Information Map 2';
            app.Sample_Information_3_TextArea = uitextarea(app.MultiGraphTab);
            app.Map3 = uiaxes(app.MultiGraphTab);
            title(app.Map3, 'Map 2')
            xlabel(app.Map3, 'X [mm]')
            ylabel(app.Map3, 'Y [mm]')
            app.Spectrum2 = uiaxes(app.MultiGraphTab);
            title(app.Spectrum2, 'Spectrum 1')
            xlabel(app.Spectrum2, 'Wavelength [nm]')
            ylabel(app.Spectrum2, 'Counts [1/s]')
            app.Spectrum3 = uiaxes(app.MultiGraphTab);
            title(app.Spectrum3, 'Spectrum 2')
            xlabel(app.Spectrum3, 'Wavelength [nm]')
            ylabel(app.Spectrum3, 'Counts [1/s]')
            app.Map2 = uiaxes(app.MultiGraphTab);
            title(app.Map2, 'Map 1')
            xlabel(app.Map2, 'X [mm]')
            ylabel(app.Map2, 'Y [mm]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_multi_graph(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end