classdef app_path_maker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hyperspectralanalyzerbyMarcelSchmidtUIFigure  matlab.ui.Figure
        x_range_minSpinnerLabel      matlab.ui.control.Label
        x_range_minSpinner           matlab.ui.control.Spinner
        x_range_maxSpinnerLabel      matlab.ui.control.Label
        x_range_maxSpinner           matlab.ui.control.Spinner
        y_range_minSpinnerLabel      matlab.ui.control.Label
        y_range_minSpinner           matlab.ui.control.Spinner
        y_range_maxSpinnerLabel      matlab.ui.control.Label
        y_range_maxSpinner           matlab.ui.control.Spinner
        sample_shapDropDownLabel     matlab.ui.control.Label
        sample_shapDropDown          matlab.ui.control.DropDown
        x_circle_middleSpinnerLabel  matlab.ui.control.Label
        x_circle_middleSpinner       matlab.ui.control.Spinner
        y_circle_middleSpinnerLabel  matlab.ui.control.Label
        y_circle_middleSpinner       matlab.ui.control.Spinner
        radius_circleSpinnerLabel    matlab.ui.control.Label
        radius_circleSpinner         matlab.ui.control.Spinner
        directionDropDownLabel       matlab.ui.control.Label
        directionDropDown            matlab.ui.control.DropDown
        file_nameEditFieldLabel      matlab.ui.control.Label
        file_nameEditField           matlab.ui.control.EditField
        creat_pathButton             matlab.ui.control.Button
        step_size_minSpinnerLabel    matlab.ui.control.Label
        step_size_minSpinner         matlab.ui.control.Spinner
        step_size_maxSpinnerLabel    matlab.ui.control.Label
        step_size_maxSpinner         matlab.ui.control.Spinner
        meanderCheckBox              matlab.ui.control.CheckBox
        saveButton                   matlab.ui.control.Button
        GridLabel                    matlab.ui.control.Label
        waferLabel                   matlab.ui.control.Label
        SampleStyleLabel             matlab.ui.control.Label
        display_styleDropDownLabel   matlab.ui.control.Label
        display_styleDropDown        matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        path % Description
    end
    
    methods (Access = private)
        
        function path = creat_path(app)
            display_type = '';
            
            %% starting and ending step size
            % if step_size > min_step_size the path beginns with step size = step_size
            % and then fills the spaces between untill min_step_size has been reached
            step_size = app.step_size_maxSpinner.Value;
            
            %% declaring functional variables
            path = [];
            path_temp = [];
            i = 0;
            
            %% creation off all maeshes
            while 1
                i = i+1;
                x = app.x_range_minSpinner.Value : step_size : app.x_range_maxSpinner.Value;
                y = app.y_range_minSpinner.Value : step_size : app.y_range_maxSpinner.Value;
                
                [X,Y] = meshgrid(x,y);
                mesh_size = size(X);
                
                %combining x and y coordinat
                for ii = 1 : mesh_size(1)
                    for iii = 1 : mesh_size(2)
                        path_temp_temp(iii,1) = X(ii,iii);
                        path_temp_temp(iii,2) = Y(ii,iii);
                    end
                    
                    %creation of meanda structure
                    if app.meanderCheckBox.Value
                        if mod(ii,2) == 1
                            path_temp_temp = flipud(path_temp_temp);
                        end
                    end
                    path_temp = [path_temp;path_temp_temp];
                end
                
                if app.meanderCheckBox.Value
                    %conecting smaler meshes with the rigth meanda
                    if mod(i,2) == 1
                        path_temp = flipud(path_temp);
                    end
                end
                path = [path;path_temp];
                
                %decreasing the step size of the mesh and break if the smalest mesh size is
                %reacht
                step_size = step_size/2;
                if app.step_size_minSpinner.Value > step_size
                    break
                end
            end
            
            %% delet all double values
            path = unique(path,'rows','stable');
            
            %% delet all points outside of the circle
            switch app.sample_shapDropDown.Value
                case 'wafer'
                    path(sqrt((app.x_circle_middleSpinner.Value-path(:,1)).^2+(app.y_circle_middleSpinner.Value-path(:,2)).^2)>=app.radius_circleSpinner.Value,:)=[];
            end
            
            %% change direction
            switch app.directionDropDown.Value
                case 'upwards'
                    path = flipud(path);
            end
            
            %% plot the points
            plot(app.UIAxes,path(:,1),path(:,2),app.display_styleDropDown.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %sets the window to the middle of the screen
            screen = get(0,'ScreenSize');
            window_width = app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Position(3);
            window_height = app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Position(4);
            x = screen(1,3)/2-window_width/2;
            y = screen(1,4)/2-window_height/2;
            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Position = [x,y,window_width,window_height];
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if ~isempty(app.path)
                path_add_save_temp = uigetdir(matlabroot,'Select path for saving');
                str = [path_add_save_temp,"/",app.file_nameEditField.Value,".txt"];
                file_name = char(join(str,""));
                
                %% wrigth coordinats to .txt file
                writematrix(app.path,file_name,'Delimiter','tab');
            else
                f = errordlg('You must create a path first');
            end
        end

        % Button pushed function: creat_pathButton
        function creat_pathButtonPushed(app, event)
            app.path = creat_path(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure = uifigure('Visible', 'off');
            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Name = 'hyperspectral analyzer by Marcel Schmidt at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.x_range_minSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_range_minSpinnerLabel.Text = 'x_range_min';
            app.x_range_minSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_range_minSpinner.Limits = [0 Inf];
            app.x_range_maxSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_range_maxSpinnerLabel.Text = 'x_range_max';
            app.x_range_maxSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_range_maxSpinner.Limits = [0 Inf];
            app.x_range_maxSpinner.Value = 76;
            app.y_range_minSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.y_range_minSpinnerLabel.Text = 'y_range_min';
            app.y_range_minSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.y_range_minSpinner.Limits = [0 Inf];
            app.y_range_maxSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.y_range_maxSpinnerLabel.Text = 'y_range_max';
            app.y_range_maxSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.y_range_maxSpinner.Limits = [0 Inf];
            app.y_range_maxSpinner.Value = 76;
            app.sample_shapDropDownLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.sample_shapDropDownLabel.Text = 'sample_shap';
            app.sample_shapDropDown = uidropdown(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.sample_shapDropDown.Items = {'wafer', 'rectangle'};
            app.sample_shapDropDown.Value = 'wafer';
            app.x_circle_middleSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_circle_middleSpinnerLabel.Text = 'x_circle_middle';
            app.x_circle_middleSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.x_circle_middleSpinner.Limits = [0 Inf];
            app.x_circle_middleSpinner.Value = 37.5;
            app.y_circle_middleSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.y_circle_middleSpinnerLabel.Text = 'y_circle_middle';
            app.y_circle_middleSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.y_circle_middleSpinner.Limits = [0 Inf];
            app.y_circle_middleSpinner.Value = 37;
            app.radius_circleSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.radius_circleSpinnerLabel.Text = 'radius_circle';
            app.radius_circleSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.radius_circleSpinner.LowerLimitInclusive = 'off';
            app.radius_circleSpinner.Limits = [0 Inf];
            app.radius_circleSpinner.Value = 39;
            app.directionDropDownLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.directionDropDownLabel.Text = 'direction';
            app.directionDropDown = uidropdown(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.directionDropDown.Items = {'downwards', 'upwards'};
            app.directionDropDown.Value = 'downwards';
            app.file_nameEditFieldLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.file_nameEditFieldLabel.Text = 'file_name';
            app.file_nameEditField = uieditfield(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'text');
            app.creat_pathButton = uibutton(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'push');
            app.creat_pathButton.ButtonPushedFcn = createCallbackFcn(app, @creat_pathButtonPushed, true);
            app.creat_pathButton.Text = 'creat_path';
            app.step_size_minSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.step_size_minSpinnerLabel.Text = 'step_size_min';
            app.step_size_minSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.step_size_minSpinner.LowerLimitInclusive = 'off';
            app.step_size_minSpinner.Limits = [0 Inf];
            app.step_size_minSpinner.Value = 1;
            app.step_size_maxSpinnerLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.step_size_maxSpinnerLabel.Text = 'step_size_max';
            app.step_size_maxSpinner = uispinner(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.step_size_maxSpinner.LowerLimitInclusive = 'off';
            app.step_size_maxSpinner.Limits = [0 Inf];
            app.step_size_maxSpinner.Value = 1;
            app.meanderCheckBox = uicheckbox(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.meanderCheckBox.Text = 'meander';
            app.meanderCheckBox.Value = true;
            app.saveButton = uibutton(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.GridLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.GridLabel.Text = 'Grid';
            app.waferLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.waferLabel.Text = 'wafer';
            app.SampleStyleLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.SampleStyleLabel.Text = 'Sample Style';
            app.display_styleDropDownLabel = uilabel(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.display_styleDropDownLabel.Text = 'display_style';
            app.display_styleDropDown = uidropdown(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            app.display_styleDropDown.Items = {'-', '*'};
            app.display_styleDropDown.Value = '-';
            app.UIAxes = uiaxes(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure);
            title(app.UIAxes, 'Path Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.hyperspectralanalyzerbyMarcelSchmidtUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_path_maker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hyperspectralanalyzerbyMarcelSchmidtUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hyperspectralanalyzerbyMarcelSchmidtUIFigure)
        end
    end
end