classdef app_single_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        menuMenu                        matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        CopytoclipboardMenu             matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        Copy_mapMenu                    matlab.ui.container.Menu
        Copy_map_graphMenu              matlab.ui.container.Menu
        Copy_map_dataMenu               matlab.ui.container.Menu
        Copy_spectrumMenu               matlab.ui.container.Menu
        Copy_spectrum_graphMenu         matlab.ui.container.Menu
        Copy_spectrum_dataMenu          matlab.ui.container.Menu
        send_data_toMenu                matlab.ui.container.Menu
        single_graphMenu                matlab.ui.container.Menu
        multi_graph_1Menu               matlab.ui.container.Menu
        multi_graph_2Menu               matlab.ui.container.Menu
        multi_graph_bothMenu            matlab.ui.container.Menu
        gradient_graphMenu              matlab.ui.container.Menu
        fit_graphMenu                   matlab.ui.container.Menu
        map_settingsMenu                matlab.ui.container.Menu
        Map_colorMenu                   matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        graph_settingsMenu              matlab.ui.container.Menu
        Lock_zoomMenu                   matlab.ui.container.Menu
        histogram_settingsMenu          matlab.ui.container.Menu
        Disable_statisticsMenu          matlab.ui.container.Menu
        binsMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SingleGraphTab                  matlab.ui.container.Tab
        MapParameterPanel               matlab.ui.container.Panel
        LowerWavelengthSpinnerLabel     matlab.ui.control.Label
        MapLowerWavelengthSpinner_1     matlab.ui.control.Spinner
        UpperWavelengthSpinner_4Label   matlab.ui.control.Label
        MapUpperWavelengthSpinner_1     matlab.ui.control.Spinner
        MapRefreshButton_1              matlab.ui.control.Button
        MaxCountsEditField_3Label       matlab.ui.control.Label
        MapMaxCountsEditField_1         matlab.ui.control.NumericEditField
        MinCountsEditField_3Label       matlab.ui.control.Label
        MapMinCountsEditField_1         matlab.ui.control.NumericEditField
        ColorstepsEditField_3Label      matlab.ui.control.Label
        MapColorstepsEditField_1        matlab.ui.control.NumericEditField
        Map_switch_1                    matlab.ui.control.Switch
        PowerSpinnerLabel               matlab.ui.control.Label
        PowerSpinner_1                  matlab.ui.control.Spinner
        TemperatureIndexSpinnerLabel    matlab.ui.control.Label
        TemperatureIndexSpinner_1       matlab.ui.control.Spinner
        MapappearanceDropDown_1         matlab.ui.control.DropDown
        ContourCheckBox_1               matlab.ui.control.CheckBox
        Tamperature_Map                 matlab.ui.control.Switch
        ncontourlinesSpinner_1Label     matlab.ui.control.Label
        ncontourlinesSpinner_1          matlab.ui.control.Spinner
        SpectrumParameterPanel          matlab.ui.container.Panel
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner_1                      matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner_1                      matlab.ui.control.Spinner
        Spectrum_RefreshButton_1        matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        showfullrangeCheckBox_1         matlab.ui.control.CheckBox
        pointsDropDown_1Label           matlab.ui.control.Label
        pointsDropDown_1                matlab.ui.control.DropDown
        SmoothingDropDown_1Label        matlab.ui.control.Label
        SmoothingDropDown_1             matlab.ui.control.DropDown
        WindowsizeSpinner_1Label        matlab.ui.control.Label
        WindowsizeSpinner_1             matlab.ui.control.Spinner
        sgolaydegreeSpinner_1Label      matlab.ui.control.Label
        sgolaydegreeSpinner_1           matlab.ui.control.Spinner
        startwavelengthSpinnerLabel     matlab.ui.control.Label
        startwavelengthSpinner          matlab.ui.control.Spinner
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        x_contentSpinnerLabel           matlab.ui.control.Label
        x_contentSpinner                matlab.ui.control.Spinner
        TEditFieldLabel                 matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        show_materialCheckBox           matlab.ui.control.CheckBox
        T_typeDropDownLabel             matlab.ui.control.Label
        T_typeDropDown                  matlab.ui.control.DropDown
        SampleInformationTextAreaLabel  matlab.ui.control.Label
        Sample_Information_1_TextArea   matlab.ui.control.TextArea
        WindowParameterPanel            matlab.ui.container.Panel
        XSpinner_5Label                 matlab.ui.control.Label
        XSpinner_5                      matlab.ui.control.Spinner
        YSpinner_5Label                 matlab.ui.control.Label
        YSpinner_5                      matlab.ui.control.Spinner
        WSpinnerLabel                   matlab.ui.control.Label
        WSpinner                        matlab.ui.control.Spinner
        HSpinnerLabel                   matlab.ui.control.Label
        HSpinner                        matlab.ui.control.Spinner
        locktoareaCheckBox_1            matlab.ui.control.CheckBox
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        contrastEditFieldLabel          matlab.ui.control.Label
        contrastEditField               matlab.ui.control.NumericEditField
        Map1                            matlab.ui.control.UIAxes
        Spectrum1                       matlab.ui.control.UIAxes
        Spectrum1_2                     matlab.ui.control.UIAxes
    end

    %     --------------------------------------------------------------------------------------------------------------------
    %     Copyright (c) 2022 Marcel Schmidt
    %     This program is free software: you can redistribute it and/or modify
    %     it under the terms of the GNU Affero General Public License as published
    %     by the Free Software Foundation, either version 3 of the License, or
    %     (at your option) any later version.
    %
    %     This program is distributed in the hope that it will be useful,
    %     but WITHOUT ANY WARRANTY; without even the implied warranty of
    %     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %     GNU Affero General Public License for more details.
    %
    %     You should have received a copy of the GNU Affero General Public License
    %     along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %     --------------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        DialogImportApp;
        DialogExportApp;
        DialogPreferencesApp;
        cross_handle;
        rectangle_handle;
        area_count_range_handle1;
        area_spectral_range_handle1;
        starting_x;
        starting_y;
        starting_power;
        starting_tamperature;
        starting_color_steps;
        map_typ;
        idx_list;
        spectrum;
        range_vector;
        last_map;
        c;
        last_spectrum;
        lock_zoom_graph;
        bin;
        show_histogram_statistics;
    end
    
    properties (Access = public)
        gradient_dimantion;
        PLMap_Data;
        data;
        data_path;
        Data_Fit;
        programm_path;
        min_file_size;
        contourtext;
        contour_lines;
        selectedTab;
        data_map;
        data_position;
        area_dim_1;
    end
    
    methods (Access = private)
        function import_data(app,Data_Map,Data_Fit)
            start_values = cell(1,5);
            start_values{1,1} = app.starting_power;
            start_values{1,2} = app.starting_tamperature;
            start_values{1,3} = app.starting_x;
            start_values{1,4} = app.starting_y;
            start_values{1,5} = app.starting_color_steps;
            start_values{1,6} = app.map_typ;
            
            app.PLMap_Data = [];
            app.PLMap_Data = Data_Map;
            app.Data_Fit = [];
            app.Data_Fit = Data_Fit;
            
            [app.MapLowerWavelengthSpinner_1.Value,app.MapUpperWavelengthSpinner_1.Value,app.TemperatureIndexSpinner_1.Value,app.PowerSpinner_1.Value] = start_Map(app,app.Map1,app.Spectrum1,start_values,app.XSpinner_1,app.YSpinner_1,app.PowerSpinner_1,app.TemperatureIndexSpinner_1,app.ContourCheckBox_1.Value);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_1.Value,1);
            temperature = meta_data_text_average_tamperature(app.PLMap_Data.Temperature_array,1,power_index);
            app.Sample_Information_1_TextArea.Value = {app.PLMap_Data.Sample;app.PLMap_Data.Date;app.PLMap_Data.Measurment_time;temperature};
        end 
        % ----------------------------------------------------------------------------------------------------------------
        % Display Map
        % ----------------------------------------------------------------------------------------------------------------
        function [lower_wavelength,upper_wavelength,temperature,power] = start_Map(app,Defind_Map_Axis,spectrum,start_values,x_spinner,...
                y_spinner,power_spiner,tamperature_spinner,contour_state)
            %define indeces of start power and tamperature
            power_index = define_Spinner(power_spiner,app.PLMap_Data.Power,start_values{1,1});
            tamperature_index = define_Spinner(tamperature_spinner,app.PLMap_Data.Temperature_index,...
                start_values{1,2});
            x = define_Spinner(x_spinner,app.PLMap_Data.X_vector,start_values{1,3});
            y = define_Spinner(y_spinner,app.PLMap_Data.Y_vector,start_values{1,4});
            
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
  
            %displays Map
            [lower_wavelength,upper_wavelength,temperature,power,app.data_map,app.c] = update_map(app.PLMap_Data,tamperature_spinner.Value,...
                power_spiner.Value,0,2000,Defind_Map_Axis,start_values{1,5},start_values{1,6},-inf,inf,label,'integrated',contour_state,...
                app.ncontourlinesSpinner_1.Value,app.contourtext,app.area_dim_1,app.locktoareaCheckBox_1.Value);
            
            app.last_map = Defind_Map_Axis;
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            app.minEditField.Value = min(app.data_map,[],'all');
            app.maxEditField.Value = max(app.data_map,[],'all');
            app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;
            
            %Display first Spectrum at start point
            [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(spectrum,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array,...
                tamperature_index,power_index,y,x,lower_wavelength,upper_wavelength,'All_points','movmean',1,1,1000,'PL_Map',0);
        end
        
        function show_map(app)
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            label{4} = 'Intensity (Counts/s)';
            
            %displays Map
            [app.MapLowerWavelengthSpinner_1.Value,app.MapUpperWavelengthSpinner_1.Value,app.TemperatureIndexSpinner_1.Value,app.PowerSpinner_1.Value,app.data_map,app.c] = update_map(app.PLMap_Data,...
                app.TemperatureIndexSpinner_1.Value,app.PowerSpinner_1.Value,app.MapLowerWavelengthSpinner_1.Value,...
                app.MapUpperWavelengthSpinner_1.Value,app.Map1,app.MapColorstepsEditField_1.Value,app.map_typ,app.MapMinCountsEditField_1.Value,...
                app.MapMaxCountsEditField_1.Value,label,app.MapappearanceDropDown_1.Value,app.ContourCheckBox_1.Value,...
                app.ncontourlinesSpinner_1.Value,app.contourtext,app.area_dim_1,app.locktoareaCheckBox_1.Value);
            
            app.last_map = app.Map1;
        end
    end 
    
    methods (Access = public)
        function import_data_public(app,Data_Map,Data_Fit,~)
            start_values = cell(1,5);
            start_values{1,1} = app.starting_power;
            start_values{1,2} = app.starting_tamperature;
            start_values{1,3} = app.starting_x;
            start_values{1,4} = app.starting_y;
            start_values{1,5} = app.starting_color_steps;
            start_values{1,6} = app.map_typ;
            
            app.PLMap_Data = [];
            app.PLMap_Data = Data_Map;
            app.Data_Fit = Data_Fit;
            
            [app.MapLowerWavelengthSpinner_1.Value,app.MapUpperWavelengthSpinner_1.Value,app.TemperatureIndexSpinner_1.Value,app.PowerSpinner_1.Value] = start_Map(app,app.Map1,app.Spectrum1,start_values,app.XSpinner_1,app.YSpinner_1,app.PowerSpinner_1,app.TemperatureIndexSpinner_1,app.ContourCheckBox_1.Value);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_1.Value,1);
            temperature = meta_data_text_average_tamperature(app.PLMap_Data.Temperature_array,1,power_index);
            app.Sample_Information_1_TextArea.Value = {app.PLMap_Data.Sample;app.PLMap_Data.Date;app.PLMap_Data.Measurment_time;temperature};
        end 
        
        function update_contour_lines(app,contour_lines,contourtext)
            app.contour_lines = contour_lines;
            app.ncontourlinesSpinner_1.Value = contour_lines;
            app.contourtext = contourtext;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data_Map, Data_Fit)
            %Imports the Data to a Variable
            app.PLMap_Data = [];
            app.Data_Fit = [];
            
            str = pwd; %path containing the data
            str = [str,'/'];
            app.programm_path = join(str,"");
            temp_import_Structure = load([app.programm_path, 'config']);
            config_data = temp_import_Structure.config;
            
            app.starting_x = config_data.x;
            app.starting_y = config_data.y;
            app.starting_power = 'low';
            app.starting_tamperature = 'low';
            app.starting_color_steps = config_data.color_steps;
            
            app.map_typ =  config_data.map_typ;
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black'};
            app.idx_list = find(contains(list,config_data.map_typ));
            
            app.MapColorstepsEditField_1.Value = config_data.color_steps;
            app.data_path = config_data.path;
            app.min_file_size = config_data.min_file_size;
            app.contour_lines = config_data.contour_lines;
            app.contourtext = config_data.contourtext;
            app.bin = config_data.bin;
            app.show_histogram_statistics = config_data.show_histogram_statistics;
            app.area_dim_1 = [];
            app.lock_zoom_graph = 0;

            app.selectedTab = app.TabGroup.SelectedTab;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            if ~isempty(Data_Map)
                import_data(app,Data_Map,Data_Fit);
            end
        end

        % Value changed function: MapLowerWavelengthSpinner_1, 
        % MapUpperWavelengthSpinner_1, MapappearanceDropDown_1
        function MapLowerWavelengthSpinner_1ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            app.minEditField.Value = min(app.data_map,[],'all');
            app.maxEditField.Value = max(app.data_map,[],'all');
            app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;
            
            %draws range in the Graph
            XSpinner_1ValueChanged(app, event)
        end

        % Button pushed function: MapRefreshButton_1
        function MapRefreshButton_1Pushed(app, event)
            %define colormap dimentions
            app.MapColorstepsEditField_1.Value =100;
            app.MapMinCountsEditField_1.Value = -inf;
            app.MapMaxCountsEditField_1.Value = inf;
            
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            app.minEditField.Value = min(app.data_map,[],'all');
            app.maxEditField.Value = max(app.data_map,[],'all');
            app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;
        end

        % Value changed function: TEditField, T_typeDropDown, 
        % WindowsizeSpinner_1, XSpinner_1, YSpinner_1, 
        % sgolaydegreeSpinner_1, show_materialCheckBox, 
        % showfullrangeCheckBox_1, startwavelengthSpinner, 
        % x_contentSpinner
        function XSpinner_1ValueChanged(app, event)
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_1.Value,1);
            app.XSpinner_1.Value = app.PLMap_Data.X_vector(x);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_1.Value,1);
            app.YSpinner_1.Value = app.PLMap_Data.Y_vector(y);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_1.Value,1);
            app.PowerSpinner_1.Value = app.PLMap_Data.Power(power_index);
            
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %Display first Spectrum at starting Point
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            if app.showfullrangeCheckBox_1.Value
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %dispalys the full spectrale range
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(app.Spectrum1,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array, ...
                    app.TemperatureIndexSpinner_1.Value,power_index,y,x,min(app.PLMap_Data.Wavelength), ...
                    max(app.PLMap_Data.Wavelength),app.pointsDropDown_1.Value,app.SmoothingDropDown_1.Value, ...
                    app.WindowsizeSpinner_1.Value,app.sgolaydegreeSpinner_1.Value,app.startwavelengthSpinner.Value,'PL_Map',app.lock_zoom_graph);
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %marks the spectral region used for the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                if(app.MapLowerWavelengthSpinner_1.Value~=min(app.PLMap_Data.Wavelength))...
                        ||(app.MapUpperWavelengthSpinner_1.Value~=max(app.PLMap_Data.Wavelength))
                    mark_area(app.Spectrum1,app.area_spectral_range_handle1,app.data_map,app.spectrum,...
                        app.MapLowerWavelengthSpinner_1,app.MapUpperWavelengthSpinner_1,0)
                end
                
                if app.show_materialCheckBox.Value
                    display_material(app.Spectrum1,app.spectrum,0,app.MaterialDropDown.Value,app.x_contentSpinner.Value,app.TEditField.Value,app.T_typeDropDown.Value)
                end
            else
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %displays only the region used in the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(app.Spectrum1,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array, ...
                    app.TemperatureIndexSpinner_1.Value,power_index,y,x,app.MapLowerWavelengthSpinner_1.Value, ...
                    app.MapUpperWavelengthSpinner_1.Value,app.pointsDropDown_1.Value,app.SmoothingDropDown_1.Value, ...
                    app.WindowsizeSpinner_1.Value,app.sgolaydegreeSpinner_1.Value,app.startwavelengthSpinner.Value,'PL_Map',app.lock_zoom_graph);
            end
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %draws a cross in the the heatmap at the position of the spectrum
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            delete(app.cross_handle);
            hold(app.Map1,"on")
            app.cross_handle = plot(app.Map1,app.XSpinner_1.Value,app.YSpinner_1.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map1,"off")
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            change_spectrum_y_scale(app.Switch.Value,app.Spectrum1)
        end

        % Value changed function: MapColorstepsEditField_1, 
        % MapMaxCountsEditField_1, MapMinCountsEditField_1
        function MapMaxCountsEditField_1ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            app.minEditField.Value = min(app.data_map,[],'all');
            app.maxEditField.Value = max(app.data_map,[],'all');
            app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;

            %draws range in the Graph
            mark_area(app.Spectrum1_2,app.area_count_range_handle1,app.data_map,app.spectrum,app.MapMinCountsEditField_1,app.MapMaxCountsEditField_1,1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogImportApp)
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.DialogExportApp = DialogExportApp(app,app.map_typ);
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            app.DialogPreferencesApp = DialogPreferencesApp(app);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            app.DialogImportApp = DialogImportApp(app);
        end

        % Value changed function: PowerSpinner_1
        function PowerSpinner_1ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            app.minEditField.Value = min(app.data_map,[],'all');
            app.maxEditField.Value = max(app.data_map,[],'all');
            app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;
            
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_1.Value,1);
            app.XSpinner_1.Value = app.PLMap_Data.X_vector(x);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_1.Value,1);
            app.YSpinner_1.Value = app.PLMap_Data.Y_vector(y);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_1.Value,1);
            app.PowerSpinner_1.Value = app.PLMap_Data.Power(power_index);
            
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %Display first Spectrum at starting Point
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            if app.showfullrangeCheckBox_1.Value
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %dispalys the full spectrale range
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(app.Spectrum1,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array, ...
                    app.TemperatureIndexSpinner_1.Value,power_index,y,x,min(app.PLMap_Data.Wavelength), ...
                    max(app.PLMap_Data.Wavelength),app.pointsDropDown_1.Value,app.SmoothingDropDown_1.Value, ...
                    app.WindowsizeSpinner_1.Value,app.sgolaydegreeSpinner_1.Value,app.startwavelengthSpinner.Value,'PL_Map',app.lock_zoom_graph);
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %marks the spectral region used for the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                if(app.MapLowerWavelengthSpinner_1.Value~=min(app.PLMap_Data.Wavelength))...
                        ||(app.MapUpperWavelengthSpinner_1.Value~=max(app.PLMap_Data.Wavelength))
                    mark_area(app.Spectrum1,app.area_spectral_range_handle1,app.data_map,app.spectrum,...
                        app.MapLowerWavelengthSpinner_1,app.MapUpperWavelengthSpinner_1,0)
                end
            else
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %displays only the region used in the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(app.Spectrum1,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array, ...
                    app.TemperatureIndexSpinner_1.Value,power_index,y,x,app.MapLowerWavelengthSpinner_1.Value, ...
                    app.MapUpperWavelengthSpinner_1.Value,app.pointsDropDown_1.Value,app.SmoothingDropDown_1.Value, ...
                    app.WindowsizeSpinner_1.Value,app.sgolaydegreeSpinner_1.Value,app.startwavelengthSpinner.Value,'PL_Map',app.lock_zoom_graph);
            end
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %draws a cross in the the heatmap at the position of the spectrum
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            delete(app.cross_handle);
            hold(app.Map1,"on")
            app.cross_handle = plot(app.Map1,app.XSpinner_1.Value,app.YSpinner_1.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map1,"off")
        end

        % Value changed function: TemperatureIndexSpinner_1
        function TemperatureIndexSpinner_1ValueChanged(app, event)
            show_map(app)
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
            
            app.minEditField.Value = min(app.data_map,[],'all');
            app.maxEditField.Value = max(app.data_map,[],'all');
            app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;
            
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %define indeces of x/y position and power and changes the value to
            %the colosest value
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_1.Value,1);
            app.XSpinner_1.Value = app.PLMap_Data.X_vector(x);
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_1.Value,1);
            app.YSpinner_1.Value = app.PLMap_Data.Y_vector(y);
            power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_1.Value,1);
            app.PowerSpinner_1.Value = app.PLMap_Data.Power(power_index);
            
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %Display first Spectrum at starting Point
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            if app.showfullrangeCheckBox_1.Value
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %dispalys the full spectrale range
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(app.Spectrum1,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array, ...
                    app.TemperatureIndexSpinner_1.Value,power_index,y,x,min(app.PLMap_Data.Wavelength), ...
                    max(app.PLMap_Data.Wavelength),app.pointsDropDown_1.Value,app.SmoothingDropDown_1.Value, ...
                    app.WindowsizeSpinner_1.Value,app.sgolaydegreeSpinner_1.Value,app.startwavelengthSpinner.Value,'PL_Map',app.lock_zoom_graph);
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %marks the spectral region used for the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                if(app.MapLowerWavelengthSpinner_1.Value~=min(app.PLMap_Data.Wavelength))...
                        ||(app.MapUpperWavelengthSpinner_1.Value~=max(app.PLMap_Data.Wavelength))
                    mark_area(app.Spectrum1,app.area_spectral_range_handle1,app.data_map,app.spectrum,...
                        app.MapLowerWavelengthSpinner_1,app.MapUpperWavelengthSpinner_1,0)
                end
            else
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                %displays only the region used in the heatmap
                %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
                [app.spectrum,app.range_vector,app.last_spectrum,app.c] = Display_Spectrum(app.Spectrum1,app.PLMap_Data.Wavelength,app.PLMap_Data.data_array, ...
                    app.TemperatureIndexSpinner_1.Value,power_index,y,x,app.MapLowerWavelengthSpinner_1.Value, ...
                    app.MapUpperWavelengthSpinner_1.Value,app.pointsDropDown_1.Value,app.SmoothingDropDown_1.Value, ...
                    app.WindowsizeSpinner_1.Value,app.sgolaydegreeSpinner_1.Value,app.startwavelengthSpinner.Value,'PL_Map',app.lock_zoom_graph);
            end
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %draws a cross in the the heatmap at the position of the spectrum
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            delete(app.cross_handle);
            hold(app.Map1,"on")
            app.cross_handle = plot(app.Map1,app.XSpinner_1.Value,app.YSpinner_1.Value,"Color",'g',"Marker","+","MarkerSize",6,'LineWidth',1.5);
            hold(app.Map1,"off")
        end

        % Value changed function: WSpinner, XSpinner_5
        function XSpinner_5ValueChanged(app, event)
            %correct dimations to grid
            x = find_index(app.PLMap_Data.X_vector,app.XSpinner_5.Value,1);
            app.XSpinner_5.Value = app.PLMap_Data.X_vector(x);
            w = find_index(app.PLMap_Data.X_vector,app.XSpinner_5.Value + app.WSpinner.Value,1);
            app.WSpinner.Value = app.PLMap_Data.X_vector(w) - app.PLMap_Data.X_vector(x);
            
            %draws the waferadges in the Graph
            delete(app.rectangle_handle);
            app.rectangle_handle = rectangle(app.Map1,'Position',[app.XSpinner_5.Value app.YSpinner_5.Value app.WSpinner.Value app.HSpinner.Value],'EdgeColor','g','LineStyle','--', 'LineWidth', 2);
        end

        % Value changed function: HSpinner, YSpinner_5
        function YSpinner_5ValueChanged(app, event)
            %correct dimations to grid
            y = find_index(app.PLMap_Data.Y_vector,app.YSpinner_5.Value,1);
            app.YSpinner_5.Value = app.PLMap_Data.Y_vector(y);
            h = find_index(app.PLMap_Data.Y_vector,app.YSpinner_5.Value + app.HSpinner.Value,1);
            app.HSpinner.Value = app.PLMap_Data.Y_vector(h) - app.PLMap_Data.Y_vector(y);
            
            %draws the waferadges in the Graph
            delete(app.rectangle_handle);
            app.rectangle_handle = rectangle(app.Map1,'Position',[app.XSpinner_5.Value app.YSpinner_5.Value app.WSpinner.Value app.HSpinner.Value],'EdgeColor','g','LineStyle','--', 'LineWidth', 2);
        end

        % Value changed function: Tamperature_Map
        function Tamperature_MapValueChanged(app, event)
            %get info for map
            label{1} = 'Map';
            label{2} = 'X [mm]';
            label{3} = 'Y [mm]';
            
            switch app.Tamperature_Map.Value
                case 'Temperature_Map'
                    %label for the color plot
                    label{4} = 'Temperature (K)';
                    
                    %define start end end Wavelength index
                    power_index = find_index(app.PLMap_Data.Power,app.PowerSpinner_1.Value,1);
                    temperature_index = find_index(app.PLMap_Data.Temperature_index,app.TemperatureIndexSpinner_1.Value,1);
                    
                    %Sets Spinner Values to the Limets
                    app.TemperatureIndexSpinner_1.Value = app.PLMap_Data.Temperature_index(temperature_index);
                    app.PowerSpinner_1.Value = app.PLMap_Data.Power(power_index);
                    
                    if app.locktoareaCheckBox_1.Value == 1
                        [x_squer,y_squer,window_x,window_y] = define_area_size(app.PLMap_Data,app.area_dim_1);
                    else
                        x_squer = find_index(app.PLMap_Data.X_vector,min(app.PLMap_Data.X_vector),1);
                        y_squer = find_index(app.PLMap_Data.Y_vector,min(app.PLMap_Data.Y_vector),1);
                        window_x = length(app.PLMap_Data.X_vector);
                        window_y = length(app.PLMap_Data.Y_vector);
                    end
                    
                    pre_processed_data_map = creat_temp_Map(app.PLMap_Data.Temperature_array,temperature_index,power_index,x_squer,window_x,y_squer,window_y);
                    app.data_map = pre_processed_data_map;
                    
                    %display map
                    [app.last_map,app.c] = Display_Map(app.Map1,app.PLMap_Data.X_vector(x_squer:window_x),app.PLMap_Data.Y_vector(y_squer:window_y),...
                        pre_processed_data_map,app.MapColorstepsEditField_1.Value,app.map_typ,label,app.MapMinCountsEditField_1.Value,...
                        app.MapMaxCountsEditField_1.Value,app.ContourCheckBox_1.Value,app.ncontourlinesSpinner_1.Value,app.contourtext,app.locktoareaCheckBox_1.Value);
                case 'PL_Map'
                    %label for the color plot
                    label{4} = 'Intensity (Counts/s)';
                    
                    %displays Map
                    [app.MapLowerWavelengthSpinner_1.Value,app.MapUpperWavelengthSpinner_1.Value,app.TemperatureIndexSpinner_1.Value,app.PowerSpinner_1.Value,app.data_map,app.c] = update_map(app.PLMap_Data,...
                        app.TemperatureIndexSpinner_1.Value,app.PowerSpinner_1.Value,app.MapLowerWavelengthSpinner_1.Value,...
                        app.MapUpperWavelengthSpinner_1.Value,app.Map1,app.MapColorstepsEditField_1.Value,app.map_typ,app.MapMinCountsEditField_1.Value,...
                        app.MapMaxCountsEditField_1.Value,label,app.MapappearanceDropDown_1.Value,app.ContourCheckBox_1.Value,...
                        app.ncontourlinesSpinner_1.Value,app.contourtext,app.area_dim_1,app.locktoareaCheckBox_1.Value);
                    
                    app.last_map = app.Map1;
                    
                    %display histogram
                    update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
                    
                    app.minEditField.Value = min(app.data_map,[],'all');
                    app.maxEditField.Value = max(app.data_map,[],'all');
                    app.contrastEditField.Value = (app.maxEditField.Value / app.minEditField.Value) - 1;
            end
        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            copyed_array = mat2clip(app.data_map, '\t');
        end

        % Value changed function: pointsDropDown_1
        function pointsDropDown_1ValueChanged(app, event)
            %enable the spiner for the spectromater transition wavelength
            switch app.pointsDropDown_1.Value
                case "All_points"
                    app.startwavelengthSpinnerLabel.Enable = 'off';
                    app.startwavelengthSpinner.Enable = 'off';
                otherwise
                    app.startwavelengthSpinnerLabel.Enable = 'on';
                    app.startwavelengthSpinner.Enable = 'on';
            end
            
            %draws range in the Graph
            XSpinner_1ValueChanged(app, event)
        end

        % Value changed function: ContourCheckBox_1, 
        % ncontourlinesSpinner_1
        function ContourCheckBox_1ValueChanged(app, event)
            if app.ContourCheckBox_1.Value
                app.ncontourlinesSpinner_1Label.Enable = 'on';
                app.ncontourlinesSpinner_1.Enable = 'on';
            else
                app.ncontourlinesSpinner_1Label.Enable = 'off';
                app.ncontourlinesSpinner_1.Enable = 'off';
            end
            MapLowerWavelengthSpinner_1ValueChanged(app, event);
        end

        % Value changed function: SmoothingDropDown_1
        function SmoothingDropDown_1ValueChanged(app, event)
            switch app.SmoothingDropDown_1.Value
                case 'sgolay'
                    app.sgolaydegreeSpinner_1Label.Enable = 'on';
                    app.sgolaydegreeSpinner_1.Enable = 'on';
                otherwise
                    app.sgolaydegreeSpinner_1Label.Enable = 'off';
                    app.sgolaydegreeSpinner_1.Enable = 'off';
            end
            XSpinner_1ValueChanged(app, event);
        end

        % Menu selected function: multi_graph_1Menu
        function multi_graph_1MenuSelected(app, event)
            app_multi_graph(app.PLMap_Data,app.Data_Fit,[],[]);
        end

        % Menu selected function: multi_graph_2Menu
        function multi_graph_2MenuSelected(app, event)
            app_multi_graph([],[],app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: multi_graph_bothMenu
        function multi_graph_bothMenuSelected(app, event)
            app_multi_graph(app.PLMap_Data,app.Data_Fit,app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: gradient_graphMenu
        function gradient_graphMenuSelected(app, event)
            app_gradient_graph(app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: fit_graphMenu
        function fit_graphMenuSelected(app, event)
            app_fit_graph(app.PLMap_Data,app.Data_Fit,'PL_Map');
        end

        % Value changed function: locktoareaCheckBox_1
        function locktoareaCheckBox_1ValueChanged(app, event)
            if app.locktoareaCheckBox_1.Value
                app.area_dim_1(1) = app.XSpinner_5.Value;
                app.area_dim_1(2) = app.YSpinner_5.Value;
                app.area_dim_1(3) = app.WSpinner.Value;
                app.area_dim_1(4) = app.HSpinner.Value;
            else
                app.area_dim_1 = [];
            end
            Tamperature_MapValueChanged(app, event);
        end

        % Menu selected function: Map_colorMenu
        function Map_colorMenuSelected(app, event)
            list = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray',...
                'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'black','wbry'};
            [idx,~] = listdlg('ListString',list,'InitialValue',app.idx_list,'SelectionMode','single');
            if ~isempty(idx)
                app.idx_list = idx;
                app.map_typ = list{idx};
            end
            show_map(app);
        end

        % Menu selected function: Lock_zoomMenu
        function Lock_zoomMenuSelected(app, event)
            if app.lock_zoom_graph
                app.lock_zoom_graph = 0;
            else
                app.lock_zoom_graph = 1;
            end
        end

        % Button pushed function: Spectrum_RefreshButton_1
        function Spectrum_RefreshButton_1Pushed(app, event)
            app.lock_zoom_graph = 0;
            XSpinner_1ValueChanged(app, event);
        end

        % Menu selected function: single_graphMenu
        function single_graphMenuSelected(app, event)
            app_single_graph(app.PLMap_Data,app.Data_Fit);
        end

        % Menu selected function: binsMenu
        function binsMenuSelected(app, event)
            prompt = {'Enter number of bins:'};
            dlgtitle = 'define the number of bins';
            dims = [1 25];
            s = num2str(app.bin,'%u');
            definput = {s};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.bin = ceil(str2num(answer{1}));
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
        end

        % Menu selected function: Copy_map_graphMenu
        function Copy_map_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_map,ax, 'colorbar', app.c);
        end

        % Menu selected function: Copy_map_dataMenu
        function Copy_map_dataMenuSelected(app, event)
            copyed_array = mat2clip(app.data_map, '\t');
        end

        % Menu selected function: Copy_spectrum_graphMenu
        function Copy_spectrum_graphMenuSelected(app, event)
            f = figure;
            ax = axes(f);
            copyUIGraphics.copyUIAxes(app.last_spectrum,ax);
        end

        % Menu selected function: Copy_spectrum_dataMenu
        function Copy_spectrum_dataMenuSelected(app, event)
            coppy_array = [app.range_vector,double(app.spectrum')];
            copyed_array = mat2clip(coppy_array, '\t');
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            switch app.MaterialDropDown.Value
                case {'gaas', 'alas', 'inas'}
                    app.x_contentSpinnerLabel.Enable = 'off';
                    app.x_contentSpinner.Enable = 'off';
                case {'algaas','ingaas'}
                    app.x_contentSpinnerLabel.Enable = 'on';
                    app.x_contentSpinner.Enable = 'on';
            end
        end

        % Menu selected function: Disable_statisticsMenu
        function Disable_statisticsMenuSelected(app, event)
            switch app.Disable_statisticsMenu.Text
                case 'Disable_statistics'
                    app.show_histogram_statistics = 0;
                    app.Disable_statisticsMenu.Text = 'Enable_statistics';
                case 'Enable_statistics'
                    app.show_histogram_statistics = 1;
                    app.Disable_statisticsMenu.Text = 'Disable_statistics';
            end
            
            %display histogram
            update_histogram(app.Spectrum1_2,app.data_map,app.bin,app.show_histogram_statistics);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'hyperspectral analyzer by Marcel Schmidt at Lehrstuhl für Angewandte Festkörpersphyik Bochum';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.menuMenu = uimenu(app.UIFigure);
            app.menuMenu.Text = 'menu';
            app.LoadDataMenu = uimenu(app.menuMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Accelerator = 'l';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExportMenu = uimenu(app.menuMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.CopytoclipboardMenu = uimenu(app.menuMenu);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Accelerator = 'c';
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.PreferencesMenu = uimenu(app.menuMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Accelerator = 'p';
            app.PreferencesMenu.Text = 'Preferences';
            app.Copy_mapMenu = uimenu(app.menuMenu);
            app.Copy_mapMenu.Text = 'Copy_map';
            app.Copy_map_graphMenu = uimenu(app.Copy_mapMenu);
            app.Copy_map_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @Copy_map_graphMenuSelected, true);
            app.Copy_map_graphMenu.Text = 'Copy_map_graph';
            app.Copy_map_dataMenu = uimenu(app.Copy_mapMenu);
            app.Copy_map_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @Copy_map_dataMenuSelected, true);
            app.Copy_map_dataMenu.Text = 'Copy_map_data';
            app.Copy_spectrumMenu = uimenu(app.menuMenu);
            app.Copy_spectrumMenu.Text = 'Copy_spectrum';
            app.Copy_spectrum_graphMenu = uimenu(app.Copy_spectrumMenu);
            app.Copy_spectrum_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @Copy_spectrum_graphMenuSelected, true);
            app.Copy_spectrum_graphMenu.Text = 'Copy_spectrum_graph';
            app.Copy_spectrum_dataMenu = uimenu(app.Copy_spectrumMenu);
            app.Copy_spectrum_dataMenu.MenuSelectedFcn = createCallbackFcn(app, @Copy_spectrum_dataMenuSelected, true);
            app.Copy_spectrum_dataMenu.Text = 'Copy_spectrum_data';
            app.send_data_toMenu = uimenu(app.UIFigure);
            app.send_data_toMenu.Text = 'send_data_to';
            app.single_graphMenu = uimenu(app.send_data_toMenu);
            app.single_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @single_graphMenuSelected, true);
            app.single_graphMenu.Text = 'single_graph';
            app.multi_graph_1Menu = uimenu(app.send_data_toMenu);
            app.multi_graph_1Menu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_1MenuSelected, true);
            app.multi_graph_1Menu.Text = 'multi_graph_1';
            app.multi_graph_2Menu = uimenu(app.send_data_toMenu);
            app.multi_graph_2Menu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_2MenuSelected, true);
            app.multi_graph_2Menu.Text = 'multi_graph_2';
            app.multi_graph_bothMenu = uimenu(app.send_data_toMenu);
            app.multi_graph_bothMenu.MenuSelectedFcn = createCallbackFcn(app, @multi_graph_bothMenuSelected, true);
            app.multi_graph_bothMenu.Text = 'multi_graph_both';
            app.gradient_graphMenu = uimenu(app.send_data_toMenu);
            app.gradient_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @gradient_graphMenuSelected, true);
            app.gradient_graphMenu.Text = 'gradient_graph';
            app.fit_graphMenu = uimenu(app.send_data_toMenu);
            app.fit_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @fit_graphMenuSelected, true);
            app.fit_graphMenu.Text = 'fit_graph';
            app.map_settingsMenu = uimenu(app.UIFigure);
            app.map_settingsMenu.Text = 'map_settings';
            app.Map_colorMenu = uimenu(app.map_settingsMenu);
            app.Map_colorMenu.MenuSelectedFcn = createCallbackFcn(app, @Map_colorMenuSelected, true);
            app.Map_colorMenu.Text = 'Map_color';
            app.Menu_2 = uimenu(app.map_settingsMenu);
            app.Menu_2.Text = 'Menu';
            app.graph_settingsMenu = uimenu(app.UIFigure);
            app.graph_settingsMenu.Text = 'graph_settings';
            app.Lock_zoomMenu = uimenu(app.graph_settingsMenu);
            app.Lock_zoomMenu.MenuSelectedFcn = createCallbackFcn(app, @Lock_zoomMenuSelected, true);
            app.Lock_zoomMenu.Text = 'Lock_zoom';
            app.histogram_settingsMenu = uimenu(app.UIFigure);
            app.histogram_settingsMenu.Text = 'histogram_settings';
            app.Disable_statisticsMenu = uimenu(app.histogram_settingsMenu);
            app.Disable_statisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @Disable_statisticsMenuSelected, true);
            app.Disable_statisticsMenu.Text = 'Disable_statistics';
            app.binsMenu = uimenu(app.histogram_settingsMenu);
            app.binsMenu.MenuSelectedFcn = createCallbackFcn(app, @binsMenuSelected, true);
            app.binsMenu.Text = '#bins';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SingleGraphTab = uitab(app.TabGroup);
            app.SingleGraphTab.Title = 'Single Graph';
            app.MapParameterPanel = uipanel(app.SingleGraphTab);
            app.MapParameterPanel.Title = 'Map Parameter';
            app.LowerWavelengthSpinnerLabel = uilabel(app.MapParameterPanel);
            app.LowerWavelengthSpinnerLabel.Text = 'Lower Wavelength';
            app.MapLowerWavelengthSpinner_1 = uispinner(app.MapParameterPanel);
            app.MapLowerWavelengthSpinner_1.ValueDisplayFormat = '%.3f';
            app.MapLowerWavelengthSpinner_1.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_1ValueChanged, true);
            app.MapLowerWavelengthSpinner_1.Value = 1000;
            app.UpperWavelengthSpinner_4Label = uilabel(app.MapParameterPanel);
            app.UpperWavelengthSpinner_4Label.Text = 'Upper Wavelength';
            app.MapUpperWavelengthSpinner_1 = uispinner(app.MapParameterPanel);
            app.MapUpperWavelengthSpinner_1.ValueDisplayFormat = '%.3f';
            app.MapUpperWavelengthSpinner_1.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_1ValueChanged, true);
            app.MapUpperWavelengthSpinner_1.Value = 1715.109591;
            app.MapRefreshButton_1 = uibutton(app.MapParameterPanel, 'push');
            app.MapRefreshButton_1.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButton_1Pushed, true);
            app.MapRefreshButton_1.Text = 'Reset';
            app.MaxCountsEditField_3Label = uilabel(app.MapParameterPanel);
            app.MaxCountsEditField_3Label.Text = 'Max Counts';
            app.MapMaxCountsEditField_1 = uieditfield(app.MapParameterPanel, 'numeric');
            app.MapMaxCountsEditField_1.ValueDisplayFormat = '%1.2e';
            app.MapMaxCountsEditField_1.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_1ValueChanged, true);
            app.MapMaxCountsEditField_1.Value = Inf;
            app.MinCountsEditField_3Label = uilabel(app.MapParameterPanel);
            app.MinCountsEditField_3Label.Text = 'Min Counts';
            app.MapMinCountsEditField_1 = uieditfield(app.MapParameterPanel, 'numeric');
            app.MapMinCountsEditField_1.ValueDisplayFormat = '%1.2e';
            app.MapMinCountsEditField_1.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_1ValueChanged, true);
            app.MapMinCountsEditField_1.Value = -Inf;
            app.ColorstepsEditField_3Label = uilabel(app.MapParameterPanel);
            app.ColorstepsEditField_3Label.Text = 'Color steps';
            app.MapColorstepsEditField_1 = uieditfield(app.MapParameterPanel, 'numeric');
            app.MapColorstepsEditField_1.RoundFractionalValues = 'on';
            app.MapColorstepsEditField_1.ValueChangedFcn = createCallbackFcn(app, @MapMaxCountsEditField_1ValueChanged, true);
            app.MapColorstepsEditField_1.Value = 100;
            app.Map_switch_1 = uiswitch(app.MapParameterPanel, 'slider');
            app.Map_switch_1.Items = {'log', 'linear'};
            app.Map_switch_1.Value = 'linear';
            app.PowerSpinnerLabel = uilabel(app.MapParameterPanel);
            app.PowerSpinnerLabel.Text = 'Power';
            app.PowerSpinner_1 = uispinner(app.MapParameterPanel);
            app.PowerSpinner_1.Step = 0.5;
            app.PowerSpinner_1.Limits = [0 76];
            app.PowerSpinner_1.ValueChangedFcn = createCallbackFcn(app, @PowerSpinner_1ValueChanged, true);
            app.PowerSpinner_1.Value = 1;
            app.TemperatureIndexSpinnerLabel = uilabel(app.MapParameterPanel);
            app.TemperatureIndexSpinnerLabel.Text = 'Temperature Index';
            app.TemperatureIndexSpinner_1 = uispinner(app.MapParameterPanel);
            app.TemperatureIndexSpinner_1.Step = 0.5;
            app.TemperatureIndexSpinner_1.Limits = [0 76];
            app.TemperatureIndexSpinner_1.ValueChangedFcn = createCallbackFcn(app, @TemperatureIndexSpinner_1ValueChanged, true);
            app.TemperatureIndexSpinner_1.Value = 1;
            app.MapappearanceDropDown_1 = uidropdown(app.MapParameterPanel);
            app.MapappearanceDropDown_1.Items = {'integrated', 'averaged'};
            app.MapappearanceDropDown_1.ValueChangedFcn = createCallbackFcn(app, @MapLowerWavelengthSpinner_1ValueChanged, true);
            app.MapappearanceDropDown_1.Value = 'integrated';
            app.ContourCheckBox_1 = uicheckbox(app.MapParameterPanel);
            app.ContourCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @ContourCheckBox_1ValueChanged, true);
            app.ContourCheckBox_1.Text = 'Contour';
            app.Tamperature_Map = uiswitch(app.MapParameterPanel, 'slider');
            app.Tamperature_Map.Items = {'Temperature_Map', 'PL_Map'};
            app.Tamperature_Map.ValueChangedFcn = createCallbackFcn(app, @Tamperature_MapValueChanged, true);
            app.Tamperature_Map.Value = 'PL_Map';
            app.ncontourlinesSpinner_1Label = uilabel(app.MapParameterPanel);
            app.ncontourlinesSpinner_1Label.Enable = 'off';
            app.ncontourlinesSpinner_1Label.Text = 'n contour lines';
            app.ncontourlinesSpinner_1 = uispinner(app.MapParameterPanel);
            app.ncontourlinesSpinner_1.Limits = [1 Inf];
            app.ncontourlinesSpinner_1.ValueChangedFcn = createCallbackFcn(app, @ContourCheckBox_1ValueChanged, true);
            app.ncontourlinesSpinner_1.Enable = 'off';
            app.ncontourlinesSpinner_1.Value = 5;
            app.SpectrumParameterPanel = uipanel(app.SingleGraphTab);
            app.SpectrumParameterPanel.Title = 'Spectrum Parameter';
            app.XSpinnerLabel = uilabel(app.SpectrumParameterPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner_1 = uispinner(app.SpectrumParameterPanel);
            app.XSpinner_1.Step = 0.5;
            app.XSpinner_1.Limits = [0 76];
            app.XSpinner_1.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.XSpinner_1.Value = 1;
            app.YSpinnerLabel = uilabel(app.SpectrumParameterPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner_1 = uispinner(app.SpectrumParameterPanel);
            app.YSpinner_1.Step = 0.5;
            app.YSpinner_1.Limits = [0 76];
            app.YSpinner_1.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.YSpinner_1.Value = 1;
            app.Spectrum_RefreshButton_1 = uibutton(app.SpectrumParameterPanel, 'push');
            app.Spectrum_RefreshButton_1.ButtonPushedFcn = createCallbackFcn(app, @Spectrum_RefreshButton_1Pushed, true);
            app.Spectrum_RefreshButton_1.Text = 'Reset';
            app.Switch = uiswitch(app.SpectrumParameterPanel, 'slider');
            app.Switch.Items = {'log', 'linear'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'linear';
            app.showfullrangeCheckBox_1 = uicheckbox(app.SpectrumParameterPanel);
            app.showfullrangeCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.showfullrangeCheckBox_1.Text = 'show full range';
            app.showfullrangeCheckBox_1.Value = true;
            app.pointsDropDown_1Label = uilabel(app.SpectrumParameterPanel);
            app.pointsDropDown_1Label.Text = 'points';
            app.pointsDropDown_1 = uidropdown(app.SpectrumParameterPanel);
            app.pointsDropDown_1.Items = {'All_points', 'even', 'odd'};
            app.pointsDropDown_1.ValueChangedFcn = createCallbackFcn(app, @pointsDropDown_1ValueChanged, true);
            app.pointsDropDown_1.Value = 'All_points';
            app.SmoothingDropDown_1Label = uilabel(app.SpectrumParameterPanel);
            app.SmoothingDropDown_1Label.Text = 'Smoothing:';
            app.SmoothingDropDown_1 = uidropdown(app.SpectrumParameterPanel);
            app.SmoothingDropDown_1.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.SmoothingDropDown_1.ValueChangedFcn = createCallbackFcn(app, @SmoothingDropDown_1ValueChanged, true);
            app.SmoothingDropDown_1.Tooltip = {'''movmean'' — Moving average over each window of A. This method is useful for reducing periodic trends in data.'; '''movmedian'' — Moving median over each window of A. This method is useful for reducing periodic trends in data when outliers are present.'; '''gaussian'' — Gaussian-weighted moving average over each window of A.'; '''lowess'' — Linear regression over each window of A. This method can be computationally expensive'; ' but results in fewer discontinuities.'; '''loess'' — Quadratic regression over each window of A. This method is slightly more computationally expensive than ''lowess''.'; '''rlowess'' — Robust linear regression over each window of A. This method is a more computationally expensive version of the method ''lowess'''; ' but it is more robust to outliers.'; '''rloess'' — Robust quadratic regression over each window of A. This method is a more computationally expensive version of the method ''loess'''; ' but it is more robust to outliers.'; '''sgolay'' — Savitzky-Golay filter'; ' which smooths according to a quadratic polynomial that is fitted over each window of A. This method can be more effective than other methods when the data varies rapidly.'};
            app.SmoothingDropDown_1.Value = 'movmean';
            app.WindowsizeSpinner_1Label = uilabel(app.SpectrumParameterPanel);
            app.WindowsizeSpinner_1Label.Text = 'Window size';
            app.WindowsizeSpinner_1 = uispinner(app.SpectrumParameterPanel);
            app.WindowsizeSpinner_1.Limits = [1 Inf];
            app.WindowsizeSpinner_1.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.WindowsizeSpinner_1.Value = 1;
            app.sgolaydegreeSpinner_1Label = uilabel(app.SpectrumParameterPanel);
            app.sgolaydegreeSpinner_1Label.Enable = 'off';
            app.sgolaydegreeSpinner_1Label.Text = 'sgolay degree';
            app.sgolaydegreeSpinner_1 = uispinner(app.SpectrumParameterPanel);
            app.sgolaydegreeSpinner_1.Limits = [1 Inf];
            app.sgolaydegreeSpinner_1.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.sgolaydegreeSpinner_1.Enable = 'off';
            app.sgolaydegreeSpinner_1.Value = 1;
            app.startwavelengthSpinnerLabel = uilabel(app.SpectrumParameterPanel);
            app.startwavelengthSpinnerLabel.Enable = 'off';
            app.startwavelengthSpinnerLabel.Text = 'start wavelength';
            app.startwavelengthSpinner = uispinner(app.SpectrumParameterPanel);
            app.startwavelengthSpinner.Limits = [320 1716];
            app.startwavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.startwavelengthSpinner.Enable = 'off';
            app.startwavelengthSpinner.Value = 1000;
            app.MaterialDropDownLabel = uilabel(app.SpectrumParameterPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.SpectrumParameterPanel);
            app.MaterialDropDown.Items = {'gaas', 'alas', 'algaas', 'inas', 'ingaas'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'gaas';
            app.x_contentSpinnerLabel = uilabel(app.SpectrumParameterPanel);
            app.x_contentSpinnerLabel.Enable = 'off';
            app.x_contentSpinnerLabel.Text = 'x_content';
            app.x_contentSpinner = uispinner(app.SpectrumParameterPanel);
            app.x_contentSpinner.Step = 0.01;
            app.x_contentSpinner.Limits = [0 1];
            app.x_contentSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.x_contentSpinner.Enable = 'off';
            app.x_contentSpinner.Value = 0.06;
            app.TEditFieldLabel = uilabel(app.SpectrumParameterPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.SpectrumParameterPanel, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.TEditField.Value = 300;
            app.show_materialCheckBox = uicheckbox(app.SpectrumParameterPanel);
            app.show_materialCheckBox.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.show_materialCheckBox.Text = 'show_material';
            app.T_typeDropDownLabel = uilabel(app.SpectrumParameterPanel);
            app.T_typeDropDownLabel.Text = 'T_type';
            app.T_typeDropDown = uidropdown(app.SpectrumParameterPanel);
            app.T_typeDropDown.Items = {'varshnie', 'peassler'};
            app.T_typeDropDown.ValueChangedFcn = createCallbackFcn(app, @XSpinner_1ValueChanged, true);
            app.T_typeDropDown.Value = 'varshnie';
            app.SampleInformationTextAreaLabel = uilabel(app.SingleGraphTab);
            app.SampleInformationTextAreaLabel.Text = 'Sample Information';
            app.Sample_Information_1_TextArea = uitextarea(app.SingleGraphTab);
            app.WindowParameterPanel = uipanel(app.SingleGraphTab);
            app.WindowParameterPanel.Title = 'Window Parameter';
            app.XSpinner_5Label = uilabel(app.WindowParameterPanel);
            app.XSpinner_5Label.Text = 'X';
            app.XSpinner_5 = uispinner(app.WindowParameterPanel);
            app.XSpinner_5.Step = 0.5;
            app.XSpinner_5.Limits = [0 Inf];
            app.XSpinner_5.ValueChangedFcn = createCallbackFcn(app, @XSpinner_5ValueChanged, true);
            app.XSpinner_5.Value = 30;
            app.YSpinner_5Label = uilabel(app.WindowParameterPanel);
            app.YSpinner_5Label.Text = 'Y';
            app.YSpinner_5 = uispinner(app.WindowParameterPanel);
            app.YSpinner_5.Step = 0.5;
            app.YSpinner_5.Limits = [0 Inf];
            app.YSpinner_5.ValueChangedFcn = createCallbackFcn(app, @YSpinner_5ValueChanged, true);
            app.YSpinner_5.Value = 30;
            app.WSpinnerLabel = uilabel(app.WindowParameterPanel);
            app.WSpinnerLabel.Text = 'W';
            app.WSpinner = uispinner(app.WindowParameterPanel);
            app.WSpinner.Step = 0.5;
            app.WSpinner.Limits = [0 Inf];
            app.WSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinner_5ValueChanged, true);
            app.WSpinner.Tooltip = {'Width of the window area.'};
            app.WSpinner.Value = 10;
            app.HSpinnerLabel = uilabel(app.WindowParameterPanel);
            app.HSpinnerLabel.Text = 'H';
            app.HSpinner = uispinner(app.WindowParameterPanel);
            app.HSpinner.Step = 0.5;
            app.HSpinner.Limits = [0 Inf];
            app.HSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinner_5ValueChanged, true);
            app.HSpinner.Tooltip = {'Window area height.'};
            app.HSpinner.Value = 10;
            app.locktoareaCheckBox_1 = uicheckbox(app.WindowParameterPanel);
            app.locktoareaCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @locktoareaCheckBox_1ValueChanged, true);
            app.locktoareaCheckBox_1.Text = 'lock to area';
            app.minEditFieldLabel = uilabel(app.WindowParameterPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.WindowParameterPanel, 'numeric');
            app.maxEditFieldLabel = uilabel(app.WindowParameterPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.WindowParameterPanel, 'numeric');
            app.contrastEditFieldLabel = uilabel(app.WindowParameterPanel);
            app.contrastEditFieldLabel.Text = 'contrast';
            app.contrastEditField = uieditfield(app.WindowParameterPanel, 'numeric');
            app.Map1 = uiaxes(app.SingleGraphTab);
            title(app.Map1, 'Map')
            xlabel(app.Map1, 'X [mm]')
            ylabel(app.Map1, 'Y [mm]')
            app.Spectrum1 = uiaxes(app.SingleGraphTab);
            title(app.Spectrum1, 'Spectrum')
            xlabel(app.Spectrum1, 'Wavelength [nm]')
            ylabel(app.Spectrum1, 'Counts [1/s]')
            app.Spectrum1_2 = uiaxes(app.SingleGraphTab);
            title(app.Spectrum1_2, 'Spectral range')
            xlabel(app.Spectrum1_2, 'Intensity [Counts/s]')
            ylabel(app.Spectrum1_2, 'Counts [#]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_single_graph(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end